% description: L’aplicació Geo-SIV (v 1.0 2021) és un programari lliure desenvolupat per l’Àrea de Recursos Geològics de l’Institut Cartogràfic i Geològic de Catalunya (ICGC) distribuït sota la
% llicència d’ús CC-BY, dissenyat per permetre efectuar avaluacions preliminars de sistemes d’intercanvi geotèrmic en circuit vertical tancat per instal·lacions de calefacció, refrigeració i aigua
% calenta sanitària de fins a 70 kW (tipus A i B d'acord amb la norma UNE-100715-2014) en el territori de Catalunya. El programaria permet calcular la demanda tèrmica mitjançant el mètode bin
% (RETScreen®, Natural Resources, Canada 2016) i dimensiona el camp de captació necessari mitjançant bescanviadors de calor geotèrmics (pous verticals de circuit tancat) en base a la teoria de la font
% lineal de Kelvin, que també és la base de la metodologia desenvolupada per la International Ground Source Heat Pump Association (IGSHPA) i també implementa a IDAE (2010). Les característiques de les
% bombes de calor geotèrmiques són seleccionades a partir d’una base de dades o introduïdes per l’usuari. L’eina utilitza dades geològiques i climatològiques publicades al GeoÍndex-Geotèrmia superficial
% de Catalunya (ICGC, 2018). L’aplicació també disposa d’un mòdul d’anàlisi econòmica i ambiental que permet comparar la solució projectada basada en geotèrmia superficial respecte altres tecnologies
% renovables i no renovables. Les dades de sortida es poden utilitzar per a estudis de pre-viabilitat. El codi s’ha programat mitjançant el programari MATLAB (v. 2020a)
classdef GeoSIV_codi1_15 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeoSIVdesigntoolv10UIFigure     matlab.ui.Figure
        lay_1                           matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        IntroTab                        matlab.ui.container.Tab
        GridLayout210                   matlab.ui.container.GridLayout
        GridLayout211                   matlab.ui.container.GridLayout
        MetodologiaButton               matlab.ui.control.Button
        IniciareldissenyButton_2        matlab.ui.control.Button
        GridLayout213                   matlab.ui.container.GridLayout
        Image51                         matlab.ui.control.Image
        GridLayout214                   matlab.ui.container.GridLayout
        Image49                         matlab.ui.control.Image
        GridLayout291                   matlab.ui.container.GridLayout
        Image59                         matlab.ui.control.Image
        MetodologiaTab                  matlab.ui.container.Tab
        lay_2                           matlab.ui.container.GridLayout
        GridLayout69                    matlab.ui.container.GridLayout
        DiagramadelfluxdetreballdeGeoSIVv12Label  matlab.ui.control.Label
        Image52                         matlab.ui.control.Image
        GridLayout70                    matlab.ui.container.GridLayout
        ManualGeoSIVv12Button_6         matlab.ui.control.Button
        EnrereButton_2                  matlab.ui.control.Button
        IniciareldissenyButton          matlab.ui.control.Button
        Image58                         matlab.ui.control.Image
        DadesdentradaTab                matlab.ui.container.Tab
        lay_17                          matlab.ui.container.GridLayout
        lay_23                          matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        building_typoligy_panel         matlab.ui.container.Panel
        lay_26                          matlab.ui.container.GridLayout
        lay_29                          matlab.ui.container.GridLayout
        GridLayout80                    matlab.ui.container.GridLayout
        Superficieconsiderantsoterranim2Label  matlab.ui.control.Label
        superficie                      matlab.ui.control.NumericEditField
        RelacioberturessuperficieLabel  matlab.ui.control.Label
        finestra                        matlab.ui.control.NumericEditField
        NombredeplantessoterraninoinclsLabel  matlab.ui.control.Label
        plantes                         matlab.ui.control.NumericEditField
        MximnombreefectiudocupantsLabel  matlab.ui.control.Label
        persones                        matlab.ui.control.NumericEditField
        TemperaturadeconfortalhivernCLabel  matlab.ui.control.Label
        t_confort_h                     matlab.ui.control.NumericEditField
        TemperaturadeconfortalestiuCEditFieldLabel  matlab.ui.control.Label
        t_confort_c                     matlab.ui.control.NumericEditField
        DemandaACSlocupantdiaLabel      matlab.ui.control.Label
        ACS_especific                   matlab.ui.control.NumericEditField
        FactorguanysolarhivernEditFieldLabel  matlab.ui.control.Label
        SC_h                            matlab.ui.control.NumericEditField
        FactorguanysolarestiuEditFieldLabel  matlab.ui.control.Label
        SC_c                            matlab.ui.control.NumericEditField
        UnknownDemand                   matlab.ui.control.CheckBox
        GridLayout265                   matlab.ui.container.GridLayout
        TipologiaEdifici                matlab.ui.container.ButtonGroup
        ResiNoSoterrani                 matlab.ui.control.RadioButton
        ResiSoterrani                   matlab.ui.control.RadioButton
        CentreEducatiu                  matlab.ui.control.RadioButton
        Oficines                        matlab.ui.control.RadioButton
        Hosteleria                      matlab.ui.control.RadioButton
        Hospital                        matlab.ui.control.RadioButton
        CentreEsportiu                  matlab.ui.control.RadioButton
        GridLayout266                   matlab.ui.container.GridLayout
        GeometriaPlanta                 matlab.ui.container.ButtonGroup
        PlantaQuadrada                  matlab.ui.control.RadioButton
        PlantaRectangular1              matlab.ui.control.RadioButton
        PlantaRectangular2              matlab.ui.control.RadioButton
        GridLayout267                   matlab.ui.container.GridLayout
        Image19                         matlab.ui.control.Image
        Image20                         matlab.ui.control.Image
        Image21                         matlab.ui.control.Image
        GridLayout268                   matlab.ui.container.GridLayout
        Image39                         matlab.ui.control.Image
        GridLayout4                     matlab.ui.container.GridLayout
        borehole_panel                  matlab.ui.container.Panel
        lay_41                          matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        GridLayout83                    matlab.ui.container.GridLayout
        user_PropTerm                   matlab.ui.control.CheckBox
        info_tooltip                    matlab.ui.control.Image
        Diffusivitattrmicadelterrenymm2sLabel  matlab.ui.control.Label
        user_difu                       matlab.ui.control.NumericEditField
        ConductivitattrmicadelterrenyWmKLabel  matlab.ui.control.Label
        user_cdty                       matlab.ui.control.NumericEditField
        T_subsolTnopertorbadadelterrenyCLabel  matlab.ui.control.Label
        ChangeT_ground0                 matlab.ui.control.NumericEditField
        GridLayout86                    matlab.ui.container.GridLayout
        ConfiguracisondesPanel          matlab.ui.container.Panel
        GridLayout196                   matlab.ui.container.GridLayout
        GridLayout197                   matlab.ui.container.GridLayout
        Image22                         matlab.ui.control.Image
        Image23                         matlab.ui.control.Image
        Panel_12                        matlab.ui.container.Panel
        ConfigSondes                    matlab.ui.container.ButtonGroup
        Udoble                          matlab.ui.control.RadioButton
        Usimple                         matlab.ui.control.RadioButton
        GridLayout195                   matlab.ui.container.GridLayout
        EspaiatpousmLabel               matlab.ui.control.Label
        d_well                          matlab.ui.control.NumericEditField
        GridLayout89                    matlab.ui.container.GridLayout
        LprospPous                      matlab.ui.container.ButtonGroup
        m80                             matlab.ui.control.RadioButton
        m90                             matlab.ui.control.RadioButton
        m100                            matlab.ui.control.RadioButton
        m110                            matlab.ui.control.RadioButton
        m120                            matlab.ui.control.RadioButton
        m130                            matlab.ui.control.RadioButton
        m140                            matlab.ui.control.RadioButton
        m150                            matlab.ui.control.RadioButton
        GridLayout276                   matlab.ui.container.GridLayout
        Corr_T_ground                   matlab.ui.control.CheckBox
        GridLayout90                    matlab.ui.container.GridLayout
        DiamSondes                      matlab.ui.container.ButtonGroup
        mm32                            matlab.ui.control.RadioButton
        mm40                            matlab.ui.control.RadioButton
        SistemadedistribucialinteriordeledificiPanel  matlab.ui.container.Panel
        GridLayout277                   matlab.ui.container.GridLayout
        CALORLabel_5                    matlab.ui.control.Label
        FREDLabel_5                     matlab.ui.control.Label
        GridLayout278                   matlab.ui.container.GridLayout
        GridLayout279                   matlab.ui.container.GridLayout
        SistemaDist_c                   matlab.ui.container.ButtonGroup
        Dist_TR_c                       matlab.ui.control.RadioButton
        Dist_FC_c                       matlab.ui.control.RadioButton
        GridLayout280                   matlab.ui.container.GridLayout
        SistemaDist_h                   matlab.ui.container.ButtonGroup
        Dist_TR_h                       matlab.ui.control.RadioButton
        Dist_FC_h                       matlab.ui.control.RadioButton
        Dist_RR_h                       matlab.ui.control.RadioButton
        GridLayout281                   matlab.ui.container.GridLayout
        TerraradiantLabel               matlab.ui.control.Label
        FancoilsLabel                   matlab.ui.control.Label
        RadiadorsLabel                  matlab.ui.control.Label
        GridLayout282                   matlab.ui.container.GridLayout
        Image44                         matlab.ui.control.Image
        GridLayout287                   matlab.ui.container.GridLayout
        PanelLocalitzacio               matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        YUTMLabel                       matlab.ui.control.Label
        y_loc                           matlab.ui.control.NumericEditField
        XUTMLabel                       matlab.ui.control.Label
        x_loc                           matlab.ui.control.NumericEditField
        GridLayout73                    matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        LinkVISSIR                      matlab.ui.control.Button
        SwitchCAT_Gir                   matlab.ui.control.Switch
        GironaciutatreesurbanaiperiurbanaLabel  matlab.ui.control.Label
        thermal_demand_panel            matlab.ui.container.Panel
        lay_25                          matlab.ui.container.GridLayout
        lab1                            matlab.ui.control.Label
        pot_estiu                       matlab.ui.control.NumericEditField
        lab2                            matlab.ui.control.Label
        pot_hivern                      matlab.ui.control.NumericEditField
        lab3                            matlab.ui.control.Label
        e_hivern                        matlab.ui.control.NumericEditField
        lab4                            matlab.ui.control.Label
        e_estiu                         matlab.ui.control.NumericEditField
        CrregatrmicadedissenyenmodeproduccidACSkWLabel  matlab.ui.control.Label
        pot_ACS                         matlab.ui.control.NumericEditField
        DemandaanualdenergiaperaproduccidACSkWhLabel  matlab.ui.control.Label
        e_ACS                           matlab.ui.control.NumericEditField
        GridLayout288                   matlab.ui.container.GridLayout
        thermal_load_panel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        lay_40                          matlab.ui.container.GridLayout
        hp_criteria                     matlab.ui.container.ButtonGroup
        database_hp                     matlab.ui.control.RadioButton
        owner_hp                        matlab.ui.control.RadioButton
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout203                   matlab.ui.container.GridLayout
        PotnciacalefaccikWLabel         matlab.ui.control.Label
        user_hp_heat                    matlab.ui.control.NumericEditField
        COPLabel                        matlab.ui.control.Label
        user_COP                        matlab.ui.control.NumericEditField
        PotnciarefrigeracikWLabel       matlab.ui.control.Label
        user_hp_cool                    matlab.ui.control.NumericEditField
        EERLabel                        matlab.ui.control.Label
        user_EER                        matlab.ui.control.NumericEditField
        PotnciaACSkWLabel               matlab.ui.control.Label
        user_hp_ACS                     matlab.ui.control.NumericEditField
        COPacsLabel_2                   matlab.ui.control.Label
        user_COPACS                     matlab.ui.control.NumericEditField
        GridLayout283                   matlab.ui.container.GridLayout
        Image45                         matlab.ui.control.Image
        GridLayout204                   matlab.ui.container.GridLayout
        RefBCGLabel                     matlab.ui.control.Label
        PcalefkWLabel                   matlab.ui.control.Label
        COPLabel_3                      matlab.ui.control.Label
        PrefrikWLabel                   matlab.ui.control.Label
        EERLabel_3                      matlab.ui.control.Label
        PacskWLabel                     matlab.ui.control.Label
        COPacsLabel                     matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        Label_56                        matlab.ui.control.Label
        Label_67                        matlab.ui.control.Label
        Label_78                        matlab.ui.control.Label
        Label_89                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        Label_57                        matlab.ui.control.Label
        Label_68                        matlab.ui.control.Label
        Label_79                        matlab.ui.control.Label
        Label_90                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        Label_58                        matlab.ui.control.Label
        Label_69                        matlab.ui.control.Label
        Label_80                        matlab.ui.control.Label
        Label_91                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        Label_59                        matlab.ui.control.Label
        Label_70                        matlab.ui.control.Label
        Label_81                        matlab.ui.control.Label
        Label_92                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        Label_60                        matlab.ui.control.Label
        Label_71                        matlab.ui.control.Label
        Label_82                        matlab.ui.control.Label
        Label_93                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        Label_50                        matlab.ui.control.Label
        Label_61                        matlab.ui.control.Label
        Label_72                        matlab.ui.control.Label
        Label_83                        matlab.ui.control.Label
        Label_94                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        Label_51                        matlab.ui.control.Label
        Label_62                        matlab.ui.control.Label
        Label_73                        matlab.ui.control.Label
        Label_84                        matlab.ui.control.Label
        Label_95                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        Label_52                        matlab.ui.control.Label
        Label_63                        matlab.ui.control.Label
        Label_74                        matlab.ui.control.Label
        Label_85                        matlab.ui.control.Label
        Label_96                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_42                        matlab.ui.control.Label
        Label_53                        matlab.ui.control.Label
        Label_64                        matlab.ui.control.Label
        Label_75                        matlab.ui.control.Label
        Label_86                        matlab.ui.control.Label
        Label_97                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_54                        matlab.ui.control.Label
        Label_65                        matlab.ui.control.Label
        Label_76                        matlab.ui.control.Label
        Label_87                        matlab.ui.control.Label
        Label_98                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        Label_55                        matlab.ui.control.Label
        Label_66                        matlab.ui.control.Label
        Label_77                        matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_99                        matlab.ui.control.Label
        GridLayout36                    matlab.ui.container.GridLayout
        Etiqueta_PestanyaDemanda        matlab.ui.control.Label
        Image53                         matlab.ui.control.Image
        GridLayout198                   matlab.ui.container.GridLayout
        ManualGeoSIVv12Button           matlab.ui.control.Button
        back_2_workf                    matlab.ui.control.Button
        reset                           matlab.ui.control.Button
        RunSizing                       matlab.ui.control.Button
        ResultatsDissenyTab             matlab.ui.container.Tab
        lay_36                          matlab.ui.container.GridLayout
        lay_37                          matlab.ui.container.GridLayout
        Etiqueta_PestanyaDisseny        matlab.ui.control.Label
        Image54                         matlab.ui.control.Image
        lay_38                          matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout93                    matlab.ui.container.GridLayout
        GridLayout94                    matlab.ui.container.GridLayout
        label_show_result               matlab.ui.control.Label
        label_show_result2              matlab.ui.control.Label
        recomendation_label             matlab.ui.control.Label
        GridLayout95                    matlab.ui.container.GridLayout
        Panel_8                         matlab.ui.container.Panel
        plot_results                    matlab.ui.control.UIAxes
        Panel_7                         matlab.ui.container.Panel
        MonthDemandPlot                 matlab.ui.control.UIAxes
        GridLayout263                   matlab.ui.container.GridLayout
        ChooseCarCap_DemPro_Plot        matlab.ui.container.ButtonGroup
        show_thermal_r                  matlab.ui.control.RadioButton
        show_e_r                        matlab.ui.control.RadioButton
        GridLayout264                   matlab.ui.container.GridLayout
        ChooseEner_t_e_Plot             matlab.ui.container.ButtonGroup
        ConsumEnergia                   matlab.ui.control.RadioButton
        ConsumElectricitat              matlab.ui.control.RadioButton
        GridLayout104                   matlab.ui.container.GridLayout
        Panel_Instalacio                matlab.ui.container.Panel
        GridLayout107                   matlab.ui.container.GridLayout
        PotnciaEnergiatrmicaPanel       matlab.ui.container.Panel
        GridLayout108                   matlab.ui.container.GridLayout
        CrregadedissenykWtLabel         matlab.ui.control.Label
        PotDisCalfkW                    matlab.ui.control.NumericEditField
        CALORLabel                      matlab.ui.control.Label
        ACSLabel                        matlab.ui.control.Label
        PotDis_ACSkW                    matlab.ui.control.NumericEditField
        PotDisRefrkW                    matlab.ui.control.NumericEditField
        PotInsRefrkW                    matlab.ui.control.NumericEditField
        ProdRefrMWht                    matlab.ui.control.NumericEditField
        PotInsCalfkW                    matlab.ui.control.NumericEditField
        PotnciainstalladakWtLabel       matlab.ui.control.Label
        PotInsACSkW                     matlab.ui.control.NumericEditField
        Prod_ACSMWht                    matlab.ui.control.NumericEditField
        ProduccienergiaMWhtLabel        matlab.ui.control.Label
        ProdCalfMWht                    matlab.ui.control.NumericEditField
        SPFcSPFrSPFacsWhtWheLabel       matlab.ui.control.Label
        SPF_Calf                        matlab.ui.control.NumericEditField
        SPF_Refr                        matlab.ui.control.NumericEditField
        SPF_ACS                         matlab.ui.control.NumericEditField
        FREDLabel                       matlab.ui.control.Label
        PotnciaEnergiaelctricaPanel     matlab.ui.container.Panel
        GridLayout109                   matlab.ui.container.GridLayout
        GridLayout252                   matlab.ui.container.GridLayout
        ConsumelctricMWheEditFieldLabel  matlab.ui.control.Label
        Cons_eCalfMWhe                  matlab.ui.control.NumericEditField
        Cons_eRefrMWhe                  matlab.ui.control.NumericEditField
        Cons_e_ACSMWhe                  matlab.ui.control.NumericEditField
        GridLayout253                   matlab.ui.container.GridLayout
        PotnciaacontractarkWeLabel      matlab.ui.control.Label
        PotElecContrat                  matlab.ui.control.NumericEditField
        GridLayout254                   matlab.ui.container.GridLayout
        ConsumelctrictotalMWheLabel     matlab.ui.control.Label
        ConElecMWhe                     matlab.ui.control.NumericEditField
        GridLayout110                   matlab.ui.container.GridLayout
        CampdecaptaciPanel              matlab.ui.container.Panel
        GridLayout273                   matlab.ui.container.GridLayout
        CaracterstiquesprincipalsPanel  matlab.ui.container.Panel
        GridLayout274                   matlab.ui.container.GridLayout
        NombredepousgeotrmicsEditFieldLabel  matlab.ui.control.Label
        n_well                          matlab.ui.control.NumericEditField
        LongitudmitjanadelspousmLabel   matlab.ui.control.Label
        l_well                          matlab.ui.control.NumericEditField
        Superfcieocupadapelcampdecaptacim2EditFieldLabel  matlab.ui.control.Label
        m2_well                         matlab.ui.control.NumericEditField
        T_subsolinicialCLabel           matlab.ui.control.Label
        T_ground0                       matlab.ui.control.NumericEditField
        ComportamenttrmicPanel          matlab.ui.container.Panel
        GridLayout275                   matlab.ui.container.GridLayout
        BalanenergticanualenelsubsolMWhtLabel  matlab.ui.control.Label
        BalancSubsol                    matlab.ui.control.NumericEditField
        EstimacivariacilocalT_subsolallargterminiCLabel  matlab.ui.control.Label
        Estimacio_Var_T                 matlab.ui.control.EditField
        PerodedestabilitzacitrmicadelterrenyanysLabel  matlab.ui.control.Label
        t_estabilitzacio                matlab.ui.control.EditField
        GridLayout272                   matlab.ui.container.GridLayout
        Panels_BCGValorsNom             matlab.ui.container.Panel
        GridLayout106                   matlab.ui.container.GridLayout
        PotnciatrmicakWtLabel           matlab.ui.control.Label
        hp_heat                         matlab.ui.control.NumericEditField
        COPEERCOPacsWtWeLabel           matlab.ui.control.Label
        COP_hp_heat                     matlab.ui.control.NumericEditField
        hp_cool                         matlab.ui.control.NumericEditField
        EER_hp_cool                     matlab.ui.control.NumericEditField
        hp_ACS                          matlab.ui.control.NumericEditField
        COP_hp_ACS                      matlab.ui.control.NumericEditField
        CALORLabel_4                    matlab.ui.control.Label
        FREDLabel_4                     matlab.ui.control.Label
        ACSLabel_4                      matlab.ui.control.Label
        EWT_ACS                         matlab.ui.control.NumericEditField
        unitats_BCG                     matlab.ui.control.Label
        TemperaturasortidacondCLabel    matlab.ui.control.Label
        LWT_Calf                        matlab.ui.control.NumericEditField
        EWT_Calf                        matlab.ui.control.NumericEditField
        EWT_Refr                        matlab.ui.control.NumericEditField
        T_inBCG_label                   matlab.ui.control.Label
        LWT_Refr                        matlab.ui.control.NumericEditField
        LWT_ACS                         matlab.ui.control.NumericEditField
        CondicionstrmiquesdepartidaPanel  matlab.ui.container.Panel
        GridLayout111                   matlab.ui.container.GridLayout
        HIVERNLabel                     matlab.ui.control.Label
        ESTIULabel                      matlab.ui.control.Label
        TemperaturaambientdedissenyCLabel  matlab.ui.control.Label
        T_dis_w                         matlab.ui.control.NumericEditField
        T_dis_s                         matlab.ui.control.NumericEditField
        dheat_show                      matlab.ui.control.NumericEditField
        DuraciperodeestacionaldiesLabel  matlab.ui.control.Label
        dcool_show                      matlab.ui.control.NumericEditField
        LabelClimaRef                   matlab.ui.control.Label
        TemperaturaliquidapousmnmxCLabel  matlab.ui.control.Label
        t_ent_min_w                     matlab.ui.control.NumericEditField
        t_ent_max_w                     matlab.ui.control.NumericEditField
        GridLayout200                   matlab.ui.container.GridLayout
        back_2_demand                   matlab.ui.control.Button
        ManualGeoSIVv12Button_2         matlab.ui.control.Button
        Informe_disseny                 matlab.ui.control.Button
        Panel_11                        matlab.ui.container.Panel
        PotElecAlthkWeLabel             matlab.ui.control.Label
        PotElecAlthkW                   matlab.ui.control.NumericEditField
        ConElecAltcMWheLabel            matlab.ui.control.Label
        ConElecAltcMWhe                 matlab.ui.control.NumericEditField
        PotElecAltckWeLabel             matlab.ui.control.Label
        PotElecAltckW                   matlab.ui.control.NumericEditField
        ConElecAlthMWheEditFieldLabel   matlab.ui.control.Label
        ConElecAlthMWhe                 matlab.ui.control.NumericEditField
        ClimaRefLabel                   matlab.ui.control.Label
        ClimaRef                        matlab.ui.control.NumericEditField
        PotElecAltACSkWeLabel           matlab.ui.control.Label
        PotElecAltACSkW                 matlab.ui.control.NumericEditField
        ConElecAltACSMWheLabel          matlab.ui.control.Label
        ConElecAltACSMWhe               matlab.ui.control.NumericEditField
        reaColectorsSTLabel             matlab.ui.control.Label
        AreaMinST                       matlab.ui.control.NumericEditField
        RendibilitatprojecteButton      matlab.ui.control.Button
        AnalisiEcoAmbTab                matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        GridLayout25                    matlab.ui.container.GridLayout
        DadesEntradaComparacio          matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        GEOTRMIALabel                   matlab.ui.control.Label
        ALTERNATIVALabel                matlab.ui.control.Label
        OpcionsAlternativa_h            matlab.ui.control.DropDown
        OpcionsAlternativa_c            matlab.ui.control.DropDown
        OpcionsAlternativa_ACS          matlab.ui.control.DropDown
        CALORLabel_2                    matlab.ui.control.Label
        FREDLabel_2                     matlab.ui.control.Label
        ACSLabel_2                      matlab.ui.control.Label
        InstallacisubvencionadaLabel    matlab.ui.control.Label
        GridLayout121                   matlab.ui.container.GridLayout
        AltSubv                         matlab.ui.control.CheckBox
        GridLayout122                   matlab.ui.container.GridLayout
        GeoSubv                         matlab.ui.control.CheckBox
        GridLayout123                   matlab.ui.container.GridLayout
        GeoPres                         matlab.ui.control.CheckBox
        GridLayout124                   matlab.ui.container.GridLayout
        AltPres                         matlab.ui.control.CheckBox
        GridLayout126                   matlab.ui.container.GridLayout
        CostEscomesa                    matlab.ui.control.NumericEditField
        Label_CostEscomesa              matlab.ui.control.Label
        GridLayout127                   matlab.ui.container.GridLayout
        kWLabel_4                       matlab.ui.control.Label
        CostEspecificAlt_h              matlab.ui.control.NumericEditField
        GridLayout128                   matlab.ui.container.GridLayout
        kWLabel_9                       matlab.ui.control.Label
        MantAlt_h                       matlab.ui.control.NumericEditField
        GridLayout129                   matlab.ui.container.GridLayout
        GridLayout130                   matlab.ui.container.GridLayout
        CostEspecificAlt_c              matlab.ui.control.NumericEditField
        kWLabel_5                       matlab.ui.control.Label
        GridLayout131                   matlab.ui.container.GridLayout
        MantAlt_c                       matlab.ui.control.NumericEditField
        kWLabel_10                      matlab.ui.control.Label
        GridLayout132                   matlab.ui.container.GridLayout
        GridLayout133                   matlab.ui.container.GridLayout
        CostEspecificAlt_ACS            matlab.ui.control.NumericEditField
        LabelCostEspecificAlt_ACS       matlab.ui.control.Label
        GridLayout134                   matlab.ui.container.GridLayout
        MantAlt_ACS                     matlab.ui.control.NumericEditField
        LabelMantAlt_ACS                matlab.ui.control.Label
        GridLayout137                   matlab.ui.container.GridLayout
        ImportAltSubv                   matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        GridLayout138                   matlab.ui.container.GridLayout
        TramAltPres                     matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        GridLayout139                   matlab.ui.container.GridLayout
        InteresAltPres                  matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        GridLayout140                   matlab.ui.container.GridLayout
        RetornAltPres                   matlab.ui.control.NumericEditField
        anysLabel_2                     matlab.ui.control.Label
        CostespecficpousescomesaLabel   matlab.ui.control.Label
        CostmantenimentanualLabel       matlab.ui.control.Label
        GridLayout141                   matlab.ui.container.GridLayout
        CostEspecInstGeo                matlab.ui.control.NumericEditField
        kWLabel                         matlab.ui.control.Label
        GridLayout142                   matlab.ui.container.GridLayout
        CostEspecificPous               matlab.ui.control.NumericEditField
        mlLabel                         matlab.ui.control.Label
        GridLayout143                   matlab.ui.container.GridLayout
        CostEspecificGeo                matlab.ui.control.NumericEditField
        kWLabel_2                       matlab.ui.control.Label
        GridLayout144                   matlab.ui.container.GridLayout
        MantGeo                         matlab.ui.control.NumericEditField
        kWLabel_8                       matlab.ui.control.Label
        ImportsubvenciLabel             matlab.ui.control.Label
        GridLayout146                   matlab.ui.container.GridLayout
        ImportGeoSubv                   matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        TraminversiambprstecLabel       matlab.ui.control.Label
        GridLayout147                   matlab.ui.container.GridLayout
        TramGeoPres                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        TaxaintersprstecLabel           matlab.ui.control.Label
        GridLayout148                   matlab.ui.container.GridLayout
        InteresGeoPres                  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        PeroderetornprstecLabel         matlab.ui.control.Label
        GridLayout149                   matlab.ui.container.GridLayout
        RetornGeoPres                   matlab.ui.control.NumericEditField
        anysLabel                       matlab.ui.control.Label
        CostinversiclimatitzaciLabel    matlab.ui.control.Label
        GridLayout160                   matlab.ui.container.GridLayout
        CAPEXGeo                        matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        GridLayout161                   matlab.ui.container.GridLayout
        CAPEXAltc                       matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        FinanamentambprstecLabel        matlab.ui.control.Label
        GridLayout187                   matlab.ui.container.GridLayout
        CostEspecInstAlt_h              matlab.ui.control.NumericEditField
        kWLabel_3                       matlab.ui.control.Label
        GridLayout205                   matlab.ui.container.GridLayout
        Label_100                       matlab.ui.control.Label
        CAPEXAlth                       matlab.ui.control.NumericEditField
        GridLayout206                   matlab.ui.container.GridLayout
        Label_101                       matlab.ui.control.Label
        CAPEXAltACS                     matlab.ui.control.NumericEditField
        GridLayout208                   matlab.ui.container.GridLayout
        Label_102                       matlab.ui.control.Label
        CAPEXGeoTOT                     matlab.ui.control.NumericEditField
        GridLayout209                   matlab.ui.container.GridLayout
        Label_103                       matlab.ui.control.Label
        CAPEXAltTOT                     matlab.ui.control.NumericEditField
        CostinversiTOTALLabel           matlab.ui.control.Label
        CostespecficinstallaciLabel     matlab.ui.control.Label
        CostespecficsistemaLabel        matlab.ui.control.Label
        GridLayout289                   matlab.ui.container.GridLayout
        CostEspecInstAlt_c              matlab.ui.control.NumericEditField
        kWLabel_6                       matlab.ui.control.Label
        GridLayout290                   matlab.ui.container.GridLayout
        CostEspecInstAlt_ACS            matlab.ui.control.NumericEditField
        LabelCostEspecInstAlt_ACS       matlab.ui.control.Label
        GridLayout27                    matlab.ui.container.GridLayout
        HibridaciambenergiasolarfotovolticaPanel_2  matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        GridLayout56                    matlab.ui.container.GridLayout
        AcumulaciambbateriesLabel       matlab.ui.control.Label
        GeoBat                          matlab.ui.control.CheckBox
        AltBat                          matlab.ui.control.CheckBox
        GridLayout57                    matlab.ui.container.GridLayout
        PotnciaPVkWpLabel_2             matlab.ui.control.Label
        GeokWpPV                        matlab.ui.control.NumericEditField
        AltkWpPV                        matlab.ui.control.NumericEditField
        GridLayout58                    matlab.ui.container.GridLayout
        reapanellsm2Label_2             matlab.ui.control.Label
        Geom2PV                         matlab.ui.control.NumericEditField
        Altm2PV                         matlab.ui.control.NumericEditField
        GridLayout59                    matlab.ui.container.GridLayout
        CostespecficbaterieskWhLabel    matlab.ui.control.Label
        CostEspecBat                    matlab.ui.control.NumericEditField
        GridLayout60                    matlab.ui.container.GridLayout
        EficinciacrregadescrregaLabel   matlab.ui.control.Label
        Ch_DisCh_EffBat                 matlab.ui.control.NumericEditField
        GridLayout61                    matlab.ui.container.GridLayout
        CapacitatnominalkWhLabel        matlab.ui.control.Label
        PVGeoEneBat                     matlab.ui.control.NumericEditField
        PVAltEneBat                     matlab.ui.control.NumericEditField
        GridLayout179                   matlab.ui.container.GridLayout
        GEOTRMIALabel_2                 matlab.ui.control.Label
        ALTERNATIVALabel_2              matlab.ui.control.Label
        GridLayout180                   matlab.ui.container.GridLayout
        HibridaciambPVLabel             matlab.ui.control.Label
        GeoHibPV                        matlab.ui.control.CheckBox
        AltHibPV                        matlab.ui.control.CheckBox
        GridLayout181                   matlab.ui.container.GridLayout
        Superfciedisponiblem2Label      matlab.ui.control.Label
        PV_area                         matlab.ui.control.NumericEditField
        GridLayout182                   matlab.ui.container.GridLayout
        CostespecficPVkWpLabel          matlab.ui.control.Label
        PV_cost                         matlab.ui.control.NumericEditField
        GridLayout183                   matlab.ui.container.GridLayout
        EficinciaconversiLabel          matlab.ui.control.Label
        PV_eff                          matlab.ui.control.NumericEditField
        GridLayout184                   matlab.ui.container.GridLayout
        RetribuciexcedentskWhLabel      matlab.ui.control.Label
        Retrib_excdPV                   matlab.ui.control.NumericEditField
        GridLayout185                   matlab.ui.container.GridLayout
        AutoproducciPVLabel             matlab.ui.control.Label
        HibPVGeo_cob                    matlab.ui.control.NumericEditField
        HibPVAlt_cob                    matlab.ui.control.NumericEditField
        GridLayout186                   matlab.ui.container.GridLayout
        CostinversiPVBateriesLabel      matlab.ui.control.Label
        PVGeoCostInv                    matlab.ui.control.NumericEditField
        PVAltCostInv                    matlab.ui.control.NumericEditField
        GridLayout207                   matlab.ui.container.GridLayout
        ProfunditatdedescrregaEditFieldLabel  matlab.ui.control.Label
        DoD_Bat                         matlab.ui.control.NumericEditField
        GridLayout150                   matlab.ui.container.GridLayout
        DadesbsiquesdelprojectePanel_2  matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        SwitchTipusProjecte             matlab.ui.control.Switch
        GridLayout151                   matlab.ui.container.GridLayout
        AnysdevidaencasdinstallaciexistentLabel  matlab.ui.control.Label
        vidaprojexistent                matlab.ui.control.NumericEditField
        GridLayout152                   matlab.ui.container.GridLayout
        VidaestimadadelprojecteanysLabel  matlab.ui.control.Label
        vidaprojecte                    matlab.ui.control.NumericEditField
        GridLayout153                   matlab.ui.container.GridLayout
        TaxadedescompteLabel            matlab.ui.control.Label
        Taxadescompte                   matlab.ui.control.NumericEditField
        GridLayout178                   matlab.ui.container.GridLayout
        TaxainflacigeneralanualLabel    matlab.ui.control.Label
        Infl_general                    matlab.ui.control.NumericEditField
        CostosdeconsumenergticvalorsmonetarisambIVAinclsPanel  matlab.ui.container.Panel
        GridLayout154                   matlab.ui.container.GridLayout
        CostfixanualLabel               matlab.ui.control.Label
        CostvariableLabel               matlab.ui.control.Label
        InflacianualcostfixLabel        matlab.ui.control.Label
        InflacianualcostvariableLabel   matlab.ui.control.Label
        ELECTRICITATLabel               matlab.ui.control.Label
        BIOMASSALabel                   matlab.ui.control.Label
        GASNATURALLabel                 matlab.ui.control.Label
        GASPROPLabel                    matlab.ui.control.Label
        GASOILLabel                     matlab.ui.control.Label
        GridLayout155                   matlab.ui.container.GridLayout
        kWLabel_7                       matlab.ui.control.Label
        CostkWVall                      matlab.ui.control.NumericEditField
        CostkWPlaPunta                  matlab.ui.control.NumericEditField
        GridLayout156                   matlab.ui.container.GridLayout
        kWhLabel                        matlab.ui.control.Label
        CostkWhPunta                    matlab.ui.control.NumericEditField
        CostkWhVall                     matlab.ui.control.NumericEditField
        CostkWhPla                      matlab.ui.control.NumericEditField
        GridLayout157                   matlab.ui.container.GridLayout
        Label_12                        matlab.ui.control.Label
        Infl_AnualVar_e                 matlab.ui.control.NumericEditField
        GridLayout158                   matlab.ui.container.GridLayout
        Infl_AnualFix_e                 matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        GridLayout159                   matlab.ui.container.GridLayout
        NALabel                         matlab.ui.control.Label
        GridLayout162                   matlab.ui.container.GridLayout
        NALabel_2                       matlab.ui.control.Label
        GridLayout163                   matlab.ui.container.GridLayout
        CostkgBiom                      matlab.ui.control.NumericEditField
        kgLabel                         matlab.ui.control.Label
        GridLayout164                   matlab.ui.container.GridLayout
        Infl_AnualVar_Biom              matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        GridLayout165                   matlab.ui.container.GridLayout
        CostGN                          matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        GridLayout166                   matlab.ui.container.GridLayout
        Infl_AnualFix_GN                matlab.ui.control.NumericEditField
        Label_19                        matlab.ui.control.Label
        GridLayout167                   matlab.ui.container.GridLayout
        CostkWhGN                       matlab.ui.control.NumericEditField
        kWhLabel_3                      matlab.ui.control.Label
        GridLayout168                   matlab.ui.container.GridLayout
        Label_17                        matlab.ui.control.Label
        Infl_AnualVar_GN                matlab.ui.control.NumericEditField
        GridLayout169                   matlab.ui.container.GridLayout
        CostGC                          matlab.ui.control.NumericEditField
        Label_21                        matlab.ui.control.Label
        GridLayout171                   matlab.ui.container.GridLayout
        CostkgGC                        matlab.ui.control.NumericEditField
        kgLabel_2                       matlab.ui.control.Label
        GridLayout172                   matlab.ui.container.GridLayout
        Infl_AnualFix_GC                matlab.ui.control.NumericEditField
        Label_20                        matlab.ui.control.Label
        GridLayout173                   matlab.ui.container.GridLayout
        Label_16                        matlab.ui.control.Label
        Infl_AnualVar_GC                matlab.ui.control.NumericEditField
        GridLayout174                   matlab.ui.container.GridLayout
        NALabel_3                       matlab.ui.control.Label
        GridLayout175                   matlab.ui.container.GridLayout
        NALabel_4                       matlab.ui.control.Label
        GridLayout176                   matlab.ui.container.GridLayout
        CostlGasoil                     matlab.ui.control.NumericEditField
        lLabel                          matlab.ui.control.Label
        GridLayout177                   matlab.ui.container.GridLayout
        Infl_AnualVar_Gasoil            matlab.ui.control.NumericEditField
        Label_15                        matlab.ui.control.Label
        GridLayout188                   matlab.ui.container.GridLayout
        ObservacionsLabel               matlab.ui.control.Label
        Label_EcoAmbObs1                matlab.ui.control.Label
        Label_EcoAmbObs2                matlab.ui.control.Label
        Label_EcoAmbObs3                matlab.ui.control.Label
        Label_EcoAmbObs4                matlab.ui.control.Label
        GridLayout35                    matlab.ui.container.GridLayout
        LabelEcoAmb_Main                matlab.ui.control.Label
        Image55                         matlab.ui.control.Image
        GridLayout199                   matlab.ui.container.GridLayout
        RunEcoAmb                       matlab.ui.control.Button
        ValorsperdefecteButton          matlab.ui.control.Button
        ManualGeoSIVv12Button_3         matlab.ui.control.Button
        EnrereButton                    matlab.ui.control.StateButton
        ResultatsEcoAmbTab              matlab.ui.container.Tab
        GridLayout96                    matlab.ui.container.GridLayout
        GridLayout97                    matlab.ui.container.GridLayout
        ResultatsdelanlisieconmicaiambientalLabel  matlab.ui.control.Label
        Image56                         matlab.ui.control.Image
        GridLayout98                    matlab.ui.container.GridLayout
        GridLayout99                    matlab.ui.container.GridLayout
        GridLayout113                   matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        UIplotFluxeVAN                  matlab.ui.control.UIAxes
        Panel_10                        matlab.ui.container.Panel
        GridLayout190                   matlab.ui.container.GridLayout
        EcoPlotOption                   matlab.ui.container.ButtonGroup
        PlotDespesa                     matlab.ui.control.RadioButton
        PlotVAN                         matlab.ui.control.RadioButton
        Panel_RendibilitatEcoAmb        matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        PerodederetornsimpleanysLabel   matlab.ui.control.Label
        Paybacktext                     matlab.ui.control.EditField
        ValoractualitzatnetVANLabel     matlab.ui.control.Label
        VAN                             matlab.ui.control.NumericEditField
        EstalviemissionsCO2eqLabel      matlab.ui.control.Label
        EstalviCO2rel                   matlab.ui.control.NumericEditField
        EstalviemissionsCO2eqkganyLabel  matlab.ui.control.Label
        EstalviCO2                      matlab.ui.control.NumericEditField
        PerodederetorndescomptatanysLabel  matlab.ui.control.Label
        PaybackDesctext                 matlab.ui.control.EditField
        Estalviesperat                  matlab.ui.control.NumericEditField
        TaxainternaderetornTIREditFieldLabel  matlab.ui.control.Label
        TIRt                            matlab.ui.control.EditField
        RendibilitatnetaEditFieldLabel  matlab.ui.control.Label
        RendibNetTotal                  matlab.ui.control.EditField
        GridLayout285                   matlab.ui.container.GridLayout
        EstalviestimatLabel             matlab.ui.control.Label
        EstalviNetTotal                 matlab.ui.control.NumericEditField
        GridLayout189                   matlab.ui.container.GridLayout
        PanelAltCon_e                   matlab.ui.container.Panel
        MonthPlotAlt                    matlab.ui.control.UIAxes
        PanelGeoCon_e                   matlab.ui.container.Panel
        MonthPlotGeo                    matlab.ui.control.UIAxes
        GridLayout194                   matlab.ui.container.GridLayout
        ConLabel_ConsumElectricHib      matlab.ui.container.Panel
        GridLayout191                   matlab.ui.container.GridLayout
        GridLayout192                   matlab.ui.container.GridLayout
        GEOTRMIALabel_3                 matlab.ui.control.Label
        ALTERNATIVALabel_3              matlab.ui.control.Label
        GridLayout193                   matlab.ui.container.GridLayout
        AltCons_e                       matlab.ui.control.NumericEditField
        GeoCons_e                       matlab.ui.control.NumericEditField
        LabelCons_e                     matlab.ui.control.Label
        LabelDesp_e                     matlab.ui.control.Label
        LabelAuto_e                     matlab.ui.control.Label
        GraudautoconsumLabel            matlab.ui.control.Label
        ExcedentanualaxarxaMWheLabel    matlab.ui.control.Label
        GeoDesp_e                       matlab.ui.control.NumericEditField
        AltDesp_e                       matlab.ui.control.NumericEditField
        GeoAutoprod_e                   matlab.ui.control.NumericEditField
        AltAutoprod_e                   matlab.ui.control.NumericEditField
        GeoAutocons_e                   matlab.ui.control.NumericEditField
        AltAutocons_e                   matlab.ui.control.NumericEditField
        EstalvigrciesaautoconsumLabel   matlab.ui.control.Label
        GeoEstalviAutoc_e               matlab.ui.control.NumericEditField
        AltEstalviAutoc_e               matlab.ui.control.NumericEditField
        GeoExcdPV_e                     matlab.ui.control.NumericEditField
        AltExcdPV_e                     matlab.ui.control.NumericEditField
        GridLayout215                   matlab.ui.container.GridLayout
        GEOPV_PLOTlabel                 matlab.ui.control.Label
        GridLayout216                   matlab.ui.container.GridLayout
        ALTPV_PLOTlabel_c               matlab.ui.control.Label
        ALTPV_PLOTlabel_h               matlab.ui.control.Label
        ALTPV_PLOTlabel_ACS             matlab.ui.control.Label
        plus_1                          matlab.ui.control.Label
        plus_2                          matlab.ui.control.Label
        GridLayout201                   matlab.ui.container.GridLayout
        InformeTOT                      matlab.ui.control.Button
        EnrereButtonResultatsEcoAmb     matlab.ui.control.StateButton
        ManualGeoSIVv12Button_4         matlab.ui.control.Button
        ResumprojecteButton             matlab.ui.control.Button
        ResumTab                        matlab.ui.container.Tab
        GridLayout217                   matlab.ui.container.GridLayout
        GridLayout218                   matlab.ui.container.GridLayout
        Label_Resumdelprojecte          matlab.ui.control.Label
        Image57                         matlab.ui.control.Image
        GridLayout219                   matlab.ui.container.GridLayout
        EnrereButton_3                  matlab.ui.control.Button
        ManualGeoSIVv12Button_5         matlab.ui.control.Button
        GridLayout220                   matlab.ui.container.GridLayout
        GridLayout222                   matlab.ui.container.GridLayout
        Panel_PrevisioCostos            matlab.ui.container.Panel
        GridLayout271                   matlab.ui.container.GridLayout
        Panel_SubPrevisioCostos         matlab.ui.container.Panel
        GridLayout224                   matlab.ui.container.GridLayout
        GridLayout242                   matlab.ui.container.GridLayout
        GridLayout245                   matlab.ui.container.GridLayout
        Panel_EscenariProjecte          matlab.ui.container.Panel
        GridLayout246                   matlab.ui.container.GridLayout
        EscenariProjecte1               matlab.ui.control.Label
        EscenariProjecte2               matlab.ui.control.Label
        EscenariProjecte3               matlab.ui.control.Label
        Panel_VidaProjecte              matlab.ui.container.Panel
        GridLayout247                   matlab.ui.container.GridLayout
        VidaProjecte                    matlab.ui.control.Label
        Panel_Rendibilitat              matlab.ui.container.Panel
        GridLayout248                   matlab.ui.container.GridLayout
        Rendibilitat1                   matlab.ui.control.Label
        Rendibilitat2                   matlab.ui.control.Label
        Rendibilitat3                   matlab.ui.control.Label
        Panel_RecuperacioInversio       matlab.ui.container.Panel
        GridLayout248_2                 matlab.ui.container.GridLayout
        RecuperacioInversio             matlab.ui.control.Label
        Panel_BeneficisAmbientals       matlab.ui.container.Panel
        GridLayout248_3                 matlab.ui.container.GridLayout
        BeneficisAmbientals             matlab.ui.control.Label
        Panel_CostosRentabilitat        matlab.ui.container.Panel
        GridLayout228                   matlab.ui.container.GridLayout
        GridLayout229                   matlab.ui.container.GridLayout
        Label_Campdecaptacio            matlab.ui.control.Label
        CostCaptacioGeo                 matlab.ui.control.Label
        NALabel_5                       matlab.ui.control.Label
        GridLayout230                   matlab.ui.container.GridLayout
        EquipsInstalacioGeo             matlab.ui.control.Label
        CostEquipsAlt                   matlab.ui.control.Label
        CostEquipsGeo                   matlab.ui.control.Label
        GridLayout231                   matlab.ui.container.GridLayout
        Label_Hibridacio                matlab.ui.control.Label
        HibSiNoGeo                      matlab.ui.control.Label
        HibSiNoAlt                      matlab.ui.control.Label
        GridLayout232                   matlab.ui.container.GridLayout
        Label_Panells                   matlab.ui.control.Label
        CostPanellsGeo                  matlab.ui.control.Label
        CostPanellsAlt                  matlab.ui.control.Label
        GridLayout233                   matlab.ui.container.GridLayout
        CostBateriesAlt                 matlab.ui.control.Label
        CostBateriesGeo                 matlab.ui.control.Label
        Label_Bateries                  matlab.ui.control.Label
        GridLayout234                   matlab.ui.container.GridLayout
        Label_COSTTOTAL                 matlab.ui.control.Label
        COSTTOTALAlt                    matlab.ui.control.Label
        COSTTOTALGeo                    matlab.ui.control.Label
        GridLayout235                   matlab.ui.container.GridLayout
        Label_FinSubvencio              matlab.ui.control.Label
        SubvencioGeo                    matlab.ui.control.Label
        SubvencioAlt                    matlab.ui.control.Label
        GridLayout236                   matlab.ui.container.GridLayout
        Label_FinPrestec                matlab.ui.control.Label
        PrestecAlt                      matlab.ui.control.Label
        PrestecGeo                      matlab.ui.control.Label
        GridLayout249                   matlab.ui.container.GridLayout
        Label_ALTERNATIVA               matlab.ui.control.Label
        Label_GEOTRMIA                  matlab.ui.control.Label
        GridLayout250                   matlab.ui.container.GridLayout
        DadesPresGeo                    matlab.ui.control.Label
        DadesPresAlt                    matlab.ui.control.Label
        GridLayout251                   matlab.ui.container.GridLayout
        Label_CostNetInversio           matlab.ui.control.Label
        COSTNETGeo                      matlab.ui.control.Label
        GridLayout262                   matlab.ui.container.GridLayout
        Label_BeneficiNet               matlab.ui.control.Label
        BeneficiNet                     matlab.ui.control.Label
        GridLayout286                   matlab.ui.container.GridLayout
        Panel_EstalviMensual            matlab.ui.container.Panel
        GridLayout284                   matlab.ui.container.GridLayout
        GENLabel                        matlab.ui.control.Label
        FEBLabel                        matlab.ui.control.Label
        MARLabel                        matlab.ui.control.Label
        DESLabel                        matlab.ui.control.Label
        NOVLabel                        matlab.ui.control.Label
        OCTLabel                        matlab.ui.control.Label
        SETLabel                        matlab.ui.control.Label
        AGOLabel                        matlab.ui.control.Label
        JULLabel                        matlab.ui.control.Label
        MAILabel                        matlab.ui.control.Label
        JUNLabel                        matlab.ui.control.Label
        ABRLabel                        matlab.ui.control.Label
        Label_Est01TOT                  matlab.ui.control.Label
        Label_Est02TOT                  matlab.ui.control.Label
        Label_Est03TOT                  matlab.ui.control.Label
        Label_Est04TOT                  matlab.ui.control.Label
        Label_Est05TOT                  matlab.ui.control.Label
        Label_Est06TOT                  matlab.ui.control.Label
        Label_Est07TOT                  matlab.ui.control.Label
        Label_Est08TOT                  matlab.ui.control.Label
        Label_Est09TOT                  matlab.ui.control.Label
        Label_Est10TOT                  matlab.ui.control.Label
        Label_Est11TOT                  matlab.ui.control.Label
        Label_Est12TOT                  matlab.ui.control.Label
        LabelTOTALestalvi               matlab.ui.control.Label
        GridLayout240                   matlab.ui.container.GridLayout
        Panel_DemandaConsums            matlab.ui.container.Panel
        GridLayout269                   matlab.ui.container.GridLayout
        Panel_SubDemandaConsums         matlab.ui.container.Panel
        GridLayout107_2                 matlab.ui.container.GridLayout
        Panel_PotEneTermica             matlab.ui.container.Panel
        GridLayout108_2                 matlab.ui.container.GridLayout
        CALORLabel_3                    matlab.ui.control.Label
        FREDLabel_3                     matlab.ui.control.Label
        ACSLabel_3                      matlab.ui.control.Label
        Label_Capacitat                 matlab.ui.control.Label
        Label_Demanda                   matlab.ui.control.Label
        DemandaCalf                     matlab.ui.control.Label
        DemandaRefr                     matlab.ui.control.Label
        Demanda_ACS                     matlab.ui.control.Label
        CapacitatCalf                   matlab.ui.control.Label
        CapacitatRefr                   matlab.ui.control.Label
        Capacitat_ACS                   matlab.ui.control.Label
        RendimentsestacionalsLabel      matlab.ui.control.Label
        SPFcalefaccio                   matlab.ui.control.Label
        SPFrefrigeracio                 matlab.ui.control.Label
        SPFacs                          matlab.ui.control.Label
        Panel_PotEneElectrica           matlab.ui.container.Panel
        GridLayout109_2                 matlab.ui.container.GridLayout
        GridLayout256                   matlab.ui.container.GridLayout
        Label_PotenciaeContratada       matlab.ui.control.Label
        PotenciaeContratada             matlab.ui.control.Label
        GridLayout257                   matlab.ui.container.GridLayout
        Label_ConsumeTotal              matlab.ui.control.Label
        ConsumeTotal                    matlab.ui.control.Label
        Panel_SimulacioCampCaptacio     matlab.ui.container.Panel
        GridLayout270                   matlab.ui.container.GridLayout
        Panel_SubSimulacioCampCaptacio  matlab.ui.container.Panel
        GridLayout258                   matlab.ui.container.GridLayout
        Panel_Pous                      matlab.ui.container.Panel
        GridLayout259                   matlab.ui.container.GridLayout
        PousInfo1_wrt                   matlab.ui.control.Label
        PousInfo2_wrt                   matlab.ui.control.Label
        Panel_Sondes                    matlab.ui.container.Panel
        GridLayout260                   matlab.ui.container.GridLayout
        SondesInfo_wrt                  matlab.ui.control.Label
        Panel_Materials                 matlab.ui.container.Panel
        GridLayout261                   matlab.ui.container.GridLayout
        MaterialsInfo_wrt               matlab.ui.control.Label
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ManualGeoSIVv12Button, 
        % ManualGeoSIVv12Button_2, ManualGeoSIVv12Button_3, 
        % ManualGeoSIVv12Button_4, ManualGeoSIVv12Button_5, 
        % ManualGeoSIVv12Button_6
        function LinkTo_Manual(app, event)
            winopen('Geo-SIV 1.2 2022 Guia Ràpida.pdf');
        end

        % Button pushed function: IniciareldissenyButton, 
        % IniciareldissenyButton_2, back_2_demand
        function GoTo_DadesEntrada(app, event)
            app.TabGroup.SelectedTab = app.DadesdentradaTab;
        end

        % Button pushed function: LinkVISSIR
        function LinkTo_VISSIRPushed(app, event)
            dos('explorer http://www.icc.cat/vissir3/index.html?YSkTmS8BD');
        end

        % Value changed function: UnknownDemand
        function Select_DemandaConeguda(app, event)
            if (app.UnknownDemand.Value == false)
                app.pot_estiu.Enable = 1;
                app.pot_hivern.Enable = 1;
                app.e_estiu.Enable = 1;
                app.e_hivern.Enable = 1;
                app.e_ACS.Enable = 1;
                app.pot_ACS.Enable = 1;
                app.finestra.Enable = 0;
                app.SC_h.Enable=0;
                app.SC_c.Enable=0;
                app.superficie.Enable = 0;
                app.plantes.Enable = 0;
                app.persones.Enable = 0;
                app.ACS_especific.Enable = 0;
                app.t_confort_h.Enable = 0;
                app.t_confort_c.Enable = 0;
                app.ResiNoSoterrani.Enable = 0;
                app.ResiSoterrani.Enable=0;
                app.CentreEducatiu.Enable=0;
                app.Oficines.Enable=0;
                app.Hosteleria.Enable=0;
                app.Hospital.Enable=0;
                app.CentreEsportiu.Enable=0;
                app.PlantaQuadrada.Enable = 0;
                app.PlantaRectangular1.Enable=0;
                app.PlantaRectangular2.Enable=0;
            else
                app.pot_estiu.Enable = 0;
                app.pot_hivern.Enable = 0;
                app.e_estiu.Enable = 0;
                app.e_hivern.Enable = 0;
                app.e_ACS.Enable = 0;
                app.pot_ACS.Enable = 0;
                app.finestra.Enable = 1;
                app.SC_h.Enable=1;
                app.SC_c.Enable=1;
                app.superficie.Enable = 1;
                app.plantes.Enable = 1;
                app.persones.Enable = 1;
                app.ACS_especific.Enable = 1;
                app.t_confort_h.Enable = 1;
                app.t_confort_c.Enable = 1;
                app.ResiNoSoterrani.Enable = 1;
                app.ResiSoterrani.Enable=1;
                app.CentreEducatiu.Enable=1;
                app.Oficines.Enable=1;
                app.Hosteleria.Enable=1;
                app.Hospital.Enable=1;
                app.CentreEsportiu.Enable=1;
                app.PlantaQuadrada.Enable = 1;
                app.PlantaRectangular1.Enable=1;
                app.PlantaRectangular2.Enable=1;
            end
        end

        % Selection changed function: TipologiaEdifici
        function Choose_TipologiaEdifici(app, event)
            parammatrix=readmatrix('019_parametres_calcul_carregues.xlsx');
            if app.ResiNoSoterrani.Value==1 %JGC: Tipologia residencial sense soterrani (pensem en una casa)
%                 app.superficie.Value=100; %JGC[m2]
%                 app.finestra.Value=0.2;
%                 app.SC_h.Value=0.7;
%                 app.SC_c.Value=0.45;
%                 app.plantes.Value=1;
%                 app.persones.Value=4;
%                 app.ACS_especific.Value=41; %JGC: [litres/persona/dia]
                  selector_use=1;
            elseif app.ResiSoterrani.Value==1 %JGC: Tipologia residencial amb soterrani (pensem en una casa d'una planta)
%                 app.superficie.Value=200; %JGC[m2]
%                 app.finestra.Value=0.2;
%                 app.SC_h.Value=0.7;
%                 app.SC_c.Value=0.45;
%                 app.plantes.Value=1;
%                 app.persones.Value=4;
%                 app.ACS_especific.Value=41; %JGC: [litres/persona/dia]
                  selector_use=2;
            elseif app.CentreEducatiu.Value==1 %JGC: Tipologia centre educatiu (pensem en un edifici de dues plantes, 1 linia per curs, de P3 a 6è, sense dutxes)
%                 app.superficie.Value=1000; %JGC[m2]
%                 app.finestra.Value=0.2;
%                 app.SC_h.Value=0.6;
%                 app.SC_c.Value=0.35;
%                 app.plantes.Value=2;
%                 app.persones.Value=150; %JGC: 225 alumnes (considerats des d'un punt de vista energètic com mig-adults) + 25 adults
%                 app.ACS_especific.Value=4; %JGC: [litres/persona/dia]
                  selector_use=3;
            elseif app.Oficines.Value==1 %JGC: Tipologia edifici d'oficines (pensem en un edifici acristallat de 5 plantes)
%                 app.superficie.Value=1000; %JGC[m2]
%                 app.finestra.Value=0.4;
%                 app.SC_h.Value=0.3;
%                 app.SC_c.Value=0.15;
%                 app.plantes.Value=3;
%                 app.persones.Value=100;
%                 app.ACS_especific.Value=2; %JGC: [litres/persona/dia]
                  selector_use=4;
            elseif app.Hosteleria.Value==1 %JGC: Tipologia hotel i/o restauració (pensem en un hotel*** de 4 plantes, 20 habs. per planta, i rest. 1a planta)
%                 app.superficie.Value=1000; %JGC[m2]
%                 app.finestra.Value=0.15;
%                 app.SC_h.Value=0.6;
%                 app.SC_c.Value=0.3;
%                 app.plantes.Value=4;
%                 app.persones.Value=100;
%                 app.ACS_especific.Value=100; %JGC: [litres/persona/dia]
                  selector_use=5;
            elseif app.Hospital.Value==1 %JGC: Tipologia centre sanitari (pensem en un ambulatori de poble)
%                 app.superficie.Value=400; %JGC[m2]
%                 app.finestra.Value=0.15;
%                 app.SC_h.Value=0.5;
%                 app.SC_c.Value=0.3;
%                 app.plantes.Value=1;
%                 app.persones.Value=25;
%                 app.ACS_especific.Value=10; %JGC: [litres/persona/dia]
                  selector_use=6;
            elseif app.CentreEsportiu.Value==1 %JGC: Tipologia centre esportiu (pensem en un gimnàs petit, sense piscina)
%                 app.superficie.Value=500; %JGC[m2]
%                 app.finestra.Value=0.2;
%                 app.SC_h.Value=0.4;
%                 app.SC_c.Value=0.25;
%                 app.plantes.Value=1;
%                 app.persones.Value=50;
%                 app.ACS_especific.Value=200; %JGC: [litres/persona/dia] (hem de considerar que aquest valor correspon a la suma de moltes dutxes pq
                % no es que tinguem 50 persones tot el dia, sino potser 500 persones "repartides en 10 torns"% )
                 selector_use=7;
            end
            paramload=parammatrix(:,selector_use);
            app.superficie.Value=paramload(5); %JGC[m2]
            app.finestra.Value=paramload(7);
            app.SC_h.Value=paramload(8);
            app.SC_c.Value=paramload(9);
            app.plantes.Value=paramload(6);
            app.persones.Value=paramload(10);
            app.ACS_especific.Value=paramload(12); %JGC: [litres/persona/dia]
            app.t_confort_h.Value=paramload(13);
            app.t_confort_c.Value=paramload(14);
        end

        % Callback function: GeometriaPlanta, finestra, plantes, 
        % superficie
        function Input_SuperficieTotalEdifici(app, event)
            sup=double(app.superficie.Value);
            plt=double(app.plantes.Value);
            parammatrix = readmatrix("019_parametres_calcul_carregues.xlsx");
            %JGC: hem d'establir primer de quin tipus d'edifici es tracta,
            %a l'igual que amb la rutina de dimensionament.
            if app.ResiNoSoterrani.Value==1 %JGC: Tipologia residencial sense soterrani (pensem en una casa)
                    selector_use = 1; 
                elseif app.ResiSoterrani.Value==1 %JGC: Tipologia residencial amb soterrani (pensem en una casa d'una planta)
                    selector_use = 2;
                elseif app.CentreEducatiu.Value==1 %JGC: Tipologia centre educatiu (pensem en un edifici de dues plantes, 1 linia per curs, de P3 a 6è, sense dutxes)
                    selector_use = 3;
                elseif app.Oficines.Value==1 %JGC: Tipologia edifici d'oficines (pensem en un edifici acristallat de 5 plantes)
                    selector_use = 4;
                elseif app.Hosteleria.Value==1 %JGC: Tipologia hotel i/o restauració (pensem en un hotel*** de 4 plantes, 20 habs. per planta, i rest. 1a planta)
                    selector_use = 5;
                elseif app.Hospital.Value==1 %JGC: Tipologia centre sanitari (pensem en un ambulatori de poble)
                    selector_use = 6;
                elseif app.CentreEsportiu.Value==1 %JGC: Tipologia centre esportiu (pensem en un gimnàs petit, sense piscina)
                    selector_use = 7;
            end
            paramload=parammatrix(:,selector_use);
            
            if (app.ResiSoterrani.Value==true)
                supplt=sup/(plt+1);
            else
                supplt=sup/plt;
            end
            if app.PlantaQuadrada.Value==true
                perimetre=4*sqrt(supplt);%JGC:[m]
            else
                perimetre=6*sqrt(0.5*supplt); %JGC:[m]
            end
            paret=paramload(1); %JGC:[m]
            Lim_finestra=0.9*round((perimetre*paret-paramload(4))/supplt,2);
%             if (app.ResiNoSoterrani.Value==true)||(app.ResiSoterrani.Value==true)
%                 paret=paramload(1);%JGC:[m]
%                 Lim_finestra=0.9*round((perimetre*paret-paramload(4))/supplt,2);
%             else
%                 paret=paramload(1); %JGC:[m]
%                 Lim_finestra=0.9*round((perimetre*paret-paramload(4))/supplt,2);
%             end
            app.finestra.Limits=[0 Lim_finestra];
        end

        % Value changed function: t_confort_h
        function Input_Tconforth(app, event)
            value = app.t_confort_h.Value;
            app.t_confort_c.Limits=[value 35];
        end

        % Selection changed function: hp_criteria
        function Choose_BCGManual_o_Automatic(app, event)
            if app.owner_hp.Value==true
                app.user_hp_heat.Enable=1;
                app.user_COP.Enable=1;
                app.user_hp_cool.Enable=1;
                app.user_EER.Enable=1;
                app.user_hp_ACS.Enable=1;
                app.user_COPACS.Enable=1;
            else
                app.user_hp_heat.Enable=0;
                app.user_COP.Enable=0;
                app.user_hp_cool.Enable=0;
                app.user_EER.Enable=0;
                app.user_hp_ACS.Enable=0;
                app.user_COPACS.Enable=0;
            end            
        end

        % Selection changed function: SistemaDist_h
        function Select_SistemaDist_h(app, event)
        %JGC: Si no tenim seleccionat terra radiant per calefacció, és
        %absurd poder-ho seleccionar per refrigeració
            if app.Dist_TR_h.Value==1
                app.Dist_TR_c.Enable=1;
            else
                app.Dist_TR_c.Enable=0;
                app.Dist_FC_c.Value=1;
            end
            BCG_nominal_Tevap10=readmatrix('007_Generic heat pumps_extended.xlsx');
            Matrix_labels_BCG=zeros(11,4);
            for i=1:1:11
                if app.Dist_TR_h.Value==1
                    Matrix_labels_BCG(i,1)=BCG_nominal_Tevap10(i,1)/1000;
                    Matrix_labels_BCG(i,2)=BCG_nominal_Tevap10(i,2);
                    Matrix_labels_BCG(i,3)=BCG_nominal_Tevap10(i+11,3)/1000;
                    Matrix_labels_BCG(i,4)=BCG_nominal_Tevap10(i+11,4);
                elseif app.Dist_FC_h.Value==1
                    Matrix_labels_BCG(i,1)=BCG_nominal_Tevap10(i+11,1)/1000;
                    Matrix_labels_BCG(i,2)=BCG_nominal_Tevap10(i+11,2);
                    Matrix_labels_BCG(i,3)=BCG_nominal_Tevap10(i+11,3)/1000;
                    Matrix_labels_BCG(i,4)=BCG_nominal_Tevap10(i+11,4);
                elseif app.Dist_RR_h.Value==1
                    Matrix_labels_BCG(i,1)=BCG_nominal_Tevap10(i+22,1)/1000;
                    Matrix_labels_BCG(i,2)=BCG_nominal_Tevap10(i+22,2);
                    Matrix_labels_BCG(i,3)=BCG_nominal_Tevap10(i+11,3)/1000;
                    Matrix_labels_BCG(i,4)=BCG_nominal_Tevap10(i+11,4);
                end
            end
            app.Label_44.Text=num2str(Matrix_labels_BCG(1,1),'%.2f');
            app.Label_34.Text=num2str(Matrix_labels_BCG(2,1),'%.2f');
            app.Label_35.Text=num2str(Matrix_labels_BCG(3,1),'%.2f');
            app.Label_36.Text=num2str(Matrix_labels_BCG(4,1),'%.2f');
            app.Label_37.Text=num2str(Matrix_labels_BCG(5,1),'%.2f');
            app.Label_38.Text=num2str(Matrix_labels_BCG(6,1),'%.2f');
            app.Label_39.Text=num2str(Matrix_labels_BCG(7,1),'%.2f');
            app.Label_40.Text=num2str(Matrix_labels_BCG(8,1),'%.2f');
            app.Label_41.Text=num2str(Matrix_labels_BCG(9,1),'%.2f');
            app.Label_42.Text=num2str(Matrix_labels_BCG(10,1),'%.2f');
            app.Label_43.Text=num2str(Matrix_labels_BCG(11,1),'%.2f');
            app.Label_55.Text=num2str(Matrix_labels_BCG(1,2),'%.2f');
            app.Label_45.Text=num2str(Matrix_labels_BCG(2,2),'%.2f');
            app.Label_46.Text=num2str(Matrix_labels_BCG(3,2),'%.2f');
            app.Label_47.Text=num2str(Matrix_labels_BCG(4,2),'%.2f');
            app.Label_48.Text=num2str(Matrix_labels_BCG(5,2),'%.2f');
            app.Label_49.Text=num2str(Matrix_labels_BCG(6,2),'%.2f');
            app.Label_50.Text=num2str(Matrix_labels_BCG(7,2),'%.2f');
            app.Label_51.Text=num2str(Matrix_labels_BCG(8,2),'%.2f');
            app.Label_52.Text=num2str(Matrix_labels_BCG(9,2),'%.2f');
            app.Label_53.Text=num2str(Matrix_labels_BCG(10,2),'%.2f');
            app.Label_54.Text=num2str(Matrix_labels_BCG(11,2),'%.2f');
            app.Label_66.Text=num2str(Matrix_labels_BCG(1,3),'%.2f');
            app.Label_56.Text=num2str(Matrix_labels_BCG(2,3),'%.2f');
            app.Label_57.Text=num2str(Matrix_labels_BCG(3,3),'%.2f');
            app.Label_58.Text=num2str(Matrix_labels_BCG(4,3),'%.2f');
            app.Label_59.Text=num2str(Matrix_labels_BCG(5,3),'%.2f');
            app.Label_60.Text=num2str(Matrix_labels_BCG(6,3),'%.2f');
            app.Label_61.Text=num2str(Matrix_labels_BCG(7,3),'%.2f');
            app.Label_62.Text=num2str(Matrix_labels_BCG(8,3),'%.2f');
            app.Label_63.Text=num2str(Matrix_labels_BCG(9,3),'%.2f');
            app.Label_64.Text=num2str(Matrix_labels_BCG(10,3),'%.2f');
            app.Label_65.Text=num2str(Matrix_labels_BCG(11,3),'%.2f');
            app.Label_77.Text=num2str(Matrix_labels_BCG(1,4),'%.2f');
            app.Label_67.Text=num2str(Matrix_labels_BCG(2,4),'%.2f');
            app.Label_68.Text=num2str(Matrix_labels_BCG(3,4),'%.2f');
            app.Label_69.Text=num2str(Matrix_labels_BCG(4,4),'%.2f');
            app.Label_70.Text=num2str(Matrix_labels_BCG(5,4),'%.2f');
            app.Label_71.Text=num2str(Matrix_labels_BCG(6,4),'%.2f');
            app.Label_72.Text=num2str(Matrix_labels_BCG(7,4),'%.2f');
            app.Label_73.Text=num2str(Matrix_labels_BCG(8,4),'%.2f');
            app.Label_74.Text=num2str(Matrix_labels_BCG(9,4),'%.2f');
            app.Label_75.Text=num2str(Matrix_labels_BCG(10,4),'%.2f');
            app.Label_76.Text=num2str(Matrix_labels_BCG(11,4),'%.2f');
        end

        % Selection changed function: SistemaDist_c
        function Select_SistemaDist_c(app, event)
            BCG_nominal_Tevap10=readmatrix('007_Generic heat pumps_extended.xlsx');
            Matrix_labels_BCG=zeros(11,2);
            for i=1:1:11
                if app.Dist_TR_c.Value==1
                    Matrix_labels_BCG(i,1)=BCG_nominal_Tevap10(i,3)/1000;
                    Matrix_labels_BCG(i,2)=BCG_nominal_Tevap10(i,4);
                elseif app.Dist_FC_c.Value==1
                    Matrix_labels_BCG(i,1)=BCG_nominal_Tevap10(i+11,3)/1000;
                    Matrix_labels_BCG(i,2)=BCG_nominal_Tevap10(i+11,4);
                end
            end
            app.Label_66.Text=num2str(Matrix_labels_BCG(1,1),'%.2f');
            app.Label_56.Text=num2str(Matrix_labels_BCG(2,1),'%.2f');
            app.Label_57.Text=num2str(Matrix_labels_BCG(3,1),'%.2f');
            app.Label_58.Text=num2str(Matrix_labels_BCG(4,1),'%.2f');
            app.Label_59.Text=num2str(Matrix_labels_BCG(5,1),'%.2f');
            app.Label_60.Text=num2str(Matrix_labels_BCG(6,1),'%.2f');
            app.Label_61.Text=num2str(Matrix_labels_BCG(7,1),'%.2f');
            app.Label_62.Text=num2str(Matrix_labels_BCG(8,1),'%.2f');
            app.Label_63.Text=num2str(Matrix_labels_BCG(9,1),'%.2f');
            app.Label_64.Text=num2str(Matrix_labels_BCG(10,1),'%.2f');
            app.Label_65.Text=num2str(Matrix_labels_BCG(11,1),'%.2f');
            app.Label_77.Text=num2str(Matrix_labels_BCG(1,2),'%.2f');
            app.Label_67.Text=num2str(Matrix_labels_BCG(2,2),'%.2f');
            app.Label_68.Text=num2str(Matrix_labels_BCG(3,2),'%.2f');
            app.Label_69.Text=num2str(Matrix_labels_BCG(4,2),'%.2f');
            app.Label_70.Text=num2str(Matrix_labels_BCG(5,2),'%.2f');
            app.Label_71.Text=num2str(Matrix_labels_BCG(6,2),'%.2f');
            app.Label_72.Text=num2str(Matrix_labels_BCG(7,2),'%.2f');
            app.Label_73.Text=num2str(Matrix_labels_BCG(8,2),'%.2f');
            app.Label_74.Text=num2str(Matrix_labels_BCG(9,2),'%.2f');
            app.Label_75.Text=num2str(Matrix_labels_BCG(10,2),'%.2f');
            app.Label_76.Text=num2str(Matrix_labels_BCG(11,2),'%.2f');
            
        end

        % Value changed function: SwitchCAT_Gir
        function Select_GeographicalDomain(app, event)
            if app.SwitchCAT_Gir.Value=="Catalunya"
                app.x_loc.Limits=[260130.1 527429.9];
                app.y_loc.Limits=[4488675.1 4748054.9];
                input_utm_x = double(app.x_loc.Value);    %input longitude, in UTM31
                input_utm_y = double(app.y_loc.Value);   %input latitude, in UTM31
                [map_provin,struct_provin] = readgeoraster('provincias.tif');
                provin_info = imref2d(size(map_provin));
                provin_info.XWorldLimits = struct_provin.XWorldLimits;
                provin_info.YWorldLimits = struct_provin.YWorldLimits;
                [x_w_provin,y_w_provin] = worldToIntrinsic(provin_info,input_utm_x,input_utm_y);
                getsizeprovin = size(map_provin);
                lect_provin = interp2(map_provin,x_w_provin,getsizeprovin(1)-y_w_provin);
                lect_provin = double(lect_provin);
                if lect_provin < 5
                    if (app.user_PropTerm.Value == 1)
                        app.ChangeT_ground0.Enable=1;
                        app.user_difu.Enable = 1;
                        app.user_cdty.Enable = 1;
                    else
                        app.ChangeT_ground0.Enable=0;
                        app.user_difu.Enable = 0;
                        app.user_cdty.Enable = 0;
                        %Thermal conductivity
                        [map_cdty,struct_cdty] = readgeoraster('ThermalConductivity.tif');
                        info_cdty = imref2d(size(map_cdty));
                        mapsize_cdty = size(map_cdty);
                        info_cdty.XWorldLimits = struct_cdty.XWorldLimits;
                        info_cdty.YWorldLimits = struct_cdty.YWorldLimits;
                        [x_w_cdty,y_w_cdty] = worldToIntrinsic(info_cdty,input_utm_x,input_utm_y);
                        lect_cdty = interp2(map_cdty,x_w_cdty,mapsize_cdty(1)-y_w_cdty,'bilinear');
                        lect_cdty = double(lect_cdty); %JGC: [W/mK] valor promig
                        if (lect_cdty< 0)
                            app.user_cdty.Value = 0;
                        else 
                            app.user_cdty.Value = double(lect_cdty);
                        end
                        app.user_cdty.Value=lect_cdty;
                        %Thermal difusivity
                        [map_difu,struct_difu] = readgeoraster('ThermalDifusivity.tif');
                        info_difu = imref2d(size(map_difu));
                        mapsize_difu = size(map_cdty);
                        info_difu.XWorldLimits = struct_difu.XWorldLimits;
                        info_difu.YWorldLimits = struct_difu.YWorldLimits;
                        [x_w_difu,y_w_difu] = worldToIntrinsic(info_difu,input_utm_x,input_utm_y);
                        lect_difu = interp2(map_difu,x_w_difu,mapsize_difu(1)-y_w_difu,'bilinear');
                        lect_difu = double(lect_difu);%JGC: [mm2/s]
                        if (lect_difu < 0)
                            app.user_difu.Value = 0;
                        else 
                            app.user_difu.Value = double(lect_difu);
                        end
                    end
                else
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'La ubicació seleccionada es troba fora dels límits terrestres de Catalunya','Missatge d''error','Icon','error')     
                end
            else
                %JGC: Dades carregades específicament de Girona (Resultats projecte MUSE)
                app.x_loc.Limits=[476807.6 486707.4];
                app.y_loc.Limits=[4641588.9 4650528.8];
                app.x_loc.Value = 484620.6; %JGC %JGC Parc Central, zona sud davant Pg. d'Olot
                app.y_loc.Value = 4647211.7; %JGC %JGC Parc Central, zona sud davant Pg. d'Olot
                app.user_difu.Value = 1.342;
                app.user_cdty.Value = 2.681;
                app.ChangeT_ground0.Value = 17.1;
                if (app.user_PropTerm.Value == 1)
                    app.ChangeT_ground0.Enable=1;
                    app.user_difu.Enable = 0;
                    app.user_cdty.Enable = 0;
                else
                    app.ChangeT_ground0.Enable=0;
                    app.user_difu.Enable = 0;
                    app.user_cdty.Enable = 0;
                end
                %JGC: NOTA 19/11/2021: No necessito calcular en aquest punt
                %les propietats tèrmiques, ja que al clicar sobre el botó
                %de switch des de Catalunya a Girona, estic escollint
                %automàticament ubicació, amb unes propietats que ja conec
%                 input_utm_x = double(app.x_loc.Value);    %input longitude, in UTM31
%                 input_utm_y = double(app.y_loc.Value);   %input latitude, in UTM31
%                 [map_difuGIR,struct_difuGIR] = readgeoraster('MUSE_ThermalDifusivity.tif');
%                 difuGIR_info = imref2d(size(map_difuGIR));
%                 difuGIR_info.XWorldLimits = struct_difuGIR.XWorldLimits;
%                 difuGIR_info.YWorldLimits = struct_difuGIR.YWorldLimits;
%                 [x_w_difuGIR,y_w_difuGIR] = worldToIntrinsic(difuGIR_info,input_utm_x,input_utm_y);
%                 getsizedifuGIR = size(map_difuGIR);
%                 lect_difuGIR = interp2(map_difuGIR,x_w_difuGIR,getsizedifuGIR(1)-y_w_difuGIR);
%                 lect_difuGIR = double(lect_difuGIR);
%                 setappdata(0,'DifusivitatGirona',lect_difuGIR);
%                 
%                 [map_cdtyGIR,struct_cdtyGIR] = readgeoraster('MUSE_ThermalConductivity.tif');
%                 cdtyGIR_info = imref2d(size(map_cdtyGIR));
%                 cdtyGIR_info.XWorldLimits = struct_cdtyGIR.XWorldLimits;
%                 cdtyGIR_info.YWorldLimits = struct_cdtyGIR.YWorldLimits;
%                 [x_w_cdtyGIR,y_w_cdtyGIR] = worldToIntrinsic(cdtyGIR_info,input_utm_x,input_utm_y);
%                 getsizecdtyGIR = size(map_cdtyGIR);
%                 lect_cdtyGIR = interp2(map_cdtyGIR,x_w_cdtyGIR,getsizecdtyGIR(1)-y_w_cdtyGIR);
%                 lect_cdtyGIR = double(lect_cdtyGIR);
%                 setappdata(0,'ConductivitatGirona',lect_cdtyGIR);
%                 
%                 %Temperature at 50 meters
%                 [map_GIR50,struct_GIR50] = readgeoraster('MUSE_t050.tif');
%                 info_GIR50 = imref2d(size(map_GIR50));
%                 mapsizetemp = size(map_GIR50);
%                 info_GIR50.XWorldLimits = [476807.5 486707.5];
% %                     info_GIR50.XWorldLimits = struct_GIR50.XWorldLimits;
%                 info_GIR50.YWorldLimits = [4641588.898 4650528.898];
% %                     info_GIR50.YWorldLimits = struct_GIR50.YWorldLimits;
%                 [x_w_GIR50,y_w_GIR50] = worldToIntrinsic(info_GIR50,input_utm_x,input_utm_y);
%                 lect_GIR50 = interp2(map_GIR50,x_w_GIR50,mapsizetemp(1)-y_w_GIR50,'bilinear');
%                 
%                 if (lect_difuGIR > 0)&&(lect_GIR50 > 0)
%                         app.user_difu.Value = double(lect_difuGIR);
%                         app.user_cdty.Value = double(lect_cdtyGIR);
%                     if (app.user_PropTerm.Value == 1)
%                         app.ChangeT_ground0.Enable=1;
%                         app.user_difu.Enable = 0;
%                         app.user_cdty.Enable = 0;
%                     else
%                         app.ChangeT_ground0.Enable=0;
%                         app.user_difu.Enable = 0;
%                         app.user_cdty.Enable = 0;
%                     end
%                 else
%                     alert_fig = app.GeoSIVdesigntoolv10UIFigure;
%                     uialert(alert_fig,'La ubicació seleccionada es troba fora de l''àmbit específic de l''àrea urbana i periurbana de Girona. Canviar ubicació o seleccionar àmbit de Catalunya','Missatge d''error','Icon','error')
%                 end
            end
        end

        % Value changed function: user_PropTerm
        function Choose_PropTermManual_o_Automatic(app, event)
            input_utm_x = double(app.x_loc.Value);    %input longitude, in UTM31
            input_utm_y = double(app.y_loc.Value);   %input latitude, in UTM31
            if app.SwitchCAT_Gir.Value=="Catalunya"
                [map_provin,struct_provin] = readgeoraster('provincias.tif');
                provin_info = imref2d(size(map_provin));
                provin_info.XWorldLimits = struct_provin.XWorldLimits;
                provin_info.YWorldLimits = struct_provin.YWorldLimits;
                [x_w_provin,y_w_provin] = worldToIntrinsic(provin_info,input_utm_x,input_utm_y);
                getsizeprovin = size(map_provin);
                lect_provin = interp2(map_provin,x_w_provin,getsizeprovin(1)-y_w_provin);
                lect_provin = double(lect_provin);
                if lect_provin < 5
                    if (app.user_PropTerm.Value == 1)
                        app.ChangeT_ground0.Enable=1;
                        app.user_difu.Enable = 1;
                        app.user_cdty.Enable = 1;
                    else
                        app.ChangeT_ground0.Enable=0;
                        app.user_difu.Enable = 0;
                        app.user_cdty.Enable = 0;
                        %Thermal conductivity
                        [map_cdty,struct_cdty] = readgeoraster('ThermalConductivity.tif');
                        info_cdty = imref2d(size(map_cdty));
                        mapsize_cdty = size(map_cdty);
                        info_cdty.XWorldLimits = struct_cdty.XWorldLimits;
                        info_cdty.YWorldLimits = struct_cdty.YWorldLimits;
                        [x_w_cdty,y_w_cdty] = worldToIntrinsic(info_cdty,input_utm_x,input_utm_y);
                        lect_cdty = interp2(map_cdty,x_w_cdty,mapsize_cdty(1)-y_w_cdty,'bilinear');
                        lect_cdty = double(lect_cdty); %JGC: [W/mK] valor promig
                        if (lect_cdty< 0)
                            app.user_cdty.Value = 0;
                        else 
                            app.user_cdty.Value = double(lect_cdty);
                        end
                        app.user_cdty.Value=lect_cdty;
                        %Thermal difusivity
                        [map_difu,struct_difu] = readgeoraster('ThermalDifusivity.tif');
                        info_difu = imref2d(size(map_difu));
                        mapsize_difu = size(map_cdty);
                        info_difu.XWorldLimits = struct_difu.XWorldLimits;
                        info_difu.YWorldLimits = struct_difu.YWorldLimits;
                        [x_w_difu,y_w_difu] = worldToIntrinsic(info_difu,input_utm_x,input_utm_y);
                        lect_difu = interp2(map_difu,x_w_difu,mapsize_difu(1)-y_w_difu,'bilinear');
                        lect_difu = double(lect_difu);%JGC: [mm2/s]
                        if (lect_difu < 0)
                            app.user_difu.Value = 0;
                        else 
                            app.user_difu.Value = double(lect_difu);
                        end
                    end
                else
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'La ubicació seleccionada es troba fora dels límits terrestres de Catalunya','Missatge d''error','Icon','error')     
                end
            else
                %JGC: Dades carregades específicament de Girona (Resultats projecte MUSE)
                [map_difuGIR,struct_difuGIR] = readgeoraster('MUSE_ThermalDifusivity.tif');
                difuGIR_info = imref2d(size(map_difuGIR));
                difuGIR_info.XWorldLimits = struct_difuGIR.XWorldLimits;
                difuGIR_info.YWorldLimits = struct_difuGIR.YWorldLimits;
                [x_w_difuGIR,y_w_difuGIR] = worldToIntrinsic(difuGIR_info,input_utm_x,input_utm_y);
                getsizedifuGIR = size(map_difuGIR);
                lect_difuGIR = interp2(map_difuGIR,x_w_difuGIR,getsizedifuGIR(1)-y_w_difuGIR);
                lect_difuGIR = double(lect_difuGIR);
                setappdata(0,'DifusivitatGirona',lect_difuGIR);
                
                [map_cdtyGIR,struct_cdtyGIR] = readgeoraster('MUSE_ThermalConductivity.tif');
                cdtyGIR_info = imref2d(size(map_cdtyGIR));
                cdtyGIR_info.XWorldLimits = struct_cdtyGIR.XWorldLimits;
                cdtyGIR_info.YWorldLimits = struct_cdtyGIR.YWorldLimits;
                [x_w_cdtyGIR,y_w_cdtyGIR] = worldToIntrinsic(cdtyGIR_info,input_utm_x,input_utm_y);
                getsizecdtyGIR = size(map_cdtyGIR);
                lect_cdtyGIR = interp2(map_cdtyGIR,x_w_cdtyGIR,getsizecdtyGIR(1)-y_w_cdtyGIR);
                lect_cdtyGIR = double(lect_cdtyGIR);
                setappdata(0,'ConductivitatGirona',lect_cdtyGIR);
                
                %Temperature at 50 meters
                [map_GIR50,struct_GIR50] = readgeoraster('MUSE_t050.tif');
                info_GIR50 = imref2d(size(map_GIR50));
                mapsizetemp = size(map_GIR50);
                info_GIR50.XWorldLimits = [476807.5 486707.5];
%                     info_GIR50.XWorldLimits = struct_GIR50.XWorldLimits;
                info_GIR50.YWorldLimits = [4641588.898 4650528.898];
%                     info_GIR50.YWorldLimits = struct_GIR50.YWorldLimits;
                [x_w_GIR50,y_w_GIR50] = worldToIntrinsic(info_GIR50,input_utm_x,input_utm_y);
                lect_GIR50 = interp2(map_GIR50,x_w_GIR50,mapsizetemp(1)-y_w_GIR50,'bilinear');
                
                if (lect_difuGIR > 0)&&(lect_GIR50 > 0)
                        app.user_difu.Value = double(lect_difuGIR);
                        app.user_cdty.Value = double(lect_cdtyGIR);
                        app.ChangeT_ground0.Value = double(lect_GIR50);
                    if (app.user_PropTerm.Value == 1)
                        app.ChangeT_ground0.Enable=1;
                        app.user_difu.Enable = 0;
                        app.user_cdty.Enable = 0;
                        alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                        uialert(alert_fig,'A l''àmbit de l''àrea urbana i periurbana de Girona només es permet editar el valor de T_subsol. Per editar els valors de conductivitat i difussivitat tèrmiques, canviar a àmbit de Catalunya.','Missatge d''avís','Icon','warning')
                    else
                        app.ChangeT_ground0.Enable=0;
                        app.user_difu.Enable = 0;
                        app.user_cdty.Enable = 0;
                    end
                else
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'La ubicació seleccionada es troba fora de l''àmbit específic de l''àrea urbana i periurbana de Girona. Canviar ubicació o seleccionar àmbit de Catalunya','Missatge d''error','Icon','error')
                end
            end            
        end

        % Button pushed function: RunSizing
        function RUN_DimensionamentGeotermia(app, event)
            DemandValid = 0;
            BCGValid = 0;
            PropTermValid = 0;
   
            input_utm_x = double(app.x_loc.Value);    %input longitude, in UTM31
            input_utm_y = double(app.y_loc.Value);   %input latitude, in UTM31
            
            %JGC: El mapa que es carrega s'emmagatzema com una matriu. Les files estan ordenades de nord a sud, i les columnes, d'oest a est.
            %No obstant, la coordenada Y de nord a sud van en ordre decreixent, al contrari que l'índex de la matriu que el representa.
            % En el cas de la coordenada X, el sentit creixent de la coordenada (d'oest a est) és el mateix que el de l'índex de la matriu.
            % Això obliga a executar un algoritme simple per a fer correspondre l'Y d'entrada al valor correcte dins dels rasters:
            %1.- S'ha d'obtenir la grandària de la matriu en l'eix Y: getsizeprovin= size(map_provin)
            %2.- Es té en compte el valor màxim de la coordenada Ymax:4748055 values at size(map)[1] - y position. Aquesta solució llegeix el valor correcte
            [map_provin,struct_provin] = readgeoraster('provincias.tif');
            provin_info = imref2d(size(map_provin));
            provin_info.XWorldLimits = struct_provin.XWorldLimits;
            provin_info.YWorldLimits = struct_provin.YWorldLimits;
            [x_w_provin,y_w_provin] = worldToIntrinsic(provin_info,input_utm_x,input_utm_y);
            getsizeprovin = size(map_provin);
            lect_provin = interp2(map_provin,x_w_provin,getsizeprovin(1)-y_w_provin);
            lect_provin = double(lect_provin);
            setappdata(0,'provincia',lect_provin);
            
            %JGC: Anem a definir les condicions per validar les coordenades de localització
            if app.SwitchCAT_Gir.Value==" " %JGC (Girona)
                %JGC: Dades carregades específicament de Girona (Resultats projecte MUSE)
                [map_difuGIR,struct_difuGIR] = readgeoraster('MUSE_ThermalDifusivity.tif');
                difuGIR_info = imref2d(size(map_difuGIR));
                difuGIR_info.XWorldLimits = struct_difuGIR.XWorldLimits;
                difuGIR_info.YWorldLimits = struct_difuGIR.YWorldLimits;
                [x_w_difuGIR,y_w_difuGIR] = worldToIntrinsic(difuGIR_info,input_utm_x,input_utm_y);
                getsizedifuGIR = size(map_difuGIR);
                lect_difuGIR = interp2(map_difuGIR,x_w_difuGIR,getsizedifuGIR(1)-y_w_difuGIR);
                lect_difuGIR = double(lect_difuGIR);
                app.user_difu.Value=lect_difuGIR;
                setappdata(0,'DifusivitatGirona',lect_difuGIR);
                
                [map_cdtyGIR,struct_cdtyGIR] = readgeoraster('MUSE_ThermalConductivity.tif');
                cdtyGIR_info = imref2d(size(map_cdtyGIR));
                cdtyGIR_info.XWorldLimits = struct_cdtyGIR.XWorldLimits;
                cdtyGIR_info.YWorldLimits = struct_cdtyGIR.YWorldLimits;
                [x_w_cdtyGIR,y_w_cdtyGIR] = worldToIntrinsic(cdtyGIR_info,input_utm_x,input_utm_y);
                getsizecdtyGIR = size(map_cdtyGIR);
                lect_cdtyGIR = interp2(map_cdtyGIR,x_w_cdtyGIR,getsizecdtyGIR(1)-y_w_cdtyGIR);
                lect_cdtyGIR = double(lect_cdtyGIR);
                app.user_cdty.Value=lect_cdtyGIR;
                setappdata(0,'ConductivitatGirona',lect_cdtyGIR);
                
                %Temperature at 50 meters
                [map_GIR50,struct_GIR50] = readgeoraster('MUSE_t050.tif');
                info_GIR50 = imref2d(size(map_GIR50));
                mapsizetemp = size(map_GIR50);
                info_GIR50.XWorldLimits = [476807.5 486707.5];
%                     info_GIR50.XWorldLimits = struct_GIR50.XWorldLimits;
                info_GIR50.YWorldLimits = [4641588.898 4650528.898];
%                     info_GIR50.YWorldLimits = struct_GIR50.YWorldLimits;
                [x_w_GIR50,y_w_GIR50] = worldToIntrinsic(info_GIR50,input_utm_x,input_utm_y);
                lect_GIR50 = interp2(map_GIR50,x_w_GIR50,mapsizetemp(1)-y_w_GIR50,'bilinear');
                
                if (lect_difuGIR > 0)&&(lect_GIR50 > 0)
                    LocValidated=1;
                else
                    LocValidated=0;
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'La ubicació seleccionada es troba fora de l''àmbit específic de l''àrea urbana i periurbana Girona. Canviar ubicació o seleccionar àmbit de Catalunya','Missatge d''error','Icon','error')
                end
            else %JGC: Catalunya
                if (app.x_loc.Value >= 260130)&&(app.x_loc.Value <= 527430)&&(app.y_loc.Value >= 4488675)&&(app.y_loc.Value <= 4748055)&&(lect_provin < 5)
                    LocValidated=1;
                else
                    LocValidated=0;
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'La ubicació seleccionada es troba fora dels límits terrestres de Catalunya','Missatge d''error','Icon','error')
                end
            end
            
            %JGC: Anem a definir les condicions per validad propietats tèrmiques
            if (LocValidated == 1)
                if app.SwitchCAT_Gir.Value=="Catalunya"
                    if (app.user_PropTerm.Value == 1)
                        if app.user_difu.Value<0.2 %JGC: valor mínim del raster de difussivitat àmbit Catalunya
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt baix de la difusivitat tèrmica. Revisar','Missatge d''avís','Icon','warning')
                        elseif app.user_difu.Value>2.53 %JGC: valor màxim del raster de difussivitat àmbit Catalunya
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt elevat de la difusivitat tèrmica','Missatge d''avís','Icon','warning')
                        elseif app.user_cdty.Value<0.45 %JGC: valor mínim del raster de conductivitat àmbit Catalunya
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt baix de la conductivitat tèrmica. Revisar','Missatge d''avís','Icon','warning')
                        elseif app.user_cdty.Value>5.5 %JGC: valor mínim del raster de conductivitat àmbit Catalunya
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt elevat de la conductivitat tèrmica. Revisar','Missatge d''avís','Icon','warning')
                        elseif (app.ChangeT_ground0.Value<-0.8) %JGC: valor mínim del raster de T_mitjana anual àmbit Catalunya
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt baix de T_Subsol. Revisar','Missatge d''avís','Icon','warning')
                        elseif (app.ChangeT_ground0.Value>18.4) %JGC: valor màxim del raster de T_mitjana anual àmbit Catalunya
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt elevat de T_Subsol. Revisar','Missatge d''avís','Icon','warning')
                        end
                        PropTermValid=1;
                        lect_cdty = app.user_cdty.Value;
                        lect_difu = app.user_difu.Value;
                    else
                        %JGC: Primer plantegem qué pasa amb la conductivitat i difussivitat
                        [map_cdty,struct_cdty] = readgeoraster('ThermalConductivity.tif');
                        info_cdty = imref2d(size(map_cdty));
                        mapsize_cdty = size(map_cdty);
                        info_cdty.XWorldLimits = struct_cdty.XWorldLimits;
                        info_cdty.YWorldLimits = struct_cdty.YWorldLimits;
                        [x_w_cdty,y_w_cdty] = worldToIntrinsic(info_cdty,input_utm_x,input_utm_y);
                        lect_cdty = interp2(map_cdty,x_w_cdty,mapsize_cdty(1)-y_w_cdty,'bilinear'); %JGC: [W/mK] valor promig de la conductivitat del terra
                        [map_difu,struct_difu] = readgeoraster('ThermalDifusivity.tif');
                        info_difu = imref2d(size(map_difu));
                        mapsize_difu=size(map_difu);
                        info_difu.XWorldLimits = struct_difu.XWorldLimits;
                        info_difu.YWorldLimits = struct_difu.YWorldLimits;
                        [x_w_difu,y_w_difu] = worldToIntrinsic(info_difu,input_utm_x,input_utm_y);
                        lect_difu = interp2(map_difu,x_w_difu,mapsize_difu(1)-y_w_difu,'bilinear');%JGC: [mm2/s] valor promig de la difusivitat del terra
                        %JGC: Lectura conductivitat i difusivitat tèrmica. Per a que siguin 0 (o menor que 0??), hauriem d'estar
                        %fora del territori català. Improbable, però no descartable. Depén de la correcció dels rasters.    
                        if (lect_cdty <= 0)
                            app.user_cdty.Value = 0;
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Localització correcta pero sense dades de conductivitat. Introduïr manualment','Dades insuficients','Icon','error')
                        elseif (lect_difu <= 0)
                            app.user_difu.Value = 0;
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Localització correcta pero sense dades de difusivitat. Introduïr manualment','Dades insuficients','Icon','error')
                        else
                            app.user_cdty.Value = double(lect_cdty);
                            app.user_difu.Value = double(lect_difu);
                            PropTermValid=1;
                        end
                    end    
                else %JGC: Àrea de Girona
                    app.user_cdty.Value = double(lect_cdtyGIR);
                    lect_cdty=app.user_cdty.Value;
                    app.user_cdty.Enable=0;
                    app.user_difu.Value = double(lect_difuGIR);
                    lect_difu=app.user_difu.Value;
                    app.user_difu.Enable=0;
                    PropTermValid=1;
                    if app.user_PropTerm.Value==1
                        if (app.ChangeT_ground0.Value<7)
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt baix de T_Subsol. Revisar','Missatge d''avís','Icon','warning')
                        elseif (app.ChangeT_ground0.Value>27)
                            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                            uialert(alert_fig,'Valor molt elevat de T_Subsol. Revisar','Missatge d''avís','Icon','warning')
                        end
                    end
                end
            end
            store_decission = app.user_PropTerm.Value;
             
            
            %JGC: Després plantegem què passa amb les dades introduïdes referents a les càrregues tèrmiques
            if (app.UnknownDemand.Value == false) %JGC: Conec la càrrega
                if(app.pot_estiu.Value > 0)&&(app.e_estiu.Value == 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Falta dada de demanda de refrigeració o sobra dada de càrrega de disseny','Dades errònies','Icon','error')
                elseif (app.pot_estiu.Value == 0)&&(app.e_estiu.Value > 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Falta dada de càrrega de disseny de refrigeració o sobra dada de demanda','Dades errònies','Icon','error')
                elseif (app.pot_hivern.Value > 0)&&(app.e_hivern.Value == 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Falta dada de demanda de calefacció o sobra dada de càrrega de disseny','Dades errònies','Icon','error')
                elseif (app.pot_hivern.Value == 0)&&(app.e_hivern.Value > 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Falta dada de càrrega de disseny de calefacció o sobra dada de demanda','Dades errònies','Icon','error')
                elseif (app.pot_ACS.Value > 0)&&(app.e_ACS.Value == 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Falta dada de demanda d''ACS o sobra dada de càrrega de disseny','Dades errònies','Icon','error')
                elseif (app.pot_ACS.Value == 0)&&(app.e_ACS.Value > 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Falta dada de càrrega de disseny d''ACS o sobra dada de demanda','Dades errònies','Icon','error')
                elseif ((app.pot_estiu.Value == 0)&&(app.pot_hivern.Value == 0))&&((app.e_estiu.Value == 0)&&(app.e_hivern.Value == 0))&&(app.e_ACS.Value>0)&&(app.pot_ACS.Value>0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'No s''ha introduït cap dada referent a climatització','Dades insuficients','Icon','error')
                elseif ((app.pot_estiu.Value == 0)&&(app.pot_hivern.Value == 0))&&((app.e_estiu.Value == 0)&&(app.e_hivern.Value == 0))&&(app.e_ACS.Value==0)&&(app.pot_ACS.Value==0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'No s''ha introduït cap dada','Dades insuficients','Icon','error')
                elseif (app.e_ACS.Value==0)&&(app.pot_ACS.Value==0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'No s''ha introduït cap dada referent a ACS','Missatge d''avís','Icon','warning')
                    DemandValid = 1;
                else
                    DemandValid = 1;
                end
            else %JGC Si no conec la càrrega i l'ha de calcular GeoSIV
                if (app.superficie.Value <= 0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Valor de superfície total no introduït','Dades insuficients','Icon','error')
                elseif (app.t_confort_h.Value > app.t_confort_c.Value)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Temperatura de confort d''hivern superior a la d''estiu','Dades incoherents','Icon','error')
                else
                    DemandValid = 1;
                end
            end
            
            setappdata(0,'CalcDifuCdty',LocValidated)
            
            %JGC: Condicionant per a validar les dades sobre la bomba de calor
            if (app.owner_hp.Value == 1)
                if (app.user_hp_heat.Value==0)||(app.user_hp_cool.Value==0)||(app.user_COP.Value==0)||(app.user_EER.Value==0)||(app.user_hp_ACS.Value==0)||(app.user_COPACS.Value==0)
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Faltan dades de la bomba de calor geotèrmica','Dades insuficients','Icon','error')
                else
                    BCGValid = 1;
                end
            else
                BCGValid = 1; 
            end
    
    
            if (LocValidated == 1)&&(DemandValid == 1)&&(PropTermValid == 1)&&(BCGValid==1) %JGC: Ara sí que comença el càlcul!
                alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                uialert(alert_fig,'Dades validades. Esperi fins que l''aplicació finalitzi els càlculs','Enhorabona!','Icon','success')
                app.TabGroup.SelectedTab=app.ResultatsDissenyTab;
                
                %JGC: Comencem per llegir els excels amb les dades necessàries pel càlcul de les càrregues tèrmiques i la demanda
                temp_data = readmatrix('001_Yearly t-bin.xlsx');
                rough_data = readmatrix('004_Hourly t-bin.xlsx');
                u_limit_info = readmatrix('005_u_limit.xlsx');
                BCG_nominal_rough = readmatrix('007_Generic heat pumps_extended.xlsx');
                info_DR = readmatrix('008_DR_calculation.xlsx');
                design_conditions = readmatrix('009_Design temperatures.xlsx');
                monthly_tbin = readmatrix('012_Monthly t-bin.xlsx');
                vall_tbin = readmatrix('013_Monthly t-binVall.xlsx');
                pla_tbin = readmatrix('013_Monthly t-binPla.xlsx');
                punta_tbin = readmatrix('013_Monthly t-binPunta.xlsx');
                rough_Irr45_ST = readmatrix('015_Monthly_Irr4SolarThermal.xlsx'); %JGC: [kWh/m2/mes] Irradiació promig global calculada amb panells inclinats 45º climes: Matriu = (ClimaRef,mes)
                param = readmatrix("018_parametersEcoAmb.xlsx");
                parammatrix = readmatrix("019_parametres_calcul_carregues.xlsx");
                hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");%JGC: Aquí dins està la humitat mitjana de disseny (hivern i estiu) i la irradiació global mitjana anual [kWh/m2/dia]
                solar_gains = readmatrix("021_IRR90_orientacio_estacio.xlsx");
                solar_tbins_data= readmatrix("022_Yearly t-Solarbin.xlsx");
                monthly_tsolarbin = readmatrix("022_Monthly t-Solarbin.xlsx");
                vall_tsolarbin = readmatrix("023_Monthly t-SolarbinVall.xlsx");
                pla_tsolarbin = readmatrix("023_Monthly t-SolarbinPla.xlsx");
                punta_tsolarbin = readmatrix("023_Monthly t-SolarbinPunta.xlsx");
                
                %JGC: En cas de no coneixer les càrregues ni la demanda, comencem per saber quin tipus d'ús tindrà l'edifici. També
                %adjudiquem uns valors per defecte a cada tipologia
                if app.ResiNoSoterrani.Value==1 %JGC: Tipologia residencial sense soterrani (pensem en una casa)
                    selector_use = 1; 
                elseif app.ResiSoterrani.Value==1 %JGC: Tipologia residencial amb soterrani (pensem en una casa d'una planta)
                    selector_use = 2;
                elseif app.CentreEducatiu.Value==1 %JGC: Tipologia centre educatiu (pensem en un edifici de dues plantes, 1 linia per curs, de P3 a 6è, sense dutxes)
                    selector_use = 3;
                elseif app.Oficines.Value==1 %JGC: Tipologia edifici d'oficines (pensem en un edifici acristallat de 5 plantes)
                    selector_use = 4;
                elseif app.Hosteleria.Value==1 %JGC: Tipologia hotel i/o restauració (pensem en un hotel*** de 4 plantes, 20 habs. per planta, i rest. 1a planta)
                    selector_use = 5;
                elseif app.Hospital.Value==1 %JGC: Tipologia centre sanitari (pensem en un ambulatori de poble)
                    selector_use = 6;
                elseif app.CentreEsportiu.Value==1 %JGC: Tipologia centre esportiu (pensem en un gimnàs petit, sense piscina)
                    selector_use = 7;
                end
                paramload=parammatrix(:,selector_use);
               
                % JGC: Distingim entre els següents casos: use_case=1: Demanda desconeguda; use_case=2: Demanda coneguda.
                % l'use_case 1 distingeix entre soterrani o sense.
                conoce_datos = 1-double(app.UnknownDemand.Value); %JGC: Coneixem les càrregues i la demanda?
                input_power_summer = 1000*(double(app.pot_estiu.Value));  %JGC: De kW a W 
                input_power_winter = 1000*(double(app.pot_hivern.Value));  %JGC: De kW a W
%                 input_power_ACS = 1000*(double(app.pot_ACS.Value)); %JGC: De kW a W
                input_e_summer = 1000*(double(app.e_estiu.Value));   %JGC: De kWh a Wh    
                input_e_winter = 1000*(double(app.e_hivern.Value));  %JGC: De kWh a Wh          
                input_e_ACS = 1000*(double(app.e_ACS.Value)); %JGC: De kWh a Wh    
                desbal = (input_e_summer + input_e_winter + input_e_ACS)/1000;%JGC: [kWh] Energia tèrmica total consumida per any
                z_floor = double(app.plantes.Value); % JGC: Només es necessari definir això aquí per a que el codi no em doni error (de moment)
                s_area = double(app.superficie.Value);   %JGC: Àrea total construïda, incloent soterrani. 
                f_window = double(app.finestra.Value); %JGC: Relació entre la superfície d'acristallament i la superfície construïda
                T_in_h = double(app.t_confort_h.Value);  %JGC: [°C]Temperatura de confort a l'hivern
                T_in_c = double(app.t_confort_c.Value);  %JGC: [°C]Temperatura de confort a l'estiu
                max_ocup = double(app.persones.Value); %JGC: Nombe màxim d'ocupants a l'edifici. %JGC: factor correcció emissió: Homes = 1, Dones = 0.85, Nens 10-14anys = 0.75, Nens <10 anys = 0.6
                litresACS= double(app.ACS_especific.Value); %JGC litres d'ACS a 60ºC per persona i dia
    
                if (app.UnknownDemand.Value == true) 
                    if (selector_use == 2) %JGC: Demanda no coneguda. Us residencial amb soterrani
                        use_case = 1;
                        basement = 1;
                        z_floor = double(app.plantes.Value) + 1; %number of floors [-]. Basement included
                    else %JGC: Demanda no coneguda.Ús residencial sense soterrani
                        use_case = 1;
                        basement = 0;
                        z_floor = double(app.plantes.Value); %number of floors [-]. Basement NOT included                    
                    end
                else
                    use_case = 2; %JGC: Coneixem demanda, independentment de l'ús  
                end   
               
                if app.PlantaQuadrada.Value==true
                    selector_geometry = 1;
                    l_sun = sqrt(s_area/z_floor);
                    l_side = l_sun;
                elseif app.PlantaRectangular1.Value==true % JGC: Long side oriented to the sun
                    selector_geometry = 2;
                    l_side = sqrt(s_area/(2*z_floor));
                    l_sun = 2*l_side;
                elseif app.PlantaRectangular2.Value==true % JGC: Short side oriented to the sun
                    selector_geometry = 3;
                    l_sun = sqrt(s_area/(2*z_floor));
                    l_side = 2*l_sun;
                end                       
                
               
                user_pump = double(app.owner_hp.Value);      %JGC: "0" implica selecció automàticament de la llista. "1" significa dades introduïdes per l'usuari           
                user_winter_p = 1000*(double(app.user_hp_heat.Value));   %JGC: L'usuari introdueix la capacitat en mode calefaccio en kW i GeoSIV o transforma a W
                user_summer_p = 1000*(double(app.user_hp_cool.Value));   %JGC: L'usuari introdueix la capacitat en mode refrigeració en kW i GeoSIV o transforma a W
                user_ACS_p = 1000*(double(app.user_hp_ACS.Value)); %JGC: %JGC: L'usuari introdueix la capacitat en mode ACSen kW i GeoSIV o transforma a W
                user_winter_cop = double(app.user_COP.Value);  %heating COP
                user_summer_cop = double(app.user_EER.Value);  %cooling COP
                user_ACS_cop= double(app.user_COPACS.Value); %JGC: ACS COP
                
                %JGC: L'usuari ha d'introduïr una primera estimació de la fondària màxima dels pous
                if app.m80.Value==true
                    l_prosp = 80;          
                elseif app.m90.Value==true
                    l_prosp = 90;          
                elseif app.m100.Value==true
                    l_prosp = 100;        
                elseif app.m110.Value==true
                    l_prosp = 110;
                elseif app.m120.Value==true
                    l_prosp = 120;
                elseif app.m130.Value==true
                    l_prosp = 130;
                elseif app.m140.Value==true
                    l_prosp = 140;        
                elseif app.m150.Value==true
                    l_prosp = 150;
                end
                
                if app.mm32.Value==true
                    d_o_pipe = 32/1000;     %Outter diameter of the pipe, in meters
                    d_i_pipe = 26.2/1000;   %Inner diameter of the pipe, in meters
                elseif app.mm40.Value==true 
                    d_o_pipe = 40/1000;     %Outter diameter of the pipe, in meters
                    d_i_pipe = 32.6/1000;   %Inner diameter of the pipe, in meters --> JGC: Modified Value. Previous value d_i_pipe=37/1000.
                end
 
                %JGC: Càrreguem ràster dels dies a on es requereix refrigeració
                [map_dcool,struct_dcool] = readgeoraster('dias_cooling_23.tif');
                dcool_info = imref2d(size(map_dcool));
                dcool_info.XWorldLimits = struct_dcool.XWorldLimits;
                dcool_info.YWorldLimits = struct_dcool.YWorldLimits;
                getsizemapped = size(map_dcool);
                [x_w_dcool,y_w_dcool] = worldToIntrinsic(dcool_info,input_utm_x,input_utm_y);
                lect_dcool = interp2(map_dcool,x_w_dcool,getsizemapped(1)-y_w_dcool,'bilinear');
                lect_dcool = double(lect_dcool); %JGC:  [dies]
    
                %JGC: Càrreguem ràster dels dies a on es requereix calefacció
                [map_dheat,struct_dheat] = readgeoraster('dias_heating_18.tif');
                dheat_info = imref2d(size(map_dheat));
                dheat_info.XWorldLimits = struct_dheat.XWorldLimits;
                dheat_info.YWorldLimits = struct_dheat.YWorldLimits;
                [x_w_dheat,y_w_dheat] = worldToIntrinsic(dheat_info,input_utm_x,input_utm_y);
                lect_dheat = interp2(map_dheat,x_w_dheat,getsizemapped(1)-y_w_dheat,'bilinear');
                lect_dheat = double(lect_dheat); %JGC:  [dies]
    
                %Anual Mean Air Temperature
                [map_mat,struct_mat] = readgeoraster('t_med_air.tif');
                mat_info = imref2d(size(map_mat));
                mat_info.XWorldLimits = struct_mat.XWorldLimits;
                mat_info.YWorldLimits = struct_mat.YWorldLimits;
                [x_w_mat,y_w_mat] = worldToIntrinsic(mat_info,input_utm_x,input_utm_y);
                lect_mat = interp2(map_mat,x_w_mat,getsizemapped(1)-y_w_mat,'bilinear');
                lect_mat = double(lect_mat); %JGC: [ºC] aquesta dada es correspondria amb la temperatura mitjana anual del terreny
    
                if app.SwitchCAT_Gir.Value==" " %JGC (Girona)
                    lect_50 = double(lect_GIR50); %Lectura considerada vàlida als primers 50 m al territori de Girona urbà i periurbà 
                else
                    %Temperature at 50 meters
                    [map_50,struct_50] = readgeoraster('t050.tif');
                    info_50 = imref2d(size(map_50));
                    mapsizetemp = size(map_50);
                    info_50.XWorldLimits = struct_50.XWorldLimits;
                    info_50.YWorldLimits = struct_50.YWorldLimits;
                    [x_w_50,y_w_50] = worldToIntrinsic(info_50,input_utm_x,input_utm_y);
                    lect_50 = interp2(map_50,x_w_50,mapsizetemp(1)-y_w_50,'bilinear');
                    lect_50 = double(lect_50); %lecture 50 is given in ºC. It is the mean temperature through the year at a depth of 50 meters
        
                    %Temperature at 100 meters
                    [map_100,struct_100] = readgeoraster('t100.tif');
                    info_100 = imref2d(size(map_100));
                    info_100.XWorldLimits = struct_100.XWorldLimits;
                    info_100.YWorldLimits = struct_100.YWorldLimits;
                    [x_w_100,y_w_100] = worldToIntrinsic(info_100,input_utm_x,input_utm_y);
                    lect_100 = interp2(map_100,x_w_100,mapsizetemp(1)-y_w_100,'bilinear');
                    lect_100 = double(lect_100); %lecture 100 is given in ºC. It is the mean temperature through the year at a depth of 100 meters
        
                    %Temperature at 150 meters
                    [map_150,struct_150] = readgeoraster('t150.tif');
                    info_150 = imref2d(size(map_150));
                    info_150.XWorldLimits = struct_150.XWorldLimits;
                    info_150.YWorldLimits = struct_150.YWorldLimits;
                    [x_w_150,y_w_150] = worldToIntrinsic(info_150,input_utm_x,input_utm_y);
                    lect_150 = interp2(map_150,x_w_150,mapsizetemp(1)-y_w_150,'bilinear');
                    lect_150 = double(lect_150); %lecture given in ºC. Mean average temperature at 150 meters deep.   
                end

                %height
                [map_height,struct_height] = readgeoraster('MDE30x30.tif');
            
                row_limit_height = struct_height.RasterSize(1);
                col_limit_height = struct_height.RasterSize(2);
            
                for i = 1:1:row_limit_height
                    for j = 1:1:col_limit_height
                        if (map_height(i,j) > 3500) %JGC: Manera de delimitar el territori respecte limits fronterers i marítims (he canviat el valor anterior que era de 5000)
                            map_height(i,j) = 0;
                        end
                    end
                end
                
                info_height = imref2d(size(map_height));
                info_height.XWorldLimits = struct_height.XWorldLimits;
                info_height.YWorldLimits = struct_height.YWorldLimits;
                [x_w_height,y_w_height] = worldToIntrinsic(info_height,input_utm_x,input_utm_y);
                getsizeheight = size(map_height);
                lect_height = interp2(map_height,x_w_height,getsizeheight(1)-y_w_height,'bilinear');
                lect_height = double(lect_height); % JGC: [m] altitud per sobre nivell del mar
                if lect_height>=1500
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'Per sobre dels 1500 m d''altitud, les dades climàtiques de referència associades a la ubicació poden diferir en excés de les dades reals','Missatge d''avís','Icon','warning')
                end

                %JGC: Selecció del clima de referència depenent de la província i l'altitud. El valor de "thermal case" determinarà les dades 
                %de "temperature bins", "T_max" i "T_min", "T_average" "T_design_winter", "T_design_summer", etc.
                if (lect_provin == 1) %JGC: BARCELONA
                    if (lect_height <= 250)
                        thermal_case = 2; %JGC: Clima referència C2
                        app.LabelClimaRef.Text='Clima de referència aplicat: C2';
                    elseif (250 < lect_height)&&(lect_height <= 450)
                        thermal_case = 5;  %JGC: Clima referència D2
                        app.LabelClimaRef.Text='Clima de referència aplicat: D2';
                    elseif (450 < lect_height)&&(lect_height <= 750)
                        thermal_case = 4;  %JGC: Clima referència D1
                        app.LabelClimaRef.Text='Clima de referència aplicat: D1';
                    else
                        thermal_case = 7;  %JGC: Clima referència E1
                        app.LabelClimaRef.Text='Clima de referència aplicat: E1';
                    end
                elseif (lect_provin == 2) %JGC: GIRONA
                    if (lect_height <= 100)
                        thermal_case = 2;
                        app.LabelClimaRef.Text='Clima de referència aplicat: C2';
                    elseif (100 < lect_height)&&(lect_height <= 600)
                        thermal_case = 5;
                        app.LabelClimaRef.Text='Clima de referència aplicat: D2';
                    else
                        thermal_case = 7;
                        app.LabelClimaRef.Text='Clima de referència aplicat: E1';
                    end
                elseif (lect_provin == 3) %JGC: LLEIDA
                    if (lect_height <= 100)
                        thermal_case = 3; %JGC: Clima referència C3
                        app.LabelClimaRef.Text='Clima de referència aplicat: C3';
                    elseif (100 < lect_height)&&(lect_height <= 600)
                        thermal_case = 6; %JGC: Clima referència D3
                        app.LabelClimaRef.Text='Clima de referència aplicat: D3';
                    else
                        thermal_case = 7;
                        app.LabelClimaRef.Text='Clima de referència aplicat: E1';
                    end
                else                     %JGC: TARRAGONA
                    if (lect_height <= 100)
                        thermal_case = 1; %JGC: Clima referència B3
                        app.LabelClimaRef.Text='Clima de referència aplicat: B3';
                    elseif (100 < lect_height)&&(lect_height <= 500)
                        thermal_case = 3;
                        app.LabelClimaRef.Text='Clima de referència aplicat: C3';
                    else
                        thermal_case = 6;
                        app.LabelClimaRef.Text='Clima de referència aplicat: D3';
                    end
                end  
                text_climaref=app.LabelClimaRef.Text;
                % JGC: Selecció dels valors de transmitància tèrmica ([W/m2K] extrets del CTE) segons el clima de referència
                if (thermal_case == 1) %JGC: clima B3
                    u_values = u_limit_info(:,3); 
                    %u_values_B = u_reg_info(:,3);
                elseif (thermal_case == 2)||(thermal_case == 3) %JGC: climes C2, C3
                    u_values = u_limit_info(:,4);
                    %u_values_B = u_reg_info(:,4);
                elseif (thermal_case == 4)||(thermal_case == 5)||(thermal_case == 6)  %JGC: climes D1, D2, D3
                    u_values = u_limit_info(:,5);
                    %u_values_B = u_reg_info(:,5);
                else %JGC: clima E1
                    u_values = u_limit_info(:,6);
                    %u_values_B = u_reg_info(:,6);
                end
                app.ClimaRef.Value=thermal_case;%JGC: necessitem aquest valor per distingir entre les differents possibilitats de producció solar
                
                % JGC: Assignació dels valors de transmitància tèrmic ([W/m2K] extrets del CTE) segons el use_case
                u_wall = u_values(1);   %u value of walls and floor, in contact with the exterior air
                u_cover = u_values(2);   %JGC: Recuperem aquests valors, que no sé perquè es varen anular (u de les cobertes)
                u_border = u_values(3); %u value of walls, floor and covers in contact with non habitable spaces, medianeras and partitions
                u_holes = u_values(4);  %u value of empty spaces and holes, and windows
                u_door = u_values(5);   %u value for doors   
                
                %After this, the temperature design, the position (for further calculations) and the average temperature variation for
                %summer, winter and annual is selected
                DR_read = info_DR(:,thermal_case);
                DR_anual = DR_read(1);
                DR_anual = DR_anual/2;%JGC: Mitjana de la semi-amplitud d'oscil·lació tèrmica diària anual
                T_des_w = design_conditions(1,thermal_case); %JGC: [ºC] Temperatura disseny hivern
                app.T_dis_w.Value=T_des_w;
                T_des_s = design_conditions(2,thermal_case); %JGC: [ºC] Temperatura disseny estiu
                app.T_dis_s.Value=T_des_s;
                pos_winter = design_conditions(3,thermal_case); %JGC: [ºC] posició del valor al bin_values
                pos_summer = design_conditions(4,thermal_case); %JGC: [ºC] posició del valor al bin_values
                setappdata(0,'Tdish',T_des_w);
                setappdata(0,'Tdisc',T_des_s);
                SCh = app.SC_h.Value; %JGC: "Shadding coefficient" (hivern/heating). Nosaltres ho considerarem com un factor global que indica la porció de radiació transformada en guany dins de l'edifici
                SCc = app.SC_c.Value;%JGC: "Shadding coefficient" (estiu/cooling). Nosaltres ho considerarem com un factor global que indica la porció de radiació transformada en guany dins de l'edifici
    
                %JGC: Construïm els temperature_bins anuals i per a cada mes. Necessari si volem veure la distribució de demanda i producció mes a mes
                thermal_bin = temp_data(:,thermal_case);    %JGC: ocurrència de cada bin_temperature al llarg de l'any
                thermal_solarbin = solar_tbins_data(:,thermal_case); %JGC: ocurrència de cada bin_temperature on hi ha radiació solar al llarg de l'any.
                thermal_nosolarbin = temp_data(:,thermal_case)-solar_tbins_data(:,thermal_case);
                %represents the number of hours in the year from -5ºC (position 1) to 37ºC,
                %in position 22. The sum is equal to 8760 hours in the year. All is ambient temperature
                bin_values = (-5):2:37; %temperature value of every bin
                bin_values_size = size(bin_values);
                rough_total = rough_data(:,thermal_case);   %rough data, for every hour of the year
                setappdata(0,'hourly_temp',rough_total);
                thermal_bin_monthly=zeros(bin_values_size(2),12); %JGC: temperature_bins totals corresponents a cada mes
                thermal_bin_vall=zeros(bin_values_size(2),12); %JGC: temperature_bins totals tarifa vall corresponents a cada mes
                thermal_bin_pla=zeros(bin_values_size(2),12); %JGC: temperature_bins totals tarifa pla corresponents a cada mes
                thermal_bin_punta=zeros(bin_values_size(2),12); %JGC: temperature_bins totals tarifa punta corresponents a cada mes
                thermal_solarbin_monthly=zeros(bin_values_size(2),12); %JGC: temperature_bins d'irradiació solar corresponents a cada mes
                thermal_solarbin_vall=zeros(bin_values_size(2),12);%JGC: temperature_bins d'irradiació solar tarifa vall corresponents a cada mes
                thermal_solarbin_pla=zeros(bin_values_size(2),12); %JGC: temperature_bins d'irradiació solar tarifa pla corresponents a cada mes
                thermal_solarbin_punta=zeros(bin_values_size(2),12);%JGC: temperature_bins d'irradiació solar tarifa punta corresponents a cada mes
                thermal_nosolarbin_monthly=zeros(bin_values_size(2),12); %JGC: temperature_bins sense irradiació solar corresponents a cada mes
                thermal_nosolarbin_vall=zeros(bin_values_size(2),12); %JGC: temperature_bins sense irradiació solar tarifa vall corresponents a cada mes
                thermal_nosolarbin_pla=zeros(bin_values_size(2),12); %JGC: temperature_bins sense irradiació solar tarifa pla corresponents a cada mes
                thermal_nosolarbin_punta=zeros(bin_values_size(2),12); %JGC: temperature_bins sense irradiació solar tarifa punta corresponents a cada mes
                for i=1:1:bin_values_size(2)
                    for j=1:1:12
                        thermal_bin_monthly(i,j)=monthly_tbin((thermal_case-1)*23+i,j+1);
                        thermal_bin_vall(i,j)=vall_tbin((thermal_case-1)*23+i,j+1);
                        thermal_bin_pla(i,j)=pla_tbin((thermal_case-1)*23+i,j+1);
                        thermal_bin_punta(i,j)=punta_tbin((thermal_case-1)*23+i,j+1);
                        thermal_solarbin_monthly(i,j)=monthly_tsolarbin((thermal_case-1)*23+i,j+1);
                        thermal_solarbin_vall(i,j)=vall_tsolarbin((thermal_case-1)*23+i,j+1);
                        thermal_solarbin_pla(i,j)=pla_tsolarbin((thermal_case-1)*23+i,j+1);
                        thermal_solarbin_punta(i,j)=punta_tsolarbin((thermal_case-1)*23+i,j+1);
                        thermal_nosolarbin_monthly(i,j)=thermal_bin_monthly(i,j)-thermal_solarbin_monthly(i,j);
                        thermal_nosolarbin_vall(i,j)=thermal_bin_vall(i,j)-thermal_solarbin_vall(i,j);
                        thermal_nosolarbin_pla(i,j)=thermal_bin_pla(i,j)-thermal_solarbin_pla(i,j);
                        thermal_nosolarbin_punta(i,j)=thermal_bin_punta(i,j)-thermal_solarbin_punta(i,j);
                    end
                end
                
                %JGC: Construïm un array de temperatures promig mensuals (em servirà per corregir gràfiques producció mes a mes quan CALOR o FRED =0, cas demanda coneguda)
                t_mesos=[31 28 31 30 31 30 31 31 30 31 30 31]; %JGC: [dies] duració de cadascún dels mesos
                T_meanMonth=zeros(1,12);
                
                sumhores=0;
                for j=1:1:12
                    h_month0=sumhores+1; %JGC: hora d'inici del sumatori pel mes j
                    h_month1=sumhores+24*t_mesos(j); %JGC: hora final del sumatori pel mes 
                    sumaTemp=0;
                    for i=h_month0:1:h_month1
                        sumaTemp=sumaTemp+rough_total(i); %JGC: [MWh]
                    end
                    sumhores=h_month1;
                    T_meanMonth(j)=sumaTemp/(24*t_mesos(j)); %JGC: [MWh]
                end
                setappdata(0,'T_mitjana_mes',T_meanMonth);
                
                %JGC: A partir d'aquí es comença a construïr el perfil de temperatua del terreny
                [~,pos_min_anual] = min(rough_total);     %minimum temperature along the year
                day_min_anual = round((pos_min_anual/24)+1); %JGC: Això ens dona el shift dins de l'expressió per a la T_ground (anàlisi Kusuda)
                difu_rough = (lect_difu*3600*24)/1e06; %JGC: [m2·dia]
                profundidad = 1:0.1:300; % JGC: es treballa amb una profunditat de 300 m, amb valors cada 10cm      
                vect_size = size(profundidad);
                t_soil = zeros(365,vect_size(2));
                t_g_min = zeros(vect_size(2),1);
                t_g_max = zeros(vect_size(2),1);
                for j = 1:1:365
                    for i = 1:1:vect_size(2)
                        t_soil(j,i) = lect_mat - DR_anual*(exp(-profundidad(i)*(sqrt(pi/(365*difu_rough)))))*cos(((2*pi)/365)*(j - day_min_anual - (profundidad(i)/2)*(sqrt(365/(pi*difu_rough)))));  
                    end
                end
                t_med_soil_an = zeros(vect_size(2),1);
                for i=1:1:vect_size(2)
                    for j = 1:1:365
                        t_med_soil_an(i) = t_med_soil_an(i) + lect_mat - DR_anual*(exp(-profundidad(i)*(sqrt(pi/(365*difu_rough)))))*cos(((2*pi)/365)*(j - day_min_anual - (profundidad(i)/2)*(sqrt(365/(pi*difu_rough)))));
                    end
                    t_med_soil_an(i) = (t_med_soil_an(i))/365; %JGC Es calcula el promig de la temperatura del terreny per a una profunditat determinada
                end
                                
                %JGC: Anem a calcular el valor promig del terra a -1m (si no hi ha soterrani) i a -3m (si hi ha soterrani) de cara a calcular les temperatures del terreny 
                % contra les quals es bescanviaria calor a través de les parts de la construcció que bescanvien contra el subsol, tant a l'hivern (mitjana gener) com a 
                % l'estiu (mitjana juliol)
                lect_math_1m=0; 
                lect_matc_1m=0;
                lect_math_3m=0;
                lect_matc_3m=0;
                for j=1:1:31 %JGC: corresponent a gener
                    lect_math_1m = lect_math_1m + lect_mat - DR_anual*(exp(-1*(sqrt(pi/(365*difu_rough)))))*cos(((2*pi)/365)*(j - day_min_anual - (1/2)*(sqrt(365/(pi*difu_rough)))));
                    lect_math_3m = lect_math_3m + lect_mat - DR_anual*(exp(-3*(sqrt(pi/(365*difu_rough)))))*cos(((2*pi)/365)*(j - day_min_anual - (3/2)*(sqrt(365/(pi*difu_rough)))));
                end
                lect_math_1m = lect_math_1m/31; %JGC:%JGC: valor mitjà temperatura terreny a -1m a l'hivern
                lect_math_3m = lect_math_3m/31; %JGC:%JGC: valor mitjà temperatura terreny a -3m a l'hivern
                for j=182:1:213 %JGC: corresponent a juliol
                    lect_matc_1m = lect_matc_1m + lect_mat - DR_anual*(exp(-1*(sqrt(pi/(365*difu_rough)))))*cos(((2*pi)/365)*(j - day_min_anual - (1/2)*(sqrt(365/(pi*difu_rough)))));
                    lect_matc_3m = lect_matc_3m + lect_mat - DR_anual*(exp(-3*(sqrt(pi/(365*difu_rough)))))*cos(((2*pi)/365)*(j - day_min_anual - (3/2)*(sqrt(365/(pi*difu_rough)))));
                end
                lect_matc_1m = lect_matc_1m/31; %JGC:%JGC: valor mitjà temperatura terreny a -1m a l'estiu
                lect_matc_3m = lect_matc_3m/31; %JGC:%JGC: valor mitjà temperatura terreny a -3m a l'estiu
                
                if app.SwitchCAT_Gir.Value==" " %JGC (Girona)
                    if (app.user_PropTerm.Value == 1) %JGC: Introduïm la possibilitat de poder canviar la T_ground0 manualment si volem, o bé utilitzar el que calcula la app 
                        T_ground_undist=app.ChangeT_ground0.Value;
                    else
                        T_ground_undist = lect_50;
                        app.ChangeT_ground0.Value= T_ground_undist;
                    end
                else
                    %JGC: Càlcul correcció T_ground0. Escollim els valors dels rasters de T_ground en profunditat, per comparar amb els
                    %calculats segons el model, el qual només té en compte els valors del ràster de temperatures mitjanes anuals
                    er_rel_50 = [lect_50,t_med_soil_an(490)];
                    er_rel_100 = [lect_100,t_med_soil_an(990)];
                    er_rel_150 = [lect_150,t_med_soil_an(1490)];
                    %JGC: Definim l'error relatiu (límit inferior) entre el valor del raster i el calculat per a les profunditats de 50, 100 i 150 m
                    rel_50 = (abs(lect_50-t_med_soil_an(490)))/(max(er_rel_50)); 
                    rel_100 = (abs(lect_100-t_med_soil_an(990)))/(max(er_rel_100));
                    rel_150 = (abs(lect_150-t_med_soil_an(1490)))/(max(er_rel_150));
                    %JGC: Definim l'error propagat tenim en compte les tres profunditats de comparació
                    res_temp = sqrt((rel_50^2) + (rel_100^2) + (rel_150^2));
                    %JGC: Criteri per quantificar un paràmetre correctiu de la temperatura mínima i màxima, tenint en compte la diferència entre model i valors dels ràsters
                    if (app.user_PropTerm.Value == 1) %JGC: Introduïm la possibilitat de poder canviar la T_ground0 manualment si volem, o bé utilitzar el que calcula la app 
                        T_ground_undist=app.ChangeT_ground0.Value;
                    else
                        if (res_temp > 0.2)
                            T_ground_undist = (t_med_soil_an(1490)+lect_150)/2; %JGC: És a dir, si l'error relatiu a -150m és superior al 20%, fem la  mitja dels dos valors
                        else
                            T_ground_undist = lect_mat; %JGC: Si l'error relatiu a -150m és inferior al 20%, ens quedem amb la mitjana anual de T_ambient
                        end
                        app.ChangeT_ground0.Value=T_ground_undist;
                    end
                end
                app.T_ground0.Value=T_ground_undist;%JGC: [ºC] valor de la temperatura no pertorbada del terreny que apareixerà als resultats

                %JGC:NOTA IMPORTANT: ELS VALORS DE LECT_MAT NO TENEN PERQUÈ COINCIDIR AMB ELS QUE ES PODRIEN OBTENIR DE LES DADES DELS
                %CLIMA DE REFERÈNCIA CORRESPONENT A AQUESTA UBICACIÓ
                
                %JGC: Ara podem definir el vector de temperatures anuals del terra a -1m, els valors del qual seràn assumits com la tempertura de l'aigua de l'aixeta (pel càlcul ACS)
                t_aixeta=t_soil(:,1);
                %JGC: Amb el vector t_aixeta, podem definir un vector de demanda d'ACS normalitzada, és a dir, la fracció diària de
                %demanda d'ACS respecte el valor anual (que es considera igual a 1)
                dem_ACS=60-t_aixeta; %JGC:  [ºC] valor reduit de la demanda diària, definit com la diferència entre la T_consigna i la T_aixeta promig diària
                sumrACS=0;
                for i=1:1:365
                    sumrACS=sumrACS+dem_ACS(i);
                end
                r_ACS=dem_ACS/(sumrACS); %JGC Aquest vector s'utilitzarà posteriorment per al càlcul d'ACS mes a mes segons si sabem o no la demanda de l'edifici
                
                %JGC: Ara calculem la càrrega de disseny de l'ACS
                if app.UnknownDemand.Value==false
                   app.PotDis_ACSkW.Value=app.pot_ACS.Value;
                   q_des_ACS=app.pot_ACS.Value*1000; %JGC: [W] 
                else
                    t_aixeta_min=min(t_aixeta);
                    q_des_ACS=max_ocup*litresACS*4180*(60-t_aixeta_min)/3600/8; %JGC: [W] 
                    %JGC: Aquest valor assumeix que el màxim d'energia tèrmica ACS diària es produeix al
                    %llarg de 8h (per garantir que l'ACS es produeix desfasat en el temps respecte el consum) amb aquesta  potència
                    %promig. Tambés s'assumeix que hi ha un dipòsit d'acumulació. No es consideren pèrdues del dipòsit.
                    app.PotDis_ACSkW.Value=q_des_ACS/1000; %JGC: [kW]
                end
                
                %After this (and with the excel sheet results of the codes of previous
                %versions, it is possible to address the thermal calculation for every use
    
                %Beginning of the calculation of the charge for every outside temperature.
                %q_use will stand for the demand power, that is given in Watt. For every To
                %there must exist a cell, and as there are six possible sources considered
                %the matrix must be 22 x 6
    
                q_use = zeros(bin_values_size(2),12);%JGC: Pasamos de (22,6) a (22,12), para añadir el mismo cálculo con las dos T_confort (h y c)
    
    
                if (use_case == 1)    %JGC: Cas unificat en que no es coneixen les càrregues i es vol fer una estimació.
                    
                    foundations = parammatrix(2,2); %JGC: fracció paret soterrani considerada que bescanvia contra terreny
                    H_wall = paramload(1);
                    H_wall_found = parammatrix(3,2);
                    
                    %C1 transmission losses 
                    
                    %JGC: Definim coeficient de transmissió per als murs, el sostre, les finestres i el terreny
                    q_basement = zeros(bin_values_size);
                    if (basement == 1)
                        for i = 1:1:bin_values_size(2)
                            if(bin_values(i) > 24) %JGC: al soterrani només comptabilitzem càrrega de refrigeració, quan T>24ºC (cambiem de 22 a 24)
                                q_basement(i) = u_border*(2*l_sun + 2*l_side)*foundations*H_wall_found*(lect_matc_3m-T_in_c); %JGC: canviem de T_in_h a T_in_c
                            else
                                q_basement(i) = 0;
                            end
                        end 
                        UA_walls = u_wall*(z_floor-1)*H_wall*(2*l_sun + 2*l_side) + (1 - foundations)*H_wall_found*(2*l_sun + 2*l_side)*u_wall;
                        UA_window = u_holes*f_window*s_area*(z_floor-1)/(z_floor);%JGC: Hem de considerar que si tenim soterrani, aquest no tindrà finestres
                    else
                        UA_walls = u_wall*z_floor*H_wall*(2*l_sun + 2*l_side);
                        UA_window = u_holes*f_window*s_area;
                    end
                    UA_ceiling = u_cover*(s_area/z_floor); %JGC: Canviem de u_wall a u_cover, que per això tenim aquests valors...
                    UA_ground = u_border*(s_area/z_floor);                    
                    UA_doors = u_door*paramload(4);
                    
                    %JGC: Constuïm valor del coefficint de transmissió global:
                    sum_UA = UA_walls + UA_ceiling + UA_window + UA_doors;

                    %JGC: Constuïm valors de pèrdues/guanys en funció de la temperatura ambient i la temepatura de comfort
                    if basement == 1
                        for i= 1:1:bin_values_size(2)
                            q_use(i,1) = sum_UA*(bin_values(i)-T_in_h) + UA_ground*(lect_math_3m-T_in_h);%JGC: segons hem especificat abans, no considerem calefacció al soterrani 
                            q_use(i,7) = sum_UA*(bin_values(i)-T_in_c) + UA_ground*(lect_matc_3m-T_in_c) + q_basement(i); 
                        end
                    else
                        for i= 1:1:bin_values_size(2)
                            q_use(i,1) = sum_UA*(bin_values(i)-T_in_h) + UA_ground*(lect_math_1m-T_in_h);
                            q_use(i,7) = sum_UA*(bin_values(i)-T_in_c) + UA_ground*(lect_matc_1m-T_in_c);
                        end
                    end

                    %C2 Sensible solar gains                     
                    %JGC: Hem d'adaptar la superficie de finestres a la cara orientada al sol (14/09/2021:això és només per percebre
                    %diferències entre geometries de planta. En realitat hem de sumar la contribució solar per cada cara de l'edifici)
                    if basement==1
                        AG_SudNord = f_window*s_area*(l_sun/(2*l_sun+2*l_side))*(z_floor-1)/(z_floor);
                        AG_EstOest = f_window*s_area*(l_side/(2*l_sun+2*l_side))*(z_floor-1)/(z_floor);
                    else
                        AG_SudNord = f_window*s_area*(l_sun/(2*l_sun+2*l_side));
                        AG_EstOest = f_window*s_area*(l_side/(2*l_sun+2*l_side));
                    end
                    
%                     SCh = app.SC_h.Value; %JGC: "Shadding coefficient" (hivern/heating). Nosaltres ho considerarem com un factor global que indica la porció de radiació transformada en guany dins de l'edifici
%                     SCc = app.SC_c.Value;%JGC: "Shadding coefficient" (estiu/cooling). Nosaltres ho considerarem com un factor global que indica la porció de radiació transformada en guany dins de l'edifici    
                    
                    for i= 1:1:bin_values_size(2)
                        col_S=4*(thermal_case-1)+1; %JGC: posició columna irradiació solar mitjana, orientació sud.
                        col_O=4*(thermal_case-1)+2; %JGC: posició columna irradiació solar mitjana, orientació est.
                        col_E=4*(thermal_case-1)+3; %JGC: posició columna irradiació solar mitjana, orientació oest.
                        col_N=4*(thermal_case-1)+4; %JGC: posició columna irradiació solar mitjana, orientació nord.
                        q_use(i,2) = SCh*((solar_gains(i,col_S)+solar_gains(i,col_N))*AG_SudNord + (solar_gains(i,col_E)+solar_gains(i,col_O))*AG_EstOest); 
                        k=i+22; %JGC: shift en número de fila per poder comptabilitzar valors irradiació a l'estiu
                        q_use(i,8) = SCc*((solar_gains(k,col_S)+solar_gains(k,col_N))*AG_SudNord + (solar_gains(k,col_E)+solar_gains(k,col_O))*AG_EstOest);
                    end
                    %JGC: Anem a "linearitzar les càrregues solars". Comencem per a la corba corresponent al mes de disseny d'hivern
                    q_use2=find(q_use(:,2)); %JGC: seleccionem els valors diferents de zero
                    q2=size(q_use2); %JGC: contem quants tenim
                    q_use2lin=zeros(q2(1),2); %JGC: generem un conjunt amb tants parells com valors diferents de zero
                    %JGC: construïm un conjunt de parells T_0 i non-zero q_solar(T_0)
                    for i=1:1:q2(1)
                        u=q_use2(i);
                        q_use2lin(i,1)=bin_values(u);
                        q_use2lin(i,2)=q_use(u,2);
                    end
                    fitq2=polyfit(q_use2lin(:,1),q_use2lin(:,2),1);
                    %JGC: i ara per a la corba d'estiu
                    q_use8=find(q_use(:,8)); 
                    q8=size(q_use8); 
                    q_use8lin=zeros(q8(1),2); 
                    for i=1:1:q8(1) 
                        v=q_use8(i);
                        q_use8lin(i,1)=bin_values(v);
                        q_use8lin(i,2)=q_use(v,8);
                    end
                    fitq8=polyfit(q_use8lin(:,1),q_use8lin(:,2),1);
                    for i=1:1:bin_values_size(2)
                        q_use(i,2)=bin_values(i)*fitq2(1)+fitq2(2);
                        q_use(i,8)=bin_values(i)*fitq8(1)+fitq8(2);
                        if q_use(i,2)<0
                           q_use(i,2)=0;
                        end
                        if q_use(i,8)<0
                           q_use(i,8)=0;
                        end
                    end
                    
                    %C3 Sensible internal gains  %JGC: Guanys per il·luminació i equips 
                    if (basement == 1)
                        k_l = paramload(18)*l_sun*l_side*(z_floor-1); 
                        k_e = paramload(19)*l_sun*l_side*(z_floor-1); 
                    else
                        k_l = paramload(18)*s_area;
                        k_e = paramload(19)*s_area;
                    end
                    
                    k_per = paramload(16)*max_ocup; %JGC: factor correcció emissió: Homes = 1, Dones = 0.85, Nens 10-14anys = 0.75, Nens <10 anys = 0.6
                
                    c0_c3 = k_l + k_e + k_per;
                
                    for i= 1:1:bin_values_size(2)
                        q_use(i,3) = c0_c3; 
                        q_use(i,9) = q_use(i,3);
                    end
                    
                    %C4 Sensible fresh air 
                    rho_air = 1.2;      %density of air [kg/m3]. JGC: Valor a 21ºC. Aire sec
                    %cp_air = 1005;      %specific heat in J/kgºC
                    
                    %JGC: Calculem el ritme de renovació de l'aire en funció de la ocupació.
                    V_rep = paramload(15)*max_ocup; %JGC: factor correcció emissió: Homes = 1, Dones = 0.85, Nens 10-14anys = 0.75, Nens <10 anys = 0.6
                        
                    %JGC La component 4 i 10 de les càrregues es considera nul·la perquèr calculem juntes les càrregues sensibles i latents referents a la ventilació
                    for i= 1:1:bin_values_size(2)
                        q_use(i,4) = 0;
                        q_use(i,10) = 0;
                    end
                    
                    %JGC:C5, càrregues sensibles i latents per renovació d'aire. S'assumeix una humitat relativa interior de confort del 50% en tot moment                  
                    %JGC:Calculem per a T_confort_h:
                    T_in_hK = T_in_h + 273;
                    hum_desh=0.01*hum_rel(1,thermal_case); %JGC:[t.p.u] Valor disseny de humitat relativa exterior (hivern)
                    water_inh = CoolProp.HAPropsSI('W','P',101300,'T',T_in_hK,'R',0.5); %JGC:[kg water vapour / kg dry air quan humitat relativa = 0.5 T=T_inh]
                    h_air_inh = CoolProp.HAPropsSI('H','P',101300,'T',T_in_hK,'R',0.5); %JGC:[J/kgK] entalpia d'aire humid a T=T_inh i Hum. Rel. 50%
                    h_water_inh = CoolProp.PropsSI('H','T',T_in_hK,'Q',1,'Water');  %JGC:[J/kgK] entalpia de vapor saturat a T=T_inh
                    %JGC Ara calculem per a T_confort_c:
                    T_in_cK = T_in_c + 273;
                    hum_desc=0.01*hum_rel(2,thermal_case); %JGC:[t.p.u] Valor disseny de humitat relativa exterior (estiu)
                    water_inc = CoolProp.HAPropsSI('W','P',101300,'T',T_in_cK,'R',0.5); %JGC:[kg water vapour / kg dry air quan humitat relativa = 0.5 a T=T_inc]
                    h_air_inc = CoolProp.HAPropsSI('H','P',101300,'T',T_in_cK,'R',0.5);  %JGC:[J/kgK] entalpia d'aire humid a T=T_inc i Hum. Rel. 50%
                    h_water_inc = CoolProp.PropsSI('H','T',T_in_cK,'Q',1,'Water'); %JGC:[J/kgK] entalpia de vapor saturat a T=T_inc
                    %JGC: Considerarem valors diferents d'humitat segons hivern/estiu, i segons clima de referència
                    h_air_outh = zeros(bin_values_size);
                    water_outh = zeros(bin_values_size);
                    h_air_outc = zeros(bin_values_size);
                    water_outc = zeros(bin_values_size);
                    h_water_out = zeros(bin_values_size);
                    for i = 1:1:bin_values_size(2)
                        if (bin_values(i)<=0)
                            h_air_outh(i) = 0;        
                            water_outh(i) = 0;
                            h_air_outc(i) = 0;        
                            water_outc(i) = 0;
                            h_water_out(i) = 0;
                        else
                            h_air_outh(i) = CoolProp.HAPropsSI('H','P',101300,'T',bin_values(i)+273.15,'R',hum_desh); %JGC: entalpia d'aire humid a T=T_out i Hum. Rel. = hum_desh       
                            water_outh(i) = CoolProp.HAPropsSI('W','P',101300,'T',bin_values(i)+273.15,'R',hum_desh); %JGC:[kg water vapour / kg dry air] quan Hum. Rel. = hum_desh i T=T_out
                            h_air_outc(i) = CoolProp.HAPropsSI('H','P',101300,'T',bin_values(i)+273.15,'R',hum_desc); %JGC: entalpia d'aire humid a T=T_out i Hum. Rel. = hum_desc       
                            water_outc(i) = CoolProp.HAPropsSI('W','P',101300,'T',bin_values(i)+273.15,'R',hum_desc); %JGC:[kg water vapour / kg dry air] quan Hum. Rel. = hum_desc i T=T_out
                            h_water_out(i) = CoolProp.PropsSI('H','T',bin_values(i)+273.15,'Q',1,'Water');%JGC: entalpia de vapor saturat a T=T_out
                        end
                    end
                
                    for i = 1:1:bin_values_size(2)
                        q_use(i,5) = rho_air*V_rep*(water_outh(i)*h_water_out(i)+h_air_outh(i)-water_inh*h_water_inh-h_air_inh); 
                        q_use(i,11) = rho_air*V_rep*(water_outc(i)*h_water_out(i)+h_air_outc(i)-water_inc*h_water_inc-h_air_inc);
                    end
                    
                    %C6 latent internal gains 
                    c0_c6 = paramload(17)*max_ocup; %JGC: càrrega deguda al calor latent dels ocupants %JGC: factor correcció emissió: Homes = 1, Dones = 0.85, Nens 10-14anys = 0.75, Nens <10 anys = 0.6
                    
                    for i= 1:1:bin_values_size(2)
                        q_use(i,6) = c0_c6; 
                        q_use(i,12)= q_use(i,6);
                    end
                elseif (use_case == 2) %JGC: Aquest és el cas en que es coneix les càrregues tèrmiques i el consum
                    
                    %JGC: Anem a calcular els coefficients per a l'hivern, amb procés iteratiu ("w" de winter)
    
                    error_Tbal_w=10; %JGC: valor inicial de l'error per començar el càlcul iteratiu de T_Balanç d'hivern
                    k_w=bin_values_size(2); %JGC: número de bin values que es tenen en compte per al càlcul de c0_w i c1_w
                    Tbal_w=bin_values(k_w); %JGC: valor inicial de la T_Balanç d'hivern
                    w=0; %JGC: número d'iteració inicial
                    while (error_Tbal_w>=0.1)&&(w<=10)
                        Tbal_w0=Tbal_w; %JGC: valor de la T_Balanç d'hivern a l'inici de l'iteració
                        suma_wTh=0; %JGC: valor inicial del sumatori dels productes (temperatura del bin)X(d'hores d'ocurrència) per al càlcul de  T_Balanç d'hivern
                        suma_wh=0; %JGC: valor inicial del sumatori d'hores d'ocurrència per al càlcul de  T_Balanç d'hivern
                        for i=1:1:k_w
                            suma_wTh=suma_wTh+bin_values(i)*thermal_bin(i); %JGC sumatori dels productes (temperatura del bin)X(d'hores d'ocurrència) implicat en el càlcul
                            suma_wh=suma_wh+thermal_bin(i); %JGC sumatori de totes les hores d'ocurrència implicades en el càlcul
                        end
                        c0_w = ((input_power_winter*suma_wTh - input_e_winter*T_des_w)/(suma_wTh - T_des_w*suma_wh));
                        c1_w = ((input_e_winter - input_power_winter*suma_wh)/(suma_wTh - T_des_w*suma_wh));
                        Tbal_w=-c0_w/c1_w; %JGC: Valor calculat de la T_Balanç a l'hivern en la iteració present
                        error_Tbal_w=abs(Tbal_w0-Tbal_w); %JGC avaluem la diferència entre la T_Balanç actual i el de l'inici de la iteració
                        k_w=find(abs(bin_values-Tbal_w)<1,1); %JGC Definim l'índex fins al qual hem de realitzar els sumatoris de la següent iteració
                        w=w+1;
                    end
    
                    %JGC: Anem a calcular els coefficients per a l'estiu, amb procés iteratiu ("s" de summer)
    
                    error_Tbal_s=10; %JGC: valor inicial de l'error per començar el càlcul iteratiu de T_Balanç d'estiu
                    k_s=1; %JGC: número de bin values que es tenen en compte per al càlcul de c0_s i c1_w
                    Tbal_s=bin_values(k_s); %JGC: valor inicial de la T_Balanç d'estiu
                    s=0; %JGC: número d'iteració inicial
                    while (error_Tbal_s>=0.1)&&(s<=10)
                        Tbal_s0=Tbal_s; %JGC: valor de la T_Balanç d'estiu a l'inici de l'iteració
                        suma_sTh=0; %JGC: valor inicial del sumatori dels productes (temperatura del bin)X(d'hores d'ocurrència) per al càlcul de  T_Balanç d'estiu
                        suma_sh=0; %JGC: valor inicial del sumatori d'hores d'ocurrència per al càlcul de  T_Balanç d'estiu
                        for i=bin_values_size(2):-1:k_s
                            suma_sTh=suma_sTh+bin_values(i)*thermal_bin(i); %JGC sumatori dels productes (temperatura del bin)X(d'hores d'ocurrència) implicat en el càlcul
                            suma_sh=suma_sh+thermal_bin(i); %JGC sumatori de totes les hores d'ocurrència implicades en el càlcul
                        end
                        c0_s = ((input_power_summer*suma_sTh - input_e_summer*T_des_s)/(suma_sTh - T_des_s*suma_sh));
                        c1_s = ((input_e_summer - input_power_summer*suma_sh)/(suma_sTh - T_des_s*suma_sh));
                        Tbal_s=-c0_s/c1_s; %JGC: Valor calculat de la T_Balanç a l'estiu en la iteració present
                        error_Tbal_s=abs(Tbal_s0-Tbal_s); %JGC avaluem la diferència entre la T_Balanç actual i el de l'inici de la iteració
                        k_s=find(abs(bin_values-Tbal_s)<1,1); %JGC Definim l'índex fins al qual hem de realitzar els sumatoris de la següent iteració
                        s=s+1;
                    end
    
                    %JGC: Construïm la corba de càrregues tèrmiques. Càrrega hivern (-) i Càrrega estiu (+)  
                    for i = 1:1:bin_values_size(2)
                        if (input_e_summer>0)&&(input_e_winter>0)
                            T_balanc=(Tbal_w+Tbal_s)/2;%JGC: Aproximació senzilla al valor de T_balanç en aquest cas particular
                            %JGC: utilitzem un marge de +/-2 graus al voltant de T_balanc a on assumim que no hi ha producció
                            if bin_values(i)<(T_balanc-1)
                               q_use(i,1) = (-1)*(c0_w + c1_w*bin_values(i));
                            elseif bin_values(i)>(T_balanc+1)                        
                                q_use(i,1) = c0_s + c1_s*bin_values(i);
                            else
                                q_use(i,1) = 0;
                            end
                        %JGC: Quan només tenim càrrega d'hivern o d'estiu, assumim que hi ha producció fins a T_balanç
                        elseif (input_e_summer>0)&&(input_e_winter==0)
                            if bin_values(i)>(Tbal_s)
                                q_use(i,1) = c0_s + c1_s*bin_values(i);
                            else
                                q_use(i,1) = 0;
                            end
                        elseif (input_e_summer==0)&&(input_e_winter>0)
                            if bin_values(i)<(Tbal_w)
                                q_use(i,1) = (-1)*(c0_w + c1_w*bin_values(i));
                            else
                                q_use(i,1) = 0;
                            end
                        end
                        q_use(i,7)=q_use(i,1); %JGC: Quan coneixem les càrregues, fem identic les components 1 i 7 de q_use
                    end
                    %adjuster_use = 1; %JGC: aquesta línia sobra? No, no sobra. Si coneixem la demanda, és necessari que aquest factor es faci 1
                end
                
                %JGC Construïm la corba de la càrrega de l'edifici (No confonde amb producció)
                q_use_final_1 = zeros(bin_values_size);
                q_use_final_2 = zeros(bin_values_size);
                q_use_final = zeros(bin_values_size); 
                for i = 1:1:bin_values_size(2)
                   %JGC: A l'hora de construïr corbes de carrega mode hivern i mode estiu, a la de mode hivern no tinc en
                   %compte les carregue solars per ser el cas més desfavorable.
                   q_use_final_1(i) = (q_use(i,1) + q_use(i,3) + q_use(i,4) + q_use(i,5) + q_use(i,6));%JGC: noteu que he tret q_use(i,2)
                   q_use_final_2(i) = (q_use(i,7) + q_use(i,8) + q_use(i,9) + q_use(i,10) + q_use(i,11) + q_use(i,12));
                   if (q_use_final_1(i)<0)&&(q_use_final_2(i)<0)
                       q_use_final(i)=q_use_final_1(i);
                   elseif (q_use_final_1(i)>0)&&(q_use_final_2(i)>0)
                       q_use_final(i)=q_use_final_2(i);
                   else
                       q_use_final(i)=0;
                   end
                end
                % JGC: Calculem T_balanc per als casos use_case=1 i use_case=2 i definim (redundantment) T_balanc per al use_case=3
                if (use_case==2)
                    if (input_e_summer>0)&&(input_e_winter>0)
                        T_balanc=(Tbal_w+Tbal_s)/2;%JGC: Aproximació senzilla al valor de T_balanç 
                    elseif (input_e_summer>0)&&(input_e_winter==0)
                        T_balanc=Tbal_s;
                    elseif (input_e_summer==0)&&(input_e_winter>0)
                        T_balanc=Tbal_w;
                    end
                else     
                      m=1;
                      while (q_use_final(m)<0)&&(m<bin_values_size(2))
                          Tbal_w=bin_values(m);
                          m=m+1;
                      end
                      n=bin_values_size(2);
                      while (q_use_final(n)>0)&&(n>0)
                          Tbal_s=bin_values(n);
                          n=n-1;
                      end
                      T_balanc=(Tbal_w+Tbal_s)/2;
                end
                                            
                %JGC: Construïm la corba de càrregues Q(T_0) que es graficarà
                carrega=zeros(bin_values_size);
                carrega_solar=zeros(bin_values_size);
                carrega_plot=zeros(bin_values_size);
                for i = 1:1:bin_values_size(2)
                    if bin_values(i)<(T_balanc-1)
                        if q_use_final(i)<0
                           carrega(i)=q_use_final(i);  %JGC: [W] Valors de cárregues. Hivern (-), Estiu (+)
                           carrega_solar(i)=q_use(i,2); %JGC: [W] Valors de càrregues associades als guanys solars (sempre +)
                        else
                           carrega(i)=0;
                           carrega_solar(i)=0;
                        end
                    elseif bin_values(i)>(T_balanc+1)
                        if q_use_final(i)>0
                           carrega(i) = q_use_final(i); %JGC: [W] Valors de cárregues. Hivern (-), Estiu (+)
                           carrega_solar(i)=q_use(i,8); %JGC: [W] Valors de càrregues associades als guanys solars (sempre +)
                        else
                           carrega(i)=0;
                           carrega_solar(i)=0;
                        end
                    else
                        carrega(i)=0;
                        carrega_solar(i)=0;
                    end
                    carrega_plot(i) = abs(carrega(i))/1000; %JGC: [kW] Valors de cárregues (+)
                end
                setappdata(0,'carrega',carrega);
                
                %JGC: Construïm la corba de demanda E(T_0) que es graficarà
                if use_case==2
                   F_ocup=1;
                else
                   F_ocup=paramload(11); %JGC: Factor d'ocupació  mitjana de l'edifici (valor de 0 a 1)
                end
                setappdata(0,'F_ocup',F_ocup);
                demanda=zeros(bin_values_size);
                demanda_plot=zeros(bin_values_size);
                for i = 1:1:bin_values_size(2)
                    if bin_values(i)<(T_balanc-1) %JGC: a l'hivern, discrimino entre hores sense sol i hores amb sol
                        demanda (i)=carrega(i)*thermal_nosolarbin(i)+(carrega(i)+carrega_solar(i))*thermal_solarbin(i); %JGC: [Wh] Valors de demanda 
                        demanda (i)=F_ocup*demanda(i);
                    elseif bin_values(i)>(T_balanc+1)
                        demanda (i)=carrega(i)*thermal_solarbin(i)+(carrega(i)-carrega_solar(i))*thermal_nosolarbin(i);
                        demanda (i)=F_ocup*demanda(i);
                    else
                        demanda (i)=0;
                    end
                    demanda_plot(i)=(-1)*demanda(i)/1000000; %JGC: [MWh] Valors de demanda per representar. Demanda_w (+); Demanda_s (-)
                end
                            
%===============================_REVISAR SI HEMOS DE RETIRAR LO SIGUIENTE, QUIZÁS SOLO AFECTE A LA GENERACIÓN DEL INFORME. YO CREO QUE NI ESO_===========================              
                %JGC: Construïm la corba de demanda E(month) (que no producció)
                demanda_month_plot_h=zeros(1,12);
                demanda_month_plot_c=zeros(1,12);
                demanda_month_plot_ACS=zeros(1,12);
                MonthAxe=[1 2 3 4 5 6 7 8 9 10 11 12];
                %JGC: Per una banda, la climatització
                for j=1:1:12
                    sumh=0;
                    sumc=0;
                    for i=1:1:bin_values_size(2)
                        if bin_values(i)<(T_balanc-1)
                           sumh=sumh-(carrega(i)*thermal_nosolarbin_monthly(i,j)+(carrega(i)+carrega_solar(i))*thermal_solarbin_monthly(i,j))/1000000;
                           %JGC: Aqui implícitament estem fent un primer pas al'hora de compensar la demanda de fred i
                           %calor quan ambdues tenen lloc en un mateix mes, en el cas de demanda desconeguda
                        elseif bin_values(i)>(T_balanc+1)
                           sumc=sumc-(carrega(i)*thermal_solarbin_monthly(i,j)+(carrega(i)-carrega_solar(i))*thermal_nosolarbin_monthly(i,j))/1000000;
                           %JGC: Aqui implícitament estem fent un primer pas al'hora de compensar la demanda de calor i
                           %fred quan ambdues tenen lloc en un mateix mes, en el cas de demanda desconeguda
                        end 
                    end
                    demanda_month_plot_h(j)=sumh*F_ocup; %JGC: [MWh]
                    demanda_month_plot_c(j)=sumc*F_ocup; %JGC: [MWh]
                end
 
                %JGC: Anem a calcular els valors totals de demanda tèrmica en climatització, independentment del valor de "use_case" (demanda coneguda o no, residencial o no)
                demanda_e_total = 0;
                demanda_e_c = 0;
                demanda_e_h = 0;
                for i=1:1:bin_values_size(2)
                    demanda_e_total = demanda_e_total + abs(demanda(i))/1000; %JGC:[kWh] Valor de demanda total en climatització, sigui quin sigui el valor de use_case
                    if (demanda(i) < 0)
                        demanda_e_h = demanda_e_h + demanda(i)/1000; %JGC:[kWh] Valor de demanda de calefaccio (+), sigui quin sigui el valor de use_case
                    else
                        demanda_e_c = demanda_e_c + demanda(i)/1000;  %JGC:[kWh] Valor de demanda refrigeració (+), sigui quin sigui el valor de use_case
                    end
                end
              
                if (use_case == 2)
                    demanda_en_superficie = 0;
                else
                    demanda_en_superficie = demanda_e_total/(s_area);
                end
%===================================================================================================================================================================
                
                %JGC: Per una altra banda, la demanda l'ACS:
                sumdies=0;
                for j=1:1:12
                    sumACS=0;
                    sumdies=sumdies+t_mesos(j); %JGC: ho necessito per situar-me a cada dia de l'any segons el mes
                    for i=1:1:t_mesos(j) %JGC: aquest array és la duració de en dies de cada mes. Està definit més amunt
                        if app.UnknownDemand.Value == false
                           sumACS=sumACS+input_e_ACS*r_ACS(sumdies-t_mesos(j)+i); %JGC: [Wh]
                        else
                           sumACS=sumACS+max_ocup*litresACS*4180*(60-t_aixeta(sumdies-t_mesos(j)+i))/3600; %JGC: [Wh] (litres d'aigua assumits a kg)
                        end
                    end
                    demanda_month_plot_ACS(j)=sumACS; %JGC: [Wh]
                end
                
                %JGC: Alguns valors de paràmetres necessaris abans de començar l'algoritme de dimensionament...
                r_s = 1/lect_cdty;               %JGC: [mK/W] Resistivitat tèrmica del terreny
                k_pipe = 0.42;                   %JGC: [W/mK] Conductivitat tèrmica del HDPE. Prèviament es considerava la del polibutilè (0.38 W/mK)
                Cv = lect_cdty/(lect_difu/1e6);  %JGC: [J/m3K] Capacitat tèrmica volumètrica del terreny.
                
                %JGC: Anem a distingir si es tracta de u simple o doble
                if app.Usimple.Value==true
                    D_oe=sqrt(2)*d_o_pipe;
                elseif app.Udoble.Value==true
                    D_oe=sqrt(4)*d_o_pipe;
                end
                D_oi=D_oe-(d_o_pipe-d_i_pipe);
                r_p = (1/(2*pi*k_pipe))*(log(D_oe/D_oi)); %JGC: Aquesta expressió assumeix les sondes múltiples com una única sonda de diàmetre equivalent D_oe
                
                %JGC: Càlcul de la longitud del camp de captació
                %JGC: Segons criteri d'hivern
                Tg0=T_ground_undist;
                TgfZ=T_ground_undist;
                error_pozos=50;
                it_pozos=1;
                it_Tgf=1;
                Z_0=paramload(20);
                Corregir_Tg=app.Corr_T_ground.Value; %JGC:"1" implica que es té en compte correcció. Valor "0" implica que no.
                while (error_pozos>=5)&&(it_pozos<=10)
                    %JGC: creem  el valor inicial de pous N0 que ens ha de servir de referència en cas de que aquest procés iteratiu divergeixi
                    if it_pozos==2
                        N_0=n_pozos; 
                    end
                    %JGC: cree el valor inicial de longitud de pous un cop hem fet la primera iteració.
                    if it_pozos>=2
                        L_0=L_drill_final;
                    end
                    
                    %JGC: Anem a seleccionar les condicions nominals d'operació de partida de la BCG
                    BCG_nominal=zeros(11,6);
                
                    t_ewt_max= (Tg0+10+Tg0+15)/2; %JGC: Valor de disseny (temperatura de l'aigua a pous). Imposem que els valors (in_cond+out_cond)/2 (estiu)
                    % han de dependre de la ubicació. La EWT a l'estiu sempre ha d'estar per sobre de la T_ground no pertorbada
                    t_ewt_min = (Tg0-7+Tg0-10)/2; %JGC: Valor de disseny (temperatura de l'aigua a pous). Imposem que els valors (in_evap+out_evap)/2 (hivern)
                    % han de dependre de la ubicació. La EWT a l'hivern sempre ha d'estar per sota de la T_ground no pertorbada
    
                    
                    %JGC: MODE CALEFACCIÓ
                    if t_ewt_min<=5 %JGC Si la temp. mín. mitjana a pous és massa baixa, hem de pensar en condicions amb salmorra (B0/WXX)
                        app.EWT_Calf.Value=0;
                        app.EWT_ACS.Value=0;
                    else %JGC: Si la temp. Mín mitjana a pous és suficientment alta, podem pensar en condicions amb aigua (W10/WXX)
                        app.EWT_Calf.Value=10;
                        app.EWT_ACS.Value=10;                    
                    end
    
                    if app.Dist_TR_h.Value==1
                        app.LWT_Calf.Value=35;
                    elseif app.Dist_RR_h.Value==1
                        app.LWT_Calf.Value=55;
                    elseif app.Dist_FC_h.Value==1
                        app.LWT_Calf.Value=45;
                    end
                
                    %JGC: MODE REFRIGERACIÓ
                    if app.Dist_TR_c.Value==1
                        app.EWT_Refr.Value=23; %JGC: Delta T= 5ºC a l'evaporador en mode refrigeració
                    elseif app.Dist_FC_c.Value==1
                        app.EWT_Refr.Value=12; %JGC: Delta T= 5ºC a l'evaporador en mode refrigeració
                    end
                    app.LWT_Refr.Value=35;
                    
                    %JGC: MODE ACS
                    app.LWT_ACS.Value=65;
                    
                    EWTnom_h=app.EWT_Calf.Value;
                    LWTnom_h=app.LWT_Calf.Value;
                    EWTnom_c=app.EWT_Refr.Value;
                    LWTnom_c=app.LWT_Refr.Value;
                    EWTnom_ACS=app.EWT_ACS.Value;
                    LWTnom_ACS=app.LWT_ACS.Value;
                    
                    %JGC: Anem a construïr la taula de dades nominals
                    for i=1:1:11
                        if (app.EWT_Calf.Value==10)&&(app.LWT_Calf.Value==35)
                            BCG_nominal(i,1)=BCG_nominal_rough(i,1);
                            BCG_nominal(i,2)=BCG_nominal_rough(i,2);
                            BCG_nominal(i,5)=BCG_nominal_rough(i,5);
                            BCG_nominal(i,6)=BCG_nominal_rough(i,6);
                        elseif (app.EWT_Calf.Value==10)&&(app.LWT_Calf.Value==45)
                            BCG_nominal(i,1)=BCG_nominal_rough(i+11,1);
                            BCG_nominal(i,2)=BCG_nominal_rough(i+11,2);
                            BCG_nominal(i,5)=BCG_nominal_rough(i+11,5);
                            BCG_nominal(i,6)=BCG_nominal_rough(i+11,6);
                        elseif (app.EWT_Calf.Value==10)&&(app.LWT_Calf.Value==55)
                            BCG_nominal(i,1)=BCG_nominal_rough(i+22,1);
                            BCG_nominal(i,2)=BCG_nominal_rough(i+22,2);
                            BCG_nominal(i,5)=BCG_nominal_rough(i+22,5);
                            BCG_nominal(i,6)=BCG_nominal_rough(i+22,6);
                        elseif (app.EWT_Calf.Value==0)&&(app.LWT_Calf.Value==35)
                            BCG_nominal(i,1)=BCG_nominal_rough(i+33,1);
                            BCG_nominal(i,2)=BCG_nominal_rough(i+33,2);
                            BCG_nominal(i,5)=BCG_nominal_rough(i+33,5);
                            BCG_nominal(i,6)=BCG_nominal_rough(i+33,6);
                        elseif (app.EWT_Calf.Value==0)&&(app.LWT_Calf.Value==45)
                            BCG_nominal(i,1)=BCG_nominal_rough(i+44,1);
                            BCG_nominal(i,2)=BCG_nominal_rough(i+44,2);
                            BCG_nominal(i,5)=BCG_nominal_rough(i+44,5);
                            BCG_nominal(i,6)=BCG_nominal_rough(i+44,6);
                        elseif (app.EWT_Calf.Value==0)&&(app.LWT_Calf.Value==55)
                            BCG_nominal(i,1)=BCG_nominal_rough(i+55,1);
                            BCG_nominal(i,2)=BCG_nominal_rough(i+55,2);
                            BCG_nominal(i,5)=BCG_nominal_rough(i+55,5);
                            BCG_nominal(i,6)=BCG_nominal_rough(i+55,6);
                        end
                        if (app.EWT_Refr.Value==23)
                            BCG_nominal(i,3)=BCG_nominal_rough(i,3);
                            BCG_nominal(i,4)=BCG_nominal_rough(i,4);
                        elseif  (app.EWT_Refr.Value==12)
                            BCG_nominal(i,3)=BCG_nominal_rough(i+11,3);
                            BCG_nominal(i,4)=BCG_nominal_rough(i+11,4);  
                        end
                    end
                
                    %JGC: Anem a calcular els valors de EWT en funció de T_bin
                    EWT=zeros(bin_values_size(2),1);
                    for i=1:1:bin_values_size(2)
%                         EWT(i)=(t_ewt_min)+((t_ewt_max-t_ewt_min)/(T_des_s-T_des_w))*(bin_values(i)-T_des_w);
                        EWT(i)=(t_ewt_min+1.5)+((t_ewt_max-2.5-t_ewt_min-1.5)/(T_des_s-T_des_w))*(bin_values(i)-T_des_w);
                        %JGC: li sumo 1.5ºC a t_ewt_min per tenir el valor real mínim d'entrada a la BCG, no el valor mitjà)
                    end
                    
                    %JGC: Algoritme per definir la capacitat de disseny
                    if (use_case == 2)
                        q_des_Calf=input_power_winter;
    %                     q_des_ACS=input_power_ACS;
                        q_des_winter = max(q_des_Calf, q_des_ACS); %JGC: [W] (q_des_ACS se ha definido más arriba)
                        q_des_summer = input_power_summer;%JGC: [W]
                    else
                        q_des_winter = abs(carrega(pos_winter));%JGC: [W]
                        q_des_summer = carrega(pos_summer);%JGC: [W]
                    end
                
                    %JGC: Algoritme per saber si necessitem una o més d'una BCG
                    if (user_pump == 0) %JGC: Cas de no definir BCG pròpia
    %                     if (criterio == 0)
                        if (q_des_winter > BCG_nominal(11,1)*1.05)
                            single_pump_w = 0;
                        else
                            single_pump_w = 1;
                            divider_w = 1;
                        end
    %                     else
                        if (q_des_summer > BCG_nominal(11,3)*1.05)
                            single_pump_s = 0;
                        else
                            single_pump_s = 1;
                            divider_s = 1;
                        end
    %                     end
                    else %JGC: Cas de definir BCG pròpia
    %                     if (criterio == 0)
                        if (q_des_winter > user_winter_p*1.05)
                            single_pump_w = 0;
                        else
                            single_pump_w = 1;
                            divider_w = 1;
                        end
    %                     else
                        if (q_des_summer > user_summer_p*1.05)
                            single_pump_s = 0;
                        else
                            single_pump_s = 1;
                            divider_s = 1;
                        end
    %                     end
                    end
                
                    %JGC: Algoritme per decidir quin model i quantes unitats BCG necessitem.
                    if (user_pump == 0) %JGC: Cas que l'usuari no introdueixi dades de BCG pròpia
                        info_model_w = zeros(1,6);
                        info_model_s = zeros(1,6);
                        if (single_pump_w == 1) %JGC: Cas que necessitem només una unitat BCG (hivern)
    %                         if (criterio == 0)
                            if (q_des_winter <= BCG_nominal(1,1))
                                model_selected_w = 1;
                                info_model_w = BCG_nominal(1,:);
                            elseif (q_des_winter >= BCG_nominal(11,1))
                                model_selected_w = 11;
                                info_model_w = BCG_nominal(11,:);
                            else
                                i = 1;
                                model_selected_w = 0;
                                stopper = 0;
                                while (i<11)&&(model_selected_w == 0)
                                    if (q_des_winter >= BCG_nominal(i,1)*0.95)&&(q_des_winter <= BCG_nominal(i,1)*1.05)
                                        model_selected_w = i;
                                        stopper = 1;
                                        info_model_w = BCG_nominal(i,:);
                                    end
                                    if ((q_des_winter > BCG_nominal(i,1))&&(q_des_winter < BCG_nominal(i+1,1)))&&(stopper == 0)
                                        model_selected_w = i+1;
                                        info_model_w = BCG_nominal(i+1,:);
                                    end
                                    i = i+1;
                                end
                            end
                        elseif (single_pump_w == 0) %JGC: Cas que necessitem més d'una unitat BCG (hivern)
                                %JGC: Re-definició de la q_des per poder comparar amb les opcions del llistat."Divider serà el nombre de unitats BCG necessàries
                            divider_w = 1;
                            q_des_winter_new = q_des_winter; 
                            while (q_des_winter_new > BCG_nominal(11,1)*1.05)
                                divider_w = divider_w + 1;
                                q_des_winter_new = q_des_winter_new*((divider_w-1)/divider_w);
                            end
                            %JGC: Ara es procedeix a escollir model, anàlogament al cas de només una unitat BCG necessària
                            if (q_des_winter_new <= BCG_nominal(1,1))
                                model_selected_w = 1;
                                info_model_w = BCG_nominal(1,:);
                            elseif (q_des_winter_new >= BCG_nominal(11,1))
                                model_selected_w = 11;
                                info_model_w = BCG_nominal(11,:);
                            else
                                i = 1;
                                model_selected_w = 0;
                                stopper = 0;
                                while (i<11)&&(model_selected_w == 0)
                                    if (q_des_winter_new >= BCG_nominal(i,1)*0.95)&&(q_des_winter_new <= BCG_nominal(i,1)*1.05)
                                        model_selected_w = i;
                                        stopper = 1;
                                        info_model_w = BCG_nominal(i,:);
                                    end
                                    if ((q_des_winter_new > BCG_nominal(i,1))&&(q_des_winter_new < BCG_nominal(i+1,1)))&&(stopper == 0)
                                        model_selected_w = i+1;
                                        info_model_w = BCG_nominal(i+1,:);
                                    end
                                    i = i+1;
                                end
                            end
                            
                        % JGC: Re-definició de la q_des per poder comparar amb les opcions del llistat."Divider serà el nombre de unitats BCG necessàries        
                        end 
                        if  (single_pump_s == 1)
                            if (q_des_summer <= BCG_nominal(1,3))
                                model_selected_s = 1;
                                info_model_s = BCG_nominal(1,:);
                            elseif (q_des_summer >= BCG_nominal(11,3))
                                model_selected_s = 11;
                                info_model_s = BCG_nominal(11,:);
                            else
                                i = 1;
                                model_selected_s = 0;
                                stopper = 0;
                                while (i<11)&&(model_selected_s == 0)
                                    if (q_des_summer >= BCG_nominal(i,3)*0.95)&&(q_des_summer <= BCG_nominal(i,3)*1.05)
                                        model_selected_s = i;
                                        stopper = 1;
                                        info_model_s = BCG_nominal(i,:);
                                    end
                                    if ((q_des_summer > BCG_nominal(i,3))&&(q_des_summer < BCG_nominal(i+1,3)))&&(stopper == 0)
                                        model_selected_s = i+1;
                                        info_model_s = BCG_nominal(i+1,:);
                                    end
                                    i = i+1;
                                end
                            end
                        elseif (single_pump_s == 0)
                            divider_s = 1;
                            q_des_summer_new = q_des_summer;
                            while (q_des_summer_new > BCG_nominal(11,3)*1.05)
                                divider_s = divider_s + 1;
                                q_des_summer_new = q_des_summer_new*((divider_s-1)/divider_s);
                            end
                            %JGC: Ara es procedeix a escollir model, anàlogament al cas de només una unitat BCG necessària
                            if (q_des_summer_new <= BCG_nominal(1,3))
                                model_selected_s = 1;
                                info_model_s = BCG_nominal(1,:);
                            elseif (q_des_summer_new >= BCG_nominal(11,3))
                                model_selected_s = 11;
                                info_model_s = BCG_nominal(11,:);
                            else
                                i = 1;
                                model_selected_s = 0;
                                stopper = 0;
                                while (i<11)&&(model_selected_s == 0)
                                    if (q_des_summer_new >= BCG_nominal(i,3)*0.95)&&(q_des_summer_new <= BCG_nominal(i,3)*1.05)
                                        model_selected_s = i;
                                        stopper = 1;
                                        info_model_s = BCG_nominal(i,:);
                                    end
                                    if ((q_des_summer_new > BCG_nominal(i,3))&&(q_des_summer_new < BCG_nominal(i+1,3)))&&(stopper == 0)
                                        model_selected_s = i+1;
                                        info_model_s = BCG_nominal(i+1,:);
                                    end
                                    i = i+1;
                                end
                            end
                        end
                    else %JGC: Cas que l'usuari introdueixi dades de BCG pròpia
                        info_model0= [user_winter_p user_winter_cop user_summer_p user_summer_cop user_ACS_p user_ACS_cop];
                        if (single_pump_w == 0)
                           % if (criterio == 0)
                           divider_w = 1;
                           info_model_w=info_model0;
                           model_selected_w=0;
                           q_des_winter_new = q_des_winter;
                           while (q_des_winter_new > info_model_w(1)*1.05)
                              divider_w = divider_w + 1;
                              q_des_winter_new = q_des_winter_new*((divider_w-1)/divider_w);
                           end
                        else 
                           divider_w = 1;
                           info_model_w=info_model0;
                           model_selected_w=0;
                        end
                        if(single_pump_s == 0)
                           divider_s = 1;
                           info_model_s=info_model0;
                           model_selected_s=0;
                           q_des_summer_new = q_des_summer;
                           while (q_des_summer_new > info_model_s(3)*1.05)
                              divider_s = divider_s + 1;
                              q_des_summer_new = q_des_summer_new*((divider_s-1)/divider_s);
                           end
                        else
                           divider_s = 1;
                           info_model_s=info_model0;
                           model_selected_s=0;
                        end
                    end
          
                    %JGC: Ja tenim el model de BCG (encara no sabem si segons criteri hivern o estiu), les condicions nominals d'operació i la corba EWT(T_bin). Anem a calcular el COP/EER/COP_ACS en funció d'EWT
                    ind_s=model_selected_s;
                    ind_w=model_selected_w;
                    COP_w=zeros(bin_values_size(2),1);
                    COP_s=zeros(bin_values_size(2),1);
                    COP_ACSw=zeros(bin_values_size(2),1);
                    COP_ACSs=zeros(bin_values_size(2),1);
                    EER_w=zeros(bin_values_size(2),1);
                    EER_s=zeros(bin_values_size(2),1);
                    

                    for i=1:1:bin_values_size(2)
                        if (ind_w==0)||(ind_s==0) %JGC: BCG escollida per l'usuari
                           if app.EWT_Calf.Value==0
                              COP_w0=user_winter_cop; %JGC: COP amb condicions B0WXX
                              COP_s0=COP_w0;
                              COP_w10=COP_w0/0.9; %JGC: un COP en condicions B0WXX millora en un 11.1% quan pasem a W10WXX (criteri d'acord amb dades BCG Genèriques)
                              COP_s10=COP_w10;
                              COP_ACSw0=user_ACS_cop;
                              COP_ACSs0=COP_ACS_w0;
                              COP_ACSw10=COP_ACSw0/0.9;%JGC: un  COP_ACS en condicions B0W65 millora en un 11.1% quan pasem a W10W65 (criteri d'acord amb dades BCG Genèriques)
                              COP_ACSs10=COP_ACSw10;
                           elseif app.EWT_Calf.Value==10
                              COP_w10=user_winter_cop;
                              COP_s10=COP_w10;
                              COP_w0=COP_w10*0.9;%JGC: un  COP en condicions W10WXX empitjora en un 10% quan pasem a B0WXX (criteri d'acord amb dades BCG Genèriques)
                              COP_s0=COP_w0;
                              COP_ACSw10=user_ACS_cop;
                              COP_ACSs10=COP_ACSw10;
                              COP_ACSw0=COP_ACSw10*0.9;%JGC: %JGC: un  COP_ACS en condicions B10W65 empitjora en un 10% quan pasem a W0W65 (criteri d'acord amb dades BCG Genèriques)
                              COP_ACSs0=COP_ACSw0;
                           end
                           EER_w35=user_summer_cop;
                           EER_s35=EER_w35;
                           EER_w25=EER_w35/0.85; %JGC: un EER en condicions WXXW35 millora en un 17.6% quan pasem a WXXW25 (criteri d'acord amb dades BCG Genèriques)
                           EER_s25=EER_w25;
                        else %JGC: BCG escollida automàticament. Escollim les dades de COP i COP_ACS de les files corresponents al model de bomba seleccionat automàticament
                           if app.Dist_TR_h.Value==1
                              COP_w0=BCG_nominal_rough(ind_w+33,2);
                              COP_w10=BCG_nominal_rough(ind_w,2);
                              COP_s0=BCG_nominal_rough(ind_s+33,2);
                              COP_s10=BCG_nominal_rough(ind_s,2);
                              COP_ACSw0=BCG_nominal_rough(ind_w+33,6);
                              COP_ACSw10=BCG_nominal_rough(ind_w,6);
                              COP_ACSs0=BCG_nominal_rough(ind_s+33,6);
                              COP_ACSs10=BCG_nominal_rough(ind_s,6);
                           elseif app.Dist_FC_h.Value==1
                              COP_w0=BCG_nominal_rough(ind_w+44,2);
                              COP_w10=BCG_nominal_rough(ind_w+11,2);
                              COP_s0=BCG_nominal_rough(ind_s+44,2);
                              COP_s10=BCG_nominal_rough(ind_s+11,2);
                              COP_ACSw0=BCG_nominal_rough(ind_w+44,6);
                              COP_ACSw10=BCG_nominal_rough(ind_w+11,6);
                              COP_ACSs0=BCG_nominal_rough(ind_s+44,6);
                              COP_ACSs10=BCG_nominal_rough(ind_s+11,6);
                           elseif app.Dist_RR_h.Value==1
                              COP_w0=BCG_nominal_rough(ind_w+55,2);
                              COP_w10=BCG_nominal_rough(ind_w+22,2);
                              COP_s0=BCG_nominal_rough(ind_s+55,2);
                              COP_s10=BCG_nominal_rough(ind_s+22,2);
                              COP_ACSw0=BCG_nominal_rough(ind_w+55,6);
                              COP_ACSw10=BCG_nominal_rough(ind_w+22,6);
                              COP_ACSs0=BCG_nominal_rough(ind_s+55,6);
                              COP_ACSs10=BCG_nominal_rough(ind_s+22,6);
                           end
                           if app.Dist_TR_c.Value==1
                               EER_w35=BCG_nominal_rough(ind_w,4);
                               EER_w25=EER_w35/0.85;
                               EER_s35=BCG_nominal_rough(ind_s,4);
                               EER_s25=EER_w35/0.85;
                           elseif app.Dist_FC_c.Value==1
                               EER_w35=BCG_nominal_rough(ind_w+11,4);
                               EER_w25=EER_w35/0.85;
                               EER_s35=BCG_nominal_rough(ind_s+11,4);
                               EER_s25=EER_w35/0.85;
                           end
                        end
                        %JGC: EStablim les corbes de COP i COP_ACS en funció d'EWT
                        COP_w(i)= COP_w0+(EWT(i)-0)*(COP_w10-COP_w0)/(10-0);
                        COP_s(i)= COP_s0+(EWT(i)-0)*(COP_s10-COP_s0)/(10-0);
                        COP_ACSw(i)= COP_ACSw0+(EWT(i)-0)*(COP_ACSw10-COP_ACSw0)/(10-0);
                        COP_ACSs(i)= COP_ACSs0+(EWT(i)-0)*(COP_ACSs10-COP_ACSs0)/(10-0);
                        EER_w(i)=EER_w25+(EWT(i)-20)*(EER_w35-EER_w25)/(30-20); %JGC: tinguem en compte que parlem sempre de EWT, no LWT
                        EER_s(i)=EER_s25+(EWT(i)-20)*(EER_s35-EER_s25)/(30-20); %JGC: tinguem en compte que parlem sempre de EWT, no LWT
                    end
                    COPmw=zeros(12,1);
                    COP_ACSmw=zeros(12,1);
                    EERmw=zeros(12,1);
                    COPms=zeros(12,1);
                    COP_ACSms=zeros(12,1);
                    EERms=zeros(12,1);
                    for j=1:1:12
                        COPmw0=0;
                        COPms0=0;
                        hores_hm0=0;
                        EERmw0=0;
                        EERms0=0;
                        hores_cm0=0;
                        COP_ACSmw0=0;
                        COP_ACSms0=0;
                        for i=1:1:bin_values_size(2)
                            %JGC:Per al càlcul de COP_ACSm tinc en compte totes les T_bin, perquè la producció o no d'ACS no en depén.
                            COP_ACSmw0=COP_ACSmw0+COP_ACSw(i)*thermal_bin_monthly(i,j);
                            COP_ACSms0=COP_ACSms0+COP_ACSs(i)*thermal_bin_monthly(i,j);
                            %JGC:En canvi, el COP només té enc compte hores de calefacció, i l'EER de refrigeració
                            if bin_values(i)<(T_balanc-3)
                                COPmw0=COPmw0+COP_w(i)*thermal_bin_monthly(i,j);
                                COPms0=COPms0+COP_s(i)*thermal_bin_monthly(i,j);
                                hores_hm0=hores_hm0+thermal_bin_monthly(i,j);
                            elseif bin_values(i)>(T_balanc+3)
                                EERmw0=EERmw0+EER_w(i)*thermal_bin_monthly(i,j);
                                EERms0=EERms0+EER_s(i)*thermal_bin_monthly(i,j);  
                                hores_cm0=hores_cm0+thermal_bin_monthly(i,j);
                            end  
                        end
                        if hores_hm0==0 %JGC: en el hipotètic cas que la suma de les hores d'un mes en particular fos nula, no podem deixar un valor nul del COP
                           COPmw(j)=info_model_w(2);
                           COPms(j)=info_model_s(2);
                        else
                           COPmw(j)=COPmw0/hores_hm0;
                           COPms(j)=COPms0/hores_hm0;
                        end
                        if hores_cm0==0
                           EERmw(j)=info_model_w(4);
                           EERms(j)=info_model_s(4);
                        else
                           EERmw(j)=EERmw0/hores_cm0;
                           EERms(j)=EERms0/hores_cm0; 
                        end
                        COP_ACSmw(j)=COP_ACSmw0/sum(transpose(thermal_bin_monthly(:,j)));
                        COP_ACSms(j)=COP_ACSms0/sum(transpose(thermal_bin_monthly(:,j)));
                    end
                    
                    %JGC: Creació dels perfils de temperatura mínima i màxima del terra (Ambdós convergiran a partir d'una certa fondària)
                    %Necessaris per calcular el valor màxim i mínim de la temperatura mitjana del terra al llarg de la fondària dels pous
                    for i = 1:1:vect_size(2)
                        t_g_min(i) = Tg0 - DR_anual*(exp(-profundidad(i)*(sqrt(pi/(365*difu_rough)))));
                        t_g_max(i) = Tg0 + DR_anual*(exp(-profundidad(i)*(sqrt(pi/(365*difu_rough)))));
                    end
                    if (q_des_winter==0)&&(q_des_ACS==0)
                        L_drill_w=0;
                        n_pozos_w=0; %JGC necesito definir esto para que no me de problemas el texto de observacions, en caso de no tener demanda de invierno
                        L_drill_pozo_w=0;%JGC; idem anterior
                        F_w=0;
                        F_ACS=0;
                    else
                        carrega_parcial_wACS = demanda_month_plot_ACS(1); %JGC: [Wh] 
                        carrega_parcial_w = 0;
                        for i = 1:1:bin_values_size(2)
                            if(q_use_final(i)<0)&&(abs(q_use_final(i))<q_des_winter)
                               carrega_parcial_w = carrega_parcial_w + (abs(q_use_final(i)))*thermal_bin_monthly(i,1);
                            elseif (q_use_final(i)<0)&&(abs(q_use_final(i))>q_des_winter)
                               carrega_parcial_w = carrega_parcial_w + q_des_winter*thermal_bin_monthly(i,1);
                            end
                        end
                        q_win=max(q_des_winter,q_des_ACS); %JGC: imposem com a criteri que la càrrega màxima és la càrrega de disseny
                        if q_win==q_des_winter
                            COP=COPmw(1);
                        elseif q_win==q_des_ACS
                            COP=COP_ACSmw(1);
                        end
%                         F_length_w = (carrega_parcial_w+carrega_parcial_wACS)/q_win; %JGC: [hores] temps efectiu treballant a q_win per a calefacció (se suposa que no simultàniament mai amb ACS), o EFLH
                        F_length_w = F_ocup*carrega_parcial_w/q_win; %JGC: [hores] temps efectiu treballant a q_win per a calefacció (se suposa que no simultàniament mai amb ACS), o EFLH                        
                        F_length_ACS = carrega_parcial_wACS/q_win; %JGC: [hores] temps efectiu treballant a q_win per a ACS (se suposa que no simultàniament mai amb calefacció)
                        F_w = F_length_w/(sum(thermal_bin_monthly(:,1))); %JGC: Factor de càrrega parcial (o part load factor) a l'hivern per a la calefacció
                        F_ACS = F_length_ACS/(sum(thermal_bin_monthly(:,1))); %JGC: Factor de càrrega parcial (o part load factor) a l'hivern per a l'ACS
                        F_w_eff = F_w+F_ACS; %JGC: Factor de càrrega efectiu més de disseny d'hivern.
                        L_drill_w = q_win*((COP-1)/COP)*(r_p + r_s*F_w_eff)/(Tg0- t_ewt_min); %JGC: Valor inicial L_drill
                        if L_drill_w<0
                            uialert(alert_fig,'Excés de variació de T_subsol a llarg termini. Revisi distància entre pous i valor preliminar de profunditat pous','Configuració inviable','Icon','error');
                            break
                        end
                        error_Ldrill_w=10; %JGC: valor inicial de l'error per començar el càlcul iteratiu de L_drill segons criteri hivern
                        lw=0; %JGC: número d'iteració inicial
                        while (error_Ldrill_w>=1)&&(lw<=10)
                            L_drill_w0=L_drill_w; %JGC: valor de L_drill segons criteri hivern a l'inici de l'iteració
                            n_pozos_w0 = round(L_drill_w0/l_prosp);
                            if n_pozos_w0<1
                                n_pozos_w0=1;
                            end
                            L_drill_pozo_w0 = L_drill_w0/n_pozos_w0;
                            p_indx_w=find(abs(profundidad-L_drill_pozo_w0)<0.05,1); %JGC: Definim el nombre de punts que hem de comptar pel promig de t_g_min
                            suma_tgminw=0;
                            for j=1:1:p_indx_w
                                suma_tgminw=suma_tgminw+t_g_min(j);
                            end
                            t_g_min_w=suma_tgminw/p_indx_w;
                            L_drill_w=q_win*((COP-1)/COP)*(r_p + r_s*F_w)/(t_g_min_w - t_ewt_min);
                            n_pozos_w=round(L_drill_w/l_prosp);
                            if n_pozos_w<1
                                n_pozos_w=1;
                            end
                            L_drill_pozo_w=L_drill_w/n_pozos_w;
                            error_Ldrill_w=abs(L_drill_w-L_drill_w0);
                            lw=lw+1;
                        end
                    end
                    
                    %JGC: Segons criteri d'estiu 
                    if q_des_summer==0
                        L_drill_s=0;
                        n_pozos_s=0; %JGC necesito definir esto para que no me de problemas el texto de observacions, en caso de no tener demanda de verano
                        L_drill_pozo_s=0;%JGC; idem anterior
                        F_s=0;
                    else
                        %carrega_parcial_sACS = demanda_month_plot_ACS(7);
                        %%JGC: [Wh] NOTA 18/11/2021: Traiem això que no
                        %%està bé
                        carrega_parcial_s =0;                
                        for i = 1:1:bin_values_size(2)
                            if (q_use_final(i)>0)&&(abs(q_use_final(i)) < q_des_summer) 
                                carrega_parcial_s = carrega_parcial_s + q_use_final(i)*thermal_bin_monthly(i,7);
                            elseif (q_use_final(i)>0)&&(abs(q_use_final(i)) > q_des_summer)
                                carrega_parcial_s = carrega_parcial_s + q_des_summer*thermal_bin_monthly(i,7);
                            end
                        end
                        q_sum=q_des_summer; %JGC: imposem com a criteri que la càrrega màxima és la càrrega de disseny
                        EER=EERms(7);
                        %F_length_s = (carrega_parcial_s-carrega_parcial_sACS)/q_sum; %JGC: [hores] temps efectiu treballant a capacitat màxima, o EFLH 
                        %JGC: NOTA: RESTEM LA CÀRREGA PARCIAL D'ESTIU DE L'ACS PER COMPENSAR D'ALGUNA MANERA LA PRODUCCIÓ DE CALOR PER ACS AMB LA PRODUCCIÓ DE FRED
                        F_length_s = F_ocup*(carrega_parcial_s)/q_sum; %JGC: [hores] temps efectiu treballant a capacitat màxima, o EFLH
                        %JGC: NOTA 18/11/2021. Restar la càrrega parcial
                        %d'ACS a la de regriferació no sembla bona idea,
                        %sobre tot si volem dimensionar per refrigeració i
                        %no tenim recuperació de calor. Ho treiem
                        F_s = F_length_s/(sum(thermal_bin_monthly(:,7))); %JGC: Factor de càrrega parcial (o part load factor) a l'estiu
                        L_drill_s = q_sum*((EER+1)/EER)*(r_p + r_s*F_s)/(t_ewt_max- Tg0);
                        if L_drill_s<0
                            uialert(alert_fig,'Excés de variació de T_subsol a llarg termini. Revisi distància entre pous i valor preliminar de profunditat pous','Configuració inviable','Icon','error');
                            break
                        end
                        error_Ldrill_s=10; %JGC: valor inicial de l'error per començar el càlcul iteratiu de L_drill segons criteri estiu
                        ls=0; %JGC: número d'iteració inicial
                        while (error_Ldrill_s>=1)&&(ls<=10)
                            L_drill_s0=L_drill_s; %JGC: valor de L_drill segons criteri estiu a l'inici de l'iteració
                            n_pozos_s0 = round(L_drill_s0/l_prosp);
                            if n_pozos_s0<1
                                n_pozos_s0=1;
                            else
                                n_pozos_s0 = round(L_drill_s0/l_prosp);
                            end
                            L_drill_pozo_s0 = L_drill_s0/n_pozos_s0;
                            p_indx_s=find(abs(profundidad-L_drill_pozo_s0)<0.05,1); %JGC: Definim el nombre de punts que hem de comptar pel promig de t_g_min
                            suma_t_g_max_s=0;
                            for j=1:1:p_indx_s
                                suma_t_g_max_s=suma_t_g_max_s+t_g_max(j);
                            end
                            t_g_max_s=suma_t_g_max_s/p_indx_s;
                            L_drill_s=q_sum*((EER+1)/EER)*(r_p + r_s*F_s)/(t_ewt_max-t_g_max_s);
                            n_pozos_s=round(L_drill_s/l_prosp);
                            if n_pozos_s<1
                                n_pozos_s=1;
                            end
                            L_drill_pozo_s=L_drill_s/n_pozos_s;
                            error_Ldrill_s=abs(L_drill_s-L_drill_s0);
                            ls=ls+1;
                        end
                    end  
                    
                    if (L_drill_w >= L_drill_s)
                        L_drill= L_drill_w;
                        n_pozos=n_pozos_w;
                        L_drill_pozo=L_drill_pozo_w;
                        divider=divider_w;
                        info_model=info_model_w;
                        model_selected=model_selected_w;
                        criterio=0;
                        %JGC: Defineixo per fi el conjunt de rendiments mensuals
                        COPm=COPmw;
                        EERm=EERmw;
                        COP_ACSm=COP_ACSmw;
                    else 
                        L_drill= L_drill_s;
                        n_pozos=n_pozos_s;
                        L_drill_pozo=L_drill_pozo_s;
                        divider=divider_s;
                        info_model=info_model_s;
                        model_selected=model_selected_s;
                        criterio=1;
                        %JGC: Defineixo per fi el conjunt de rendiments mensuals
                        COPm=COPms;
                        EERm=EERms;
                        COP_ACSm=COP_ACSms;
                    end
                    
                    
                    %JGC: Ara que ja hem definit quina és la capacitat (model de bomba), Longitud de pous i el criteri de disseny del nostre sistema,
                    %podem calcular les corbes de Capacitat i de Producció d'energia en funció de T ambient
                    capacitat=zeros(bin_values_size); %JGC: Potència tèrmica corresponent als moments amb càrregues més desfavorables (sense sol a l'hivern i amb sol a l'estiu)
                    capacitat_fav=zeros(bin_values_size); %JGC: Potència tèrmica corresponent als moments amb càrregues més favorables (amb sol a l'hivern i sense sol a l'estiu)
                    
                    for i = 1:1:bin_values_size(2)
                        if (input_e_summer >0)&&(input_e_winter>0)||(app.UnknownDemand.Value == true)%JGC: si estem en mode càlcul de càrregues automàtic o manual amb CALOR i FRED diferents de zero
                            if bin_values(i) < (T_balanc - 3)
                                if abs(carrega(i))<(info_model(1)*divider) %JGC: Si la càrrega es inferior a la potència instal·lada...
                                   capacitat(i)=carrega(i); %JGC:... llavors la potència es correspon amb la càrrega
                                   capacitat_fav(i)=carrega(i)+carrega_solar(i); %JGC:... i passarà el mateix pel cas d'haver irradiació solar
                                else %JGC: Si la càrrega es superior a la potència instal·lada...
                                   capacitat(i)=(-1)*info_model(1)*divider; %JGC:... llavors la potència es correspon amb la potència instal·lada
                                   if abs(carrega(i)+carrega_solar(i))<(info_model(1)*divider) %JGC: però si la càrrega amb sol és inferior a la pot. instal·lada...
                                      capacitat_fav(i)=carrega(i)+carrega_solar(i); %JGC:... llavors la potència es correspon amb la càrrega amb sol
                                   else %JGC: i si la càrrega amb sol encara es superior a la potència instal·lada...
                                      capacitat_fav(i)=(-1)*info_model(1)*divider; %JGC: llavors la potència es correspondrà novament amb la potència instal·lada.
                                   end
                                end
                                if (carrega(i)+carrega_solar(i))>0
                                   capacitat_fav(i)=0;
                                end
                            elseif bin_values(i) > (T_balanc + 3)
                                if carrega(i)<(info_model(3)*divider)
                                   capacitat(i)=carrega(i);
                                   capacitat_fav(i)=carrega(i)-carrega_solar(i);
                                else
                                   capacitat(i)=info_model(3)*divider;
                                   if (carrega(i)-carrega_solar(i))<(info_model(3)*divider)
                                       capacitat_fav(i)=carrega(i)-carrega_solar(i);
                                   else
                                       capacitat_fav(i)=info_model(3)*divider;
                                   end
                                end
                                if (carrega(i)-carrega_solar(i))<0
                                   capacitat_fav(i)=0;
                                end
                            else
                                capacitat(i)=0;
                                capacitat_fav(i)=0;
                            end        
                        elseif(input_e_summer >0)&&(input_e_winter==0)&&(app.UnknownDemand.Value == false)
                            if bin_values(i) > T_balanc+1
                                if carrega(i)<(info_model(3)*divider)
                                   capacitat(i)=carrega(i);
                                else
                                   capacitat(i)=info_model(3)*divider;
                                end
                            else
                                capacitat(i)=0;
                            end
                            capacitat_fav(i)=capacitat(i);
                        elseif (input_e_summer == 0)&&(input_e_winter > 0)&&(app.UnknownDemand.Value == false)
                            if bin_values(i) < (T_balanc-1)
                                if abs(carrega(i))<(info_model(1)*divider)
                                   capacitat(i)=carrega(i);
                                else
                                   capacitat(i)=(-1)*info_model(1)*divider;
                                end
                            else
                                capacitat(i)=0;
                            end
                            capacitat_fav(i)=capacitat(i);
                        end
                    end
                    
                    %JGC: [kW] generem la corbes de capacitat Q_P(T_0) i producció E_P(T_0) que graficarem
                    produccio=zeros(bin_values_size);
                    capacitat_plot=zeros(bin_values_size);
                    produccio_plot=zeros(bin_values_size);
                    for i=1:1:bin_values_size(2)
                        capacitat_plot(i)=abs(capacitat(i))/1000; %JGC: [kW]
                        if bin_values(i) < (T_balanc - 1)
                           produccio(i)=capacitat(i)*thermal_nosolarbin(i)+capacitat_fav(i)*thermal_solarbin(i);
                           produccio(i)=F_ocup*produccio(i);
                        elseif bin_values(i) > (T_balanc + 1)
                           produccio(i)=capacitat(i)*thermal_solarbin(i)+capacitat_fav(i)*thermal_nosolarbin(i);
                           produccio(i)=F_ocup*produccio(i);
                        else
                           produccio(i)=0;
                        end
                        produccio_plot(i)=(-1)*produccio(i)/1000000; %JGC: [MWht] 
                    end
                                                 
                    %JGC: Construïm les corbes mensuals de producció d'energia i consum elèctric diferenciat per trams tarifaris vall-pla-punta
                    produccio_month_h=zeros(1,12);
                    produccio_month_c=zeros(1,12);
                    produccio_month_ACS=demanda_month_plot_ACS; %JGC: [Wh] en el cas d'ACS, la producció es pot assumir que equivaldrà a la demanda
                    consum_month_e_vall=zeros(1,12);
                    consum_month_e_pla=zeros(1,12);
                    consum_month_e_punta=zeros(1,12);
    
                    for j=1:1:12 %JGC: primer construïm les corbes de producció (climatització) diferenciades per tarifa
                        sumhvall=0;
                        sumcvall=0;
                        sumhpla=0;
                        sumcpla=0;
                        sumhpunta=0;
                        sumcpunta=0;
                        for i=1:1:bin_values_size(2)
                            if bin_values(i) < (T_balanc - 1)
                               sumhvall=sumhvall-F_ocup*(capacitat(i)*thermal_nosolarbin_vall(i,j)+capacitat_fav(i)*thermal_solarbin_vall(i,j));
                               sumhpla=sumhpla-F_ocup*(capacitat(i)*thermal_nosolarbin_pla(i,j)+capacitat_fav(i)*thermal_solarbin_pla(i,j));
                               sumhpunta=sumhpunta-F_ocup*(capacitat(i)*thermal_nosolarbin_punta(i,j)+capacitat_fav(i)*thermal_solarbin_punta(i,j));
                            elseif bin_values(i) > (T_balanc + 1)
                               sumcvall=sumcvall-F_ocup*(capacitat(i)*thermal_solarbin_vall(i,j)+capacitat_fav(i)*thermal_nosolarbin_vall(i,j));
                               sumcpla=sumcpla-F_ocup*(capacitat(i)*thermal_solarbin_pla(i,j)+capacitat_fav(i)*thermal_nosolarbin_pla(i,j));
                               sumcpunta=sumcpunta-F_ocup*(capacitat(i)*thermal_solarbin_punta(i,j)+capacitat_fav(i)*thermal_nosolarbin_punta(i,j));
                            end
                        end
    %                     end
                        sumhtot=sumhvall+sumhpla+sumhpunta;
                        sumctot=sumcvall+sumcpla+sumcpunta;
                        
                        %JGC: Per corregir l'efecte de no tenir en compte la inèrcia tèrmica dels edificis, hem d'introduïr un
                        %criteri per discernir si la producció de fred o calor d'un més determinat té sentit físic o s'ha de corregir
                        
                        %JGC: Si tenim només un tipus de demanda en un mes determinat (fred o calor), la discriminació entre mesos amb 
                        %producció o sense es fa a través de la relació entre la T_balanc i la T_meanMonth
                        if (use_case==2)&&(input_e_winter==0)
                            if T_meanMonth(j) > (T_balanc-1)
                                produccio_month_c(j)=sumctot; %JGC:[Wh]
                                consum_month_e_vall(j)=abs(sumcvall)/EERm(j); %JGC:[Wh]
                                consum_month_e_pla(j)=abs(sumcpla)/EERm(j); %JGC:[Wh]
                                consum_month_e_punta(j)=abs(sumcpunta)/EERm(j);%JGC:[Wh]
                                %JGC: Assumim que la despesa d'ACS es reparteix equitativament entre tots els periodes de tarificació
                            else 
                                produccio_month_c(j)=0;  %JGC:[Wh]
                                consum_month_e_vall(j)=0; %JGC:[Wh]
                                consum_month_e_pla(j)=0; %JGC:[Wh]
                                consum_month_e_punta(j)=0; %JGC:[Wh]
                                %JGC: Assumim que la despesa d'ACS es reparteix equitativament entre tots els periodes de tarificació
                            end
                        elseif (use_case==2)&&(input_e_summer==0)
                            if T_meanMonth(j) < (T_balanc+1)
                                produccio_month_h(j)=sumhtot; %JGC:[Wh]
                                consum_month_e_vall(j)=abs(sumhvall)/COPm(j); %JGC:[Wh]
                                consum_month_e_pla(j)=abs(sumhpla)/COPm(j); %JGC:[Wh]
                                consum_month_e_punta(j)=abs(sumhpunta)/COPm(j); %JGC:[Wh]
                                %JGC: Assumim que la despesa d'ACS es reparteix equitativament entre tots els periodes de tarificació
                            else 
                                produccio_month_h(j)=0;  %JGC:[Wh]
                                consum_month_e_vall(j)=0; %JGC:[Wh]
                                consum_month_e_pla(j)=0; %JGC:[Wh]
                                consum_month_e_punta(j)=0; %JGC:[Wh]
                                %JGC: Assumim que la despesa d'ACS es reparteix equitativament entre tots els periodes de tarificació
                            end
                        %JGC: Si tenim demanda de fred i calor, coneguda o no, es considera simplement que preval la diferència entre
                        % producció de calor i producció de fred.
                        %JGC: En quant als consums elèctrics segons tarifa aquests es distribueixen de manera normalitzada a com ho fan els consums d'un o altre signe
                        else 
                            if abs(sumhtot) > abs(sumctot)
                                produccio_month_h(j)=sumhtot+sumctot; %JGC:[Wh]
                                produccio_month_c(j)=0; %JGC:[Wh]
                                consum_month_e_vall(j)=(sumhvall/sumhtot)*abs(produccio_month_h(j))/COPm(j); %JGC:[Wh]
                                consum_month_e_pla(j)=(sumhpla/sumhtot)*abs(produccio_month_h(j))/COPm(j); %JGC:[Wh]
                                consum_month_e_punta(j)=(sumhpunta/sumhtot)*abs(produccio_month_h(j))/COPm(j); %JGC:[Wh]
                                
                                %JGC: Assumim que la despesa d'ACS es reparteix equitativament entre tots els periodes de tarificació
                            elseif abs(sumhtot) <= abs(sumctot)
                                produccio_month_c(j)=sumhtot+sumctot;  %JGC:[Wh]
                                produccio_month_h(j)=0; %JGC:[Wh]
                                consum_month_e_vall(j)=(sumcvall/sumctot)*abs(produccio_month_c(j))/EERm(j); %JGC:[Wh]
                                consum_month_e_pla(j)=(sumcpla/sumctot)*abs(produccio_month_c(j))/EERm(j); %JGC:[Wh]
                                consum_month_e_punta(j)=(sumcpunta/sumctot)*abs(produccio_month_c(j))/EERm(j); %JGC:[Wh]
                                %JGC: Assumim que la despesa d'ACS es reparteix equitativament entre tots els periodes de tarificació
                            end 
                        end   
                    end
                                   
                    %JGC: Pel cas que coneixem la demanda, és just fer coincidir els valors entrats i el resultat de construïr la corba E_P(mes) amb el bin method. E_P(T_0)) no s'ha de tocar doncs
                    %JGC: En canvi, quan no la coneixem, un cop construïda la corba E_P(mes), hem de tornar a la corba E_P(T_0) i adaptar la seva integral a la de E_P(mes)
                    integral_calor=sum(produccio_month_h); %JGC: signe (+) [Wh]
                    integral_fred=sum(produccio_month_c); %JGC: signe (-) [Wh]
                    produccio_month_plot_h=zeros(1,12);
                    produccio_month_plot_c=zeros(1,12);
                    produccio_month_plot_ACS=produccio_month_ACS; %JGC: [Wh] 
                    consum_e_vall=zeros(1,12);
                    consum_e_pla=zeros(1,12);
                    consum_e_punta=zeros(1,12);
                    consum_e_plot=zeros(12,3);
                    
                    %JGC: "factors de normalització"
                    if use_case==2
                        if integral_calor==0
                            factor_h=1;
                        else
                            factor_h=input_e_winter/integral_calor; %JGC: signe (+)
                        end
                        if integral_fred==0
                            factor_c=1;
                        else
                            factor_c=abs(input_e_summer/integral_fred); %JGC: signe (+)
                        end
                    else
                        factor_h=1;
                        factor_c=1;
                    end
                    consum_month_e_ACS=zeros(12,1);
                    %JGC: Corregim la corba E_P(mes) si coneixiem la demanda i la deixem igual si no
                    for j=1:1:12
                        consum_month_e_ACS(j)=produccio_month_ACS(j)/COP_ACSm(j);
                        if (use_case == 2) %JGC: volem que integral d'E_P(mes) coincideixi amb inputs de l'usuari (què serà molt similar a integral d'E_P(T_0))
                            if(input_e_winter>0)&&(input_e_summer>0)
                               produccio_month_plot_h(j)=(produccio_month_h(j)*factor_h)/1e6;%JGC [MWh]
                               produccio_month_plot_c(j)=(produccio_month_c(j)*factor_c)/1e6;%JGC [MWh];
                            elseif (input_e_winter>0)&&(input_e_summer==0)
                               produccio_month_plot_h(j)=(produccio_month_h(j)*factor_h)/1e6;%JGC [MWh];
                               produccio_month_plot_c(j)=0;
                            elseif (input_e_summer>0)&&(input_e_winter==0)
                               produccio_month_plot_c(j)=(produccio_month_c(j)*factor_c)/1e6;%JGC [MWh];
                               produccio_month_plot_h(j)=0; 
                            end
                            %JGC: per a corregir els valors de consum elèctric, emprem els factors de normalització de fred i calor segons el mode d'operació d'aquell mes
                            if produccio_month_plot_c(j)==0
                                consum_e_vall(j)=(consum_month_e_vall(j)*factor_h+(1/3)*consum_month_e_ACS(j))/1e6; %JGC [MWh]
                                consum_e_pla(j)=(consum_month_e_pla(j)*factor_h+(1/3)*consum_month_e_ACS(j))/1e6; %JGC [MWh]
                                consum_e_punta(j)=(consum_month_e_punta(j)*factor_h+(1/3)*consum_month_e_ACS(j))/1e6; %JGC [MWh]
                            else
                                consum_e_vall(j)=(consum_month_e_vall(j)*factor_c+(1/3)*consum_month_e_ACS(j))/1e6; %JGC [MWh]
                                consum_e_pla(j)=(consum_month_e_pla(j)*factor_c+(1/3)*consum_month_e_ACS(j))/1e6; %JGC [MWh]
                                consum_e_punta(j)=(consum_month_e_punta(j)*factor_c+(1/3)*consum_month_e_ACS(j))/1e6; %JGC [MWh]
                            end
                        else %JGC: Considerem en aquests casos que E_P(mes) no s'ha de modificar. Aquests valors seran els finals
                            produccio_month_plot_h(j)=produccio_month_h(j)/1e6;%JGC [MWh];
                            produccio_month_plot_c(j)=produccio_month_c(j)/1e6;%JGC [MWh];
                            consum_e_vall(j)=(consum_month_e_vall(j)+(1/3)*consum_month_e_ACS(j))/1e6;%JGC [MWh];
                            consum_e_pla(j)=(consum_month_e_pla(j)+(1/3)*consum_month_e_ACS(j))/1e6;%JGC [MWh];
                            consum_e_punta(j)=(consum_month_e_punta(j)+(1/3)*consum_month_e_ACS(j))/1e6;%JGC [MWh];
                        end
                        consum_e_plot(j,1)=consum_e_vall(j);
                        consum_e_plot(j,2)=consum_e_pla(j);
                        consum_e_plot(j,3)=consum_e_punta(j);
                    end
                    
                    %JGC: Corregim la corba E_P(T_0) si desconeixiem la demanda i la deixem igual si no
                    if use_case==1 %JGC: com hem imposat E_P(mes) com a corba vàlida, volem que integral d'E_P(T_0) coincideixi amb integral d'E_P(mes)
                        integral_produccio_plot_h=0;
                        integral_produccio_plot_c=0;  
                        for i=1:1:bin_values_size(2)
                            if produccio_plot(i)>0
                                integral_produccio_plot_h= integral_produccio_plot_h+produccio_plot(i); %JGC : signe (-)
                            else
                                integral_produccio_plot_c= integral_produccio_plot_c+produccio_plot(i); %JGC : signe (+)
                            end
                        end
                        factor_hh=(integral_calor/1000000)/(integral_produccio_plot_h);
                        factor_cc=(integral_fred/1000000)/(integral_produccio_plot_c);
                        for i=1:1:bin_values_size(2)
                            if produccio_plot(i)>0
                                produccio_plot(i)=produccio_plot(i)*factor_hh;
                            else
                                produccio_plot(i)=produccio_plot(i)*factor_cc;
                            end
                        end
                    end
                    factors_norm=[factor_h factor_c];
                    setappdata(0,'factors_norm',factors_norm); %JGC: Això són els factors de normalització que relacionen la integral d'E_P(mes) i E_P(T_0)
                    
                    %JGC: Calculems els totals, independentment del use_case
                    produccio_ACS=sum(produccio_month_plot_ACS)/1e6; %JGC: signe (+) [MWh]
                    produccio_calor=sum(produccio_month_plot_h); %JGC: signe (+) [MWh]
                    produccio_fred=sum(produccio_month_plot_c); %JGC: signe (-) [MWh]
                    
                    %JGC: Balanç amb el terra, expressió única tan si coneixem la demanda com si no
                    ground_coolm0=0;
                    ground_heatm0=0;
                    for j=1:1:12
                       ground_coolm0=ground_coolm0-produccio_month_plot_c(j)*(EERm(j)+1)/EERm(j);
                       ground_heatm0=ground_heatm0-produccio_month_plot_h(j)*(COPm(j)-1)/COPm(j)-(1e-6)*produccio_month_plot_ACS(j)*(COP_ACSm(j)-1)/COP_ACSm(j);
                    end
                    ground_cool = ground_coolm0; %JGC: [MWh] Signe (+). Calor injectada al terra
                    ground_heat = ground_heatm0; %JGC: [MWh] Signe (-). Calor extreta al terra
                    thermal_imbalance = (ground_heat + ground_cool);  %JGC: [MWh] Signe (-) si s'extreu més calor del que s'injecta. Signe (+) si viceversa  
                    error_Tgf=20;
                    % it_Tgf=1;
                    Tg0=T_ground_undist;
                    while (error_Tgf>=1)&&(it_Tgf<20)
                        %JGC: Anem a calcular quina variació de temperatura es pot esperar a llarg termini degut al desequilibri injecció-extracció de calor
                        e=app.d_well.Value; 
                        E_umbalance= thermal_imbalance*1e6*3600; %JGC:[J] Balanç d'energia bescanviada amb el subsol, expressat en Joules                   
    %                     CTE=0.0353*n_pozos^3-0.3611*n_pozos^2+1.3109*n_pozos-0.4777; %JGC: expressió que relaciona un nombre de pous determinat amb el radi del cilindre equivalent que els agruparia segons JJ
    %                     R=sqrt(n_pozos*(e/2)^2); %JGC: radi del cilindre equivalent, assumint un empaquetat de panell d'abelles
                        R=sqrt(n_pozos*e^2/pi); %JGC: radi del cilindre d'àrea ciruclar equivalent a u panell reticulat quadrat de costat e·√N
    %                     Volum_subs=L_drill_pozo*pi*R^2+(1/2)*(4/3)*R^3; %JGC: volum del "cilindre amb caputxó semi-esféric" equivalent
                        Volum_subs=L_drill_pozo*pi*R^2; %JGC: volum del "cilindre equivalent
                        %JGC: Ens basem en que Q_umbalance=Cv·V·(T_gf-T_g0)
                        Tgf=E_umbalance/(Cv*Volum_subs)+Tg0; %JGC: [ºC] Temperatura que s'assoliria si considerèssim que l'energia extra injectada o extreta no es dissipés després contra el terreny
                        Z=Z_0; %JGC Distància a la qual considerem que el subsol manté la seva temperatura no pertorbada (20-40m), sense oscil·lacions.
    %                     A_cil=2*pi*R*L_drill_pozo; %JGC: Àrea equivalent del cilindre equivalent al camp de captació
    %                     A_cap=0.5*4*pi*R^2; %JGC: Àrea equivalent del caputxó de sota del camp de captació (en contacte amb le terreny)
    %                     r_S=A_cap/A_cil/n_pozos;%JGC: ratio entre Q_cap i Q_cil, que equival a un ratio entre A_cap i A_cil ponderada amb el nombre de pous
    %                     ground_QZ = (Tgf-T_ground_undist)*2*pi*lect_cdty*((1-r_S)*L_drill_pozo/(log((R+Z)/R)+r_S*R*(R+Z)/Z)); %JGC:[W] Potència tèrmica d'evacuació/absorció de calor per conducció cap als voltants del camp de captació
                        ground_QZ = (Tgf-T_ground_undist)*2*pi*lect_cdty*L_drill_pozo/log((R+Z)/R); %JGC:[W] Potència tèrmica d'evacuació/absorció de calor per conducció cap als voltants del camp de captació
                        E_conduccio = ground_QZ*3600*24*365; %JGC: [J] energia total dissipada/absorbida durant un any.
                        if abs(E_conduccio)>=abs(E_umbalance)
                            E_conduccio=E_umbalance;
                        end
                        TgfZ=Tgf-E_conduccio/(Cv*Volum_subs); %JGC: [ºC] temperatura final després de dissipar l'excés/defecte contra el terreny
    %                     Zf=R*(exp(-(ground_Qh+ground_Qc)/(2*pi*L_drill_pozo*lect_cdty*(TgfZ-Tg0)))-1); %JGC: [m] distància fora perturbacions recalculada
                        error_Tgf=100*abs(TgfZ-Tg0)/Tg0;
                        Tg0=TgfZ;
    %                     Z_0=Zf;
                        it_Tgf=it_Tgf+1;
                    end
                    if app.Corr_T_ground.Value==false
                        break
                    end
                    if it_pozos>1
                        if n_pozos>=(N_0*2)
                            uialert(alert_fig,'Excés de variació de T_subsol a llarg termini. Revisi distància entre pous i valor preliminar de profunditat pous','Configuració inviable','Icon','error');
                            break
                        end
                    end
                    if it_pozos==1
                        error_pozos=20;
                    else
                        error_pozos=100*abs(L_drill-L_0)/L_0;
                    end
                    L_drill_final=L_drill;
                    it_pozos=it_pozos+1;
                end
                Var_Tg_num=round(TgfZ-T_ground_undist,1);
                Var_Tg=num2str(Var_Tg_num);
                app.Estimacio_Var_T.Value=Var_Tg;
                if (TgfZ-T_ground_undist)<0
                   app.Estimacio_Var_T.BackgroundColor=[0.6 0.8 1];
                elseif (TgfZ-T_ground_undist)>0
                   app.Estimacio_Var_T.BackgroundColor=[1 0.5 0.5];
                elseif (TgfZ-T_ground_undist)==0
                   app.Estimacio_Var_T.BackgroundColor=[1 1 1];
                end 
                t_estable_num=it_Tgf-1;%JGC: El nombre d'iteracions es correspon amb el nombre d'anys necessaris per estabilitzar la temperatura.
                t_estable=num2str(t_estable_num);
                app.t_estabilitzacio.BackgroundColor=[1 1 1];
                app.t_estabilitzacio.Value=t_estable;
                app.BalancSubsol.Value = double(thermal_imbalance);
                if app.BalancSubsol.Value<0
                   app.BalancSubsol.BackgroundColor=[0.6 0.8 1];
                elseif app.BalancSubsol.Value>0
                   app.BalancSubsol.BackgroundColor=[1 0.5 0.5];
                elseif app.BalancSubsol.Value==0
                   app.BalancSubsol.BackgroundColor=[1 1 1];
                end    
                app.n_well.Value = double(n_pozos);
                app.l_well.Value = double(L_drill_pozo);
                %JGC: Calculem la superficie necessària per al camp de captació, assumint una forma tendint a quadrat 
                app.m2_well.Value=app.n_well.Value*(app.d_well.Value)^2;
                d_entre_sondes=app.d_well.Value;
                area_pous=app.m2_well.Value;
                
                %JGC: Escrivim valors finals de t_ewt_min a les seves caselles de la pestanya resultats dimensionament
                app.t_ent_min_w.Value=t_ewt_min;
                app.t_ent_max_w.Value=t_ewt_max;
                    
                %JGC: Reescribim el titol del panell corresponent als detalls de la BCG en funció del model de bomba (0 quan l'escollim nosaltres)
                app.Panels_BCGValorsNom.Title=['8. Bomba de calor. Ref. #' num2str(model_selected) ' (valors nominals)'];
                n_BCG_txt = num2str(divider); %JGC: Especifiquem nombre d'unitats necessàries del model seleccionat.
                app.unitats_BCG.Text=['Nombre d''unitats BCG necessàries: ' n_BCG_txt];
                    
                %JGC Fem un petit càlcul per a definir els límits sup i inf de la graduació de l'eix Y als gràfics de consum
                %energia/electricitat, pq volem que siguin els mateixos en ambdos
                total_prod_high=produccio_month_plot_h+produccio_month_plot_ACS/1e6;
                total_prod_low=produccio_month_plot_c;
%                 Y_high=round(1.2*(max(total_prod_high)),1);%JGC: round instead of ceil
%                 Y_low=round(1.2*(min(total_prod_low)),1);%JGC: round instead of floor
                Y_high=ceil(12*(max(total_prod_high)))/10;
                Y_low=floor(12*(min(total_prod_low)))/10;
                Y_e=max(Y_high,abs(Y_low));
                setappdata(0,'high_limY',Y_high);
                setappdata(0,'low_limY',Y_low);
                setappdata(0,'elec_limY',Y_e);

                %JGC: Ajuntem dades Produccio i Demanda per a representar-les mensualment
                Prod=zeros(12,3);
                Demd=zeros(12,3);
                for i=1:1:12
                    Prod(i,1)=produccio_month_plot_h(i);
                    Prod(i,2)=produccio_month_plot_c(i);
                    Prod(i,3)=produccio_month_plot_ACS(i)/1e6;
                    Demd(i,1)=demanda_month_plot_h(i);
                    Demd(i,2)=demanda_month_plot_c(i);
                    Demd(i,3)=demanda_month_plot_ACS(i)/1e6;
                end

                %JGC: Contem els dies de calefacció i de refrigeració i els comparem amb els valors de l'ICGC                  
                counted_dcool = 0;
                counted_dheat = 0;             
                for i=1:1:12
                    if produccio_month_plot_h(i)>0
                        counted_dheat=counted_dheat+t_mesos(i);
                    elseif produccio_month_plot_c(i)<0
                        counted_dcool=counted_dcool+t_mesos(i);
                    end
                end
                
                bus_max = [counted_dheat,lect_dheat];
                bus_max_2 = [counted_dcool,lect_dcool];
                v_bus_1 = max(bus_max);
                v_bus_2 = max(bus_max_2);
                
                re_dcool = abs((counted_dcool - lect_dcool)/v_bus_1);
                re_dheat = abs((counted_dheat - lect_dheat)/v_bus_2);
                
                
                app.dcool_show.Value = double(counted_dcool);
                app.dheat_show.Value = double(counted_dheat);
                if (criterio == 0)&&(re_dheat > .3)
                    all_fine_3 = 0;
                elseif (criterio == 1)&&(re_dcool > .3)
                    all_fine_3 = 0;
                else
                    all_fine_3 = 1;
                end
                
                %JGC Grafiquem càrregues+capacitats i Demanda+producció             
                if app.show_thermal_r.Value==1
                    app.plot_results.cla;
                    ylabel(app.plot_results, 'Potència tèrmica (kW)');
                    hold(app.plot_results,'on');
                    plot(app.plot_results,bin_values,carrega_plot,'Color',[0.5 0.5 0.5],'LineWidth',1.0);
                    plot(app.plot_results,bin_values,capacitat_plot,'Color',[0.96 0.62 0.13],'LineWidth',1.0);
                    legend(app.plot_results,'Càrrega tèrmica', 'Potència instal·lació');
                    legend(app.plot_results,'Location','north');
                    legend(app.plot_results,'boxoff')
                    hold(app.plot_results,'off');
                elseif app.show_e_r.Value==1
                    app.plot_results.cla;
                    ylabel(app.plot_results, 'Energia tèrmica (MWh/any)');
                    hold(app.plot_results,'on');
                    plot(app.plot_results,bin_values,demanda_plot,'Color',[0.5 0.5 0.5],'LineWidth',1.0);
                    plot(app.plot_results,bin_values,produccio_plot,'Color',[0.96 0.62 0.13],'LineWidth',1.0);
                    legend(app.plot_results,'Demanda energia tèrmica', 'Producció energia tèrmica');
                    legend(app.plot_results,'Location','northeast');
                    legend(app.plot_results,'boxoff')
                    hold(app.plot_results,'off');
                else
                    app.plot_results.cla;
                    ylabel(app.plot_results,'Potència tèrmica');
                end           
                
                %JGC Grafiquem consum calor/fred/ACS i consum elèctric mes a mes
                if app.ConsumEnergia.Value==1
                    app.MonthDemandPlot.cla;
                    ylabel(app.MonthDemandPlot,'Energia tèrmica (MWh)');
                    app.MonthDemandPlot.YLim=[Y_low Y_high];
                    bar(app.MonthDemandPlot,MonthAxe,Prod);
                    b=bar(app.MonthDemandPlot,MonthAxe,Prod,'stacked','FaceColor','Flat');
                    set(b(1),'FaceColor',[1 0.5 0.5],'EdgeColor',[0 0 0]);
                    set(b(2),'FaceColor',[0.6 0.8 1],'EdgeColor',[0 0 0]);
                    set(b(3),'FaceColor',[0.6 1 0.6],'EdgeColor',[0 0 0]);
                    legend(app.MonthDemandPlot,{'Producció de calor','Producció de fred','Producció d''ACS'});
                    if Y_high>abs(Y_low)
                        legend(app.MonthDemandPlot,'Location', 'north')
                    else 
                        legend(app.MonthDemandPlot,'Location', 'southwest')
                    end
                    legend(app.MonthDemandPlot,'boxoff');
                elseif app.ConsumElectricitat.Value==1
                    app.MonthDemandPlot.cla;
                    ylabel(app.MonthDemandPlot,'Energia elèctrica (MWh)');
                    app.MonthDemandPlot.YLim=[0 0.5*Y_e];
                    bar(app.MonthDemandPlot,MonthAxe,consum_e_plot);
                    el=(bar(app.MonthDemandPlot,MonthAxe,consum_e_plot,'stacked','FaceColor','Flat'));
                    set(el(1),'FaceColor',[0 0 0],'EdgeColor',[0 0 0]);
                    set(el(2),'FaceColor',[0.5 0.5 0.5],'EdgeColor',[0 0 0]);
                    set(el(3),'FaceColor',[0.75 0.75 0.75],'EdgeColor',[0 0 0]);
                    legend(app.MonthDemandPlot,{'Consum període "vall"','Consum període "pla"','Consum període "punta"'});
                    legend(app.MonthDemandPlot,'Location', 'north');
                    legend(app.MonthDemandPlot,'boxoff'); 
                end 
                
                %JGC: Mostrem observacions
                if (L_drill_w >= L_drill_s)
                    txt1=' Dimensionament realitzat d''acord amb el criteri d''hivern.';
                    txt2=' Alternativament, segons el criteri d''estiu seria necessari perforar ';
                    txt3=num2str(n_pozos_s);
                    if n_pozos_s==1
                        txt4=' pou de ';
                        txt6=' m.';
                    else
                        txt4=' pous de ';
                        txt6='m cadascún.';
                    end
                    txt5=num2str(round(L_drill_pozo_s,0));                  
                    if (use_case==2)&&(input_e_summer==0)
                       app.label_show_result.Text=txt1 ; 
                    else
                       app.label_show_result.Text=[txt1 txt2 txt3 txt4 txt5 txt6];
                    end
                else 
                    txt1=' Dimensionament realitzat d''acord amb criteri d''estiu.';
                    txt2=' Alternativament, segons criteri d''hivern seria necessari perforar ';
                    txt3=num2str(n_pozos_w);
                    if n_pozos_s==1
                        txt4=' pou, de ';
                        txt6='m.';
                    else
                        txt4=' pous, de ';
                        txt6='m cadascún.';
                    end
                    txt5=num2str(round(L_drill_pozo_w,0));
                    if (use_case==2)&&(input_e_winter==0)
                       app.label_show_result.Text=txt1; 
                    else
                       app.label_show_result.Text=[txt1 txt2 txt3 txt4 txt5 txt6];
                    end
                end
                if Corregir_Tg==1
                    txt7=' La possible variació local de T_subsol a llarg termini ha estat considerada en els càlculs.';
                    if t_ewt_min<5
                        txt8=' Es requereix aportació d''anticongelant a l''aigua que circula pels pous.';
                    elseif (t_ewt_min>=5)&&(t_ewt_min<10)
                        txt8=' Es recomenable l''aportació d''anticongelant a l''aigua que circula pels pous.';
                    else
                        txt8=' No és necessària l''aportació d''anticongelant a l''aigua que circula pels pous.';
                    end
                else
                    txt7=' La possible variació local de T_subsol a llarg termini NO ha estat considerada en els càlculs.';
                    if t_ewt_min<5
                        txt8=' Es requereix aportació d''anticongelant a l''aigua que circula pels pous.';
                    else
                        txt8=' Podria ser necessari l''aportació d''anticongelant a l''aigua que circula pels pous.';
                    end
                end
                txtB=[txt7 txt8];
                app.label_show_result2.Text=txtB;
                    
                %JGC: Ara ja podem anotar al panell de Resultats les característiques i comportament de la instal·lació
                
                app.ProdCalfMWht.Value=abs(produccio_calor); %JGC: final heating demand in MWht
                Cons_e_hG=0;
                Cons_e_cG=0;
                for j=1:1:12
                    if produccio_month_plot_h(j)>0
                       Cons_e_hG=Cons_e_hG+factor_h*(consum_month_e_vall(j)+consum_month_e_pla(j)+consum_month_e_punta(j))/1e6;
                    end
                end
                for j=1:1:12
                    if produccio_month_plot_c(j)<0
                       Cons_e_cG=Cons_e_cG+factor_c*(consum_month_e_vall(j)+consum_month_e_pla(j)+consum_month_e_punta(j))/1e6;
                    end 
                end
                app.Cons_eCalfMWhe.Value=Cons_e_hG;
                setappdata(0,'cons_elec_h_G',Cons_e_hG);
                if app.ProdCalfMWht.Value==0
                   app.SPF_Calf.Value=0;
                else
                   app.SPF_Calf.Value=app.ProdCalfMWht.Value/app.Cons_eCalfMWhe.Value;
                end
                SPFCalf=app.SPF_Calf.Value;
                setappdata(0,'SPF_h',SPFCalf);
                app.ProdRefrMWht.Value=abs(produccio_fred); %JGC: final cooling demand in MWht 
                app.Cons_eRefrMWhe.Value=Cons_e_cG;
                setappdata(0,'cons_elec_c_G',Cons_e_cG);
                if app.ProdRefrMWht.Value==0
                   app.SPF_Refr.Value=0;
                else
                   app.SPF_Refr.Value= app.ProdRefrMWht.Value/app.Cons_eRefrMWhe.Value;
                end
                SPFRefr=app.SPF_Refr.Value;
                setappdata(0,'SPF_c',SPFRefr);
                app.Prod_ACSMWht.Value=abs(produccio_ACS);%JGC: final cooling demand in MWht 
                app.Cons_e_ACSMWhe.Value=sum(consum_month_e_ACS)/1e6;
                Cons_ACS_cG= app.Cons_e_ACSMWhe.Value;
                setappdata(0,'cons_elec_ACS_G',Cons_ACS_cG);
                if app.Prod_ACSMWht.Value==0
                   app.SPF_ACS.Value=0;
                else
                   app.SPF_ACS.Value=app.Prod_ACSMWht.Value/app.Cons_e_ACSMWhe.Value; 
                end
                SPFACS=app.SPF_ACS.Value;
                setappdata(0,'SPF_ACS',SPFACS);
                Prod_G=app.ProdCalfMWht.Value+app.ProdRefrMWht.Value+app.Prod_ACSMWht.Value;
                setappdata(0,'Prod_e_term_tot',Prod_G);
                if use_case==2
                    Prod_Gsup=0;
                else
                    Prod_Gsup=Prod_G/app.superficie.Value;
                end
                setappdata(0,'Prod_e_term_sup',Prod_Gsup);
                if app.ProdCalfMWht.Value==0 %JGC: pot passar que no tinguem producció de calefacció, llavors q_des_winter fa referència a l'ACS
                    app.PotDisCalfkW.Value=0;
                else
                    app.PotDisCalfkW.Value=q_des_winter/1000;
                end
                app.PotDisRefrkW.Value=q_des_summer/1000;
                %JGC: NOTA: La potència de disseny d'ACS s'ha definit més adalt
       
                %JGC: Calculem les potències de refrigeració i calefacció que hem de considerar instal·lades i que seràn utilitzades pel mòdul econòmic inicialment
                if (app.owner_hp.Value == 1)
                    P_calf_show= app.user_hp_heat.Value;
                    app.hp_heat.Value=P_calf_show;
                    COP_show = app.user_COP.Value;
                    app.COP_hp_heat.Value=COP_show;
                    P_refr_show= app.user_hp_cool.Value;
                    app.hp_cool.Value=P_refr_show;                    
                    EER_show = app.user_EER.Value;
                    app.EER_hp_cool.Value=EER_show;
                    P_ACS_show=app.user_hp_ACS.Value;
                    app.hp_ACS.Value=P_ACS_show;
                    COPACS_show=app.user_COPACS.Value;
                    app.COP_hp_ACS.Value=COPACS_show;
                    %JGC Now we can transfer the Installed capacity and COP to the Economic Analysis tab
                    app.PotInsCalfkW.Value=P_calf_show*divider;% JGC: Total installed power is the product of the nominal capacity times the number of pumps
                    app.PotInsRefrkW.Value=P_refr_show*divider;
                    app.PotInsACSkW.Value=P_ACS_show*divider;
                    %JGC: Si només tenim calefacció o refrigeració, les potències instal·lades corresponents a l'altre mode han d'aparèixer =0
                    if app.ProdCalfMWht.Value==0
                       app.PotInsCalfkW.Value=0;
                    end
                    if app.ProdRefrMWht.Value==0
                       app.PotInsRefrkW.Value=0;
                    end
                    if app.Prod_ACSMWht.Value==0
                       app.PotInsACSkW.Value=0;
                    end
                    PotEl_hiv=app.PotInsCalfkW.Value/min(COPm);
                    PotEl_est= app.PotInsRefrkW.Value/min(EERm);
                    PotEl_ACS=app.PotInsACSkW.Value/min(COP_ACSm);
                    PotEl=[PotEl_hiv PotEl_est PotEl_ACS];
                    app.PotElecContrat.Value=max(PotEl); %JGC We choose the maximum electrical power consumption as the value for the electricity contract
                else
                    app.hp_heat.Value = double(info_model(1)/1000);
                    app.COP_hp_heat.Value = double(info_model(2));
                    app.hp_cool.Value = double(info_model(3)/1000);
                    app.EER_hp_cool.Value = double(info_model(4));
                    app.hp_ACS.Value=double(info_model(5)/1000);
                    app.COP_hp_ACS.Value=double(info_model(6));
                    %JGC Ara ja podem utilitzar la capacitat instalada dins del módul econòmic
                    app.PotInsCalfkW.Value=app.hp_heat.Value*divider;% JGC: Total installed power is the product of the nominal capacity times the number of pumps
                    app.PotInsRefrkW.Value=app.hp_cool.Value*divider;
                    app.PotInsACSkW.Value=app.hp_ACS.Value*divider;
                    %JGC: Si només tenim calefacció o refrigeració, les potències instal·lades corresponents a l'altre mode han d'aparèixer =0
                    if app.ProdCalfMWht.Value==0
                       app.PotInsCalfkW.Value=0;
                    end
                    if app.ProdRefrMWht.Value==0
                       app.PotInsRefrkW.Value=0;
                    end
                    if app.Prod_ACSMWht.Value==0
                       app.PotInsACSkW.Value=0;
                    end
                    PotEl_hiv=app.PotInsCalfkW.Value/min(COPm);
                    PotEl_est= app.PotInsRefrkW.Value/min(EERm);
                    PotEl_ACS=app.PotInsACSkW.Value/min(COP_ACSm);
                    PotEl=[PotEl_hiv PotEl_est PotEl_ACS];
                    app.PotElecContrat.Value=max(PotEl); %JGC We choose the maximum electrical power consumption as the value for the electricity contract          
                end
                Pot_El_G= app.PotElecContrat.Value;
                %JGC: Calculate the total electrical energy consumed along the year. 
                app.ConElecMWhe.Value=sum(consum_e_vall)+sum(consum_e_pla)+sum(consum_e_punta); %JGC: [MWhe]
                Consum_e_TOT=app.ConElecMWhe.Value;
                
%=========================================CONSTRUCCIÓ PARÀMETRES PER AEROTÈRMIA I AIRE CONDICIONAT D'AQUÍ CAP AVALL==============================================================
                %JGC: Anàlogament com hem fet per la Geotèrmia, construïrem els valors de capacitat(i) per a una instal·lació alternativa, necessari pel módul econòmic
                capacitat_a=zeros(bin_values_size); %JGC: Potència tèrmica corresponent als moments amb càrregues més desfavorables (sense sol a l'hivern i amb sol a l'estiu)
                capacitat_fava=zeros(bin_values_size); %JGC: Potència tèrmica corresponent als moments amb càrregues més favorables (amb sol a l'hivern i sense sol a l'estiu)
                
                for i = 1:1:bin_values_size(2)
                    if (input_e_summer >0)&&(input_e_winter>0)||(app.UnknownDemand.Value == true)%JGC: si estem en mode càlcul de càrregues automàtic o manual amb CALOR i FRED diferents de zero
                        if bin_values(i) < (T_balanc - 3)
                            if abs(carrega(i))<(info_model(1)*divider) %JGC: Si la càrrega es inferior a la potència instal·lada...
                               capacitat_a(i)=carrega(i); %JGC:... llavors la potència es correspon amb la càrrega
                               capacitat_fava(i)=carrega(i)+carrega_solar(i); %JGC:... i passarà el mateix pel cas d'haver irradiació solar
                            else %JGC: Si la càrrega es superior a la potència instal·lada...
                               capacitat_a(i)=(-1)*info_model(1)*divider; %JGC:... llavors la potència es correspon amb la potència instal·lada
                               if abs(carrega(i)+carrega_solar(i))<(info_model(1)*divider) %JGC: però si la càrrega amb sol és inferior a la pot. instal·lada...
                                  capacitat_fava(i)=carrega(i)+carrega_solar(i); %JGC:... llavors la potència es correspon amb la càrrega amb sol
                               else %JGC: i si la càrrega amb sol encara es superior a la potència instal·lada...
                                  capacitat_fava(i)=(-1)*info_model(1)*divider; %JGC: llavors la potència es correspondrà novament amb la potència instal·lada.
                               end
                            end
                            if (carrega(i)+carrega_solar(i))>0
                               capacitat_fava(i)=0;
%                                capacitat_q(i)=0; %JGC: És una opció anular capacitat amb sol amb capacitat sense sol quan la càrrega és positiva
                            end
                        elseif bin_values(i) > (T_balanc + 3)
                            if carrega(i)<(info_model(3)*divider)
                               capacitat_a(i)=carrega(i);
                               capacitat_fava(i)=carrega(i)-carrega_solar(i);
                            else
                               capacitat_a(i)=info_model(3)*divider;
                               if (carrega(i)-carrega_solar(i))<(info_model(3)*divider)
                                   capacitat_fava(i)=carrega(i)-carrega_solar(i);
                               else
                                   capacitat_fava(i)=info_model(3)*divider;
                               end
                            end
                            if (carrega(i)-carrega_solar(i))<0
                               capacitat_fava(i)=0;
%                                capacitat_a(i)=0; %JGC: És una opció anular capacitat amb sol amb capacitat sense sol quan la càrrega és negativa
                            end
                        else
                            capacitat_a(i)=0;
                            capacitat_fava(i)=0;
                        end        
                    elseif(input_e_summer >0)&&(input_e_winter==0)&&(app.UnknownDemand.Value == false)
                        if bin_values(i) > T_balanc+1
                            if carrega(i)<(info_model(3)*divider)
                               capacitat_a(i)=carrega(i);
                            else
                               capacitat_a(i)=info_model(3)*divider;
                            end
                        else
                            capacitat_a(i)=0;
                        end
                        capacitat_fava(i)=capacitat_a(i);
                    elseif (input_e_summer == 0)&&(input_e_winter > 0)&&(app.UnknownDemand.Value == false)
                        if bin_values(i) < (T_balanc-1)
                            if abs(carrega(i))<(info_model(1)*divider)
                               capacitat_a(i)=carrega(i);
                            else
                               capacitat_a(i)=(-1)*info_model(1)*divider;
                            end
                        else
                            capacitat_a(i)=0;
                        end
                        capacitat_fava(i)=capacitat_a(i);
                    end
                end
                
                %JGC: També establirem valors nominals d'operació per a l'aerotèrmia aire-aigua (AER1) i l'aire condicionat (AER2). 
                %Anem a calcular el COP/EER/COP_ACS en funció d'EAT (Entering Air Temperature)
                COP_AER1=zeros(bin_values_size(2),1);
                COP_AER1ACS=zeros(bin_values_size(2),1);
                EER_AER1=zeros(bin_values_size(2),1);
                EER_AER2=zeros(bin_values_size(2),1);
                
                %JGC: Agafem com a valors de referència COP_AER1(A10W45)=param(124);
                %COP_AER1ACS(A10W65)=param(125) i EER_AER1(W12A35)=param(123)
                %i EER_AER2(A20A35)=param(118)
                
                if app.Dist_TR_h.Value==1
                   COP_2AER1=0.01*param(124)*0.7/0.85; %JGC: Valor COP_AER1 condicions A2W35
                   COP_10AER1=0.01*param(124)/0.85; %JGC: Valor COP_AER1 condicions A10W35
                   COP_2AER1ACS=0.01*param(125)*0.70; %JGC: Valor COP_AER1ACS condicions A2W65
                   COP_10AER1ACS=0.01*param(125);%JGC: Valor COP_AER1ACS condicions A10W65
                elseif app.Dist_FC_h.Value==1
                   COP_2AER1=0.01*param(124)*0.75; %JGC: Valor COP_AER1 condicions A2W45
                   COP_10AER1=0.01*param(124); %JGC: Valor COP_AER1 condicions A10W45
                   COP_2AER1ACS=0.01*param(125)*0.75; %JGC: Valor COP_AER1ACS condicions A2W65
                   COP_10AER1ACS=0.01*param(125);%JGC: Valor COP_AER1ACS condicions A10W65
                elseif app.Dist_RR_h.Value==1
                   COP_2AER1=0.01*param(124)*0.75*0.8; %JGC: Valor COP_AER1 condicions A2W55
                   COP_10AER1=0.01*param(124)*0.8; %JGC: Valor COP_AER1 condicions A10W55
                   COP_2AER1ACS=0.01*param(125)*0.75; %JGC: Valor COP_AER1ACS condicions A2W65
                   COP_10AER1ACS=0.01*param(125);%JGC: Valor COP_AER1ACS condicions A10W65
                end
                if app.Dist_TR_c.Value==1
                   EER_35AER1=0.01*param(123)/0.75; %JGC: Valor EER_AER1 condicions A35W23
                   EER_25AER1=0.01*param(123)/0.75/0.78; %JGC: Valor EER_AER1 condicions A25W23
                elseif app.Dist_FC_c.Value==1
                   EER_35AER1=0.01*param(123); %JGC: Valor EER_AER1 condicions W12A35
                   EER_25AER1=0.01*param(123)/0.78; %JGC: Valor EER_AER1 condicions W12A25
                end
                EER_35AER2=0.01*param(118); %JGC Valor EER_AER2 condicions A27A35
                EER_25AER2=0.01*param(118)/0.80; %JGC Valor EER_AER2 condicions A27A25
                
                for i=1:1:bin_values_size(2)
                    %JGC: EStablim les corbes de COP i COP_ACS en funció d'EAT
                    COP_AER1(i)= COP_2AER1+(bin_values(i)-2)*(COP_10AER1-COP_2AER1)/(10-2);
                    COP_AER1ACS(i)= COP_2AER1ACS+(bin_values(i)-2)*(COP_10AER1ACS-COP_2AER1ACS)/(10-2);
                    EER_AER1(i)=EER_25AER1+(bin_values(i)-25)*(EER_35AER1-EER_25AER1)/(35-25);
                    EER_AER2(i)=EER_25AER2+(bin_values(i)-25)*(EER_35AER2-EER_25AER2)/(35-25);
                end
                COP_AER1m=zeros(12,1);
                EER_AER1m=zeros(12,1);
                COP_AER1ACSm=zeros(12,1);
                EER_AER2m=zeros(12,1);
                for j=1:1:12
                   COP_AER1m0=0;
                   hores_AER1hm0=0;
                   EER_AER1m0=0;
                   EER_AER2m0=0;
                   hores_AERcm0=0;
                   COP_AER1ACSm0=0;
                   for i=1:1:bin_values_size(2)
                       %JGC:Per al càlcul de COP_ACSm tinc en compte totes les T_bin, perquè la producció o no d'ACS no en depén.
                       COP_AER1ACSm0=COP_AER1ACSm0+COP_AER1ACS(i)*thermal_bin_monthly(i,j);
                       %JGC:En canvi, el COP només té enc compte hores de calefacció, i l'EER de refrigeració
                       if bin_values(i)<(T_balanc-3)
                           COP_AER1m0=COP_AER1m0+COP_AER1(i)*thermal_bin_monthly(i,j);
                           hores_AER1hm0=hores_AER1hm0+thermal_bin_monthly(i,j);
                       elseif bin_values(i)>(T_balanc+3)
                           EER_AER1m0=EER_AER1m0+EER_AER1(i)*thermal_bin_monthly(i,j);
                           EER_AER2m0=EER_AER2m0+EER_AER2(i)*thermal_bin_monthly(i,j);
                           hores_AERcm0=hores_AERcm0+thermal_bin_monthly(i,j);
                       end  
                   end
                   if hores_AER1hm0==0 %JGC: en el hipotètic cas que la suma de les hores d'un mes en particular fos nula, no podem deixar un valor nul del COP
                      COP_AER1m(j)=0.01*param(124);
                   else
                      COP_AER1m(j)=COP_AER1m0/hores_AER1hm0;
                   end
                   if hores_AERcm0==0
                      EER_AER1m(j)=0.01*param(123);
                      EER_AER2m(j)=0.01*param(118);
                   else
                      EER_AER1m(j)=EER_AER1m0/hores_AERcm0;
                      EER_AER2m(j)=EER_AER2m0/hores_AERcm0;
                   end
                   COP_AER1ACSm(j)=COP_AER1ACSm0/sum(transpose(thermal_bin_monthly(:,j)));
                end
                MetaConsumAER1m=zeros(12,3);
                MetaConsumAER2m=zeros(12,1);
                for j=1:1:12
                    MetaConsumAER1m(j,1)=Prod(j,1)/COP_AER1m(j);
                    MetaConsumAER1m(j,2)=Prod(j,2)/EER_AER1m(j); %JGC: signo negativo
                    MetaConsumAER1m(j,3)=Prod(j,3)/COP_AER1ACSm(j);
                    MetaConsumAER2m(j)=Prod(j,2)/EER_AER2m(j); %JGC: signo negativo
                end
                %JGC: Haig de considerar el cas en que no tinguem consum producció de calor, fred o ACS a l'hora de calcular els
                %hipotètics rendiments de les bombes de calor AER1 i AER2
                if sum(MetaConsumAER1m(:,1))==0
                    SPFCalfAER1=mean(COP_AER1m);
                else
                    SPFCalfAER1=sum(Prod(:,1))/sum(MetaConsumAER1m(:,1));
                end
                if sum(MetaConsumAER1m(:,2))==0
                    SPFRefrAER1=mean(EER_AER1m);
                else
                    SPFRefrAER1=sum(Prod(:,2))/sum(MetaConsumAER1m(:,2));
                end
                if sum(MetaConsumAER1m(:,3))==0
                    SPFACSAER1=mean(COP_AER1ACSm);
                else
                    SPFACSAER1=sum(Prod(:,3))/sum(MetaConsumAER1m(:,3));
                end
                if sum(MetaConsumAER2m)==0
                    SPFRefrAER2=mean(EER_AER2m);
                else
                   SPFRefrAER2=sum(Prod(:,2))/sum(MetaConsumAER2m); 
                end               
                
%===========================================CONSTRUCCIÓ PARÀMETRES PER AEROTÈRMIA I AIRE CONDICIONAT D'AQUÍ CAP AMUNT=======================================================================


                %JGC: Potències instal·lades en Geotèrmia:
                P_inshG=app.PotInsCalfkW.Value;
                P_inscG=app.PotInsRefrkW.Value;
                P_insACSG=app.PotInsACSkW.Value;
                
                %JGC: Potències a considerar per a instal·lar en instal·lació Alternativa
                P_inshA=P_inshG;
                P_inscA=P_inscG;
                P_insACSA=app.PotDis_ACSkW.Value;
                
                %JGC: Construïm la corba d'irradiació mensual a 45º per a produïr ACS amb Col·lectors solars tèrmics, eventualment
                Irr45_ST=rough_Irr45_ST(thermal_case,:); %JGC: [Wh/m2/mes] Irradiació promig global calculada amb panells inclinats 45º per al clima de referència en qüestió.
                Eff_ST=0.3; %JGC: valor eficiència promig dels Col·lectors solars tèrmics (valor conservador)
                Area_ST=0.6*produccio_ACS*1e6/(Eff_ST*sum(Irr45_ST));%JGC: [m2] "0.6" fa referència a la obligació de cobrir al menys un 60% per casos de <5000l/dia.
                app.AreaMinST.Value=Area_ST;
                
                %JGC fem un càlcul amb els valors per defecte del cost inicial d'inversió de la instal·lació Geotèrmica
                P_insG=app.hp_heat.Value*divider; %JGC El valor de potència (a efectes de càlcul del cost) serà la corresponent al mode calefacció
                P_insA=P_insG;
                CAPEXBCG=app.CostEspecificGeo.Value*P_insG; %JGC [€] cost equip BCG
                CAPEXInsGeo=app.CostEspecInstGeo.Value*P_insG;%JGC [€] cost instal·lació (tancs d'inèrcia, canonades, etc.)
                CAPEXpous=app.CostEspecificPous.Value*n_pozos*L_drill_pozo; %JGC [€] cost camp de captació (pous, sondes, accessos, etc.)
                app.CAPEXGeo.Value=CAPEXBCG+CAPEXInsGeo+CAPEXpous; %JGC [€] posem a la cel·la no editable el valor inicial del CAPEX de la inst. Geotèrmica                
                
                % JGC: Al panell econòmic, les opcions alternatives estaran actives només si el valor de demanda corresponent és diferent de zero
                Op_h=app.OpcionsAlternativa_h.Value;
                Op_c=app.OpcionsAlternativa_c.Value;
                Op_ACS=app.OpcionsAlternativa_ACS.Value;
                 
                %JGC fem el càlcul inicial dels consums elèctrics de les alternatives
                if Op_h=="Biomassa"
                    cons_e_biom=param(8); %JGC: [kWe/kWt] potència elèctrica específica d'una caldera de biomassa
                    app.ConElecAlthMWhe.Value=app.ProdCalfMWht.Value*cons_e_biom; %JGC: [MWhe] Consum elèctric anual de la Caldera de Biomassa (Calf., elecció per defecte)
                    app.PotElecAlthkW.Value=P_inshA*cons_e_biom; %JGC: [kWe] Potència elèctria contractada corresponent a la caldera de biomassa (porció atribuïble)
                elseif (Op_h=="Gas Natural")||(Op_h=="Gas Propà")||(Op_h=="Gas Propà Canalitzat")||(Op_h=="Gasoil")
                    app.ConElecAlthMWhe.Value=0;
                    app.PotElecAlthkW.Value=0;
                elseif (Op_h=="Electricitat")
                    COP_EP=0.01*param(114); %JGC: eficiència d'un sistema elèctric de dissipació per efecte joule.
                    app.ConElecAlthMWhe.Value=app.ProdCalfMWht.Value/COP_EP; %JGC: consum elèctric corresponent a un sistema electric de dissipació per efecte Joule
                    app.PotElecAlthkW.Value=P_inshA/COP_EP;
                else
                    Cons_e_AER1h=zeros(12,1);
                    for j=1:1:12
                        Cons_e_AER1h(j)=produccio_month_plot_h(j)/COP_AER1m(j);
                    end
                    app.ConElecAlthMWhe.Value=sum(Cons_e_AER1h); %JGC: consum elèctric corresponent a un sistema d'Aerotèrmia aire-aigua
                    app.PotElecAlthkW.Value=P_inshA/min(COP_AER1m);
                end
                if Op_c=="Aire Condicionat"
                    Cons_e_AER2c=zeros(12,1);
                    for j=1:1:12
                        Cons_e_AER2c(j)=abs(produccio_month_plot_c(j)/EER_AER2m(j));
                    end
                    app.ConElecAltcMWhe.Value=sum(Cons_e_AER2c);%JGC: [MWhe] Consum elèctric anual de l'Aire Condicionat (elecció per defecte)
                    app.PotElecAltckW.Value=P_inscA/min(EER_AER2m(3:10)); %JGC: [kWe] Potència elèctria contractada corresponent a l'Aire Condicionat (porció atribuïble)
                else
                    Cons_e_AER1c=zeros(12,1);
                    for j=1:1:12
                        Cons_e_AER1c(j)=abs(produccio_month_plot_c(j)/EER_AER1m(j));
                    end
                    app.ConElecAltcMWhe.Value=sum(Cons_e_AER1c); %JGC: consum elèctric corresponent a un sistema d'Aerotèrmia aire-aigua
                    app.PotElecAltckW.Value=P_inscA/min(EER_AER1m(3:10)); %JGC: [kWe] Potència elèctria contractada corresponent a l'Aerotèrmia
                end
                if Op_ACS=="Col·lectors Solars Tèrmics"
                    cons_e_ST=param(15);
                    if (P_inshA>0)&&(Op_h=="Electricitat")
                        app.ConElecAltACSMWhe.Value=0.6*app.Prod_ACSMWht.Value*cons_e_ST+0.4*app.Prod_ACSMWht.Value/(0.01*param(114));
                    else
                        app.ConElecAltACSMWhe.Value=app.Prod_ACSMWht.Value*cons_e_ST; %JGC: [MWhe] Consum elèctric anual dels col·lectors solars tèrmics
                    end
                    app.PotElecAltACSkW.Value=P_insACSA*cons_e_ST; 
                elseif Op_ACS=="Biomassa"
                    cons_e_biom=param(8); %JGC: [kWe/kWt] potència elèctrica específica d'una caldera de biomassa
                    if (P_inshA>0)&&(Op_h=="Electricitat")
                         app.ConElecAltACSMWhe.Value=0.6*app.Prod_ACSMWht.Value*cons_e_biom+0.4*app.Prod_ACSMWht.Value/(0.01*param(114));
                    else
                         app.ConElecAltACSMWhe.Value=app.Prod_ACSMWht.Value*cons_e_biom; %JGC: [MWhe] Consum elèctric anual de la Caldera de Biomassa (ACS., elecció per defecte)
                    end
                    app.PotElecAltACSkW.Value=P_insACSA*cons_e_biom; %JGC: [kWe] Potència elèctria contractada corresponent a la caldera de biomassa (porció atribuïble)
                else
                    Cons_e_AER1ACS=zeros(12,1);
                    for j=1:1:12
                        Cons_e_AER1ACS(j)=(1e-6)*produccio_month_plot_ACS(j)/COP_AER1ACSm(j);
                    end
                    app.ConElecAltACSMWhe.Value=sum(Cons_e_AER1ACS); %JGC: consum elèctric corresponent a un sistema d'Aerotèrmia aire-aigua
                    app.PotElecAltACSkW.Value=P_insACSA/min(COP_AER1ACSm);
                end

                %JGC: Què passa amb les opcions alternatives en funció dels resultats i dels valors existents. L'execució de la present rutina no farà cap selecció per
                %defecte. D'aquesta manera, si ja hem fet un disseny de la instal·lació alternativa abans, no s'ha de tornar a fer al fer correr aquesta rutina una altra vegada
                if  app.SwitchTipusProjecte.Value=="Nova instal·lació"
                    Nova=1;
                else
                    Nova=0;
                end  
                if app.PotInsCalfkW.Value>70
                    alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                    uialert(alert_fig,'El disseny que fa Geo-SIV no és confiable per valors de potències tèrmiques instal·lades superiors a 70 kW. Es recomana revisar les dades d''entrada. ','Missatge d''avís','Icon','warning')
                end
                %JGC: Comencem amb que totes les cel·les relacionades amb els preus dels combustibles estan inactives, menys les de l'electricitat
                app.CostkWVall.Enable=1;
                app.CostkWPlaPunta.Enable=1;
                app.CostkWhVall.Enable=1;
                app.CostkWhPla.Enable=1;
                app.CostkWhPunta.Enable=1;
                app.Infl_AnualFix_e.Enable=1;
                app.Infl_AnualVar_e.Enable=1;
                app.CostkgBiom.Enable=0;
                app.Infl_AnualVar_Biom.Enable=0;
                app.CostkWhGN.Enable=0;
                app.CostGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkgGC.Enable=0;
                app.CostGC.Enable=0;
                app.CostGC.Value=0;
                app.Infl_AnualVar_GC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0;
                if (app.ProdCalfMWht.Value==0)
                   app.CAPEXAlth.Value=0;
                   app.OpcionsAlternativa_h.Enable=0;
                   app.CostEscomesa.Visible=0;
                   app.CostEscomesa.Enable=0;
                   app.Label_CostEscomesa.Visible=0;
                   app.CostEspecInstAlt_h.Enable=0;
                   app.CostEspecificAlt_h.Enable=0;
                   app.MantAlt_h.Enable=0;
                   app.OpcionsAlternativa_c.Enable=1;
                   app.CostEspecInstAlt_c.Enable=1;
                   app.CostEspecificAlt_c.Enable=1;
                   app.MantAlt_c.Enable=1; 
                   if Op_c=="Aerotèrmia"
                      app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                   else
                      app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aire condicionat per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER2,'%.2f')];
                   end
                   if (Op_c=="Aerotèrmia")||(app.Prod_ACSMWht.Value==0)
                      app.OpcionsAlternativa_ACS.Enable=0;
                      app.CostEspecificAlt_ACS.Enable=0;
                      app.CostEspecInstAlt_ACS.Enable=0;
                      app.MantAlt_ACS.Enable=0;
                      app.CAPEXAltACS.Value=0;
                      app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA,P_insACSA);
                   else
                      app.OpcionsAlternativa_ACS.Enable=1;
                      app.CostEspecInstAlt_ACS.Enable=1;
                      app.CostEspecificAlt_ACS.Enable=1;
                      app.MantAlt_ACS.Enable=1; 
                      app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                      if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                         app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;%+CAPEXPVAlt;
                         CostAltACSsup=0;
                         if Op_ACS=="Biomassa"
                            app.CostkgBiom.Enable=1;
                            app.Infl_AnualVar_Biom.Enable=1;
                         end
                      else 
                         if app.Prod_ACSMWht.Value<5 %JGC: Tarifa 3.1 GN
                            Escomesa=param(20);
                            Inst_esp=param(21);
                            Cald_esp=param(22);
                         elseif (app.Prod_ACSMWht.Value>=5)&&(app.Prod_ACSMWht.Value<15) %JGC: Tarifa 3.2(a) GN
                            Escomesa=param(26);
                            Inst_esp=param(27);
                            Cald_esp=param(28);
                         elseif (app.Prod_ACSMWht.Value>=15)&&(app.Prod_ACSMWht.Value<50) %JGC: Tarifa 3.2(b) GN
                            Escomesa=param(32);
                            Inst_esp=param(33);
                            Cald_esp=param(34);
                         elseif (app.Prod_ACSMWht.Value>=50)&&(app.Prod_ACSMWht.Value<100) %JGC: Tarifa 3.3 GN
                            Escomesa=param(38);
                            Inst_esp=param(39);
                            Cald_esp=param(40);
                         elseif (app.Prod_ACSMWht.Value>=100) %JGC: Tarifa 3.4 GN
                            Escomesa=param(44);
                            Inst_esp=param(45);
                            Cald_esp=param(46);
                         end
                         CostAltACSsup=Escomesa+(Inst_esp+Cald_esp)*P_insACSA; %JGC: Asumim que la instal·lació de suport en aquest cas és Gas Natural
                      end
                      app.CAPEXAltACS.Value=Nova*((app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST)+CostAltACSsup;   
                   end
                else % JGC: calor>0
                   app.OpcionsAlternativa_h.Enable=1;
                   app.CostEspecInstAlt_h.Enable=1;
                   app.CostEspecificAlt_h.Enable=1;
                   app.MantAlt_h.Enable=1;
                   if Op_h=="Aerotèrmia"
                      app.Label_EcoAmbObs3.Text=['Es considera un sistema d''aerotèrmia per a producció de CALOR (i FRED i/o ACS) amb un rendiment estacional SPFc = ' num2str(SPFCalfAER1,'%.2f') ' , SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                      app.Label_EcoAmbObs4.Text='';
                      app.OpcionsAlternativa_c.Enable=0;
                      app.CostEspecInstAlt_c.Enable=0;
                      app.CostEspecificAlt_c.Enable=0;
                      app.MantAlt_c.Enable=0;
                      app.OpcionsAlternativa_ACS.Enable=0;
                      app.CostEspecificAlt_ACS.Enable=0;
                      app.CostEspecInstAlt_ACS.Enable=0;
                      app.MantAlt_ACS.Enable=0;
                      app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                      app.CAPEXAltc.Value=0;
                      app.CAPEXAltACS.Value=0;
                   elseif Op_h=="Biomassa"% JGC: calor>0;
                      app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de biomassa per a la producció de CALOR (i ACS) amb un rendiment del ' num2str(param(7)) ' %'];
                      app.OpcionsAlternativa_ACS.Enable=0;
                      app.CostEspecificAlt_ACS.Enable=0;
                      app.CostEspecInstAlt_ACS.Enable=0;
                      app.MantAlt_ACS.Enable=0;
                      app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                      app.CAPEXAltACS.Value=0;
                      app.CostkgBiom.Enable=1;
                      app.Infl_AnualVar_Biom.Enable=1;
                      if app.ProdRefrMWht.Value==0
                         app.Label_EcoAmbObs4.Text='';
                         app.OpcionsAlternativa_c.Enable=0;
                         app.CostEspecInstAlt_c.Enable=0;
                         app.CostEspecificAlt_c.Enable=0;
                         app.MantAlt_c.Enable=0;
                         app.CAPEXAltc.Value=0;
                      else
                         if Op_c=="Aerotèrmia"
                             app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f')];
                         else
                             app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aire condicionat per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER2,'%.2f')];
                         end
                         app.OpcionsAlternativa_c.Enable=1;
                         app.CostEspecInstAlt_c.Enable=1;
                         app.CostEspecificAlt_c.Enable=1;
                         app.MantAlt_c.Enable=1;
                         app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                      end      
                   else % JGC:calor>0; Op_h no és "Biomassa" ni "Aerotèrmia"
                      if Op_h=="Gas Natural"
                          app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de condensació de gas natural per a la producció de CALOR (i suport ACS) amb un rendiment del ' num2str(param(18)) ' %'];
                          app.CostEscomesa.Visible=1;
                          app.CostEscomesa.Enable=1;
                          app.Label_CostEscomesa.Visible=1;
                          app.CostkWhGN.Enable=1;
                          app.CostGN.Enable=1;
                          app.Infl_AnualVar_GN.Enable=1;
                          app.Infl_AnualFix_GN.Enable=1;
                      elseif Op_h=="Gas Propà"
                          app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de condensació de gas propà per a la producció de CALOR (i suport ACS) amb un rendiment del ' num2str(param(52)) ' %'];
                          app.CostkgGC.Enable=1;
                          app.Infl_AnualVar_GC.Enable=1;
                      elseif Op_h=="Gas Propà Canalitzat"
                          app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de condensació de gas propà per a la producció de CALOR (i suport ACS) amb un rendiment del ' num2str(param(52)) ' %'];
                          app.CostEscomesa.Visible=1;
                          app.CostEscomesa.Enable=1;
                          app.Label_CostEscomesa.Visible=1;
                          app.CostGC.Enable=1;
                          app.CostkgGC.Enable=1;
                          app.Infl_AnualFix_GC.Enable=1;
                          app.Infl_AnualVar_GC.Enable=1;
                      elseif Op_h=="Gasoil"
                          app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de gasoil per a la producció de CALOR (i suport ACS) amb un rendiment del ' num2str(param(100)) ' %'];
                          app.CostlGasoil.Enable=1;
                          app.Infl_AnualVar_Gasoil.Enable=1;   
                      end
                      if app.ProdRefrMWht.Value==0
                         app.Label_EcoAmbObs4.Text='';
                         app.OpcionsAlternativa_c.Enable=0;
                         app.CostEspecInstAlt_c.Enable=0;
                         app.CostEspecificAlt_c.Enable=0;
                         app.MantAlt_c.Enable=0;
                         app.CAPEXAltc.Value=0;
                         app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                         if app.Prod_ACSMWht.Value==0
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Enable=0;
                            app.CAPEXAltACS.Value=0;
                         else
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                               if Op_ACS=="Biomassa"
                                  app.CostkgBiom.Enable=1;
                                  app.Infl_AnualVar_Biom.Enable=1;
                               end
                            else
                               app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                            end
                         end
                      else % JGC:calor>0; fred>0 Op_h no és "Biomassa" ni "Aerotèrmia"
                         if Op_c=="Aerotèrmia"
                             app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f')];
                         else
                             app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aire condicionat per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER2,'%.2f')];
                         end
                         app.OpcionsAlternativa_c.Enable=1;
                         app.CostEspecInstAlt_c.Enable=1;
                         app.CostEspecificAlt_c.Enable=1;
                         app.MantAlt_c.Enable=1;
                         if (app.Prod_ACSMWht.Value==0)||(Op_c=="Aerotèrmia")
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Enable=0;
                            app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA,P_insACSA);
                            app.CAPEXAltACS.Value=0;
                         else %JGC:calor>0; fred>0; ACS>0, Op_h no és "Biomassa" ni "Aerotèrmia"; Op_c no és "Aerotèrmia"
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                               if Op_ACS=="Biomassa"
                                  app.CostkgBiom.Enable=1;
                                  app.Infl_AnualVar_Biom.Enable=1;
                               end
                            else
                               app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                            end
                         end
                      end
                   end
                end
                app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
                app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
                
                %JGC: Ara activem totes les cel·les i opcions necessàries al tab Anàlisi econòmica i ambiental
                app.SwitchTipusProjecte.Enable=1;
                app.vidaprojecte.Enable=1;
                app.Taxadescompte.Enable=1;
                app.Infl_general.Enable=1;
                app.CostEspecificPous.Enable=1;
                app.CostEspecInstGeo.Enable=1;
                app.CostEspecificGeo.Enable=1;
                app.MantGeo.Enable=1;
                app.GeoSubv.Enable=1;
                app.GeoPres.Enable=1;
                app.AltSubv.Enable=Nova;
                app.AltPres.Enable=Nova;
                app.AltPres.Enable=1;
                app.AltHibPV.Enable=1;
                app.GeoHibPV.Enable=1;
%                 app.Label_EcoAmbObs3.Text='';
%                 app.Label_EcoAmbObs4.Text='';
                app.UIplotFluxeVAN.cla;
                app.MonthPlotGeo.cla;
                app.MonthPlotAlt.cla;

                %JGC: hem de posar un límit inferior a l'àrea disponible si es vol estudiar una possible hibridació amb PV
                %JGC: Per això assumim que hem de poder cobrir l'1% del consum de la geotèrmia amb 5 kWh/m2/any amb un factor
                %d'ocupació espacial de 2.5 i una efficiència dels panells del 10%
                LimS_insPV=ceil(2.5*app.ConElecMWhe.Value*1000*0.01/(365*5*0.10));
                app.PV_area.Limits=[LimS_insPV Inf];
                app.PV_area.Value=LimS_insPV;
                Prod_e=365*5*LimS_insPV*0.1; %JGC [kWh/any] Estimació de la producció eléctrica anual amb panells.
                %JGC: També hem d'establir els límits superiors de la cobertura del màxima del consum elèctric atribuïble als panells
                Lim_ratioPVGeo=round(100*Prod_e/(app.ConElecMWhe.Value*1000),2); %JGC: [%] percentatge màxim de consum elèctric de la instal·lació Geotèrmica que podria cobrir-se amb panells.
                Cons_e_Alt=1000*(app.ConElecAltcMWhe.Value+app.ConElecAlthMWhe.Value+app.ConElecAltACSMWhe.Value);%JGC: [kWh]
                if Cons_e_Alt==0
                    app.AltHibPV.Value=0;
                    app.AltHibPV.Enable=0;
                    app.AltBat.Value=0;
                    app.AltBat.Enable=0;
                    app.HibPVAlt_cob.Enable=0;
                    app.PVAltEneBat.Enable=0;
                    app.AltkWpPV.Enable=0;
                    app.Altm2PV.Enable=0;
                    app.PVAltCostInv.Enable=0;
                    app.PVAltCostInv.Value=0;
                    if app.GeoHibPV.Value==1
                        app.PV_area.Enable=1;
                        app.PV_cost.Enable=1;
                        app.PV_eff.Enable=1;
                        app.Retrib_excdPV.Enable=1;
                        if app.GeoBat.Value==1
                            app.CostEspecBat.Enable=1;
                            app.Ch_DisCh_EffBat.Enable=1;
                            app.DoD_Bat.Enable=1;
                        else
                            app.CostEspecBat.Enable=0;
                            app.Ch_DisCh_EffBat.Enable=0;
                            app.DoD_Bat.Enable=0;
                        end
                        app.PVGeoCostInv.Enable=1;     
                    else 
                        app.PV_area.Enable=0;
                        app.PV_cost.Enable=0;
                        app.PV_eff.Enable=0;
                        app.Retrib_excdPV.Enable=0;
                        app.CostEspecBat.Enable=0;
                        app.Ch_DisCh_EffBat.Enable=0;
                        app.DoD_Bat.Enable=0;
                        app.PVAltCostInv.Enable=0;
                    end
                    Lim_ratioPVAlt=1e-5;%JGC: poso això per a que no em doni problemes l'app
                else
                   Lim_ratioPVAlt=ceil(100*Prod_e/Cons_e_Alt);
                   app.AltHibPV.Enable=1;
                end
                app.HibPVGeo_cob.Limits=[0 Lim_ratioPVGeo]; 
                app.HibPVAlt_cob.Limits=[0 Lim_ratioPVAlt];
                 
                %finished with this, the number of days requiring heating or cooling is calculated
                app.RunEcoAmb.Enable=1;
                
                %JGC: Escrivim valors a la Tab "Resum"
                %JGC: Panell Demanda
                txtDem1=num2str(app.PotInsCalfkW.Value,'%.1f');
                txtkW=' kW';
                app.CapacitatCalf.Text=[txtDem1 txtkW];
                txtDem2=num2str(app.PotInsRefrkW.Value,'%.1f');
                app.CapacitatRefr.Text=[txtDem2 txtkW];
                txtDem3=num2str(app.PotInsACSkW.Value,'%.1f');
                app.Capacitat_ACS.Text=[txtDem3 txtkW];
                txtDem4=num2str(round(1000*app.ProdCalfMWht.Value,0));
                txtkWh=' kWh';
                app.DemandaCalf.Text=[txtDem4 txtkWh];
                txtDem5=num2str(round(1000*app.ProdRefrMWht.Value,0));
                app.DemandaRefr.Text=[txtDem5 txtkWh];
                txtDem6=num2str(round(1000*app.Prod_ACSMWht.Value,0));
                app.Demanda_ACS.Text=[txtDem6 txtkWh];
                txtDem6a=num2str(app.SPF_Calf.Value,'%.2f');
                app.SPFcalefaccio.Text=['SPFc = ' txtDem6a];
                txtDem6b=num2str(app.SPF_Refr.Value,'%.2f');
                app.SPFrefrigeracio.Text=['SPFr = ' txtDem6b];
                txtDem6c=num2str(app.SPF_ACS.Value,'%.2f');
                app.SPFacs.Text=['SPFacs = ' txtDem6c];
                txtDem7=num2str(app.PotElecContrat.Value,'%.1f');
                app.PotenciaeContratada.Text=[txtDem7 txtkW];
                txtDem8=num2str(round(1000*app.ConElecMWhe.Value,0));
                app.ConsumeTotal.Text=[txtDem8 txtkWh];
                %JGC:Panell Camp de Captació:
                txtpous1='Seria necessari perforar ';
                txtpous2=num2str(app.n_well.Value);
                if app.n_well.Value==1
                    txtpous3=' pou de ';
                    txtpous4=num2str(round(app.l_well.Value,0));
                    txtpous5=' m.';
                    txtpous6='';
                    txtpous7='La superfície requerida estimada és de  ';
                    txtpous8=num2str(app.m2_well.Value);
                    txtpous9=' m2.';
                    txtpous10='';
                    txtpous11='';
                else
                    txtpous3=' pous de ';
                    txtpous4=num2str(round(app.l_well.Value,0));
                    txtpous5=' m cadascún,';                    
                    txtpous6=' amb una distància entre';
                    txtpous7='perforacions de ';
                    txtpous8=num2str(round(app.d_well.Value,1));
                    txtpous9=' m. La superfície requerida estimada és de ';
                    txtpous10=num2str(app.m2_well.Value);
                    txtpous11=' m2.';
                end
                app.PousInfo1_wrt.Text=[txtpous1 txtpous2 txtpous3 txtpous4 txtpous5 txtpous6];
                app.PousInfo2_wrt.Text=[txtpous7 txtpous8 txtpous9 txtpous10 txtpous11];
                txtpous12='Sondes tipus "U"';
                if app.Usimple.Value==1
                    txtpous13=' simple.'; 
                else
                    txtpous13=' doble.';
                end
                txtpous14=num2str(round(app.n_well.Value*app.l_well.Value,0));
                txtpous15=' Serien necessaris ';
                txtpous16=' m de sonda.';
                app.SondesInfo_wrt.Text=[txtpous12 txtpous13 txtpous15 txtpous14 txtpous16];
                txtpous17='Polietilè d''alta densitat (HDPE), Ø_ext ';
                if app.mm32.Value==1
                    txtpous18='32 mm i gruix de 2.9 mm.';
                else
                    txtpous18='40 mm i gruix de 3.6 mm.';
                end
                app.MaterialsInfo_wrt.Text=[txtpous17 txtpous18];
                
                %JGC:Activem el botó de generar informe
                app.Informe_disseny.Enable=1;
                app.InformeTOT.Enable=0; %JGC: quan executem la rutina de dimensionament de camp de captació, inactivem la inclusió de resultats económics
                %i ambientals a l'informe fins que no executem aquesta l'analisi econòmica i ambiental, precisament.
                
                %JGC: Establim els valors per a que estiguin accessibles fora d'aquest Callback
                completely_executed = 1;
                setappdata(0,'executable',completely_executed);
                %JGC: per al gràfic de càrrega/capacitat i demanda/producció
                if app.SwitchCAT_Gir.Value=="Catalunya"
                    ambit=1;
                else
                    ambit=0;
                end
                setappdata(0,'ambit_app',ambit);
                setappdata(0,'valors_carrega',carrega_plot);
                setappdata(0,'valors_capacitat',capacitat_plot);
                setappdata(0,'valors_demanda',demanda_plot);
                setappdata(0,'valors_produccio',produccio_plot);
                setappdata(0,'eix_temperatura',bin_values);
                %JGC: per al gràfic de consum energia/electricitat per mes
                setappdata(0,'valors_prod', Prod);
                setappdata(0,'valors_elec', consum_e_plot);
                setappdata(0,'eix_mes', MonthAxe);              
                setappdata(0,'rl_pou',L_drill_pozo);
                setappdata(0,'rn_pou',n_pozos);
                setappdata(0,'r_criterio',criterio);
                setappdata(0,'r_des_q_w',q_des_winter);
                setappdata(0,'r_des_q_s',q_des_summer);
                setappdata(0,'r_des_q_ACS',q_des_ACS);
                produccio_fred=abs(produccio_fred);
                setappdata(0,'r_d_cool',produccio_fred);
                setappdata(0,'r_d_heat',produccio_calor);
                setappdata(0,'r_d_ACS',produccio_ACS);
                setappdata(0,'dies_heat',counted_dheat);
                setappdata(0,'dies_cool',counted_dcool);
                setappdata(0,'Pot_h_G',P_inshG);
                setappdata(0,'Pot_c_G',P_inscG);
                setappdata(0,'Pot_ACS_G',P_insACSG);
                setappdata(0,'Pot_G',P_insG);
                setappdata(0,'ID_r',user_pump);
                setappdata(0,'divider_r',divider);
                setappdata(0,'pump_data_r',info_model);
                setappdata(0,'model_r',model_selected);
                setappdata(0,'carga_r',demanda_e_total);
%                 setappdata(0,'t_wat',entering);
                setappdata(0,'verification_r',all_fine_3);
                setappdata(0,'nominal_r',d_o_pipe);
                setappdata(0,'eco_t',thermal_imbalance);
                setappdata(0,'x_sitio',input_utm_x);
                setappdata(0,'y_sitio',input_utm_y);
                setappdata(0,'z_sitio',lect_height);
                setappdata(0,'clima_refer',text_climaref);
                setappdata(0,'provinci',lect_provin);
                setappdata(0,'thermal_tipo',conoce_datos);
                setappdata(0,'ground_dec',store_decission);
                setappdata(0,'ground_T',T_ground_undist);
                setappdata(0,'dato_difu',lect_difu);
                setappdata(0,'dato_cdty',lect_cdty);
                setappdata(0,'carga_por_m2',demanda_en_superficie);
                setappdata(0,'uso_para_r',selector_use);
                setappdata(0,'geom_para_r',selector_geometry);
                setappdata(0,'s_para_r',s_area);
                setappdata(0,'z_para_r',z_floor);
                setappdata(0,'f_para_r',f_window);
                setappdata(0,'FGS_hivern',SCh);
                setappdata(0,'FGS_estiu',SCc);
                setappdata(0,'t_para_rh',T_in_h);
                setappdata(0,'t_para_rc',T_in_c);
                setappdata(0,'ocup_para_r',max_ocup);
                setappdata(0,'T_ground_corr',Corregir_Tg);
                setappdata(0,'desbal_para_r',desbal);
                setappdata(0,'solo_cool',demanda_e_c);
                setappdata(0,'solo_cool_use_3',input_e_summer);
                setappdata(0,'solo_heat_use_3',input_e_winter);
                setappdata(0,'solo_heat',demanda_e_h);
                setappdata(0,'AreaColetorsST',Area_ST);
                setappdata(0,'Irr45_ST',Irr45_ST);
                setappdata(0,'Temp_Balanc',T_balanc);
                setappdata(0,'us',use_case);
                setappdata(0,'d_well',d_entre_sondes);
                setappdata(0,'s_well',area_pous);
                setappdata(0,'VarT_est',Var_Tg_num);
                setappdata(0,'VarT_anys',t_estable_num);
                setappdata(0,'t_max_pous',t_ewt_max);
                setappdata(0,'t_min_pous',t_ewt_min);
                setappdata(0,'EWTnom_h',EWTnom_h);
                setappdata(0,'LWTnom_h',LWTnom_h);
                setappdata(0,'EWTnom_c',EWTnom_c);
                setappdata(0,'LWTnom_c',LWTnom_c);
                setappdata(0,'EWTnom_ACS',EWTnom_ACS);
                setappdata(0,'LWTnom_ACS',LWTnom_ACS);
                setappdata(0,'COPm',COPm);
                setappdata(0,'EERm',EERm);
                setappdata(0,'COP_ACSm',COP_ACSm);
                setappdata(0,'COP_AER1_m',COP_AER1m);
                setappdata(0,'COP_AER1ACS_m',COP_AER1ACSm);
                setappdata(0,'EER_AER1_m',EER_AER1m);
                setappdata(0,'EER_AER2_m',EER_AER2m);
                setappdata(0,'capacitat',capacitat);
                setappdata(0,'capacitat_fav',capacitat_fav);
                setappdata(0,'capacitat_a',capacitat_a);
                setappdata(0,'capacitat_fava',capacitat_fava);
                setappdata(0,'Produccio',Prod);
                setappdata(0,'Pot_elec_contr',Pot_El_G);
                setappdata(0,'cons_elec_G',Consum_e_TOT);
                setappdata(0,'SPFc_AER1',SPFCalfAER1);
                setappdata(0,'SPFr_AER1',SPFRefrAER1);
                setappdata(0,'SPFacs_AER1',SPFACSAER1);
                setappdata(0,'SPFr_AER2',SPFRefrAER2);
                setappdata(0,'F_hivern',F_w); %JGC: Factor de càrrega calefacció per al més de disseny d'hivern (gener)
                setappdata(0,'F_hivern_ACS',F_ACS); %JGC: Factor de càrrega ACS per al més de disseny d'hivern (gener)
                setappdata(0,'F_estiu',F_s); %JGC: Factor de càrrega per al més de disseny d'estiu (juliol)
            end
        end

        % Button pushed function: reset
        function Write_ValorsDefecteDadesEntrada(app, event)
            if app.SwitchCAT_Gir.Value=="Catalunya"
                app.x_loc.Limits=[260130.1 527429.9];
                app.y_loc.Limits=[4488675.1 4748054.9];
                app.x_loc.Value = 428960; %JGC:Previous default value=0. Modifed by JGC. Location: Davant edifici Mies Van De Roe, Barcelona
                app.y_loc.Value = 4580260; %JGC:Previous default value=0. Modifed by JGC. Location: Davant edifici Mies Van De Roe, Barcelona
                app.user_difu.Value = 1.220;
                app.user_cdty.Value = 2.680;
                app.ChangeT_ground0.Value = 18.1;
            else
                app.x_loc.Limits=[476807.6 486707.4];
                app.y_loc.Limits=[4641588.9 4650528.8];
                app.x_loc.Value = 484620.6; %JGC Parc Central, zona sud davant Pg. d'Olot
                app.y_loc.Value = 4647211.7; %JGC Parc Central, zona sud davant Pg. d'Olot
                app.user_difu.Value = 1.342;
                app.user_cdty.Value = 2.681;
                app.ChangeT_ground0.Value = 17.1;
            end
            parammatrix=readmatrix('019_parametres_calcul_carregues.xlsx');
            paramload=parammatrix(:,1); %JGC: Cas residencial 
            app.UnknownDemand.Value = false;
            app.pot_estiu.Enable = 1;
            app.pot_estiu.Value = 19; %JGC:Previous default value=0. Modifed by JGC
            app.pot_hivern.Enable = 1;
            app.pot_hivern.Value = 22; %JGC:Previous default value=0. Modifed by JGC
            app.pot_ACS.Enable=1;
            app.pot_ACS.Value = 2;
            app.e_estiu.Enable = 1;
            app.e_estiu.Value = 20000; %JGC:Previous default value=0. Modifed by JGC
            app.e_hivern.Enable = 1;
            app.e_hivern.Value = 45000; %JGC:Previous default value=0. Modifed by JGC
            app.e_ACS.Enable=1;
            app.e_ACS.Value=5000;
            app.PlantaQuadrada.Value = 1;
            app.superficie.Enable = 0;
            app.superficie.Value = paramload(5);
            app.finestra.Enable = 0;
            app.finestra.Value = paramload(7);
            app.SC_h.Enable=0;
            app.SC_h.Value=paramload(8);
            app.SC_c.Enable=0;
            app.SC_c.Value=paramload(9);
            app.plantes.Enable = 0;
            app.plantes.Value = paramload(6);
            app.persones.Enable = 0;
            app.persones.Value = paramload(10);
            app.ACS_especific.Enable=0;
            app.ACS_especific.Value=paramload(12);
            app.t_confort_h.Enable = 0;
            app.t_confort_h.Value = paramload(13);
            app.t_confort_c.Enable = 0;
            app.t_confort_c.Value = paramload(14);
            app.ResiNoSoterrani.Enable = 0;
            app.ResiNoSoterrani.Value = 1;
            app.ResiSoterrani.Enable=0;
            app.CentreEducatiu.Enable=0;
            app.Oficines.Enable=0;
            app.Hosteleria.Enable=0;
            app.Hospital.Enable=0;
            app.CentreEsportiu.Enable=0;
            app.PlantaQuadrada.Enable = 0;
            app.PlantaQuadrada.Value = true;
            app.PlantaRectangular1.Enable=0;
            app.PlantaRectangular2.Enable=0;
            app.database_hp.Value = 1;
            app.user_hp_heat.Enable = 0;%JGC:Previous default value=0. Modifed by JGC
            app.user_hp_heat.Value = 26.00; %JGC:Previous default value=0. Modifed by JGC
            app.user_hp_cool.Enable = 0;%JGC:Previous default value=0. Modifed by JGC
            app.user_hp_cool.Value = 22.00; %JGC:Previous default value=0. Modifed by JGC
            app.user_hp_ACS.Enable=0;
            app.user_hp_ACS.Value=25;
            app.user_COP.Enable = 0;%JGC:Previous default value=0. Modifed by JGC
            app.user_COP.Value = 4.00; %JGC:Previous default value=0. Modifed by JGC
            app.user_EER.Enable = 0;%JGC:Previous default value=0. Modifed by JGC
            app.user_EER.Value = 3.90; %JGC:Previous default value=0. Modifed by JGC
            app.user_COPACS.Enable=0;
            app.user_COPACS.Value=3.25;
            app.m100.Value=true;
            app.mm40.Value=true;
            app.Usimple.Value=true;
            app.Corr_T_ground.Value=true;
            app.Estimacio_Var_T.Value='0.0';
            app.t_estabilitzacio.Value='0';
            app.user_PropTerm.Value = 0;
            app.user_difu.Enable = 0;
            app.user_cdty.Enable = 0;
        end

        % Button pushed function: MetodologiaButton, back_2_workf
        function GoTo_Metodologia(app, event)
            app.TabGroup.SelectedTab = app.MetodologiaTab;
        end

        % Value changed function: EnrereButton
        function GoTo_ResultatsDisseny(app, event)
            app.TabGroup.SelectedTab = app.ResultatsDissenyTab;
        end

        % Selection changed function: ChooseCarCap_DemPro_Plot
        function Choose_CarCap_o_DemPro_Plot(app, event)
            canexecute = getappdata(0,'executable');
            energy_plot = getappdata(0,'valors_demanda');
            prod_plot = getappdata(0,'valors_produccio');
            charge_plot = getappdata(0,'valors_carrega');
            capa_plot = getappdata(0,'valors_capacitat');
            axis_value = getappdata(0,'eix_temperatura');
            
            if (canexecute==1)&&(app.show_thermal_r.Value==1)
                app.plot_results.cla;
                ylabel(app.plot_results, 'Potència tèrmica (kW)');
                hold(app.plot_results,'on');
                plot(app.plot_results,axis_value,charge_plot,'Color',[0.5 0.5 0.5],'LineWidth',1.0);
                plot(app.plot_results,axis_value,capa_plot, 'Color',[0.96 0.62 0.13],'LineWidth',1.0);
                legend(app.plot_results,'Càrrega tèrmica','Potència instal·lació');
                legend(app.plot_results,'Location','north');
                legend(app.plot_results,'boxoff');
                hold(app.plot_results,'off');
            elseif (canexecute==1)&&(app.show_e_r.Value==1)
                app.plot_results.cla;
                ylabel(app.plot_results, 'Energia tèrmica (MWh/any)');
                hold(app.plot_results,'on');
                plot(app.plot_results,axis_value,energy_plot,'Color',[0.5 0.5 0.5],'LineWidth',1.0);
                plot(app.plot_results,axis_value,prod_plot,'Color',[0.96 0.62 0.13],'LineWidth',1.0);
                legend(app.plot_results,'Demanda energia tèrmica','Producció energia tèrmica');
                legend(app.plot_results,'Location','northeast');
                legend(app.plot_results,'boxoff');
                hold(app.plot_results,'off');
            else
                app.plot_results.cla;
                ylabel(app.plot_results,'Potència tèrmica');
            end
        end

        % Selection changed function: ChooseEner_t_e_Plot
        function Choose_Ener_t_o_e_Plot(app, event)
            canexecute = getappdata(0,'executable');
            prod_plot = getappdata(0,'valors_prod');
            elec_plot = getappdata(0,'valors_elec');
            eix_mes = getappdata(0,'eix_mes');
            Y_high = getappdata(0,'high_limY');
            Y_low = getappdata(0,'low_limY');
            Y_e=getappdata(0,'elec_limY');
            if (canexecute==1)&&(app.ConsumEnergia.Value==1)
                app.MonthDemandPlot.cla;
                ylabel(app.MonthDemandPlot,'Energia tèrmica (MWh)');
                app.MonthDemandPlot.YLim=[Y_low Y_high];
                bar(app.MonthDemandPlot,eix_mes,prod_plot);
                b=bar(app.MonthDemandPlot,eix_mes,prod_plot,'stacked','FaceColor','Flat');
                set(b(1),'FaceColor',[1 0.5 0.5],'EdgeColor',[0 0 0]);
                set(b(2),'FaceColor',[0.6 0.8 1],'EdgeColor',[0 0 0]);
                set(b(3),'FaceColor',[0.6 1 0.6],'EdgeColor',[0 0 0]);
                legend(app.MonthDemandPlot,{'Producció de calor','Producció de fred','Producció d''ACS'});
                if Y_high>abs(Y_low)
                    legend(app.MonthDemandPlot,'Location', 'north')
                else 
                    legend(app.MonthDemandPlot,'Location', 'southwest')
                end
                legend(app.MonthDemandPlot,'boxoff');
            elseif(canexecute==1)&&(app.ConsumElectricitat.Value==1)
                app.MonthDemandPlot.cla;
                ylabel(app.MonthDemandPlot,'Energia elèctrica (MWh)');
                app.MonthDemandPlot.YLim=[0 0.5*Y_e];
                bar(app.MonthDemandPlot,eix_mes,elec_plot);
                el=(bar(app.MonthDemandPlot,eix_mes,elec_plot,'stacked','FaceColor','Flat'));
                set(el(1),'FaceColor',[0 0 0],'EdgeColor',[0 0 0]);
                set(el(2),'FaceColor',[0.5 0.5 0.5],'EdgeColor',[0 0 0]);
                set(el(3),'FaceColor',[0.75 0.75 0.75],'EdgeColor',[0 0 0]);
                legend(app.MonthDemandPlot,{'Consum període "vall"','Consum període "pla"','Consum període "punta"'});
                legend(app.MonthDemandPlot,'Location', 'north');
                legend(app.MonthDemandPlot,'boxoff'); 
            else
                app.MonthDemandPlot.cla;
                ylabel(app.MonthDemandPlot,'Energia tèrmica (MWh)'); 
            end 
        end

        % Button pushed function: InformeTOT, Informe_disseny
        function Generate_Report(app, event)
            l_pou_r = getappdata(0,'rl_pou');
            n_pou_r = getappdata(0,'rn_pou');
            criteri_r = getappdata(0,'r_criterio');
            if app.PotDisCalfkW.Value==0
                q_f_win_r = 0;
            else
                q_f_win_r = getappdata(0,'r_des_q_w');
                q_f_win_r = q_f_win_r/1000;
            end
            q_f_sum_r = getappdata(0,'r_des_q_s');
            q_f_sum_r = q_f_sum_r/1000;
            q_f_ACS_r = getappdata(0,'r_des_q_ACS');
            q_f_ACS_r = q_f_ACS_r/1000;
            d_cool_r = getappdata(0,'r_d_cool');
            d_heat_r = getappdata(0,'r_d_heat');
            d_ACS_r = getappdata(0,'r_d_ACS');
            P_inshG_r = getappdata(0,'Pot_h_G');
            P_inscG_r = getappdata(0,'Pot_c_G');
            P_insACSG_r = getappdata(0,'Pot_ACS_G');
            SPFh = getappdata(0,'SPF_h');
            SPFc = getappdata(0,'SPF_c');
            SPFACS = getappdata(0,'SPF_ACS');
            param=readmatrix("018_parametersEcoAmb.xlsx");
            SPFCalfAER1=getappdata(0,'SPFc_AER1');
            SPFRefrAER1=getappdata(0,'SPFr_AER1');
            SPFACSAER1=getappdata(0,'SPFacs_AER1');
            SPFRefrAER2=getappdata(0,'SPFr_AER2');       
            Elec_h_G  =getappdata(0,'cons_elec_h_G');
            Elec_c_G  =getappdata(0,'cons_elec_c_G');
            Elec_ACS_G = getappdata(0,'cons_elec_ACS_G');
            Elec_G = getappdata(0,'cons_elec_G');
            Pot_e_contr = getappdata(0,'Pot_elec_contr');
            dies_heat_r = getappdata(0,'dies_heat');
            dies_cool_r = getappdata(0,'dies_cool');
            n_pum_r = getappdata(0,'divider_r');
            id_model_r = getappdata(0,'model_r');
            r_pump = getappdata(0,'pump_data_r');  
            r_pump(1) = r_pump(1)/1000;
            r_pump(3) = r_pump(3)/1000;
            r_pump(5) = r_pump(5)/1000;
            EWTnom_h = getappdata(0,'EWTnom_h');
            LWTnom_h = getappdata(0,'LWTnom_h');
            EWTnom_c = getappdata(0,'EWTnom_c');
            LWTnom_c = getappdata(0,'LWTnom_c');
            EWTnom_ACS = getappdata(0,'EWTnom_ACS');
            LWTnom_ACS = getappdata(0,'LWTnom_ACS');
            verifi_r = getappdata(0,'verification_r');
%             exchange_r = getappdata(0,'nominal_r');
            t_imb_r = getappdata(0,'eco_t');
            x_loc_r = getappdata(0,'x_sitio');
            y_loc_r = getappdata(0,'y_sitio');
            z_loc_r = getappdata(0,'z_sitio');
            ambit_app = getappdata(0,'ambit_app');
            prov_r = getappdata(0,'provinci');
            FGS_h = getappdata(0,'FGS_hivern');
            FGS_c = getappdata(0,'FGS_estiu');
            T_dis_hivern = getappdata(0,'Tdish');
            T_dis_estiu = getappdata(0,'Tdisc');
            Factor_h = getappdata(0,'F_hivern');
            Factor_h=100*Factor_h;
            Factor_ACS = getappdata(0,'F_hivern_ACS');
            Factor_ACS = 100*Factor_ACS;
            Factor_r = getappdata(0,'F_estiu');
            Factor_r=100*Factor_r;
            fecha_r = clock;
            fecha_r = fix(fecha_r);
            txt_any = num2str(fecha_r(1));
            if fecha_r(2)<10
                txt_mes=['0' num2str(fecha_r(2))];
            else
                txt_mes=num2str(fecha_r(2));
            end
            if fecha_r(3)<10
                txt_dia=['0' num2str(fecha_r(3))];
            else
                txt_dia=num2str(fecha_r(3));
            end
            if fecha_r(4)<10
                txt_hor=['0' num2str(fecha_r(4))];
            else
                txt_hor=num2str(fecha_r(4));
            end
            if fecha_r(5)<10
                txt_min=['0' num2str(fecha_r(5))];
            else
                txt_min=num2str(fecha_r(5));
            end
            tipo_calc_r = getappdata(0,'thermal_tipo');
            ground_sys_r = getappdata(0,'ground_dec');
            corregim_Tg = getappdata(0,'T_ground_corr');
            VarT_est = getappdata(0,'VarT_est');
            VarT_anys = getappdata(0,'VarT_anys');
            t_ground = getappdata(0,'ground_T');
            cdty_r = getappdata(0,'dato_cdty');
            difu_r = getappdata(0,'dato_difu');
            d_entre_sondes = getappdata(0,'d_well');
            area_sondes = getappdata(0,'s_well');
%             carg_sup = getappdata(0,'carga_por_m2');
            s_en_r = getappdata(0,'s_para_r');
            z_en_r = getappdata(0,'z_para_r');
            f_en_r =getappdata(0,'f_para_r');
            ocup_en_r = getappdata(0,'ocup_para_r');
            ocupacio = getappdata(0,'F_ocup');
            ocupacio = 100*ocupacio;
            tc_en_r = getappdata(0,'t_para_rc');
            th_en_r = getappdata(0,'t_para_rh');
            t_min_pous = getappdata(0,'t_min_pous');
            t_max_pous = getappdata(0,'t_max_pous');
%             desbal_en_r = getappdata(0,'desbal_para_r');
            Prod_ener_t_G = getappdata(0,'Prod_e_term_tot');
            Prod_ener_t_Gsup = getappdata(0,'Prod_e_term_sup');
%             solo_cool_r = getappdata(0,'solo_cool');
%             solo_cool_r_3 = getappdata(0,'solo_cool_use_3');
%             solo_cool_r_3 = solo_cool_r_3/1000;
%             solo_heat_r_3 = getappdata(0,'solo_heat_use_3');    
%             solo_heat_r_3 = solo_heat_r_3/1000;    
%             solo_heat_r = getappdata(0,'solo_heat');
            if (app.Informe_disseny.Enable==1)&&(app.InformeTOT.Enable==0)
                file_name=[txt_any txt_mes txt_dia '_' txt_hor txt_min '_Informe_Disseny_GeoSIV.txt'];
            elseif (app.Informe_disseny.Enable==1)&&(app.InformeTOT.Enable==1)
                file_name=[txt_any txt_mes txt_dia '_' txt_hor txt_min '_Informe_Complert_GeoSIV.txt'];
            end
            [file_name,path]=uiputfile(file_name);            
            ident = fopen(fullfile(path,file_name),'w+t');
            fprintf(ident,'                                         Geo-SIV (v1.2) (2022)                                          \n');
            fprintf(ident,'                             Institut Cartogràfic i Geològic de Catalunya                               \n');
            fprintf(ident,'                                 Universitat Politècnica de Catalunya                                   \n');
            fprintf(ident,'                                              CC-BY 4.0                                                 \n');
            if (fecha_r(2) == 1)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de gener',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 2)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de febrer',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 3)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de març',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 4)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d d''abril',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 5)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de maig',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 6)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de juny',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 7)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de juliol',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 8)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d d''agost',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 9)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de setembre',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 10)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d d''octubre',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            elseif (fecha_r(2) == 11)
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de novembre',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            else
                fprintf(ident,'\n\t\t\tData de creació de l''informe: %d de desembre',fecha_r(3));
                fprintf(ident,', %d\n\n',fecha_r(1));
            end
            fprintf(ident,'\n======================================================================================================\n');
            fprintf(ident,'                                      || RESULTATS DISSENY ||                                         ');
            fprintf(ident,'\n======================================================================================================\n\n');
            
            fprintf(ident,'\nLOCALITZACIÓ DEL PROJECTE SEGONS SISTEMA ETRS89 /UTM zona 31N (EPSG: 25831)\n');
            fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
            if ambit_app==1
                fprintf(ident,' - Àmbit territorial escollit:\t\t\t\t\t Catalunya\n');
            else
                fprintf(ident,' - Àmbit territorial escollit:\t\t\t\t\t Àrea urbana i periurbana de la ciutat de Girona\n');
            end
            fprintf(ident,' - Coordenada X:\t\t\t\t\t\t %6.2f\n',x_loc_r);
            fprintf(ident,' - Coordenada Y:\t\t\t\t\t\t %7.2f\n',y_loc_r);
            if (prov_r == 1)
                fprintf(ident,' - Província:\t\t\t\t\t\t\t Barcelona\n');
            elseif (prov_r == 2)
                fprintf(ident,' - Província:\t\t\t\t\t\t\t Girona\n');
            elseif (prov_r == 3)
                fprintf(ident,' - Província:\t\t\t\t\t\t\t Lleida\n');
            else
                fprintf(ident,' - Província:\t\t\t\t\t\t\t Tarragona\n');
            end
            fprintf(ident,' - Altitud:\t\t\t\t\t\t\t %d m.s.n.m.\n',z_loc_r);
            if (prov_r == 1) %JGC: BARCELONA
                if (z_loc_r <= 250)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t C2\n');
                elseif (250 < z_loc_r)&&(z_loc_r <= 450)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t D2\n');
                elseif (450 < z_loc_r)&&(z_loc_r <= 750)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t D1\n');
                else
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t E1\n');
                end
            elseif (prov_r == 2) %JGC: GIRONA
                if (z_loc_r <= 100)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t C2\n');
                elseif (100 < z_loc_r)&&(z_loc_r <= 600)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t D2\n');
                else
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t E1\n');
                end
            elseif (prov_r == 3) %JGC: LLEIDA
                if (z_loc_r <= 100)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t C3\n');
                elseif (100 < z_loc_r)&&(z_loc_r <= 600)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t D3\n');
                else
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t E1\n');
                end
            else                     %JGC: TARRAGONA
                if (z_loc_r <= 100)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t B3\n');
                elseif (100 < z_loc_r)&&(z_loc_r <= 500)
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t C3\n');
                else
                    fprintf(ident,' - Clima de referència aplicat:\t\t\t\t\t D3\n');
                end
            end 
            fprintf(ident,' - Temperatura de disseny d''hivern:\t\t\t\t %2.1f °C\n',T_dis_hivern);
            fprintf(ident,' - Temperatura de disseny d''estiu:\t\t\t\t %2.1f °C\n\n',T_dis_estiu);
            
            fprintf(ident,'\nCRITERIS DE SIMULACIÓ\n');
            fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
            fprintf(ident,' - Dimensionament del camp de captació realitzat seguint\n   metodologia adaptada de l''IGSHPA\n');
            if (tipo_calc_r == 0)
                fprintf(ident,' - Càlcul de les càrregues tèrmiques seguint el mètode modificat\n   de les caselles (bin method) de RETScreen:\n');
                if app.ResiNoSoterrani.Value==1 %JGC: Tipologia residencial sense soterrani (pensem en una casa)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tResidencial (sense soterrani)\n');
                elseif app.ResiSoterrani.Value==1 %JGC: Tipologia residencial amb soterrani (pensem en una casa d'una planta)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tResidencial (amb soterrani)\n');
                elseif app.CentreEducatiu.Value==1 %JGC: Tipologia centre educatiu (pensem en un edifici de dues plantes, 1 linia per curs, de P3 a 6è, sense dutxes)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tCentre Educatiu)\n');
                elseif app.Oficines.Value==1 %JGC: Tipologia edifici d'oficines (pensem en un edifici acristallat de 5 plantes)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tOficines\n');
%                 elseif app.Industrial.Value==1 %JGC: Tipologia edifici d'ús industrial (pensem en una nau industrial com a exemple)
%                     fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tIndústria\n');
                elseif app.Hosteleria.Value==1 %JGC: Tipologia hotel i/o restauració (pensem en un hotel*** de 4 plantes, 20 habs. per planta, i rest. 1a planta)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\tHosteleria\n');
%                 elseif app.Comercial.Value==1 %JGC: Tipologia local comercial (pensem en un supermercat, sense finestres gairebé, sense dutxes)
%                     fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tComercial\n');
                elseif app.Hospital.Value==1 %JGC: Tipologia centre sanitari (pensem en un ambulatori de poble)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tCentre sanitari\n');
                elseif app.CentreEsportiu.Value==1 %JGC: Tipologia centre esportiu (pensem en un gimnàs petit, sense piscina)
                    fprintf(ident,'\n\t - Ús de l''edifici:\t\t\t\t\tCentre esportiu (sense piscina)\n');
                end
                if app.PlantaQuadrada.Value==1
                    fprintf(ident,'\t - Geometria de planta seleccionada:\t\t\tPlanta quadrada\n');
                elseif app.PlantaRectangular1.Value==1
                    fprintf(ident,'\t - Geometria de planta seleccionada:\t\t\tPlanta rectangular (façana llarga orientada sud)\n');
                elseif app.PlantaRectangular2.Value==1
                    fprintf(ident,'\t - Geometria de planta seleccionada:\t\t\tPlanta rectangular (façana curta orientada sud)\n');
                end
                fprintf(ident,'\t - Àrea total construïda:\t\t\t\t%5.2f m2\n',s_en_r);
                fprintf(ident,'\t - Nombre de plantes (soterrani no inclós):\t\t%d\n',z_en_r);
                fprintf(ident,'\t - Relació d''acristallament:\t\t\t\t%.2f\n',f_en_r);
                fprintf(ident,'\t - Factor guany solar a l''hivern\t\t\t%.2f\n',FGS_h);
                fprintf(ident,'\t - Factor guany solar a l''estiu\t\t\t\t%.2f\n',FGS_c);
                fprintf(ident,'\t - Màxim nombre d''ocupants:\t\t\t\t%3.1f\n',ocup_en_r);
                fprintf(ident,'\t - Ocupació mitjana anual assumida:\t\t\t%2.1f %%\n',ocupacio);
                fprintf(ident,'\t - Temperatura de confort d''hivern:\t\t\t%3.1f °C\n',th_en_r); 
                fprintf(ident,'\t - Temperatura de confort d''estiu:\t\t\t%3.1f °C\n\n',tc_en_r); 
            else
                fprintf(ident,' - Càrrega i demanda tèrmiques especificades per l''usuari\n');
            end
            if (app.Dist_TR_h.Value==1)&&(app.Dist_TR_c.Value==1)
                fprintf(ident,' - Sistema de distribució a l''interior de l''edifici:\t\t Terra radiant\n');
            elseif (app.Dist_TR_h.Value==1)&&(app.Dist_FC_c.Value==1)
                if app.ProdCalfMWht.Value==0
                    fprintf(ident,' - Sistema de distribució a l''interior de l''edifici:\t\t Fancoils\n');
                elseif app.ProdRefrMWht.Value==0
                    fprintf(ident,' - Sistema de distribució a l''interior de l''edifici:\t\t Terra radiant\n');
                else
                    fprintf(ident,' - Sistema de distribució a l''interior de l''edifici:\t\t Terra radiant i fancoils\n');
                end
            elseif (app.Dist_FC_h.Value==1)&&(app.Dist_FC_c.Value==1)
                fprintf(ident,' - Sistema de distribució a l''interior de l''edifici:\t\t Fancoils\n');
            elseif (app.Dist_RR_h.Value==1)&&(app.Dist_FC_c.Value==1)  
                fprintf(ident,' - Sistema de distribució a l''interior de l''edifici:\t\t Radiadors i fancoils\n');
            end
            if (ground_sys_r == 0)
                fprintf(ident,' - Propietats tèrmiques del subsol carregades des de la\n   base de dades de l''ICGC:\n\n');
            else 
                fprintf(ident,' - Propietats tèrmiques del subsol especificades per l''usuari:\n\n');
            end
            fprintf(ident,'\t - Temperatura no pertorbada del terreny:\t\t %2.1f °C\n',t_ground);
            fprintf(ident,'\t - Conductivitat tèrmica:\t\t\t\t %.3f W/mK\n',cdty_r);
            fprintf(ident,'\t - Difussivitat tèrmica:\t\t\t\t %.3f mm2/s\n\n',difu_r);
            if (app.Usimple.Value==1)&&(app.mm32.Value==1)
                fprintf(ident,' - Sondes tipus "U" simple de dimensions: Ø_ext = 32 mm, gruix = 2.9 mm\n');
            elseif (app.Usimple.Value==1)&&(app.mm40.Value==1)
                fprintf(ident,' - Sondes tipus "U" simple de dimensions: Ø_ext = 40 mm, gruix = 3.6 mm\n');
            elseif (app.Udoble.Value==1)&&(app.mm32.Value==1)
                fprintf(ident,' - Sondes tipus "U" simple de dimensions: Ø_ext = 32 mm, gruix = 2.9 mm\n');
            elseif (app.Udoble.Value==1)&&(app.mm40.Value==1)
                fprintf(ident,' - Sondes tipus "U" simple de dimensions: Ø_ext = 40 mm, gruix = 3.6 mm\n');
            end
            fprintf(ident,' - Separació entre pous de %2.0f m\n',d_entre_sondes);
            
            fprintf(ident,'======================================================================================================\n\n');
            fprintf(ident,'\nCAMP DE CAPTACIÓ SIMULAT\n');
            fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
            if (criteri_r == 0)
                fprintf(ident,' - Criteri de disseny:\t\t\t\t\t\tHivern\n');      
            else
                fprintf(ident,' - Criteri de disseny:\t\t\t\t\t\tEstiu\n');
            end
            fprintf(ident,' - Nombre de pous requerits:\t\t\t\t\t%d\n',n_pou_r);
            fprintf(ident,' - Longitud dels pous:\t\t\t\t\t\t%.2f m\n',l_pou_r);   
            fprintf(ident,' - Superfície requerida:\t\t\t\t\t%d m2\n',area_sondes);
            fprintf(ident,' - Balanç anual d''energia tèrmica bescanviada amb el subsol:\t%.2f MWh\n\n',t_imb_r);
            if corregim_Tg==1
               fprintf(ident,'   NOTA: S''ha tingut en compte una possible variació de la\n         temperatura no pertorbada del terreny (T_subsol) a\n');
               fprintf(ident,'         llarg termini en el dimensionament dels pous\n');
            else
               fprintf(ident,'   NOTA: No s''ha tingut en compte una possible variació de la\n         temperatura no pertorbada del terreny (T_subsol) a\n');
               fprintf(ident,'         llarg termini en el dimensionament dels pous\n');
            end
            fprintf(ident,'\n - Estimació de la variació local de T_subsol a llarg termini:\t%2.1f °C\n',VarT_est);
            fprintf(ident,' - Estimació del període d''estabilització tèrmica del terreny:\t%1.0f anys\n',VarT_anys);

            fprintf(ident,'\n   NOTA: L''estimació de la variació de T_subsol representa un\n         límit superior.\n\n');
            
            fprintf(ident,'\nBOMBA DE CALOR GEOTÈRMICA\n');
            fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
            if (id_model_r == 0)
                fprintf(ident,' - Valors nominals especificats per l''usuari:\n\n');
            else
                fprintf(ident,' - Valors nominals extrets de la base de dades interna de Geo-SIV:\n\n');
            end
            fprintf(ident,'\t - Potència nominal en mode calefacció:\t\t\t%3.2f kW\n',r_pump(1));
            fprintf(ident,'\t   COP:\t\t\t\t\t\t\t%.2f\n',r_pump(2));
            fprintf(ident,'\t   T_entrada (evap.) / T_sortida (cond.) BCG:\t\t%2.1f °C / %2.1f °C\n\n', EWTnom_h,LWTnom_h);
            fprintf(ident,'\t - Potència nominal en mode refrigeració:\t\t%3.2f kW\n',r_pump(3));
            fprintf(ident,'\t   EER:\t\t\t\t\t\t\t%.2f\n',r_pump(4));
            fprintf(ident,'\t   T_entrada (evap.) / T_sortida (cond.) BCG:\t\t%2.1f °C / %2.1f °C\n\n', EWTnom_c,LWTnom_c);
            fprintf(ident,'\t - Potència nominal en mode ACS:\t\t\t%3.2f kW\n',r_pump(5));
            fprintf(ident,'\t   COPacs:\t\t\t\t\t\t%.2f\n',r_pump(6));
            fprintf(ident,'\t   T_entrada (evap.) / T_sortida (cond.) BCG:\t\t%2.1f °C / %2.1f °C\n\n', EWTnom_ACS,LWTnom_ACS);
            fprintf(ident,' - Nombre d''unitats requerides:\t\t\t\t\t%d\n\n',n_pum_r);

            
            fprintf(ident,'\nCARACTERÍSTIQUES I COMPORTAMENT ANUAL DE LA INSTAL·LACIÓ\n');
            fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
            fprintf(ident,'                        \t\t\t\t\tCALOR\t\tFRED\t\tACS     \n');
            fprintf(ident,'                                                              --------------------------------------- \n');
            fprintf(ident,' - Càrrega de disseny [kW]:\t\t\t\t\t%.2f\t\t%.2f\t\t%.2f\n',q_f_win_r,q_f_sum_r,q_f_ACS_r);
            fprintf(ident,' - Potència instal·lada [kW]:\t\t\t\t\t%.2f\t\t%.2f\t\t%.2f\n',P_inshG_r,P_inscG_r,P_insACSG_r);
            fprintf(ident,' - Producció energia tèrmica [MWh]:\t\t\t\t%.2f\t\t%.2f\t\t%.2f\n',d_heat_r,d_cool_r,d_ACS_r);
            fprintf(ident,' - Factor de càrrega mes de disseny [%%]:\t\t\t%.2f\t\t%.2f\t\t%.2f\n',Factor_h,Factor_r,Factor_ACS);
            fprintf(ident,' - Rendiment estacional (SPFc / SPFr / SPFacs) [Wht/Whe]:\t%.2f\t\t%.2f\t\t%.2f\n',SPFh,SPFc,SPFACS);
            fprintf(ident,' - Consum elèctric [MWh]:\t\t\t\t\t%.2f\t\t%.2f\t\t%.2f\n',Elec_h_G,Elec_c_G,Elec_ACS_G);
            fprintf(ident,'                                                              --------------------------------------- \n\n');
            if (tipo_calc_r == 0)
                fprintf(ident,' - Producció d''energia tèrmica per unitat de superfície:\t%4.2f MWh/m2\n',Prod_ener_t_Gsup);
            else
                fprintf(ident,' - Producció anual d''energia tèrmica:\t\t\t\t%4.2f MWh\n',Prod_ener_t_G);
            end
            fprintf(ident,' - Consum elèctric total: \t\t\t\t\t%.2f MWh\n',Elec_G);
            fprintf(ident,' - Potència elèctrica a contractar: \t\t\t\t%.2f kW\n',Pot_e_contr);
            fprintf(ident,' - Duració període mode refrigeració:\t\t\t\t%d dies\n',dies_cool_r);
            fprintf(ident,' - Duració període mode calefacció:\t\t\t\t%d dies \n\n',dies_heat_r);
            if (verifi_r == 1)
                fprintf(ident,'   NOTA: La duració dels períodes de calefacció i refrigeració\n');
                fprintf(ident,'         es poden considerar d''acord amb els valors de la base\n');
                fprintf(ident,'         de dades de l''ICGC\n\n');
            else
                fprintf(ident,'   NOTA: La duració dels períodes de calefacció i refrigeració\n');        
                fprintf(ident,'         difereixen dels valors de la base de dades de l''ICGC\n');
                fprintf(ident,'         més d''un 25 %%. Això pot estar relacionat amb el fet\n');
                fprintf(ident,'         d''introduïr manualment les càrregues tèrmiques i la\n');
                fprintf(ident,'         demanda quan el nombre d''ocupants de l''edifici és\n');
                fprintf(ident,'         superior a la mitjana o quan l''ús de l''edifici escollit\n');
                fprintf(ident,'         és diferent del residencial\n\n');
            end
            fprintf(ident,' - Temperatura mínima del líquid caloportador a pous:\t\t%2.1f °C\n',t_min_pous);
            fprintf(ident,' - Temperatura màxima del líquid caloportador a pous:\t\t%2.1f °C\n\n',t_max_pous);
            if t_min_pous<=5
                fprintf(ident,'   NOTA: Es requereix aigua + líquid anticongelant\n');
                fprintf(ident,'         com a líquid caloportador a la banda de pous.\n\n'); 
            elseif (t_min_pous>5)&&(t_min_pous<10)
                fprintf(ident,'   NOTA: El líquid caloportador a la banda de pous pot ser aigua.\n');
                fprintf(ident,'         però és recomenable incorporar-hi anticongelant.\n\n');
            elseif t_min_pous>=10
                fprintf(ident,'   NOTA: El líquid caloportador a la banda de pous pot ser aigua.\n\n');
            end
            
            if app.InformeTOT.Enable==0
                fprintf(ident,'\n======================================================================================================\n\n');
            else
                OpAlth = getappdata(0,'opcio_alth');
                OpAltc = getappdata(0,'opcio_altc');
                OpAltACS = getappdata(0,'opcio_altACS');
                CostCaptacio = getappdata(0,'CostCaptacio');
                CostEqGeo = getappdata(0,'CostEqGeo');
                CostEqAlt = getappdata(0,'CostEqAlt');
                CostInvExtGeo = getappdata(0,'CostInvExtGeo');
                CostPanGeo = getappdata(0,'CostPanGeo');
                CostPanAlt = getappdata(0,'CostPanAlt');
                CostBatGeo = getappdata(0,'CostBatGeo');
                CostBatAlt = getappdata(0,'CostBatAlt');
                CostInvGeo = getappdata(0,'CostInvGeo');
                CostInvAlt = getappdata(0,'CostInvAlt');
                ImpSubvGeo = getappdata(0,'ImpSubvGeo');
                ImpSubvAlt = getappdata(0,'ImpSubvAlt');
                ImpPresGeo = getappdata(0,'ImpPresGeo');
                ImpPresAlt = getappdata(0,'ImpPresAlt');
                benefici_net = getappdata(0,'benefici');
%                 rendibilitat_neta = getappdata(0,'rendibilitat_neta');
%                 retorn_inv_sim = getappdata(0,'retorn_inv_sim');
%                 vanvan = getappdata(0,'vanvan');
%                 tirtir = getappdata(0,'tirtir');
%                 retorn_inv_des = getappdata(0,'retorn_inv_des');
%                 estalvi_co2rel = getappdata(0,'estalvi_co2rel');
%                 estalvi_co2abs = getappdata(0,'estalvi_co2abs');
                
                fprintf(ident,'\n\n======================================================================================================\n');
                fprintf(ident,'                            || RESULTATS ANÀLISI ECONÒMICA I AMBIENTAL ||                             ');
                fprintf(ident,'\n======================================================================================================\n\n');
                fprintf(ident,'\nDADES BÀSIQUES DEL PROJECTE\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                if app.SwitchTipusProjecte.Value=="Nova instal·lació"
                    fprintf(ident,' - Edifici de nova construcció. S''analitza la conveniència\n   d''una inversió extra en geotèrmia en relació a una\n   alternativa equivalent.\n'); 
                else
                    fprintf(ident,' - Edifici existent. S''analitza la conveniència de substituir\n   una instal·lació de climatització existent per una basada\n   en geotèrmia.\n');
                    fprintf(ident,' - Anys de vida de la instal·lació existent: \t\t\t%d\n',app.vidaprojexistent.Value);
                end
                fprintf(ident,' - Vida del projecte: \t\t\t\t\t\t%d anys\n\n',app.vidaprojecte.Value);
                fprintf(ident,'   NOTA: duració a efectes de l''anàlisi econòmica i ambiental.\n\n');
                fprintf(ident,' - Taxa de descompte: \t\t\t\t\t\t%.2f\n',app.Taxadescompte.Value);
                fprintf(ident,' - Taxa d''inflació general anual mitjana: \t\t\t%.2f\n\n',app.Infl_general.Value);
                fprintf(ident,'\nESCENARI COMPARATIU\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                fprintf(ident,' - Es compara la instal·lació geotèrmica simulada amb una d''alternativa\n   equivalent:\n\n');
                if app.ProdCalfMWht.Value>0
                    if OpAlth==1 %JGC: Biomassa
                        if app.Prod_ACSMWht.Value>0
                            if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                   fprintf(ident,'\t - Caldera de biomassa (CALOR + ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',param(7),SPFRefrAER2);
                                else
                                   fprintf(ident,'\t - Caldera de biomassa (CALOR + ACS) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED)\n',param(7)); 
                                end
                            else
                               fprintf(ident,'\t - Caldera de biomassa (CALOR + ACS) amb un rendiment del %d %%\n',param(7)); 
                            end
                        else
                           if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                   fprintf(ident,'\t - Caldera de biomassa (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',param(7),SPFRefrAER2);
                                   
                                else
                                   fprintf(ident,'\t - Caldera de biomassa (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED) amb un rendiment estacional SPFr = %.2f\n',param(7),SPFRefrAER1); 
                                end
                            else
                               fprintf(ident,'\t - Caldera de biomassa (CALOR) amb un rendiment del %d %%\n',param(7)); 
                            end 
                        end
                    elseif OpAlth==2 %JGC: Gas Natural
                        if app.Prod_ACSMWht.Value>0
                            if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                    if OpAltACS==2
                                       fprintf(ident,'\t - Caldera de gas natural (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(18),SPFRefrAER2,Param(7));
                                    elseif OpAltACS==3
                                       fprintf(ident,'\t - Caldera de gas natural (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(18),SPFRefrAER2,SPFACSAER1);
                                    else
                                       fprintf(ident,'\t - Caldera de gas natural (CALOR + suport ACS) amb un rendiment del %d %%\n \t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(18),SPFRefrAER2,param(14));
                                    end
                                else
                                   fprintf(ident,'\t - Caldera de gas natural (CALOR ) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED + ACS) amb uns rendiment estacionals SPFr = %.2f i SPFacs = %.2f\n', param(18),SPFRefrAER1,SPFACSAER1);
                                end
                            else
                                if OpAltACS==2
                                   fprintf(ident,'\t - Caldera de gas natural (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(18),param(7));
                                elseif OpAltACS==3
                                   fprintf(ident,'\t - Caldera de gas natural (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(18),SPFACSAER1);
                                else
                                   fprintf(ident,'\t - Caldera de gas natural (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(18),param(14));
                                end
                            end
                        else
                           if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                   fprintf(ident,'\t - Caldera de gas natural (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',param(18),SPFRefrAER2);
                                else
                                   fprintf(ident,'\t - Caldera de gas natural (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED) amb un rendiment estacional SPFr = %.2f\n',param(18),SPFRefrAER1);
                                end
                            else
                               fprintf(ident,'\t - Caldera de gas natural (CALOR) amb un rendiment del %d %%\n',param(18));
                            end 
                        end
                    elseif OpAlth==3
                        if app.Prod_ACSMWht.Value>0
                            if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                    if OpAltACS==2
                                       fprintf(ident,'\t - Caldera de gas propà (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(52),SPFRefrAER2,param(7));
                                    elseif OpAltACS==3
                                       fprintf(ident,'\t - Caldera de gas propà (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(52),SPFRefrAER2,SPFACSAER1);
                                    else
                                       fprintf(ident,'\t - Caldera de gas propà (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(52),SPFRefrAER2,param(14));  
                                    end
                                else
                                   fprintf(ident,'\t - Caldera de gas propà (CALOR ) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED + ACS) amb uns rendiment estacionals SPFr = %.2f i SPFacs = %.2f\n',param(52),SPFRefrAER1,SPFACSAER1); 
                                end
                            else
                                if OpAltACS==2
                                   fprintf(ident,'\t - Caldera de gas propà (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(52),param(7));
                                elseif OpAltACS==3
                                   fprintf(ident,'\t - Caldera de gas propà (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(52),SPFACSAER1);
                                else
                                   fprintf(ident,'\t - Caldera de gas propà (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(52),param(14)); 
                                end
                            end
                        else
                           if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                   fprintf(ident,'\t - Caldera de gas propà (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',param(52),SPFRefrAER2);
                                else
                                   fprintf(ident,'\t - Caldera de gas propà (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED) amb un rendiment estacional SPFr = %.2f\n',param(52),SPFRefrAER1);
                                end
                            else
                               fprintf(ident,'\t - Caldera de gas propà (CALOR) amb un rendiment del %d %%\n',param(52));
                            end 
                        end
                    elseif OpAlth==4
                        if app.Prod_ACSMWht.Value>0
                            if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                    if OpAltACS==2
                                       fprintf(ident,'\t - Caldera de gasoil (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(100),SPFRefrAER2,param(7));
                                    elseif OpAltACS==3
                                       fprintf(ident,'\t - Caldera de gasoil (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(100),SPFRefrAER2,SPFACSAER1);
                                    else
                                       fprintf(ident,'\t - Caldera de gasoil (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(100),SPFRefrAER2,param(14));  
                                    end
                                else
                                   fprintf(ident,'\t - Caldera de gasoil (CALOR ) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED + ACS) amb uns rendiments estacionals SPFr = %.2f i SPFacs = %.2f\n',param(100),SPFRefrAER1,SPFACSAER1); 
                                end
                            else
                                if OpAltACS==2
                                   fprintf(ident,'\t - Caldera de gasoil (CALOR + ACS) amb un rendiment del %d %%\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(100),param(7));
                                elseif OpAltACS==3
                                   fprintf(ident,'\t - Caldera de gasoil (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(100),SPFACSAER1);
                                else
                                   fprintf(ident,'\t - Caldera de gasoil (CALOR + ACS) amb un rendiment del %d %%\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(100),param(14));
                                end
                            end
                        else
                           if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                   fprintf(ident,'\t - Caldera de gasoil (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',param(100),SPFRefrAER2);
                                else
                                   fprintf(ident,'\t - Caldera de gasoil (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED) amb un rendiment estacional SPFr = %.2f\n',param(100),SPFRefrAER1); 
                                end
                            else
                               fprintf(ident,'\t - Caldera de gasoil (CALOR) amb un rendiment del %d %%\n',param(100)); 
                            end 
                        end
                    elseif OpAlth==5
                        if app.Prod_ACSMWht.Value>0
                            if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                    if OpAltACS==2
                                       fprintf(ident,'\t - Sistema elèctric (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(114),SPFRefrAER2,param(7));
                                    elseif OpAltACS==3
                                       fprintf(ident,'\t - Sistema elèctric (CALOR) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(114),SPFRefrAER2,SPFACSAER1);
                                    else
                                       fprintf(ident,'\t - Sistema elèctric (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(114),SPFRefrAER2,param(14));  
                                    end
                                else
                                   fprintf(ident,'\t - Sistema elèctric (CALOR ) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED + ACS) amb uns rendiments estacionals SPFr = %.2f i SPFacs = %.2f\n',param(114),SPFRefrAER1,SPFACSAER1); 
                                end
                            else
                                if OpAltACS==2
                                   fprintf(ident,'\t - Sistema elèctric (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Caldera de biomassa (ACS) amb un rendiment del %d %%\n',param(114),param(7));
                                elseif OpAltACS==3
                                   fprintf(ident,'\t - Sistema elèctric (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (ACS) amb un rendiment estacional SPFacs = %.2f\n',param(114),SPFACSAER1);
                                else
                                   fprintf(ident,'\t - Sistema elèctric (CALOR + suport ACS) amb un rendiment del %d %%\n\t - Col·lectors solars tèrmics (ACS) amb una eficiència de conversió mitjana del %d %%\n',param(114),param(14)); 
                                end
                            end
                        else
                           if app.ProdRefrMWht.Value>0
                                if OpAltc==1
                                   fprintf(ident,'\t - Sistema elèctric (CALOR) amb un rendiment del %d %%\n\t - Aire Condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',param(114),SPFRefrAER2);
                                else
                                   fprintf(ident,'\t - Sistema elèctric (CALOR) amb un rendiment del %d %%\n\t - Bomba de calor aerotèrmica (FRED) amb un rendiment estacional SPFr = %.2f\n',param(114),SPFRefrAER1);
                                end
                            else
                               fprintf(ident,'\t - Sistema elèctric (CALOR) amb un rendiment del %d %%\n',param(114));
                            end 
                        end
                    elseif OpAlth==6
                        if app.Prod_ACSMWht.Value>0
                            if app.ProdRefrMWht.Value>0
                                fprintf(ident,'\t - Bomba de calor aerotèrmica (CALOR + FRED + ACS) amb uns rendiments estacionals SPFc = %.2f SPFr = %.2f i SPFacs = %.2f\n',SPFCalfAER1,SPFRefrAER1,SPFACSAER1);
                            else
                                fprintf(ident,'\t - Bomba de calor aerotèrmica (CALOR + ACS) amb uns rendiments estacionals SPFc = %.2f i SPFacs = €.2f\n',SPFCalfAER1,SPFACSAER1);
                            end
                        else
                            if app.ProdRefrMWht.Value>0
                                fprintf(ident,'\t - Bomba de calor aerotèrmica (CALOR + FRED) amb uns rendiments estacionals SPFc = %.2f i SPFr = €.2f\n',SPFCalfAER1,SPFRefrAER1);
                            else
                                fprintf(ident,'\t - Bomba de calor aerotèrmica (CALOR) amb un rendiment estacional SPFc = %.2f\n',SPFCalfAER1);

                            end   
                        end
                    end
                else
                   if app.ProdRefrMWht.Value>0
                       if app.Prod_ACSMWht.Value>0
                           if OpAltc==1
                               if OpAltACS==2
                                  fprintf(ident,'\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Caldera de biomassa + caldera de suport de gas natural (ACS) amb uns rendiments del %d %% i del %d %%, respectivament\n',SPFRefrAER2,param(7),param(18));
                               elseif OpAltACS==3
                                  fprintf(ident,'\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Bomba de calor aerotèrmica (ACS) mb un rendiment estacional SPFacs = %.2f\n',SPFRefrAER2,SPFACSAER1);
                               else
                                  fprintf(ident,'\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n\t - Col·lectors solars tèrmics amb una eficiència de conversió mitjana del %d %%\n   i caldera de suport de gas natural (ACS) amb un rendiment del %d %%\n',SPFRefrAER2,param(14),param(18));  
                               end
                           else
                              fprintf(ident,'\t - Bomba de calor aerotèrmica (FRED + ACS) amb uns rendiments estacionals SPFr = %.2f i SPFacs = %.2f\n',SPFRefrAER1,SPFACSAER1); 
                           end
                       else
                           if OpAltc==1
                               fprintf(ident,'\t - Aire condicionat (FRED) amb un rendiment estacional SPFr = %.2f\n',SPFRefrAER2);
                           else
                               fprintf(ident,'\t - Bomba de calor aerotèrmica (FRED) amb un rendiment estacional SPFr = %.2f\n',SPFRefrAER1);
                           end
                       end
                   else
                       if OpAltACS==2
                          fprintf(ident,'\t - Caldera de biomassa + caldera de suport de gas natural (ACS) amb uns rendiments del %d %% i del %d %%, respectivament\n',param(7),param(18));
                       elseif OpAltACS==3
                          fprintf(ident,'\t - Bomba de calor aerotèrmica (ACS)\n');
                       else
                          fprintf(ident,'\t - Col·lectors solars tèrmics amb una eficiència de conversió mitjana del %d %%\n   i caldera de suport de gas natural (ACS) amb un rendiment del %d %%\n', param(14),param(18));
                       end
                   end 
                end
                fprintf(ident,'\n   NOTA: Els diferents valors dels rendiments estacionals de sistemes aerotèrmics\n');
                fprintf(ident,'         (aire condicionat i bomba de calor aerotèrmica) tenen en compte el clima de\n');
                fprintf(ident,'         referència corresponent a la ubicació i el sistema de distribució.\n');
                fprintf(ident,'\n   NOTA: S''assumeix un consum elèctric no nul dels sistemes següents:\n');
                fprintf(ident,'\n         - Caldera de biomassa: %.2f %% del total d''energia tèrmica produïda\n',100*param(8));
                fprintf(ident,'         - Col·lectors solars tèrmics: %.2f %% del total d''energia tèrmica produïda\n',100*param(8));
                fprintf(ident,'\n\nHIBRIDACIÓ AMB ENERGIA SOLAR FOTOVOLTÀICA\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                fprintf(ident,'                        \t\t\t\t\tGEOTÈRMIA\t\tALTERNATIVA\n');
                fprintf(ident,'                                                              --------------------------------------- \n');
                if (app.GeoHibPV.Value==1)&&(app.GeoHibPV.Enable==1)
                    fprintf(ident,' - Instal·lació hibridada?\t\t\t\t\t   Sí\t\t\t');
                else
                    fprintf(ident,' - Instal·lació hibridada?\t\t\t\t\t   No\t\t\t');
                end
                if (app.AltHibPV.Value==1)&&(app.AltHibPV.Enable==1)
                    fprintf(ident,'    Sí\n');
                else
                    fprintf(ident,'    No\n');
                end
                if (app.GeoHibPV.Value==1)&&(app.GeoHibPV.Enable==1)
                    fprintf(ident,' - Potència pic instal·lada [kWp]:\t\t\t\t   %.1f\t\t\t',app.GeokWpPV.Value);
                else
                    fprintf(ident,' - Potència pic instal·lada [kWp]:\t\t\t\t   N.A\t\t\t');
                end
                if (app.AltHibPV.Value==1)&&(app.AltHibPV.Enable==1)
                    fprintf(ident,'    %.1f\n',app.AltkWpPV.Value);
                else
                    fprintf(ident,'    N.A.\n');
                end
                if (app.GeoHibPV.Value==1)&&(app.GeoHibPV.Enable==1)
                    fprintf(ident,' - Àrea estimada panells [m2]:\t\t\t\t\t   %.1f\t\t\t',app.Geom2PV.Value);
                else
                    fprintf(ident,' - Àrea estimada panells [m2]:\t\t\t\t\t   N.A\t\t\t');
                end
                if (app.AltHibPV.Value==1)&&(app.AltHibPV.Enable==1)
                    fprintf(ident,'    %.1f\n',app.Altm2PV.Value);
                else
                    fprintf(ident,'    N.A.\n');
                end
                fprintf(ident,'                                                              --------------------------------------- \n');
                if (app.GeoBat.Value==1)&&(app.GeoBat.Enable==1)
                    fprintf(ident,' - Acumulació amb bateries?\t\t\t\t\t   Sí\t\t\t');
                else
                    fprintf(ident,' - Acumulació amb bateries?\t\t\t\t\t   No\t\t\t');
                end
                if (app.AltBat.Value==1)&&(app.AltBat.Enable==1)
                    fprintf(ident,'    Sí\n');
                else
                    fprintf(ident,'    No\n');
                end
                if (app.GeoBat.Value==1)&&(app.GeoBat.Enable==1)
                    fprintf(ident,' - Capacitat nominal [kWh]:\t\t\t\t\t   %.1f\t\t\t',app.PVGeoEneBat.Value);
                else
                    fprintf(ident,' - Capacitat nominal [kWh]:\t\t\t\t\t   N.A.\t\t\t');
                end
                if (app.AltBat.Value==1)&&(app.AltBat.Enable==1)
                    fprintf(ident,'    %.1f\n',app.PVAltEneBat.Value);
                else
                    fprintf(ident,'    N.A.\n');
                end
                fprintf(ident,'                                                              --------------------------------------- \n\n');
                
                fprintf(ident,'======================================================================================================\n\n');
                fprintf(ident,'PREVISIÓ DE COSTOS (I.V.A. INCLÓS) I POSSIBLE FINANÇAMENT\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                fprintf(ident,'                        \t\t\t\t\tGEOTÈRMIA\t\tALTERNATIVA\n');
                fprintf(ident,'                                                              --------------------------------------- \n');
                fprintf(ident,' - Camp de captació [€]:\t\t\t\t\t   %5.0f\t\t   N.A.\n',CostCaptacio);
                fprintf(ident,' - Equips + instal·lació [€]:\t\t\t\t\t   %5.0f\t\t   %5.0f\n',CostEqGeo,CostEqAlt);
                fprintf(ident,' - Panells solars fotovoltàics [€]:\t\t\t\t   %5.0f\t\t   %5.0f\n',CostPanGeo,CostPanAlt);
                fprintf(ident,' - Bateries [€]:\t\t\t\t\t\t   %5.0f\t\t   %5.0f\n',CostBatGeo,CostBatAlt);
                fprintf(ident,'                                                              --------------------------------------- \n');
                fprintf(ident,' - COST D''INVERSIÓ DE CADA OPCIÓ:\t\t\t\t   %5.0f\t\t   %5.0f\n',CostInvGeo,CostInvAlt);
                fprintf(ident,'\t - Finançament mitjançant subvenció [€]:\t\t   %5.0f\t\t   %5.0f\n',ImpSubvGeo,ImpSubvAlt);
                fprintf(ident,'\t - Finançament mitjançant prèstec [€]:\t\t\t   %5.0f\t\t   %5.0f\n',ImpPresGeo,ImpPresAlt);                
                fprintf(ident,'                                                              --------------------------------------- \n\n');
                fprintf(ident,' - COST EXTRA INVERSIÓ GEOTÈRMIA [€]:\t\t\t\t   %5.0f\n\n',CostInvExtGeo);
                fprintf(ident,'\nRENDIBILITAT ECONÒMICA I EMISSIONS\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                fprintf(ident,' - Estalvi total esperat (costos operacionals) a %d anys:\t%7.0f € (%0.1f %%)\n',app.vidaprojecte.Value,app.EstalviNetTotal.Value,app.Estalviesperat.Value);
                fprintf(ident,' - Benefici net (diferència entre despeses acumulades totals):\t%7.0f €\n',1000*benefici_net);
                fprintf(ident,' - Rendibilitat neta (benefici net / cost inversió extra):\t   %s %%\n',app.RendibNetTotal.Value);%rendibilitat_neta);
                fprintf(ident,' - Període de retorn de la inversió (simple):\t\t\t   %s anys\n',app.Paybacktext.Value);
                fprintf(ident,' - Valor actualitzat net (VAN):\t\t\t\t\t%7.0f €\n',app.VAN.Value);
                fprintf(ident,' - Taxa interna de retorn (taxa de descompte tal que VAN=0):\t     %s %%\n',app.TIRt.Value);
                fprintf(ident,' - Període de retorn de la inversió (descomptat):\t\t    %s anys\n',app.PaybackDesctext.Value);
                fprintf(ident,' - Estalvi d''emissions de CO2 equivalents:\t\t\t%7.1f kg/any (%.1f %%)\n',app.EstalviCO2.Value,app.EstalviCO2rel.Value);
                
                fprintf(ident,'\nCONSUM ELÈCTRIC I AUTOPRODUCIÓ (EN CAS D''HIBRIDACIÓ AMB ENERGIA SOLAR FOTOVOLTÀICA)\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                if ((app.GeoHibPV.Value==1)&&(app.GeoHibPV.Enable==1))||((app.AltHibPV.Value==1)&&(app.AltHibPV.Enable==1))
                    fprintf(ident,'                        \t\t\t\t\tGEOTÈRMIA\t\tALTERNATIVA\n');
                    fprintf(ident,'                                                              --------------------------------------- \n');
                    fprintf(ident,' - Consum anual electricitat [kWh]:\t\t\t\t%6.2f\t\t%6.2f\n',1000*app.GeoCons_e.Value,1000*app.AltCons_e.Value);
                    fprintf(ident,' - Autoproducció anual d''electricitat [kWh]:\t\t\t%6.2f\t\t%6.2f\n',1000*app.GeoCons_e.Value,1000*app.AltCons_e.Value);
                    fprintf(ident,' - Excedents anuals bolcats a xarxa [kWh]:\t\t\t%6.2f\t\t\t%6.2f\n',1000*app.GeoAutoprod_e.Value,1000*app.AltAutoprod_e.Value);
                    fprintf(ident,' - Grau d''autoconsum [%%]:\t\t\t\t\t%6.2f\t\t\t%6.2f\n',app.GeoAutocons_e.Value,app.AltAutocons_e.Value);
                    fprintf(ident,' - Despesa neta acumulada electricitat [€]:\t\t\t%6.2f\t\t%6.2f\n',app.GeoDesp_e.Value,app.AltDesp_e.Value);
                    fprintf(ident,' - Estalvi en la factura de la llum [%%]:\t\t\t%6.2f\t\t\t%6.2f\n',app.GeoEstalviAutoc_e.Value,app.AltEstalviAutoc_e.Value);
                    fprintf(ident,'                                                              --------------------------------------- \n\n');
                else
                    fprintf(ident,'');
                end
                fprintf(ident,'\nCONCLUSIONS\n');
                fprintf(ident,'------------------------------------------------------------------------------------------------------\n\n');
                fprintf(ident,' - %s\n   %s\n   %s\n',app.Rendibilitat1.Text,app.Rendibilitat2.Text,app.Rendibilitat3.Text);
                fprintf(ident,' - %s\n',app.RecuperacioInversio.Text);
                fprintf(ident,' - Estalvi emissions CO2 equivalents: %s\n',app.BeneficisAmbientals.Text);
                fprintf(ident,'\n======================================================================================================\n\n');
            end
            
            fclose(ident);
            
            if app.InformeTOT.Enable==0
                alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                uialert(alert_fig,'S''ha generat un informe dels resultats del disseny. Trobi el document a la ubicació seleccionada','Informe generat','Icon','success')
            else
                alert_fig = app.GeoSIVdesigntoolv10UIFigure;
                uialert(alert_fig,'S''ha generat un informe dels resultats del disseny i de l''anàlisi econòmica i ambiental. Trobi el document a la ubicació seleccionada','Informe generat','Icon','success')
            end
        end

        % Value changed function: SwitchTipusProjecte
        function Switch_Nova_o_Existent(app, event)
           param=readmatrix("018_parametersEcoAmb.xlsx");
           Area_ST=app.AreaMinST.Value;
           TipIns = string(app.SwitchTipusProjecte.Value);
           if TipIns=="Nova instal·lació"
               app.vidaprojexistent.Enable=0;
%                app.vidaprojexistent.Value=0;
               app.AltSubv.Enable=1;
               app.AltPres.Enable=1;
               Nova=1;
           else 
               app.vidaprojexistent.Enable=1;
               Nova=0;
               if app.AltHibPV.Value==true
                   app.AltSubv.Enable=1;
                   app.AltPres.Enable=1;
               else
                   app.AltSubv.Enable=0;
                   app.AltSubv.Value=false;
                   app.ImportAltSubv.Enable=0;
                   app.AltPres.Enable=0;
                   app.AltPres.Value=false;
                   app.TramAltPres.Enable=0;
                   app.InteresAltPres.Enable=0;
                   app.RetornAltPres.Enable=0;
               end
           end
           P_insG = getappdat(0,'Pot_G');
           P_insA = P_insG;
           P_inscA = app.PotInsRefrkW.Value;
           P_insACSA = 1.1*app.PotDis_ACSkW.Value;
           Op_h=app.OpcionsAlternativa_h.Value;
           Op_c=app.OpcionsAlternativa_c.Value;
           Op_ACS=app.OpcionsAlternativa_ACS.Value;
           if (app.ProdCalfMWht.Value==0)
               app.CAPEXAlth.Value=0;
               app.OpcionsAlternativa_h.Enable=0;
               app.CostEspecInstAlt_h.Enable=0;
               app.CostEspecificAlt_h.Enable=0;
               app.MantAlt_h.Enable=0;
               app.OpcionsAlternativa_c.Enable=1;
               app.CostEspecInstAlt_c.Enable=1;
               app.CostEspecificAlt_c.Enable=1;
               app.MantAlt_c.Enable=1; 
               if (Op_c=="Aerotèrmia")||(app.Prod_ACSMWht.Value==0)
                  app.OpcionsAlternativa_ACS.Enable=0;
                  app.CostEspecificAlt_ACS.Enable=0;
                  app.CostEspecInstAlt_ACS.Enable=0;
                  app.MantAlt_ACS.Enable=0;
                  app.CAPEXAltACS.Value=0;
                  app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA,P_insACSA);
               else
                  app.OpcionsAlternativa_ACS.Enable=1;
                  app.CostEspecInstAlt_ACS.Enable=1;
                  app.CostEspecificAlt_ACS.Enable=1;
                  app.MantAlt_ACS.Enable=1; 
                  app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                  if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                     app.CAPEXAltACS.Value=Nova*(Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA);%+CAPEXPVAlt;
                     CostAltACSsup=0;
                  else 
                     if app.Prod_ACSMWht.Value<5 %JGC: Tarifa 3.1 GN
                        Escomesa=param(20);
                        Inst_esp=param(21);
                        Cald_esp=param(22);
                     elseif (app.Prod_ACSMWht.Value>=5)&&(app.Prod_ACSMWht.Value<15) %JGC: Tarifa 3.2(a) GN
                        Escomesa=param(26);
                        Inst_esp=param(27);
                        Cald_esp=param(28);
                     elseif (app.Prod_ACSMWht.Value>=15)&&(app.Prod_ACSMWht.Value<50) %JGC: Tarifa 3.2(b) GN
                        Escomesa=param(32);
                        Inst_esp=param(33);
                        Cald_esp=param(34);
                     elseif (app.Prod_ACSMWht.Value>=50)&&(app.Prod_ACSMWht.Value<100) %JGC: Tarifa 3.3 GN
                        Escomesa=param(38);
                        Inst_esp=param(39);
                        Cald_esp=param(40);
                     elseif (app.Prod_ACSMWht.Value>=100) %JGC: Tarifa 3.4 GN
                        Escomesa=param(44);
                        Inst_esp=param(45);
                        Cald_esp=param(46);
                     end
                     CostAltACSsup=Escomesa+(Inst_esp+Cald_esp)*P_insACSA; %JGC: Asumim que la instal·lació de suport en aquest cas és Gas Natural
                  end
                  app.CAPEXAltACS.Value=Nova*((app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST)+CostAltACSsup;   
               end
            else % JGC: calor>0
               app.OpcionsAlternativa_h.Enable=1;
               app.CostEspecInstAlt_h.Enable=1;
               app.CostEspecificAlt_h.Enable=1;
               app.MantAlt_h.Enable=1;
               if Op_h=="Aerotèrmia"
                  app.OpcionsAlternativa_c.Enable=0;
                  app.CostEspecInstAlt_c.Enable=0;
                  app.CostEspecificAlt_c.Enable=0;
                  app.MantAlt_c.Enable=0;
                  app.OpcionsAlternativa_ACS.Enable=0;
                  app.CostEspecificAlt_ACS.Enable=0;
                  app.CostEspecInstAlt_ACS.Enable=0;
                  app.MantAlt_ACS.Enable=0;
                  app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                  app.CAPEXAltc.Value=0;
                  app.CAPEXAltACS.Value=0;
               elseif Op_h=="Biomassa"% JGC: calor>0;
                  app.OpcionsAlternativa_ACS.Enable=0;
                  app.CostEspecificAlt_ACS.Enable=0;
                  app.CostEspecInstAlt_ACS.Enable=0;
                  app.MantAlt_ACS.Enable=0;
                  app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                  app.CAPEXAltACS.Value=0;
                  if app.ProdRefrMWht.Value==0
                     app.OpcionsAlternativa_c.Enable=0;
                     app.CostEspecInstAlt_c.Enable=0;
                     app.CostEspecificAlt_c.Enable=0;
                     app.MantAlt_c.Enable=0;
                     app.CAPEXAltc.Value=0;
                  else
                     app.OpcionsAlternativa_c.Enable=1;
                     app.CostEspecInstAlt_c.Enable=1;
                     app.CostEspecificAlt_c.Enable=1;
                     app.MantAlt_c.Enable=1;
                     app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                  end      
               else % JGC:calor>0; Op_h no és "Biomassa" ni "Aerotèrmia"
                  if app.ProdRefrMWht.Value==0
                     app.OpcionsAlternativa_c.Enable=0;
                     app.CostEspecInstAlt_c.Enable=0;
                     app.CostEspecificAlt_c.Enable=0;
                     app.MantAlt_c.Enable=0;
                     app.CAPEXAltc.Value=0;
                     app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                     if app.Prod_ACSMWht.Value==0
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        app.CAPEXAltACS.Value=0;
                     else
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                     end
                  else % JGC:calor>0; fred>0 Op_h no és "Biomassa" ni "Aerotèrmia"
                     app.OpcionsAlternativa_c.Enable=1;
                     app.CostEspecInstAlt_c.Enable=1;
                     app.CostEspecificAlt_c.Enable=1;
                     app.MantAlt_c.Enable=1;
                     if (app.Prod_ACSMWht.Value==0)||(Op_c=="Aerotèrmia")
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA,P_insACSA);
                        app.CAPEXAltACS.Value=0;
                     else %JGC:calor>0; fred>0; ACS>0, Op_h no és "Biomassa" ni "Aerotèrmia"; Op_c no és "Aerotèrmia"
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        app.CAPEXAlth.Value=Nova*(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        app.CAPEXAltc.Value=Nova*(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           app.CAPEXAltACS.Value=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                     end
                  end
               end
           end
           app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
           LimSubvAlt=floor(app.CAPEXAltTOT.Value);
           if LimSubvAlt==0
               app.AltSubv.Enable=0;
               app.AltSubv.Value=0;
               app.ImportAltSubv.Value=0;
               app.ImportAltSubv.Enable=0;
               app.AltPres.Value=0;
               app.AltPres.Enable=0;
               app.TramAltPres.Value=0;
               app.TramAltPres.Enable=0;
               app.InteresAltPres.Enable=0;
               app.RetornAltPres.Enable=0;
           else
               app.ImportAltSubv.Limits=[0 LimSubvAlt];  
           end
        end

        % Value changed function: vidaprojecte
        function Input_VidaProjecte(app, event)
            value = app.vidaprojecte.Value;
            limsup=value+1;
            app.RetornGeoPres.Limits=[0 value];
            app.RetornAltPres.Limits=[0 value];
            app.UIplotFluxeVAN.XLim = [0 limsup];
        end

        % Value changed function: CostEspecInstGeo, 
        % CostEspecificGeo, CostEspecificPous
        function Input_CostosGeotermia(app, event)
            %JGC recalculem el cost d'inversió inicial de la Geotèrmia si canviem el valor per defecte del cost específic dels pous.
            c_pous = app.CostEspecificPous.Value;
            c_inst= app.CostEspecInstGeo.Value;
            c_BCG= app.CostEspecificGeo.Value;
            n_pou=app.n_well.Value;
            LBHE=app.l_well.Value;
%             CAPEXPVGeo=app.PVGeoCostInv.Value;
%             P_insGChoose=[app.PotInsCalfkW.Value app.PotInsRefrkW.Value app.PotInsACSkW.Value];
%             P_insG=max(P_insGChoose);
            P_insG = getappdata(0,'Pot_G');
            app.CAPEXGeo.Value=c_pous*n_pou*LBHE+(c_inst+c_BCG)*P_insG;
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];
        end

        % Value changed function: OpcionsAlternativa_h
        function Choose_Alternativa_h(app, event)
            param=readmatrix("018_parametersEcoAmb.xlsx");
            hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");
            SPFCalfAER1=getappdata(0,'SPFc_AER1');
            SPFRefrAER1=getappdata(0,'SPFr_AER1');
            SPFACSAER1=getappdata(0,'SPFacs_AER1');
            SPFRefrAER2=getappdata(0,'SPFr_AER2');
            COP_AER1m=getappdata(0,'COP_AER1_m');
            COP_AER1ACSm=getappdata(0,'COP_AER1ACS_m');
            EER_AER1m=getappdata(0,'EER_AER1_m');
            EER_AER2m=getappdata(0,'EER_AER2_m');
            app.CostEspecInstAlt_h.Enable=1;
            app.CostEspecificAlt_h.Enable=1;
            app.MantAlt_h.Enable=1;
            P_insG=getappdata(0,'Pot_G'); %JGC: [kW] potència instal·lada a efectes de càlcul econòmic.
            P_insA=P_insG;
            P_inshA=app.PotInsCalfkW.Value;
            Cons_h=app.ProdCalfMWht.Value;
            P_inscA=app.PotInsRefrkW.Value;
            Cons_c=app.ProdRefrMWht.Value;
            P_insACSA=1.1*app.PotDis_ACSkW.Value;
            Cons_ACS=app.Prod_ACSMWht.Value;
            Op_h=app.OpcionsAlternativa_h.Value;
            Op_c=app.OpcionsAlternativa_c.Value;
            Op_ACS=app.OpcionsAlternativa_ACS.Value;
            Area_ST=app.AreaMinST.Value;
            cons_e_biom=param(8); %JGC: [kWe/kWt] potència elèctrica específica d'una caldera de biomassa
            cons_e_ST=param(15);
            if app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end
            if Op_h=="Biomassa"
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de biomassa per a la producció de CALOR (i ACS) amb un rendiment del ' num2str(param(7)) ' %'];
                app.CostEscomesa.Visible=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEscomesa.Enable=0;
                app.CostEscomesa.Value=0;
                app.CostEspecInstAlt_h.Value=param(1); %JGC [€/kW]
                app.CostEspecificAlt_h.Value=param(2); %JGC [€/kW]
                app.MantAlt_h.Value=param(3);%JGC [€/kW/any]
                app.ConElecAlthMWhe.Value=Cons_h*cons_e_biom; %JGC: Consum elèctric de l'alternativa calefacció (biomassa per defecte)
                app.PotElecAlthkW.Value=P_inshA*cons_e_biom;
                if Cons_c>0
                    if Op_c=="Aerotèrmia"
                        app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f')];
                    end
                    app.OpcionsAlternativa_c.Enable=1;
                    app.CostEspecInstAlt_c.Enable=1;
                    app.CostEspecificAlt_c.Enable=1;
                    app.MantAlt_c.Enable=1;
                else
                    app.Label_EcoAmbObs4.Text='';
                    app.OpcionsAlternativa_c.Enable=0;
                    app.CostEspecInstAlt_c.Enable=0;
                    app.CostEspecificAlt_c.Enable=0;
                    app.MantAlt_c.Enable=0;
                end
                app.OpcionsAlternativa_ACS.Value="Biomassa";
                app.OpcionsAlternativa_ACS.Enable=0;
                app.CostEspecInstAlt_ACS.Enable=0;
                app.LabelCostEspecInstAlt_ACS.Text="€/kW";
                app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_h.Value;
                app.CostEspecificAlt_ACS.Enable=0;
                app.LabelCostEspecificAlt_ACS.Text="€/kW";
                app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_h.Value;
                app.MantAlt_ACS.Enable=0;
                app.LabelMantAlt_ACS.Text="€/kW";
                app.MantAlt_ACS.Value=app.MantAlt_h.Value;
                CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                CostAltACS=0;
                app.CostkgBiom.Enable=1;
                app.Infl_AnualVar_Biom.Enable=1;
                app.CostkgBiom.Value=param(4);
                app.Infl_AnualVar_Biom.Value=param(6);
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.CostGC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostkgGC.Enable=0;
                app.Infl_AnualVar_GC.Enable=0;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0;               
            elseif Op_h=="Gas Natural"
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de condensació de gas natural per a la producció de CALOR amb un rendiment del ' num2str(param(18)) ' %'];
                app.CostEscomesa.Visible=1;
                app.Label_CostEscomesa.Visible=1;
                app.CostEscomesa.Enable=1;
                Cons_Calor=Cons_h+Cons_ACS*0.4;
                if Cons_Calor<5 %JGC: Tarifa 3.1 GN
                    app.CostEscomesa.Value=param(20);
                    app.CostEspecInstAlt_h.Value=param(21);
                    app.CostEspecificAlt_h.Value=param(22);
                    app.MantAlt_h.Value=param(23);
                    app.CostkWhGN.Value=param(24);
                    app.CostGN.Value=param(25);
                elseif (Cons_Calor>=5)&&(Cons_Calor<15) %JGC: Tarifa 3.2(a) GN
                    app.CostEscomesa.Value=param(26);
                    app.CostEspecInstAlt_h.Value=param(27);
                    app.CostEspecificAlt_h.Value=param(28);
                    app.MantAlt_h.Value=param(29);
                    app.CostkWhGN.Value=param(30);
                    app.CostGN.Value=param(31);
                elseif (Cons_Calor>=15)&&(Cons_Calor<50) %JGC: Tarifa 3.2(b) GN
                    app.CostEscomesa.Value=param(32);
                    app.CostEspecInstAlt_h.Value=param(33);
                    app.CostEspecificAlt_h.Value=param(34);
                    app.MantAlt_h.Value=param(35);
                    app.CostkWhGN.Value=param(36);
                    app.CostGN.Value=param(37);
                elseif (Cons_Calor>=50)&&(Cons_Calor<100) %JGC: Tarifa 3.3 GN
                    app.CostEscomesa.Value=param(38);
                    app.CostEspecInstAlt_h.Value=param(39);
                    app.CostEspecificAlt_h.Value=param(40);
                    app.MantAlt_h.Value=param(41);
                    app.CostkWhGN.Value=param(42);
                    app.CostGN.Value=param(43);
                elseif (Cons_Calor>=100) %JGC: Tarifa 3.4 GN
                    app.CostEscomesa.Value=param(44);
                    app.CostEspecInstAlt_h.Value=param(45);
                    app.CostEspecificAlt_h.Value=param(46);
                    app.MantAlt_h.Value=param(47);
                    app.CostkWhGN.Value=param(48);
                    app.CostGN.Value=param(49);
                end
                app.ConElecAlthMWhe.Value=0;
                app.PotElecAlthkW.Value=0;
                if (Cons_c>0)
                    app.OpcionsAlternativa_c.Enable=1;
                    app.CostEspecInstAlt_c.Enable=1;
                    app.CostEspecificAlt_c.Enable=1;
                    app.MantAlt_c.Enable=1;
                    if (Cons_ACS>0)
                        if (Op_c=="Aerotèrmia")
                            app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_c.Value;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_c.Value;
                            app.MantAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Value=app.MantAlt_c.Value;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA, P_insACSA);
                            CostAltACS=0;
%                             app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de CALOR i ACS amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                        else
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
%                             app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aire condicionat amb un rendiment estacional SPFr = ' num2str(SPFRefrAER2,'%.2f') '. '];

                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
%                                if Op_ACS=="Aerotèrmia"
%                                    txtObs3_2= ['Rendiment aerotèrmia (bomba de calor aerotèrmica aire-aigua): COP_ACS=' num2str(param(125)/100) '.'];
%                                else
%                                    txtObs3_2='';
%                                end
                            else
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
%                                txtObs3_2=['Rendiment col·lectors solars tèrmics (valor mitjà anual): ' num2str(param(14)) ' %.'];
                            end
%                              app.Label_EcoAmbObs3.Text=[txtObs3_1 txtObs3_2];
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    end
                else
                    app.Label_EcoAmbObs4.Text='';
                    app.OpcionsAlternativa_c.Enable=0;
                    app.CostEspecInstAlt_c.Enable=0;
                    app.CostEspecificAlt_c.Enable=0;
                    app.MantAlt_c.Enable=0;
                    CostAltc=0;
                    if (Cons_ACS>0)
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltACS=0;
                    end
                end    
                if (app.OpcionsAlternativa_ACS.Enable==1)&&(Op_ACS=="Biomassa")
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                end
                app.CostGN.Enable=1;
                app.Infl_AnualFix_GN.Enable=1;
                app.CostkWhGN.Enable=1;
                app.Infl_AnualVar_GN.Enable=1;
                app.CostGC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostkgGC.Enable=0;
                app.Infl_AnualVar_GC.Enable=0;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0;   
            elseif Op_h=="Gas Propà"
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de condensació de gas propà per a la producció de CALOR amb un rendiment del ' num2str(param(52)) ' %'];
                app.CostEscomesa.Visible=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEscomesa.Enable=0;
                app.CostEscomesa.Value=0;
                Cons_Calor=Cons_h+Cons_ACS*0.4;
                app.CostGC.Value=0;
                if Cons_Calor<5 %JGC: equivalent a Tarifa 3.1 GN
                    app.CostEspecInstAlt_h.Value=param(55);
                    app.CostEspecificAlt_h.Value=param(56);
                    app.MantAlt_h.Value=param(57);
                    app.CostkgGC.Value=param(58);
                    app.CostGC.Value=0;
                elseif (Cons_Calor>=5)&&(Cons_Calor<15) %JGC: equivalent a Tarifa 3.2(a) GN
                    app.CostEspecInstAlt_h.Value=param(65);
                    app.CostEspecificAlt_h.Value=param(66);
                    app.MantAlt_h.Value=param(67);
                    app.CostkgGC.Value=param(68);
                    app.CostGC.Value=0;
                elseif (Cons_Calor>=15)&&(Cons_Calor<50) %JGC: equivalent a Tarifa 3.2(b) GN
                    app.CostEspecInstAlt_h.Value=param(75);
                    app.CostEspecificAlt_h.Value=param(76);
                    app.MantAlt_h.Value=param(77);
                    app.CostkgGC.Value=param(78);
                    app.CostGC.Value=0;
                elseif (Cons_Calor>=50) %JGC: equivalent a Tarifa 3.3/3.4 GN
                    app.CostEspecInstAlt_h.Value=param(85);
                    app.CostEspecificAlt_h.Value=param(86);
                    app.MantAlt_h.Value=param(87);
                    app.CostkgGC.Value=param(88);
                    app.CostGC.Value=0;
                end
                app.ConElecAlthMWhe.Value=0;
                app.PotElecAlthkW.Value=0;
                if (Cons_c>0)
                    app.OpcionsAlternativa_c.Enable=1;
                    app.CostEspecInstAlt_c.Enable=1;
                    app.CostEspecificAlt_c.Enable=1;
                    app.MantAlt_c.Enable=1;
                    if (Cons_ACS>0)
                        if (Op_c=="Aerotèrmia")
                            app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_c.Value;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_c.Value;
                            app.MantAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Value=app.MantAlt_c.Value;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA, P_insACSA);
                            CostAltACS=0;
                        else
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                            else
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                            end
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    end
                else
                    app.Label_EcoAmbObs4.Text='';
                    app.OpcionsAlternativa_c.Enable=0;
                    app.CostEspecInstAlt_c.Enable=0;
                    app.CostEspecificAlt_c.Enable=0;
                    app.MantAlt_c.Enable=0;
                    CostAltc=0;
                    if (Cons_ACS>0)
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltACS=0;
                    end
                end    
                if (app.OpcionsAlternativa_ACS.Enable==1)&&(Op_ACS=="Biomassa")
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                end
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.CostGC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostkgGC.Enable=1;
                app.Infl_AnualVar_GC.Enable=1;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0;   
            elseif app.OpcionsAlternativa_h.Value=="Gas Propà Canalitzat"
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de condensació de gas propà per a la producció de CALOR amb un rendiment del ' num2str(param(52)) ' %'];
                app.CostEscomesa.Visible=1;
                app.Label_CostEscomesa.Visible=1;
                app.CostEscomesa.Enable=1;
                Cons_Calor=Cons_h+Cons_ACS*0.4;
                if Cons_Calor<5 %JGC: equivalent a Tarifa 3.1 GN
                    app.CostEscomesa.Value=param(59);
                    app.CostEspecInstAlt_h.Value=param(60);
                    app.CostEspecificAlt_h.Value=param(61);
                    app.MantAlt_h.Value=param(62);
                    app.CostkgGC.Value=param(63);
                    app.CostGC.Value=param(64);
                elseif (Cons_Calor>=5)&&(Cons_Calor<15) %JGC: equivalent a Tarifa 3.2(a) GN
                    app.CostEscomesa.Value=param(69);
                    app.CostEspecInstAlt_h.Value=param(70);
                    app.CostEspecificAlt_h.Value=param(71);
                    app.MantAlt_h.Value=param(72);
                    app.CostkgGC.Value=param(73);
                    app.CostGC.Value=param(74);
                elseif (Cons_Calor>=15)&&(Cons_Calor<50) %JGC: equivalent a Tarifa 3.2(b) GN
                    app.CostEscomesa.Value=param(79);
                    app.CostEspecInstAlt_h.Value=param(80);
                    app.CostEspecificAlt_h.Value=param(81);
                    app.MantAlt_h.Value=param(82);
                    app.CostkgGC.Value=param(83);
                    app.CostGC.Value=param(84);
                elseif (Cons_Calor>=50) %JGC: equivalent a Tarifa 3.3/3.4 GN
                    app.CostEscomesa.Value=param(89);
                    app.CostEspecInstAlt_h.Value=param(90);
                    app.CostEspecificAlt_h.Value=param(91);
                    app.MantAlt_h.Value=param(92);
                    app.CostkgGC.Value=param(93);
                    app.CostGC.Value=param(94);
                end
                app.ConElecAlthMWhe.Value=0;
                app.PotElecAlthkW.Value=0;
                if (Cons_c>0)
                    app.OpcionsAlternativa_c.Enable=1;
                    app.CostEspecInstAlt_c.Enable=1;
                    app.CostEspecificAlt_c.Enable=1;
                    app.MantAlt_c.Enable=1;
                    if (Cons_ACS>0)
                        if (Op_c=="Aerotèrmia")
                            app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_c.Value;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_c.Value;
                            app.MantAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Value=app.MantAlt_c.Value;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA, P_insACSA);
                            CostAltACS=0;
                        else
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                            else
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                            end
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    end
                else
                    app.Label_EcoAmbObs4.Text='';
                    app.OpcionsAlternativa_c.Enable=0;
                    app.CostEspecInstAlt_c.Enable=0;
                    app.CostEspecificAlt_c.Enable=0;
                    app.MantAlt_c.Enable=0;
                    CostAltc=0;
                    if (Cons_ACS>0)
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltACS=0;
                    end
                end    
                if (app.OpcionsAlternativa_ACS.Enable==1)&&(Op_ACS=="Biomassa")
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                end
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.CostGC.Enable=1;
                app.Infl_AnualFix_GC.Enable=1;
                app.CostkgGC.Enable=1;
                app.Infl_AnualVar_GC.Enable=1;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0;  
            elseif app.OpcionsAlternativa_h.Value=="Gasoil"
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de gasoil per a la producció de CALOR amb un rendiment del ' num2str(param(100)) ' %'];
                app.CostEscomesa.Visible=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEscomesa.Enable=0;
                app.CostEscomesa.Value=0;
                app.CostEspecInstAlt_h.Value=param(95);
                app.CostEspecificAlt_h.Value=param(96); %JGC [€/kW]
                app.MantAlt_h.Value=param(97);%JGC [€/kW/any]
                app.ConElecAlthMWhe.Value=0;
                app.PotElecAlthkW.Value=0;
                if (Cons_c>0)
                    app.OpcionsAlternativa_c.Enable=1;
                    app.CostEspecInstAlt_c.Enable=1;
                    app.CostEspecificAlt_c.Enable=1;
                    app.MantAlt_c.Enable=1;
                    if (Cons_ACS>0)
                        if (Op_c=="Aerotèrmia")
                            app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_c.Value;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_c.Value;
                            app.MantAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Value=app.MantAlt_c.Value;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA, P_insACSA);
                            CostAltACS=0;
                        else
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                            else
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                            end
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    end
                else
                    app.Label_EcoAmbObs4.Text='';
                    app.OpcionsAlternativa_c.Enable=0;
                    app.CostEspecInstAlt_c.Enable=0;
                    app.CostEspecificAlt_c.Enable=0;
                    app.MantAlt_c.Enable=0;
                    CostAltc=0;
                    if (Cons_ACS>0)
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltACS=0;
                    end
                end    
                if (app.OpcionsAlternativa_ACS.Enable==1)&&(Op_ACS=="Biomassa")
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                end
                if (app.OpcionsAlternativa_ACS.Enable==1)&&(app.OpcionsAlternativa_ACS.Value=="Biomassa")
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                end
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.CostGC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostkgGC.Enable=0;
                app.Infl_AnualVar_GC.Enable=0;
                app.CostlGasoil.Enable=1;
                app.Infl_AnualVar_Gasoil.Enable=1; 
            elseif Op_h=="Electricitat"
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera elèctrica (efecte Joule) per a la producció de CALOR amb un rendiment del ' num2str(param(114)) ' %'];
                app.CostEscomesa.Visible=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEscomesa.Enable=0;
                app.CostEscomesa.Value=0;
                app.CostEspecInstAlt_h.Value=param(111); %JGC [€/kW]
                app.CostEspecificAlt_h.Value=param(112); %JGC [€/kW]
                app.MantAlt_h.Value=param(113);%JGC [€/kW/any]
                COP_EP=0.01*param(114); %JGC: eficiència d'un sistema elèctric de dissipació per efecte joule.
                app.ConElecAlthMWhe.Value=Cons_h/COP_EP; %JGC: consum elèctric corresponent a un sistema elèctric de dissipació per efecte joule
                app.PotElecAlthkW.Value=P_inshA/COP_EP;
                if (Cons_c>0)
                    app.OpcionsAlternativa_c.Enable=1;
                    app.CostEspecInstAlt_c.Enable=1;
                    app.CostEspecificAlt_c.Enable=1;
                    app.MantAlt_c.Enable=1;
                    if (Cons_ACS>0)
                        if (Op_c=="Aerotèrmia")
                            app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_c.Value;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_c.Value;
                            app.MantAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Value=app.MantAlt_c.Value;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*max(P_inscA, P_insACSA);
                            CostAltACS=0;
                        else
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                            else
                               CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                            end
                        end
                    else 
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    end
                else
                    app.Label_EcoAmbObs4.Text='';
                    app.OpcionsAlternativa_c.Enable=0;
                    app.CostEspecInstAlt_c.Enable=0;
                    app.CostEspecificAlt_c.Enable=0;
                    app.MantAlt_c.Enable=0;
                    CostAltc=0;
                    if (Cons_ACS>0)
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        if (Op_ACS=="Biomassa")||(Op_ACS=="Aerotèrmia")
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                        else
                           CostAltACS=Nova*(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
                        end
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltACS=0;
                    end
                end    
                if (app.OpcionsAlternativa_ACS.Enable==1)&&(Op_ACS=="Biomassa")
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                end
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.CostGC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostkgGC.Enable=0;
                app.Infl_AnualVar_GC.Enable=0;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0; 
            elseif Op_h=="Aerotèrmia"
                app.Label_EcoAmbObs3.Text=['Es considera un sistema d''aerotèrmia per a producció de CALOR, FRED i ACS amb un rendiment estacional SPFc = ' num2str(SPFCalfAER1,'%.2f') ' , SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                app.Label_EcoAmbObs4.Text='';
                app.CostEscomesa.Visible=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEscomesa.Enable=0;
                app.CostEscomesa.Value=0;
                app.CostEspecInstAlt_h.Value=param(120); %JGC [€/kW]
                app.CostEspecificAlt_h.Value=param(121); %JGC [€/kW]
                app.MantAlt_h.Value=param(122);%JGC [€/kW/any]
                app.OpcionsAlternativa_c.Enable=0;
                app.OpcionsAlternativa_c.Value="Aerotèrmia";
                app.CostEspecInstAlt_c.Enable=0;
                app.CostEspecInstAlt_c.Value=app.CostEspecInstAlt_h.Value;
                app.CostEspecificAlt_c.Enable=0;
                app.CostEspecificAlt_c.Value=app.CostEspecificAlt_h.Value;
                app.MantAlt_c.Enable=0;
                app.MantAlt_c.Value=app.MantAlt_h.Value;
                app.OpcionsAlternativa_ACS.Enable=0;
                app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                app.CostEspecInstAlt_ACS.Enable=0;
                app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_h.Value;
                app.CostEspecificAlt_ACS.Enable=0;
                app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_h.Value;
                app.MantAlt_ACS.Enable=0;
                app.MantAlt_ACS.Value=app.MantAlt_h.Value;
                app.ConElecAlthMWhe.Value=Cons_h/SPFCalfAER1; %JGC: consum elèctric corresponent a una aerotèrmia aire-aigua en mode calefacció
                app.PotElecAlthkW.Value=P_inshA/min(COP_AER1m);
                app.CostEspecificAlt_c.Enable=0;
                app.MantAlt_c.Enable=0;
                app.CostkgBiom.Enable=0;
                app.Infl_AnualVar_Biom.Enable=0;
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
                app.CostGC.Enable=0;
                app.Infl_AnualFix_GC.Enable=0;
                app.CostkgGC.Enable=0;
                app.Infl_AnualVar_GC.Enable=0;
                app.CostlGasoil.Enable=0;
                app.Infl_AnualVar_Gasoil.Enable=0; 
                CostAlth=app.CostEscomesa.Value+(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                CostAltc=0;
                CostAltACS=0;
            end 
            %JGC: Introduïm els canvis necessaris a les altres opcions (fred i ACS) segons l'elecció feta de sistema de calor
            if Op_h=="Biomassa"
                app.ConElecAltACSMWhe.Value=Cons_ACS*cons_e_biom;
                app.PotElecAltACSkW.Value=P_inscA*cons_e_biom;
            elseif Op_h=="Aerotèrmia"
                app.ConElecAltcMWhe.Value=Cons_c/SPFRefrAER1;
                app.PotElecAltckW.Value=P_inscA/min(EER_AER1m(3:10));
                app.ConElecAltACSMWhe.Value=Cons_ACS/SPFACSAER1;
                app.PotElecAltACSkW.Value=P_insACSA/min(COP_AER1ACSm);
            else
                if (Op_c=="Aerotèrmia")&&(Cons_c>0)
                    app.ConElecAltcMWhe.Value=Cons_c/SPFRefrAER1;
                    app.PotElecAltckW.Value=P_inscA/min(EER_AER1m(3:10));
                    app.ConElecAltACSMWhe.Value=Cons_ACS/SPFACSAER1;
                    app.PotElecAltACSkW.Value=P_insACSA/min(COP_AER1ACSm);
                else
                    app.ConElecAltcMWhe.Value=Cons_c/SPFRefrAER2;
                    app.PotElecAltckW.Value=P_inscA/min(EER_AER2m(3:10)); 
                    if Op_ACS=="Biomassa"
                        app.PotElecAltACSkW.Value=P_insACSA*cons_e_biom;
                        if Op_h=="Electricitat"
                            app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_biom+0.4*Cons_ACS/COP_EP;
                        else
                            app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_biom;
                        end
                    elseif Op_ACS=="Aerotèrmia"
                        app.ConElecAltACSMWhe.Value=Cons_ACS/SPFACSAER1;
                        app.PotElecAltACSkW.Value=P_insACSA/min(COP_AER1ACSm);
                    else
                        app.PotElecAltACSkW.Value=P_insACSA*cons_e_ST;
                        if Op_h=="Electricitat"
                            app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_ST+0.4*Cons_ACS/COP_EP;
                        else
                            app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_ST;
                        end
                    end
                end
            end

            %JGC: Le selecció d'una opció o una altra ha de redefinir els límits de la cobertura màxima amb PV donada una superfície disponible
            Clima=app.ClimaRef.Value;%JGC Valor obtingut segons les coordenades introduïdes (valors d'1 a 7, com la variable "thermal_case" de la secció de dimensionament
%             ClimasPV= hum_rel[6.21 5.39 5.51 5.23 5.53 5.63 5.13];%JGC: [kWh/m2/dia] Irradiació promig global calculada amb panells inclinats 30º climes ref. [B3 C2 C3 D1 D2 D3 E1]
            PSH=hum_rel(3,Clima);%JGC [kWh/m2/dia] o bé [peak sun hours] La irradiació promig es correspon en valor amb el nombre d'hores equivalents diàries rebent 1kW/m2 del sol
            S_ins = app.PV_area.Value; %JGC: àrea disponible màxima per a la instal·lació fotovoltàica
            effPV = app.PV_eff.Value; %JGC: [%] efficiència global de conversió fotoelèctrica de la instal·lació PV
            S_PV = S_ins/2.5; %JGC Superfície màxima de captació asumint panells amb inclinbació 30º. Es té en compte espai de marge per manteniment i evitar autoombre
            Irr=365*PSH; %JGC [peak sun hours/any] o bé [kWh/m2/any] Estimació de les hores anuals en les que es pot produïr 1kWh amb 1kWp instal·lat:365 Dies * nombre de Peak Sun Hours
            Prod_e=Irr*S_PV*effPV/100; %JGC [kWh/any] Estimació de la producció eléctrica anual amb panells.
            Cons_e_Alt=app.ConElecAltcMWhe.Value*1000+app.ConElecAlthMWhe.Value*1000+app.ConElecAltACSMWhe.Value*1000;
            %JGC: Quan seleccionem Op_h; pot passar que Cons_e_Alt=0 (p.ex., si tenim només calefacció i és de gasoil)
            if Cons_e_Alt==0
                app.AltHibPV.Value=0;
                app.AltHibPV.Enable=0;
                app.AltBat.Value=0;
                app.AltBat.Enable=0;
                app.HibPVAlt_cob.Enable=0;
                app.PVAltEneBat.Enable=0;
                app.AltkWpPV.Enable=0;
                app.Altm2PV.Enable=0;
                app.PVAltCostInv.Enable=0;
                if app.GeoHibPV.Value==1
                    app.PV_area.Enable=1;
                    app.PV_cost.Enable=1;
                    app.PV_eff.Enable=1;
                    app.Retrib_excdPV.Enable=1;
                    if app.GeoBat.Value==1
                        app.CostEspecBat.Enable=1;
                        app.Ch_DisCh_EffBat.Enable=1;
                        app.DoD_Bat.Enable=1;
                    else
                        app.CostEspecBat.Enable=0;
                        app.Ch_DisCh_EffBat.Enable=0;
                        app.DoD_Bat.Enable=0;
                    end
                    app.PVGeoCostInv.Enable=1;     
                else 
                    app.PV_area.Enable=0;
                    app.PV_cost.Enable=0;
                    app.PV_eff.Enable=0;
                    app.Retrib_excdPV.Enable=0;
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;
                    app.PVAltCostInv.Enable=0;
                end
                Lim_ratioPVAlt=1e-5;%JGC: posem això per no tenir problemes en cas de no tenir consum elèctric a l'alternativa
            else
               Lim_ratioPVAlt=ceil(100*Prod_e/Cons_e_Alt);
               app.AltHibPV.Enable=1;
            end
            if (Lim_ratioPVAlt>100)
               app.HibPVAlt_cob.Limits=[0 100];
            else
               app.HibPVAlt_cob.Limits=[0 Lim_ratioPVAlt]; 
            end 

            %JGC: Al final de la selecció, s'ha d'actualitzar el valor del CAPEXAlt
            app.CAPEXAlth.Value=Nova*CostAlth; 
            app.CAPEXAltc.Value=Nova*CostAltc; 
            app.CAPEXAltACS.Value=Nova*CostAltACS;
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: OpcionsAlternativa_c
        function Choose_Alternativa_c(app, event)
            param=readmatrix("018_parametersEcoAmb.xlsx");
            hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");
%             SPFCalfAER1=getappdata(0,'SPFc_AER1');
            SPFRefrAER1=getappdata(0,'SPFr_AER1');
            SPFACSAER1=getappdata(0,'SPFacs_AER1');
            SPFRefrAER2=getappdata(0,'SPFr_AER2');
%             COP_AER1m=getappdata(0,'COP_AER1_m');
            COP_AER1ACSm=getappdata(0,'COP_AER1ACS_m');
            EER_AER1m=getappdata(0,'EER_AER1_m');
            EER_AER2m=getappdata(0,'EER_AER2_m');
            app.CostEspecInstAlt_c.Enable=1;
            app.CostEspecificAlt_c.Enable=1;
            app.MantAlt_c.Enable=1;
            P_insG=getappdata(0,'Pot_G');
            P_insA=P_insG;
            P_inshA=app.PotInsCalfkW.Value;
            P_inscA=app.PotInsRefrkW.Value;
            Cons_c=app.ProdRefrMWht.Value;
            P_insACSA=1.1*app.PotDis_ACSkW.Value;
            Cons_ACS=app.Prod_ACSMWht.Value;
            Op_h=app.OpcionsAlternativa_h.Value;
            Op_c=app.OpcionsAlternativa_c.Value;
%             Op_ACS=app.OpcionsAlternativa_ACS.Value;
%             Area_ST=app.AreaMinST.Value;
            cons_e_biom=param(8);
            if app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end
            if Op_c=="Aerotèrmia"
                app.CostEspecInstAlt_c.Value=param(120); %JGC [€/kW]
                app.CostEspecificAlt_c.Value=param(121); %JGC [€/kW]
                app.MantAlt_c.Value=param(122);%JGC [€/kW/any] 
                app.ConElecAltcMWhe.Value=Cons_c/SPFRefrAER1;%JGC: consum elèctric corresponent a una aerotèrmia aire-aigua en mode refrigeració
                app.PotElecAltckW.Value=P_inscA/min(EER_AER1m(3:10));
                if (P_inshA>0)&&(Op_h=="Biomassa")
                   app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f')];
                   CostAlth=(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                   CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_insA; %JGC: una Aerotèrmia que proporcioni la mateixa refrigeració que la Geotèrmia 
                   %haurà d'ésser considerada pel seu valor nominal de potència en mode calefacció a l'hora de calcular el preu.
                   CostAltACS=0;
                   app.ConElecAltACSMWhe.Value=Cons_ACS*cons_e_biom;
                   app.PotElecAltACSkW.Value=P_insACSA*cons_e_biom;
                else
                   app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aerotèrmia per a producció de FRED (i ACS) amb un rendiment estacional SPFr = ' num2str(SPFRefrAER1,'%.2f') ' i SPFacs = ' num2str(SPFACSAER1,'%.2f')];
                   app.OpcionsAlternativa_ACS.Value="Aerotèrmia";
                   app.OpcionsAlternativa_ACS.Enable=0;
                   app.CostEspecInstAlt_ACS.Enable=0;
                   app.LabelCostEspecInstAlt_ACS.Text="€/kW";
                   app.CostEspecInstAlt_ACS.Value=app.CostEspecInstAlt_c.Value;
                   app.CostEspecificAlt_ACS.Enable=0;
                   app.LabelCostEspecificAlt_ACS.Text="€/kW";
                   app.CostEspecificAlt_ACS.Value=app.CostEspecificAlt_c.Value;
                   app.MantAlt_ACS.Enable=0;
                   app.LabelMantAlt_ACS.Text="€/kW";
                   app.MantAlt_ACS.Value=app.MantAlt_c.Value;
                   app.CostkgBiom.Enable=0;
                   app.Infl_AnualVar_Biom.Enable=0;
                   CostAlth=(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_inshA;
                   CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_insA; %JGC: una Aerotèrmia que proporcioni la mateixa refrigeració que la Geotèrmia 
                   %haurà d'ésser considerada pel seu valor nominal de potència en mode calefacció a l'hora de calcular el preu.
                   CostAltACS=0;
                   app.ConElecAltACSMWhe.Value=Cons_ACS/SPFACSAER1;%JGC: consum elèctric corresponent a una aerotèrmia aire-aigua en mode ACS
                   app.PotElecAltACSkW.Value=P_insACSA/min(COP_AER1ACSm);
                end
            else %JGC: Opció "Aire Condicionat"
                app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aire condicionat per a producció de FRED amb un rendiment estacional SPFr = ' num2str(SPFRefrAER2,'%.2f')];
                app.CostEspecInstAlt_c.Value=param(115); %JGC [€/kW]
                app.CostEspecificAlt_c.Value=param(116); %JGC [€/kW]
                app.MantAlt_c.Value=param(117);%JGC [€/kW/any]
                app.ConElecAltcMWhe.Value=Cons_c/SPFRefrAER2;%JGC: consum elèctric corresponent a una aerotèrmia aire-aigua en mode refrigeració
                app.PotElecAltckW.Value=P_inscA/min(EER_AER2m(3:10));
                if P_inshA>0
                    if (Op_h=="Biomassa")
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    else %JGC: L'altra opció possible és que Op_ACS="Aerotèrmia", perquè si podem escollir Op_c="Aire Condicionat" és perquè
                         % la opció actual és Op_c="Aerotèrmia", i per tant Op_ACS també estarà amb "Aerotèrmia"
                        if (P_insACSA>0)
                            app.OpcionsAlternativa_ACS.Enable=1;
                            app.CostEspecInstAlt_ACS.Enable=1;
                            app.CostEspecificAlt_ACS.Enable=1;
                            app.MantAlt_ACS.Enable=1;
                            CostAlth=(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            CostAltACS=(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
%                             if app.OpcionsAlternativa_ACS.Value=="Col·lectors solars Tèrmics"
%                                CostAltACS=(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST;
%                             else
%                                CostAltACS=(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;   
%                             end
                        else
                            app.OpcionsAlternativa_ACS.Enable=0;
                            app.CostEspecInstAlt_ACS.Enable=0;
                            app.CostEspecificAlt_ACS.Enable=0;
                            app.MantAlt_ACS.Enable=0;
                            CostAlth=(app.CostEspecInstAlt_h.Value+app.CostEspecificAlt_h.Value)*P_insA;
                            CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                            CostAltACS=0;
                        end
                    end  
                else %JGC: P_inshA=0!
                    if (P_insACSA>0)
                        app.OpcionsAlternativa_ACS.Enable=1;
                        app.CostEspecInstAlt_ACS.Enable=1;
                        app.CostEspecificAlt_ACS.Enable=1;
                        app.MantAlt_ACS.Enable=1;
                        CostAlth=0;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
                    else
                        app.OpcionsAlternativa_ACS.Enable=0;
                        app.CostEspecInstAlt_ACS.Enable=0;
                        app.CostEspecificAlt_ACS.Enable=0;
                        app.MantAlt_ACS.Enable=0;
                        CostAlth=0;
                        CostAltc=(app.CostEspecInstAlt_c.Value+app.CostEspecificAlt_c.Value)*P_inscA;
                        CostAltACS=0;
                    end
                end  
            end

            %JGC: Le selecció d'una opció o una altra ha de redefinir els límits de la cobertura màxima amb PV donada una superfície disponible
            Clima=app.ClimaRef.Value;%JGC Valor obtingut segons les coordenades introduïdes (valors d'1 a 7, com la variable "thermal_case" de la secció de dimensionament
%             ClimasPV= [6.21 5.39 5.51 5.23 5.53 5.63 5.13];%JGC: [kWh/m2/dia] Irradiació promig global calculada amb panells inclinats 30º climes ref. [B3 C2 C3 D1 D2 D3 E1]
            PSH=hum_rel(3,Clima);%JGC [kWh/m2/dia] o bé [peak sun hours] La irradiació promig es correspon en valor amb el nombre d'hores equivalents diàries rebent 1kW/m2 del sol
            S_ins = app.PV_area.Value; %JGC: àrea disponible màxima per a la instal·lació fotovoltàica
            effPV = app.PV_eff.Value; %JGC: [%] efficiència global de conversió fotoelèctrica de la instal·lació PV
            S_PV = S_ins/2.5; %JGC Superfície màxima de captació asumint panells amb inclinbació 30º. Es té en compte espai de marge per manteniment i evitar autoombre
            Irr=365*PSH; %JGC [peak sun hours/any] o bé [kWh/m2/any] Estimació de les hores anuals en les que es pot produïr 1kWh amb 1kWp instal·lat:365 Dies * nombre de Peak Sun Hours
            Prod_e=Irr*S_PV*effPV/100; %JGC [kWh/any] Estimació de la producció eléctrica anual amb panells.
            Cons_e_Alt=app.ConElecAltcMWhe.Value*1000+app.ConElecAlthMWhe.Value*1000+app.ConElecAltACSMWhe.Value*1000;
            Lim_ratioPVAlt=ceil(100*Prod_e/Cons_e_Alt); %JGC: NOTA: Si estem podent seleccionant Op_c, significa que Cons_e_Alt no pot ser zero
            if (Lim_ratioPVAlt>100)
               app.HibPVAlt_cob.Limits=[0 100];
            else
               app.HibPVAlt_cob.Limits=[0 Lim_ratioPVAlt]; 
            end 
            %JGC: Al final de la selecció, s'ha d'actualitzar el valor del CAPEXAlt
            app.CAPEXAlth.Value=Nova*CostAlth; 
            app.CAPEXAltc.Value=Nova*CostAltc; 
            app.CAPEXAltACS.Value=Nova*CostAltACS;
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: OpcionsAlternativa_ACS
        function Choose_Alternativa_ACS(app, event)
            %JGC:Les opcions d'ACS només estaràn actives si no tenim biomassa a calefacció o aerotèrmia a calefacció+refrigeració o refrigeració
            param=readmatrix("018_parametersEcoAmb.xlsx");
            hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");
%             SPFCalfAER1=getappdata(0,'SPFc_AER1');
%             SPFRefrAER1=getappdata(0,'SPFr_AER1');
            SPFACSAER1=getappdata(0,'SPFacs_AER1');
%             SPFRefrAER2=getappdata(0,'SPFr_AER2');
%             COP_AER1m=getappdata(0,'COP_AER1_m');
            COP_AER1ACSm=getappdata(0,'COP_AER1ACS_m');
%             EER_AER1m=getappdata(0,'EER_AER1_m');
%             EER_AER2m=getappdata(0,'EER_AER2_m');
            P_insG=getappdata(0,'Pot_G');
            P_insA=P_insG;
            P_inshA=app.PotInsCalfkW.Value;
            P_insACSA=1.1*app.PotDis_ACSkW.Value;
            Cons_ACS=app.Prod_ACSMWht.Value; %JGC:[MWht] NOTA: La producció mínima d'ACS que s'ha de fer amb renovables és del 60% (asumint consum de <5000l/dia)
            Op_h=app.OpcionsAlternativa_h.Value;
            Op_c=app.OpcionsAlternativa_c.Value;
            Op_ACS=app.OpcionsAlternativa_ACS.Value;
            cons_e_biom=param(8);
            cons_e_ST=param(15);%JGC: Consum específic electricitat solar tèrmica (bombes recirculació)
            Area_ST=app.AreaMinST.Value; %JGC: [m2] àrea mínima per complir amb l'exigència del CTE (60% cobertura ACS amb renovables)
            if app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end
            %Condició en les quals necessitarem GN independentment d'altres combustibles
            if ((P_inshA>0)&&(Op_h=="Gas Natural"))||((P_inshA==0)&&(Op_ACS=="Col·lectors Solars Tèrmics"))
                app.CostGN.Enable=1;
                app.Infl_AnualFix_GN.Enable=1;
                app.CostkWhGN.Enable=1;
                app.Infl_AnualVar_GN.Enable=1;
            else
                app.CostGN.Enable=0;
                app.Infl_AnualFix_GN.Enable=0;
                app.CostkWhGN.Enable=0;
                app.Infl_AnualVar_GN.Enable=0;
            end
            
            if Op_ACS=="Col·lectors Solars Tèrmics"
                app.CostkgBiom.Enable=0;
                app.Infl_AnualVar_Biom.Enable=0;
                app.LabelCostEspecInstAlt_ACS.Text="€/m2";
                app.CostEspecInstAlt_ACS.Value=param(11);
                app.LabelCostEspecificAlt_ACS.Text="€/m2";
                app.CostEspecificAlt_ACS.Value=param(12);
                app.LabelMantAlt_ACS.Text="€/m2";
                app.MantAlt_ACS.Value=param(13);
                if (P_inshA>0)&&(Op_h=="Electricitat")
                    app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_ST+0.4*Cons_ACS/(0.01*param(114));%JGC:[MWhe]. La fracció no renovable queda coberta amb electricitat
                else
                    app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_ST;%JGC:[MWhe]
                end
                app.PotElecAltACSkW.Value=P_insACSA*cons_e_ST;%JGC [kWe] potència que s'hauria d'instal·lar per fer funcionar els col·lectors
                %JGC: Hem de considerar una instal·lació de support per cobrir el 40% d'ACS restant. Si tenim calefacció, 
                %llavors no necessitarem instal·lar res mes (a no ser que la càrrega de calefacció sigui inferior a la d'ACS, però
                %això ho gestionarem quan escollim la calefacció). En cas contrari, caldrà instal·lar font de support 
                %(escollim gas natural) amb potència calorífica corresponent a la potència de disseny d'ACS.
                %JGC: Considerem que el cost de manteniment del GN de support està inclós al manteniment dels Col·lectors
                if (P_inshA==0)&&(Op_c=="Aire Condicionat")
                    if Cons_ACS<5 %JGC: Tarifa 3.1 GN
                        Escomesa=param(20);
                        Inst_esp=param(21);
                        Cald_esp=param(22);
                    elseif (Cons_ACS>=5)&&(Cons_ACS<15) %JGC: Tarifa 3.2(a) GN
                        Escomesa=param(26);
                        Inst_esp=param(27);
                        Cald_esp=param(28);
                    elseif (Cons_ACS>=15)&&(Cons_ACS<50) %JGC: Tarifa 3.2(b) GN
                        Escomesa=param(32);
                        Inst_esp=param(33);
                        Cald_esp=param(34);
                    elseif (Cons_ACS>=50)&&(Cons_ACS<100) %JGC: Tarifa 3.3 GN
                        Escomesa=param(38);
                        Inst_esp=param(39);
                        Cald_esp=param(40);
                    elseif (Cons_ACS>=100) %JGC: Tarifa 3.4 GN
                        Escomesa=param(44);
                        Inst_esp=param(45);
                        Cald_esp=param(46);
                    end
                    CostAltACSsup=Escomesa+(Inst_esp+Cald_esp)*P_insACSA; %JGC: Asumim que la instal·lació de suport en aquest cas és Gas Natural    
                else %JGC en cas de no tenir aerotèrmia o biomassa en cap cas
                    CostAltACSsup=0;
                end
                CostAltACS=(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*Area_ST+CostAltACSsup;
            else
                app.LabelCostEspecInstAlt_ACS.Text="€/kW";
                app.LabelCostEspecificAlt_ACS.Text="€/kW";
                app.LabelMantAlt_ACS.Text="€/kW";
                if Op_ACS=="Biomassa"
                    app.CostkgBiom.Enable=1;
                    app.Infl_AnualVar_Biom.Enable=1;
                    app.CostEspecInstAlt_ACS.Value=param(1);
                    app.CostEspecificAlt_ACS.Value=param(2);
                    app.MantAlt_ACS.Value=param(3);
                    if P_inshA==0
                        app.ConElecAltACSMWhe.Value=Cons_ACS*cons_e_biom;%JGC:[MWhe] Si no hi ha calefacció, ens compensa que tota la produdcció d'ACS es faci amb biomassa
                    else %JGC: Si hi ha calefacció, ens podem permetre limitar l'ús de la biomassa al mínim exigible
                        if Op_h=="Electricitat"
                            app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_biom+0.4*Cons_ACS/(0.01*param(114));%JGC:[MWhe]
                        else
                            app.ConElecAltACSMWhe.Value=0.6*Cons_ACS*cons_e_biom;%JGC:[MWhe]
                        end
                    end 
                    app.PotElecAltACSkW.Value=P_insACSA*cons_e_biom; %JGC [kWe] potència que s'hauria d'instal·lar per fer funcionar la caldera biomassa
                else
                    app.CostkgBiom.Enable=0;
                    app.Infl_AnualVar_Biom.Enable=0;
                    app.CostEspecInstAlt_ACS.Value=param(120);
                    app.CostEspecificAlt_ACS.Value=param(121);
                    app.MantAlt_ACS.Value=param(122);
                    app.ConElecAltACSMWhe.Value=Cons_ACS/SPFACSAER1;%JGC:Nota: En el cas de bomba de calor, posem que tota la producció d'ACS 
                    % es faci amb aquest equip, donat que no tota la nergia és d'origen renovable (per això el mínim exigible és un COP_ACS de 2.5)
                    app.PotElecAltACSkW.Value=P_insACSA/min(COP_AER1ACSm);%JGC [kWe] potència que s'hauria d'instal·lar per fer funcionar l'aerotèrmia
                end
                CostAltACS=(app.CostEspecInstAlt_ACS.Value+app.CostEspecificAlt_ACS.Value)*P_insACSA;
            end
            
            %JGC: Novament, la selecció d'una opció o una altra ha de redefinir els límits de la cobertura màxima amb PV donada una superfície disponible
            Clima=app.ClimaRef.Value;%JGC Valor obtingut segons les coordenades introduïdes (valors d'1 a 7, com la variable "thermal_case" de la secció de dimensionament
%             ClimasPV= [6.21 5.39 5.51 5.23 5.53 5.63 5.13];%JGC: [kWh/m2/dia] Irradiació promig global calculada amb panells inclinats 30º climes ref. [B3 C2 C3 D1 D2 D3 E1]
            PSH=hum_rel(3,Clima);%JGC [kWh/m2/dia] o bé [peak sun hours] La irradiació promig es correspon en valor amb el nombre d'hores equivalents diàries rebent 1kW/m2 del sol
            S_ins = app.PV_area.Value; %JGC: àrea disponible màxima per a la instal·lació fotovoltàica
            effPV = app.PV_eff.Value; %JGC: [%] efficiència global de conversió fotoelèctrica de la instal·lació PV
            S_PV = S_ins/2.5; %JGC Superfície màxima de captació asumint panells amb inclinbació 30º. Es té en compte espai de marge per manteniment i evitar autoombre
            Irr=365*PSH; %JGC [peak sun hours/any] o bé [kWh/m2/any] Estimació de les hores anuals en les que es pot produïr 1kWh amb 1kWp instal·lat:365 Dies * nombre de Peak Sun Hours
            Prod_e=Irr*S_PV*effPV/100; %JGC [kWh/any] Estimació de la producció eléctrica anual amb panells.
            Cons_e_Alt=1000*(app.ConElecAltcMWhe.Value+app.ConElecAlthMWhe.Value+app.ConElecAltACSMWhe.Value);
            Lim_ratioPVAlt=ceil(100*Prod_e/Cons_e_Alt); %JGC:NOTA: Si estem seleccionant OP_ACS, significa que Cons_e_Alt no pot ser zero
            if (Lim_ratioPVAlt>100)
               app.HibPVAlt_cob.Limits=[0 100];
            else
               app.HibPVAlt_cob.Limits=[0 Lim_ratioPVAlt]; 
            end 
            app.CAPEXAltACS.Value=Nova*CostAltACS; %JGC: Al final de la selecció, s'ha d'actualitzar el valor del CAPEXAlt
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: CostEscomesa, CostEspecInstAlt_h, 
        % CostEspecificAlt_h
        function Input_CostosAlt_h(app, event)
            value_h = app.CostEspecificAlt_h.Value;
            value_insh=app.CostEspecInstAlt_h.Value;
            value_capt=app.CostEscomesa.Value;
            Op_h=app.OpcionsAlternativa_h.Value;
            %JGC: En el cas de la instal·lació alternativa, utilitzem els valors de les potències de disseny. Les potències isntal·lades
            %a la Geotèrmia són les instal·lades a la Geotèrmia i no tenen perquè coincidir amb les de l'alternativa
            P_insG=getappdata(0,'Pot_G');
            P_insA=P_insG;
            if  app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end   
            if Op_h=="Aerotèrmia"
                if app.PotDisRefrkW.Value>0
                    app.CostEspecificAlt_c.Value=value_h;
                    app.CostEspecInstAlt_c.Value=value_insh;
                end
                if app.PotDis_ACSkW.Value>0
                    app.CostEspecificAlt_ACS.Value=value_h;
                    app.CostEspecInstAlt_ACS.Value=value_insh;
                end
                app.CAPEXAlth.Value=Nova*((value_h+value_insh)*P_insA);%+CAPEXPVAlt;
                app.CAPEXAltc.Value=0;
                app.CAPEXAltACS.Value=0;
            elseif Op_h=="Biomassa"
                if app.PotDis_ACSkW.Value>0
                    app.CostEspecificAlt_ACS.Value=value_h;
                    app.CostEspecInstAlt_ACS.Value=value_insh;
                end 
                app.CostEspecificAlt_ACS.Value=value_h;
                app.CostEspecInstAlt_ACS.Value=value_insh;
                app.CAPEXAlth.Value=Nova*((value_h+value_insh)*P_insA);%+CAPEXPVAlt;                
            else
                app.CAPEXAlth.Value=Nova*(value_capt+(value_h+value_insh)*P_insA);%+CAPEXPVAlt; %JGC [€] CAPEX simplificat
            end 
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: CostEspecInstAlt_c, 
        % CostEspecificAlt_c
        function Input_CostosAlt_c(app, event)
            value_c = app.CostEspecificAlt_c.Value;
            value_insc = app.CostEspecInstAlt_c.Value;
            value_h = app.CostEspecificAlt_h.Value;
            value_insh=app.CostEspecInstAlt_h.Value;
            Op_c=app.OpcionsAlternativa_c.Value;
            %JGC: En el cas de la instal·lació alternativa, utilitzem els valors de les potències de disseny. Les potències isntal·lades
            %a la Geotèrmia són les instal·lades a la Geotèrmia i no tenen perquè coincidir amb les de l'alternativa
            P_inscA = app.PotInsRefrkW.Value;
            P_insACSA= 1.1*app.PotDis_ACSkW.Value;
            if  app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end   
            if (Op_c=="Aerotèrmia")
                if (app.PotInsCalfkW.Value>0)&&(app.OpcionsAlternativa_h.Value=="Biomassa")
                    if app.PotDis_ACSkW.Value>0
                        app.CostEspecificAlt_ACS.Value=value_h;
                        app.CostEspecInstAlt_ACS.Value=value_insh;
                    end
                    app.CAPEXAltc.Value=Nova*((value_c+value_insc)*P_inscA);%+CAPEXPVAlt;
                else
                    if app.PotDis_ACSkW.Value>0
                        app.CostEspecificAlt_ACS.Value=value_c;
                        app.CostEspecInstAlt_ACS.Value=value_insc;
                    end
                    app.CAPEXAltc.Value=Nova*((value_c+value_insc)*max(P_inscA,P_insACSA));%+CAPEXPVAlt;
                end
            else
                app.CAPEXAltc.Value=Nova*((value_c+value_insc)*P_inscA);
            end
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: CostEspecInstAlt_ACS, 
        % CostEspecificAlt_ACS
        function Input_CostostAlt_ACS(app, event)
            value_ACS = app.CostEspecificAlt_ACS.Value;
            value_insACS = app.CostEspecInstAlt_ACS.Value;
            Cons_ACS=app.Prod_ACSMWht.Value;
            Area_ST=app.AreaMinST.Value;
            Op_ACS=app.OpcionsAlternativa_ACS.Value;
            %JGC: En el cas de la instal·lació alternativa, utilitzem els valors de les potències de disseny. Les potències isntal·lades
            %a la Geotèrmia són les instal·lades a la Geotèrmia i no tenen perquè coincidir amb les de l'alternativa
            P_insACSA= 1.1*app.PotDis_ACSkW.Value;
%             CAPEXPVAlt=app.PVAltCostInv.Value;
            if  app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end   
            if (Op_ACS=="Aerotèrmia")||(Op_ACS=="Biomassa")
                app.CAPEXAltACS.Value=Nova*((value_insACS+value_ACS)*P_insACSA);%+CAPEXPVAlt;
            else 
                if (app.PotDisCalfkW.Value>0)
                    app.CAPEXAltACS.Value=Nova*((value_ACS+value_insACS)*Area_ST);
                    CostAltACSsup=0;
                else
                    if Cons_ACS<5 %JGC: Tarifa 3.1 GN
                        Escomesa=param(20);
                        Inst_esp=param(21);
                        Cald_esp=param(22);
                    elseif (Cons_ACS>=5)&&(Cons_ACS<15) %JGC: Tarifa 3.2(a) GN
                        Escomesa=param(26);
                        Inst_esp=param(27);
                        Cald_esp=param(28);
                    elseif (Cons_ACS>=15)&&(Cons_ACS<50) %JGC: Tarifa 3.2(b) GN
                        Escomesa=param(32);
                        Inst_esp=param(33);
                        Cald_esp=param(34);
                    elseif (Cons_ACS>=50)&&(Cons_ACS<100) %JGC: Tarifa 3.3 GN
                        Escomesa=param(38);
                        Inst_esp=param(39);
                        Cald_esp=param(40);
                    elseif (Cons_ACS>=100) %JGC: Tarifa 3.4 GN
                        Escomesa=param(44);
                        Inst_esp=param(45);
                        Cald_esp=param(46);
                    end
                    CostAltACSsup=Escomesa+(Inst_esp+Cald_esp)*P_insACSA; %JGC: Asumim que la instal·lació de suport en aquest cas és Gas Natural
                end
                app.CAPEXAltACS.Value=Nova*((value_ACS+value_insACS)*Area_ST)+CostAltACSsup;    
            end
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end       
        end

        % Value changed function: MantAlt_h
        function Input_MantAlt_h(app, event)
            value_Manh = app.MantAlt_h.Value;
            if (app.OpcionsAlternativa_h.Value=="Aerotèrmia")
                app.MantAlt_c.Value=value_Manh;
                app.MantAlt_ACS.Value=value_Manh;
            elseif app.OpcionsAlternativa_h.Value=="Biomassa"
                app.MantAlt_ACS.Value=value_Manh;    
            end
        end

        % Value changed function: MantAlt_c
        function Input_MantAlt_c(app, event)
            value_Manh=app.MantAlt_h.Value;
            value_Manc=app.MantAlt_c.Value;
            if (app.OpcionsAlternativa_c.Value=="Aerotèrmia")
                if (app.PotInsCalfkW.Value>0)&&(app.OpcionsAlternativa_h.Value=="Biomassa")
                    app.MantAlt_ACS.Value=value_Manh;
                else
                    app.MantAlt_ACS.Value=value_Manc;
                end            
            end
        end

        % Value changed function: GeoSubv
        function Select_GeoSubv(app, event)
            value = app.GeoSubv.Value;
            SubG=app.ImportGeoSubv.Value;
            CAPEXG=app.CAPEXGeoTOT.Value;
            if (value==1) 
                app.ImportGeoSubv.Enable=1;
                lim_pres=floor(100-100*SubG/CAPEXG);
                if  lim_pres==0
                    app.GeoPres.Value=0;
                    app.GeoPres.Enable=0;
                    app.TramGeoPres.Value=0;
                    app.TramGeoPres.Enable=0;
                    app.InteresGeoPres.Enable=0;
                    app.RetornGeoPres.Enable=0; 
                else
                    app.GeoPres.Enable=1;
                    app.TramGeoPres.Limits=[0 lim_pres];
                end
            else
                app.ImportGeoSubv.Enable=0;
                app.GeoPres.Enable=1;
                app.TramGeoPres.Limits=[0 100];         
            end
        end

        % Value changed function: AltSubv
        function Select_AltSubv(app, event)
            value = app.AltSubv.Value;
            SubA=app.ImportAltSubv.Value;
            CAPEX_A=app.CAPEXAltTOT.Value;
            if (value==true) 
                app.ImportAltSubv.Enable=1;
                lim_pres=floor(100-100*SubA/CAPEX_A);
                if  lim_pres==0
                    app.AltPres.Value=0;
                    app.AltPres.Enable=0;
                    app.TramAltPres.Value=0;
                    app.TramAltPres.Enable=0;
                    app.InteresAltPres.Enable=0;
                    app.RetornAltPres.Enable=0; 
                else
                    app.AltPres.Enable=1;
                    app.TramAltPres.Limits=[0 lim_pres];
                end
            else
                app.ImportAltSubv.Enable=0;
                app.AltPres.Enable=1;
                app.TramAltPres.Limits=[0 100];         
            end
        end

        % Value changed function: ImportGeoSubv
        function Input_ImportGeoSubv(app, event)
            SubvGeo= app.ImportGeoSubv.Value;
            Pctg_SubvGeo = 100*SubvGeo/app.CAPEXGeoTOT.Value;
            LimPres=floor(100-Pctg_SubvGeo);
            if LimPres==0
                app.GeoPres.Value=0;
                app.GeoPres.Enable=0;
                app.InteresGeoPres.Enable=0;
                app.RetornGeoPres.Enable=0;
                app.TramGeoPres.Enable=0;
                app.TramGeoPres.Value=0;
            else
                app.TramGeoPres.Limits=[0 LimPres]; 
            end       
        end

        % Value changed function: ImportAltSubv
        function Input_ImportAltSubv(app, event)
            SubvAlt = app.ImportAltSubv.Value;
            Pctg_SubvAlt = 100*SubvAlt/app.CAPEXAltTOT.Value;
            LimPres=floor(100-Pctg_SubvAlt);
            if LimPres==0
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
                app.TramAltPres.Enable=0;
                app.TramAltPres.Value=0;
            else
                app.TramAltPres.Limits=[0 LimPres]; 
            end          
        end

        % Value changed function: GeoPres
        function Select_GeoPres(app, event)
            value = app.GeoPres.Value;
            if (value==true)
                app.TramGeoPres.Enable=1;
                app.InteresGeoPres.Enable=1;
                app.RetornGeoPres.Enable=1;
            else
                app.TramGeoPres.Enable=0;
                app.InteresGeoPres.Enable=0;
                app.RetornGeoPres.Enable=0;
            end
        end

        % Value changed function: RetornGeoPres
        function Input_RetornGeoPres(app, event)
            value=app.vidaprojecte.Value;
            app.RetornGeoPres.Limits=[0 value];
            app.RetornAltPres.Limits=[0 value];
        end

        % Value changed function: AltPres
        function Select_AltPres(app, event)
            value = app.AltPres.Value;
            if (value==true)
                app.TramAltPres.Enable=1;
                app.InteresAltPres.Enable=1;
                app.RetornAltPres.Enable=1;
            else
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            end
        end

        % Value changed function: RetornAltPres
        function Input_RetornAltPres(app, event)
            value=app.vidaprojecte.Value;
            app.RetornGeoPres.Limits=[0 value];
            app.RetornAltPres.Limits=[0 value];
        end

        % Value changed function: GeoHibPV
        function Select_GeoHibPV(app, event)
            if app.GeoHibPV.Value==1
                app.HibPVGeo_cob.Enable=1;
                app.PV_area.Enable=1;
                app.PV_cost.Enable=1;
                app.PV_eff.Enable=1;
                app.Retrib_excdPV.Enable=1;
                app.GeokWpPV.Enable=1;
                app.Geom2PV.Enable=1;
                app.GeoBat.Enable=1;
                app.PVGeoCostInv.Enable=1;
                if app.GeoBat.Value==1
                    app.CostEspecBat.Enable=1;
                    app.Ch_DisCh_EffBat.Enable=1;
                    app.DoD_Bat.Enable=1;
                    app.PVGeoEneBat.Enable=1;
                else
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;
                    app.PVGeoEneBat.Enable=0;   
                end
            else
                app.HibPVGeo_cob.Enable=0;
                app.GeokWpPV.Enable=0;
                app.Geom2PV.Enable=0;
                app.GeoBat.Enable=0;
                app.CostEspecBat.Enable=0;
                app.PVGeoEneBat.Enable=0;
                app.PVGeoCostInv.Enable=0;
                if app.AltHibPV.Value==1
                    app.PV_area.Enable=1;
                    app.PV_cost.Enable=1;
                    app.PV_eff.Enable=1;
                    app.Retrib_excdPV.Enable=1;
                    if app.AltBat.Value==1
                        app.CostEspecBat.Enable=1;
                        app.Ch_DisCh_EffBat.Enable=1;
                        app.DoD_Bat.Enable=1;
                    else
                        app.CostEspecBat.Enable=0;
                        app.Ch_DisCh_EffBat.Enable=0;
                        app.DoD_Bat.Enable=0;
                    end
                else 
                    app.PV_area.Enable=0;
                    app.PV_cost.Enable=0;
                    app.PV_eff.Enable=0;
                    app.Retrib_excdPV.Enable=0;
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;
                    app.PVGeoEneBat.Enable=0;
                end
            end
            CAPEXPVGeo=app.GeokWpPV.Value*app.PV_cost.Value;
            CAPEXPVGeoBat=app.CostEspecBat.Value*app.PVGeoEneBat.Value;
            app.PVGeoCostInv.Value=app.GeoHibPV.Value*(CAPEXPVGeo+app.GeoBat.Value*CAPEXPVGeoBat);
            
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];
        end

        % Value changed function: AltHibPV
        function Select_AltHibPV(app, event)
            if app.SwitchTipusProjecte.Value=="Nova instal·lació"
                Nova=1;
            else
                Nova=0;
            end
            if  app.AltHibPV.Value==1
                app.HibPVAlt_cob.Enable=1;
                app.PV_area.Enable=1;
                app.PV_cost.Enable=1;
                app.PV_eff.Enable=1;
                app.Retrib_excdPV.Enable=1;
                app.AltkWpPV.Enable=1;
                app.Altm2PV.Enable=1;
                app.AltSubv.Enable=1;
                app.AltPres.Enable=1;
                app.AltBat.Enable=1;
                app.PVAltCostInv.Enable=1;
                if app.AltBat.Value==1
                    app.CostEspecBat.Enable=1;
                    app.Ch_DisCh_EffBat.Enable=1;
                    app.DoD_Bat.Enable=1;
                    app.PVAltEneBat.Enable=1;
                else
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;
                    app.PVAltEneBat.Enable=0;   
                end
            else
                if Nova==1
                    app.AltSubv.Enable=1;
                    app.AltPres.Enable=1; 
                else
                    app.AltSubv.Enable=0;
                    app.AltPres.Enable=0; 
                end
                app.HibPVAlt_cob.Enable=0;
                app.AltkWpPV.Enable=0;
                app.Altm2PV.Enable=0;
                app.AltBat.Enable=0;
                app.PVAltEneBat.Enable=0;
                app.PVAltCostInv.Enable=0;
                if app.GeoHibPV.Value==1
                    app.PV_area.Enable=1;
                    app.PV_cost.Enable=1;
                    app.PV_eff.Enable=1;
                    app.Retrib_excdPV.Enable=1;   
                    if app.GeoBat.Value==1
                        app.CostEspecBat.Enable=1;
                        app.Ch_DisCh_EffBat.Enable=1;
                        app.DoD_Bat.Enable=1;
                    else
                        app.CostEspecBat.Enable=0;
                        app.Ch_DisCh_EffBat.Enable=0;
                        app.DoD_Bat.Enable=0;
                    end
                else 
                    app.PV_area.Enable=0;
                    app.PV_cost.Enable=0;
                    app.PV_eff.Enable=0;
                    app.Retrib_excdPV.Enable=0;
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;                   
                end
            end
            CAPEXPVAlt=app.AltkWpPV.Value*app.PV_cost.Value;
            CAPEXPVAltBat=app.CostEspecBat.Value*app.PVAltEneBat.Value;
            app.PVAltCostInv.Value=app.AltHibPV.Value*(CAPEXPVAlt+app.AltBat.Value*CAPEXPVAltBat);
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: PV_area, PV_cost, PV_eff
        function Input_PV_area(app, event)
            hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");
            S_ins = app.PV_area.Value; %JGC: àrea disponible màxima per a la instal·lació fotovoltàica
            effPV = app.PV_eff.Value; %JGC: [%] efficiència global de conversió fotoelèctrica de la instal·lació PV
            S_PV = S_ins/2.5; %JGC Superfície màxima de captació asumint panells amb inclinbació 30º. Es té en compte espai de marge per manteniment i evitar autoombres
%             ClimasPV= [6.21 5.39 5.51 5.23 5.53 5.63 5.13];%JGC: [kWh/m2/dia] Irradiació promig global calculada amb panells inclinats 30º climes ref. [B3 C2 C3 D1 D2 D3 E1]
            Clima=app.ClimaRef.Value;%JGC Valor obtingut segons les coordenades introduïdes
            PSH=hum_rel(3,Clima);%JGC [kWh/m2/dia] o bé [peak sun hours] La irradiació promig es correspon en valor amb el nombre d'hores equivalents diàries rebent 1kW/m2 del sol
            Irr=365*PSH; %JGC [peak sun hours/any] o bé [kWh/m2/any] Estimació de les hores anuals en les que es pot produïr 1kWh amb 1kWp instal·lat:365 Dies * nombre de Peak Sun Hours
            Prod_e_max=Irr*S_PV*effPV/100; %JGC [kWh/any] Estimació de la producció eléctrica màxima anual amb panells.
            Lim_ratioPVGeo=round(100*Prod_e_max/(app.ConElecMWhe.Value*1000),2)+1e-5; %JGC: [%] percentatge màxim de consum elèctric de la instal·lació Geotèrmica que podria cobrir-se amb panells.
            Lim_ratioPVAlt=round(100*Prod_e_max/(app.ConElecAltcMWhe.Value*1000+app.ConElecAlthMWhe.Value*1000+app.ConElecAltACSMWhe.Value*1000),2)+1e-5;
            if (Lim_ratioPVGeo>100)
               app.HibPVGeo_cob.Limits=[0 100]; %JGC Imposem que el limit sigui el 100% per ajustar-nos al context de la instal·lació tèrmica.
            else
               app.HibPVGeo_cob.Limits=[0 Lim_ratioPVGeo]; 
            end   
            if (Lim_ratioPVAlt>100)
               app.HibPVAlt_cob.Limits=[0 100]; %JGC Imposem que el limit sigui el 100% per ajustar-nos al context de la instal·lació tèrmica.
            else
               app.HibPVAlt_cob.Limits=[0 Lim_ratioPVAlt]; 
            end  
            %JGC: Quan canviem el valor de la superfície disponible, s'ha d'actualitzar els valors del CostPVGeo
            CobeturaPVGeo=app.HibPVGeo_cob.Value; %JGC [%] percentatge del consum elèctric de la instal·lació Geotèrmica que esperem cobrir amb PV
            Prod_eGeo=app.ConElecMWhe.Value*1000*CobeturaPVGeo/100; %JGC [kWhe] producció PV per cobrir el percentatge establert
            kWpGeo=Prod_eGeo/365/PSH; %JGC: [kWp] nombre de kWp instal·lats necessaris per produïr Prod_e.
            app.GeokWpPV.Value=kWpGeo;
            areaGeo=Prod_eGeo/Irr/effPV*100; %JGC:[m2] area corresponent a la instal·lació PV de suport a la Geotèrmia
            app.Geom2PV.Value=areaGeo;
            Cost_PVGeo=kWpGeo*app.PV_cost.Value; %JGC: [€] Cost de la instal·lació PV de suport a la Geotèrmia
            Cap_BatGeo=Prod_eGeo/365; %JGC [kWh/dia] Valor proposat de capacitat pel banc de bateries, d'acord amb la producció PV (Geotèrmia)
            app.PVGeoEneBat.Value=round(Cap_BatGeo,2);
            Cost_BatGeo=app.PVGeoEneBat.Value*app.CostEspecBat.Value; %JGC: [€] Cost de la acumulació amb bateries Geotèrmia+PV
            app.PVGeoCostInv.Value=(Cost_PVGeo+Cost_BatGeo*app.GeoBat.Value)*app.GeoHibPV.Value;
            %JGC: Actualitzaem el valor màxim subvencionable instal·lació geotèrmica:
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];
                    
            %JGC: Quan canviem el valor de la superfície disponible, s'ha d'actualitzar els valors del CostPVAlt
            CobeturaPVAlt=app.HibPVAlt_cob.Value; %JGC [%] percentatge del consum elèctric de la instal·lació Alternativa que esperem cobrir amb PV
            Prod_eh=app.ConElecAlthMWhe.Value*1000; %JGC [kWhe] demanda elèctrica Alternativa en mode calefacció
            Prod_ec=app.ConElecAltcMWhe.Value*1000; %JGC [kWhe] demanda elèctrica Alternativa en mode refrigeració
            Prod_eACS=app.ConElecAltACSMWhe.Value*1000; %JGC [kWhe] demanda elèctrica Alternativa en mode ACS
            Prod_eAlt=(Prod_eh+Prod_ec+Prod_eACS)*CobeturaPVAlt/100; %JGC [kWhe] producció elèctrica que volem cobrir amb els panells PV
            kWpAlt=Prod_eAlt/365/PSH; %JGC: [kWp] nombre de kWp instal·lats necessaris per produïr la mateixa electricitat que es vol estalviar.
            app.AltkWpPV.Value=kWpAlt;
            areaAlt=Prod_eAlt/Irr/effPV*100; %JGC:[m2] area corresponent a la instal·lació PV de suport a l'Alternativa
            app.Altm2PV.Value=areaAlt;
            Cost_PVAlt=kWpAlt*app.PV_cost.Value; %JGC: [€] Cost de la instal·lació PV de suport a la Alternativa
            Cap_BatAlt=Prod_eAlt/365; %JGC [kWh/dia] Valor proposat de capacitat pel banc de bateries, d'acord amb la producció PV (Alternativa)
            app.PVAltEneBat.Value=round(Cap_BatAlt,2);
            Cost_BatAlt=app.PVAltEneBat.Value*app.CostEspecBat.Value; %JGC: [€] Cost de la acumulació amb bateries Alternativa+PV
            app.PVAltCostInv.Value=(Cost_PVAlt+Cost_BatAlt*app.AltBat.Value)*app.AltHibPV.Value;
            %JGC: Actualitzaem el valor màxim subvencionable instal·lació alternativa:
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: HibPVGeo_cob
        function Input_HibPVGeo_cob(app, event)
            effPV = app.PV_eff.Value; %JGC: [%] efficiència global de conversió fotoelèctrica de la instal·lació PV
            hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");
%             ClimasPV= [6.21 5.39 5.51 5.23 5.53 5.63 5.13];%JGC: [kWh/m2/dia] Irradiació promig global calculada amb panells inclinats 30º climes ref. [B3 C2 C3 D1 D2 D3 E1]
            Clima=app.ClimaRef.Value;%JGC Valor obtingut segons les coordenades introduïdes
            PSH=hum_rel(3,Clima);%JGC [kWh/m2/dia] o bé [peak sun hours] La irradiació promig es correspon en valor amb el nombre d'hores equivalents diàries rebent 1kW/m2 del sol
            Irr=365*PSH; %JGC [peak sun hours/any] o bé [kWh/m2/any] Estimació de les hores anuals en les que es pot produïr 1kWh amb 1kWp instal·lat:365 Dies * nombre de Peak Sun Hours
            
            CobeturaPVGeo=app.HibPVGeo_cob.Value; %JGC [%] percentatge del consum elèctric de la instal·lació Geotèrmica que esperem cobrir amb PV
            Prod_eGeo=app.ConElecMWhe.Value*1000*CobeturaPVGeo/100; %JGC [kWhe] producció PV per cobrir el percentatge establert
            kWpGeo=Prod_eGeo/365/PSH; %JGC: [kWp] nombre de kWp instal·lats necessaris per produïr Prod_e.
            app.GeokWpPV.Value=kWpGeo;
            areaGeo=Prod_eGeo/Irr/effPV*100; %JGC:[m2] area corresponent a la instal·lació PV de suport a la Geotèrmia
            app.Geom2PV.Value=areaGeo;
            Cost_PVGeo=kWpGeo*app.PV_cost.Value; %JGC: [€] Cost de la instal·lació PV de suport a la Geotèrmia
            Cap_BatGeo=Prod_eGeo/365; %JGC [kWh/dia] Valor proposat de capacitat pel banc de bateries, d'acord amb la producció PV (Geotèrmia)
            app.PVGeoEneBat.Value=round(Cap_BatGeo,2);
            Cost_BatGeo=app.PVGeoEneBat.Value*app.CostEspecBat.Value; %JGC: [€] Cost de la acumulació amb bateries Geotèrmia+PV
            app.PVGeoCostInv.Value=(Cost_PVGeo+Cost_BatGeo*app.GeoBat.Value)*app.GeoHibPV.Value;
            %JGC: Actualitzaem el valor màxim subvencionable instal·lació geotèrmica:
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];
        end

        % Value changed function: HibPVAlt_cob
        function Input_HibPVAlt_cob(app, event)
            effPV = app.PV_eff.Value; %JGC: [%] efficiència global de conversió fotoelèctrica de la instal·lació PV
            hum_rel = readmatrix("020_humitat_relativa_IRR30anual.xlsx");
%             ClimasPV= [6.21 5.39 5.51 5.23 5.53 5.63 5.13];%JGC: [kWh/m2/dia] Irradiació promig global calculada amb panells inclinats 30º climes ref. [B3 C2 C3 D1 D2 D3 E1]
            Clima=app.ClimaRef.Value;%JGC Valor obtingut segons les coordenades introduïdes
            PSH=hum_rel(3,Clima);%JGC [kWh/m2/dia] o bé [peak sun hours] La irradiació promig es correspon en valor amb el nombre d'hores equivalents diàries rebent 1kW/m2 del sol
            Irr=365*PSH; %JGC [peak sun hours/any] o bé [kWh/m2/any] Estimació de les hores anuals en les que es pot produïr 1kWh amb 1kWp instal·lat:365 Dies * nombre de Peak Sun Hours
            
            CobeturaPVAlt=app.HibPVAlt_cob.Value; %JGC [%] percentatge del consum elèctric de la instal·lació Alternativa que esperem cobrir amb PV
            Prod_eh=app.ConElecAlthMWhe.Value*1000; %JGC [kWhe] demanda elèctrica Alternativa en mode calefacció
            Prod_ec=app.ConElecAltcMWhe.Value*1000; %JGC [kWhe] demanda elèctrica Alternativa en mode refrigeració
            Prod_eACS=app.ConElecAltACSMWhe.Value*1000; %JGC [kWhe] demanda elèctrica Alternativa en mode ACS
            Prod_eAlt=(Prod_eh+Prod_ec+Prod_eACS)*CobeturaPVAlt/100; %JGC [kWhe] producció elèctrica que volem cobrir amb els panells PV
            kWpAlt=Prod_eAlt/365/PSH; %JGC: [kWp] nombre de kWp instal·lats necessaris per produïr la mateixa electricitat que es vol estalviar.
            app.AltkWpPV.Value=kWpAlt;
            areaAlt=Prod_eAlt/Irr/effPV*100; %JGC:[m2] area corresponent a la instal·lació PV de suport a l'Alternativa
            app.Altm2PV.Value=areaAlt;
            Cost_PVAlt=kWpAlt*app.PV_cost.Value; %JGC: [€] Cost de la instal·lació PV de suport a la Alternativa
            Cap_BatAlt=Prod_eAlt/365; %JGC [kWh/dia] Valor proposat de capacitat pel banc de bateries, d'acord amb la producció PV (Alternativa)
            app.PVAltEneBat.Value=round(Cap_BatAlt,2);
            Cost_BatAlt=app.PVAltEneBat.Value*app.CostEspecBat.Value; %JGC: [€] Cost de la acumulació amb bateries Alternativa+PV
            app.PVAltCostInv.Value=(Cost_PVAlt+Cost_BatAlt*app.AltBat.Value)*app.AltHibPV.Value;
            %JGC: Actualitzaem el valor màxim subvencionable instal·lació alternativa:
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: GeoBat
        function Select_GeoBat(app, event)
            if app.GeoBat.Value==1
                app.CostEspecBat.Enable=1;
                app.Ch_DisCh_EffBat.Enable=1;
                app.DoD_Bat.Enable=1;
                app.PVGeoEneBat.Enable=1;
            else
                app.PVGeoEneBat.Enable=0;
                if app.AltBat.Value==1
                    app.CostEspecBat.Enable=1;
                    app.Ch_DisCh_EffBat.Enable=1;
                    app.DoD_Bat.Enable=1; 
                else
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;
                end
            end
            
            CAPEXPVGeo=app.GeokWpPV.Value*app.PV_cost.Value;
            CAPEXPVGeoBat=app.CostEspecBat.Value*app.PVGeoEneBat.Value;
            app.PVGeoCostInv.Value=app.GeoHibPV.Value*(CAPEXPVGeo+app.GeoBat.Value*CAPEXPVGeoBat);
            
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];  
        end

        % Value changed function: AltBat
        function Select_AltBat(app, event)
            if app.AltBat.Value==1
                app.CostEspecBat.Enable=1;
                app.Ch_DisCh_EffBat.Enable=1;
                app.DoD_Bat.Enable=1;
                app.PVAltEneBat.Enable=1;
            else
                app.PVAltEneBat.Enable=0;
                if app.GeoBat.Value==1
                    app.CostEspecBat.Enable=1;
                    app.Ch_DisCh_EffBat.Enable=1;
                    app.DoD_Bat.Enable=1;
                else
                    app.CostEspecBat.Enable=0;
                    app.Ch_DisCh_EffBat.Enable=0;
                    app.DoD_Bat.Enable=0;
                end
            end
            
            CAPEXPVAlt=app.AltkWpPV.Value*app.PV_cost.Value;
            CAPEXPVAltBat=app.CostEspecBat.Value*app.PVAltEneBat.Value;
            app.PVAltCostInv.Value=app.AltHibPV.Value*(CAPEXPVAlt+app.AltBat.Value*CAPEXPVAltBat);
            
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Value changed function: CostEspecBat
        function Input_CostEspecBat(app, event)
            CAPEXPVAlt=app.AltkWpPV.Value*app.PV_cost.Value;
            CAPEXPVAltBat=app.CostEspecBat.Value*app.PVAltEneBat.Value;
            app.PVAltCostInv.Value=app.AltHibPV.Value*(CAPEXPVAlt+app.AltBat.Value*CAPEXPVAltBat);
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            
            %JGC: Actualitzem valor límit superior subvenció
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Value=0;
                app.AltSubv.Enable=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enagle=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];
            end
            
            CAPEXPVGeo=app.GeokWpPV.Value*app.PV_cost.Value;
            CAPEXPVGeoBat=app.CostEspecBat.Value*app.PVGeoEneBat.Value;
            app.PVGeoCostInv.Value=app.GeoHibPV.Value*(CAPEXPVGeo+app.GeoBat.Value*CAPEXPVGeoBat);
            
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];    
        end

        % Value changed function: PVGeoEneBat
        function Input_EneGeoBat(app, event)
            CAPEXPVGeo=app.GeokWpPV.Value*app.PV_cost.Value;
            CAPEXPVGeoBat=app.CostEspecBat.Value*app.PVGeoEneBat.Value;
            app.PVGeoCostInv.Value=app.GeoHibPV.Value*(CAPEXPVGeo+app.GeoBat.Value*CAPEXPVGeoBat);
            
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXGeoTOT.Value=app.CAPEXGeo.Value+app.PVGeoCostInv.Value;
            LimSubvGeo=floor(app.CAPEXGeoTOT.Value);
            app.ImportGeoSubv.Limits=[0 LimSubvGeo];  
        end

        % Value changed function: PVAltEneBat
        function Input_EneAltBat(app, event)
            CAPEXPVAlt=app.AltkWpPV.Value*app.PV_cost.Value;
            CAPEXPVAltBat=app.CostEspecBat.Value*app.PVAltEneBat.Value;
            app.PVAltCostInv.Value=app.AltHibPV.Value*(CAPEXPVAlt+app.AltBat.Value*CAPEXPVAltBat);
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            
            %JGC: Actualitzem valor límit superior subvenció
            app.CAPEXAltTOT.Value=app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value+app.PVAltCostInv.Value;
            LimSubvAlt=floor(app.CAPEXAltTOT.Value);
            if LimSubvAlt==0
                app.AltSubv.Enable=0;
                app.AltSubv.Value=0;
                app.ImportAltSubv.Value=0;
                app.ImportAltSubv.Enable=0;
                app.AltPres.Value=0;
                app.AltPres.Enable=0;
                app.TramAltPres.Value=0;
                app.TramAltPres.Enable=0;
                app.InteresAltPres.Enable=0;
                app.RetornAltPres.Enable=0;
            else
                app.ImportAltSubv.Limits=[0 LimSubvAlt];  
            end
        end

        % Button pushed function: RunEcoAmb
        function RUN_AnalisiEcoAmb(app, event)
            % JGC: Codi per al càlcul de viabilitat econòmica
            app.Estalviesperat.Enable=1;
            app.Paybacktext.Enable=1;
%             app.EstalviNetTotal.Enable=1;
            app.RendibNetTotal.Enable=1;
            app.PaybackDesctext.Enable=1;
            app.VAN.Enable=1;
            app.TIRt.Enable=1;
            app.EstalviCO2rel.Enable=1;
            app.EstalviCO2.Enable=1;
            app.PlotDespesa.Enable=1;
            app.PlotVAN.Enable=1;
            app.GeoCons_e.Enable=1;
            app.AltCons_e.Enable=1;
            app.GeoAutoprod_e.Enable=1;
            app.AltAutoprod_e.Enable=1;
            app.GeoExcdPV_e.Enable=1;
            app.AltExcdPV_e.Enable=1;
            app.GeoAutocons_e.Enable=1;
            app.AltAutocons_e.Enable=1;
            app.GeoDesp_e.Enable=1;
            app.AltDesp_e.Enable=1;
            app.GeoEstalviAutoc_e.Enable=1;
            app.AltEstalviAutoc_e.Enable=1;
            %JGC: Alertem sobre la necessitat de revisar els costos específics
            alert_fig = app.GeoSIVdesigntoolv10UIFigure;
            uialert(alert_fig,['El valor del "Cost específic sistema" que Geo-SIV mostra per defecte s''ha de revisar i' ...
                ' adaptar a les circunstàncies del projecte, atès que pot variar en funció del fabricant, model i el nombre ' ...
                'd''equips necessaris per cobrir la potència sol.licitada'],'Missatge d''avís','Icon','warning')
            
            param=readmatrix("018_parametersEcoAmb.xlsx"); %JGC: Llistat paràmetres necessaris per als càlculs. Per a més informació, llegir "Taula dades econòmiques.xlsx"
            P_insG=getappdata(0,'Pot_G'); %JGC [kW] Potència instal·lada geotèrmia a efectes de càlcul econòmic
            P_e=app.PotElecContrat.Value; %JGC: [kWe] valor potència elèctrica a contractar
            Cons_h=app.ProdCalfMWht.Value; %JGC: [MWht] Producció calefacció anual
            Cons_c=app.ProdRefrMWht.Value; %JGC: [MWht] Producció refrigeració anual
            Cons_ACS=app.Prod_ACSMWht.Value; %JGC: [MWht] Producció ACS anual
            Cons_eGeo=app.ConElecMWhe.Value; %JGC: [MWhe] Consum elèctric anual (Geotèrmia)
%             Cons_eGeoACS=app.Cons_e_ACSMWhe.Value; %JGC: [MWhe] Consum elèctric anual (Geotèrmia) només ACS
            COPGeo=getappdata(0,'COPm');
            EERGeo=getappdata(0,'EERm');
            COPACSGeo=getappdata(0,'COP_ACSm');
            P_insA=P_insG; %JGC: [kW] La potència instal·lada a efectes de càlcul econòmic ha de coincidir per a la geotèrmia i l'alternativa
            P_inscA=app.PotInsRefrkW.Value;
            P_insACSA=1.1*app.PotDis_ACSkW.Value;
            P_eAlth=app.PotElecAlthkW.Value; %JGC: [kWe]Potència elèctrica necessària Alternativa Calefacció
            P_eAltc=app.PotElecAltckW.Value; %JGC: [kWe]Potència elèctrica necessària Alternativa Refrigeració
            P_eAltACS=app.PotElecAltACSkW.Value; %JGC: [kWe]Potència elèctrica necessària Alternativa ACS
            chooseP_eAlt= [P_eAlth P_eAltc P_eAltACS];
            P_eAlt=max(chooseP_eAlt); % JGC: [kWe] Potència elèctrica a contractar a l'Alternativa
            Cons_eAlth=app.ConElecAlthMWhe.Value; %JGC:[MWhe] Consum elèctric anual (Alternativa Calefacció)
            Cons_eAltc=app.ConElecAltcMWhe.Value; %JGC:[MWhe] Consum elèctric anual (Alternativa Refrigeració)
            Cons_eAltACS=app.ConElecAltACSMWhe.Value; %JGC:[MWhe] Consum elèctric anual (Alternativa ACS)
            Cons_eAlt=Cons_eAlth+Cons_eAltc+Cons_eAltACS; %JGC: [MWhe] Consum elèctric anual (Alternativa)
            COP_AER1m=getappdata(0,'COP_AER1_m');
            COP_AER1ACSm=getappdata(0,'COP_AER1ACS_m');
            EER_AER1m=getappdata(0,'EER_AER1_m');
            EER_AER2m=getappdata(0,'EER_AER2_m');
%             SPFCalfAER1=getappdata(0,'SPFc_AER1');
%             SPFRefrAER1=getappdata(0,'SPFr_AER1');
%             SPFACSAER1=getappdata(0,'SPFacs_AER1');
%             SPFRefrAER2=getappdata(0,'SPFr_AER2');
            n_renew=15; %JGC: [anys] vida atribuida als equips (BCG, Calderes, etc.). Pot diferenciar-se per cas, si anem al detall
%             n_Alt=app.vidaprojexistent.Value;
%             n_Altrenew=n_renew; %JGC: [anys] nombre d'anys que queden fins a la pròxima renovació d'equips 
            if app.SwitchTipusProjecte.Value=="Nova instal·lació"
                n_Alt=0;  %JGC: [anys] nombre d'anys de la instal·lació alternativa. Si és nova, serà 0
                n_Altrenew=n_renew;
                Nova=1;
            else
                n_Alt=app.vidaprojexistent.Value;  %JGC: [anys] nombre d'anys de la instal·lació alternativa. Si és nova, serà 0
                n_Altrenew=n_renew-(n_Alt-n_renew*fix(n_Alt/n_renew)); %JGC: Anys fins al proper recanvi d'equips de climatització a la instal·lació Alternativa
                Nova=0;
            end
%             Area_ST=app.AreaMinST.Value;%JGC: [m2] (útil per Solar tèrmica)
%             Irr45_ST=getappdata(0,'Irr45_ST'); %JGC: [Wh/mes/m2] (7rows12columns) (útil per Solar tèrmica)
            n_life=app.vidaprojecte.Value; %JGC: anys de vida del projecte
            d_rate=0.01*app.Taxadescompte.Value; %JGC taxa de descompte del projecte
            %JGC: Hem de contemplar si hem d'incorporar noves inversions en el decurs del projecte si aquest és més llarg que la vida útil dels equips:
            C_BCG=app.CostEspecificGeo.Value*P_insG; %JGC [€] cost total de la bomba de calor. Considerem sempre el valor en mode calefacció
%             C_instGeo=app.CostEspecInstGeo.Value*P_inshG; %JGC [€] cost Instal·lació

            %JGC: Subvencions i Prèstecs associats a la Geotèrmia
            CAPEX_G=app.CAPEXGeoTOT.Value; %JGC: [€] Valor ja calculat de la inversió inicial de la Geotèrmia. Això inclou una possible hibridació amb PV
            Man_G=app.MantGeo.Value*P_insG; %JGC: [€/any] Cost anual del manteniment de la Geotèrmia
            Subv_Geo=double(app.GeoSubv.Value);%JGC: Definim variable binària per si la Geotèrmia està subvencionada (1) o no (0)
%             SubG=0;
            if Subv_Geo==1
                SubG=app.ImportGeoSubv.Value;
            else
                SubG=0;
            end
            Pres_G=double(app.GeoPres.Value); %JGC: Definim variable binària per si la Geotèrmia es financia amb prèstec (1) o no (0)
            i_pG=0.01*app.InteresGeoPres.Value; %JGC [t.p.1] taxa interés del préstec per a la Geotèrmia
            t_pG=app.RetornGeoPres.Value; %JGC període de retorn del préstec per a la Geotèrmia
            if Pres_G==1
                x_PreG=app.TramGeoPres.Value/100; %JGC: [t.p.1] fracció finançada amb prèstec del cost d'inversió (percentatge limitat automàticament si hi ha subvenció).
                PreG=CAPEX_G*x_PreG;
                QuGeo=i_pG*PreG/(1-(1+i_pG)^(-t_pG)); %JGC Quota anual de retorn Préstec Geotèrmia
            else
                PreG=0;
                QuGeo=0;
            end

            
            %JGC: Subvencions i Prèstecs associats a la Alternativa
            CAPEX_A=app.CAPEXAltTOT.Value; %JGC: [€] Valor ja calculat de la inversió inicial de l'Alternativa. Això inclou una possible hibridació amb PV
            Subv_Alt=double(app.AltSubv.Value);%JGC: Definim variable binària per si la Alternatuva està subvencionada (1) o no (0)
            if Subv_Alt==1
                SubA=app.ImportAltSubv.Value; %JGC: [€] Valor import subvencionable
            else
                SubA=0;
            end
            Pres_A=double(app.AltPres.Value); %JGC: Definim variable binària per si la Alternativa es financia amb prèstec (1) o no (0)
            i_pA=0.01*app.InteresAltPres.Value; %JGC [t.p.1] taxa interés del préstec per a l'Alternativa
            t_pA=app.RetornAltPres.Value; %JGC període de retorn del préstec per a l'Alternativa
            if Pres_A==1
                x_PreA=app.TramAltPres.Value/100; %JGC: [t.p.1] fracció finançada amb prèstec del cost d'inversió (percentatge limitat automàticament si hi ha subvenció).
                PreA=CAPEX_A*x_PreA;
                QuAlt=i_pA*PreA/(1-(1+i_pA)^(-t_pA)); %JGC Quota anual de retorn Préstec Alternativa
            else
                PreA=0;
                QuAlt=0;
            end

           
            % JGC: Anea a calcular els costos variables Geotèrmia i emissions:
            Infl=0.01*param(130); %JGC [t.p.1] inflació anual promig per a tots el usos a on aquesta inflació no estigui definida (com per exemple, el manteniment)
            vare_vall=app.CostkWhVall.Value; %JGC: [€/kWh] cost variable Electricitat, periode VALL
            vare_pla=app.CostkWhPla.Value; %JGC: [€/kWh] cost variable Electricitat, periode PLA
            vare_punta=app.CostkWhPunta.Value; %JGC: [€/kWh] cost variable Electricitat, periode PUNTA
            vare_i=0.01*app.Infl_AnualVar_e.Value; %JGC [t.p.1] inflació promig anual Electricitat
            fije_vall=app.CostkWVall.Value; %JGC: [€/kWe/any] cost específic fixe Electricitat anual, període VALL
            fije_plapunta=app.CostkWPlaPunta.Value;%JGC: [€/kWe/any] cost específic fixe Electricitat anual, període PLA-PUNTA
            fije_i=0.01*app.Infl_AnualFix_e.Value; %JGC [t.p.1] inflació dels costos fixos Electricitat
            em_e=param(110);% JGC [kgCO2/kWhe E.Final] emissions equivalents de CO2 associades al consum elèctric (si n'hi ha)
            kg2kWh_biom=param(10); %JGC[kwh/kg] per a la biomassa
            kg2kWh_GC=param(54); %JGC[kwh/kg] per al gas ciutat (propà)
            l2kWh_Gs=param(102); %JGC[kwh/l] per al gasoil

            %JGC: Anem a definir uns factors multiplicatius que ens ajudaràn a definir els costos dels equips (només dels equips) 
            Si_Calf=double(app.OpcionsAlternativa_h.Enable);
            Si_Refr=double(app.OpcionsAlternativa_c.Enable);
            Si_ACS=double(app.OpcionsAlternativa_ACS.Enable);
            E_eh=zeros(12,1); %JGC: Defineixo uns valors inicials, per si de cas no hi ha calefacció.
            E_ec=zeros(12,1); %JGC: Defineixo uns valors inicials, per si de cas no hi ha refrigeració.
            E_eACS=zeros(12,1);  %JGC: Defineixo uns valors inicials, per si de cas no hi ha ACS.
            Effh=ones(12,1);
            EffACS=ones(12,1);
            em_h=0;
            em_ACS=0;
            heat=0;
            heatACS=0;
            fijh=0;
            fijh_i=0;
            fijACS=0;
            fijACS_i=0;
            varh=0;
            varh_i=0;
            varACS=0;
            varACS_i=0;
            if app.OpcionsAlternativa_h.Value=="Biomassa"
                AltText1='BIOMASSA';
                OpAlth=1;
                Effh=Effh*0.01*param(7);
                em_h=param(9); % JGC [kgCO2/kWht E.Final] emissions equivalents de CO2 associades (energia.gob.es)
                heat=1; %JGC: Es consumeix combustible no electric? (heat=1 Sí, heat=0: No)
                varh=app.CostkgBiom.Value/kg2kWh_biom;%JGC: [€/kWh] cost variable combustible (Biomassa)
                varh_i=0.01*app.Infl_AnualVar_Biom.Value;%JGC [t.p.1] inflació promig anual del combustible
                fijh=0; %JGC: [€/kWt] cost fixe combustible (Biomassa)
                fijh_i=0;%JGC [t.p.1] inflació dels costos fixos
                E_eh=E_eh+param(8); %JGC: porció d'energia tèrmica equivalent al consum elèctric
            elseif app.OpcionsAlternativa_h.Value=="Gas Natural"
                AltText1='GAS NATURAL';
                OpAlth=2;
                Effh=Effh*0.01*param(18);
                em_h=param(19); % JGC [kgCO2/kWht E.Final] emissions equivalents de CO2 associades (energia.gob.es)
                heat=1; %JGC: Es consumeix combustible no electric? (heat=1 Sí, heat=0: No)
                varh=app.CostkWhGN.Value;%JGC: [€/kWh] cost variable combustible (GN canalitzat)
                varh_i=0.01*app.Infl_AnualVar_GN.Value;%JGC [t.p.1] inflació promig anual del combustible
                fijh=app.CostGN.Value; %JGC: [€/any] cost fixe anual combustible (GN canalitzat)
                fijh_i=0.01*app.Infl_AnualFix_GN.Value;%JGC [t.p.1] inflació dels costos fixos
%                 E_eh=0; %JGC: porció d'energia tèrmica equivalent al consum elèctric
            elseif (app.OpcionsAlternativa_h.Value=="Gas Propà")||(app.OpcionsAlternativa_h.Value=="Gas Propà Canalitzat")
                if app.OpcionsAlternativa_h.Value=="Gas Propà"
                    AltText1='PROPÀ';
                elseif app.OpcionsAlternativa_h.Value=="Gas Propà Canalitzat"
                    AltText1='PROPÀ CANALITZAT';
                end
                OpAlth=3;
                Effh=Effh*0.01*param(52);
                em_h=param(53); % JGC [kgCO2/kWht E.Final] emissions equivalents de CO2 associades (energia.gob.es)
                heat=1; %JGC: Es consumeix combustible no electric? (heat=1 Sí, heat=0: No)
                varh=app.CostkgGC.Value/kg2kWh_GC;%JGC: [€/kWh] cost variable combustible (Propà)
                varh_i=0.01*app.Infl_AnualVar_GC.Value;%JGC [t.p.1] inflació promig anual del combustible
                fijh=app.CostGC.Enable*app.CostGC.Value; %JGC: [€/any] cost fixe combustible (Propà)
                fijh_i=0.01*app.Infl_AnualFix_GC.Value;%JGC [t.p.1] inflació dels costos fixos
%                 E_eh=0; %JGC: porció d'energia tèrmica equivalent al consum elèctric
            elseif app.OpcionsAlternativa_h.Value=="Gasoil"
                AltText1='GASOIL';
                OpAlth=4;
                Effh=Effh*0.01*param(100);
                em_h=param(101); % JGC [kgCO2/kWht E.Final] emissions equivalents de CO2 associades (energia.gob.es)
                heat=1; %JGC: Es consumeix combustible no electric? (heat=1 Sí, heat=0: No)
                varh=app.CostlGasoil.Value/l2kWh_Gs;%JGC: [€/kWh] cost variable combustible (Gasoil a granel)
                varh_i=0.01*app.Infl_AnualVar_Gasoil.Value;%JGC [t.p.1] inflació promig anual del combustible
                fijh=0; %JGC: [€/kWt] cost fixe combustible (Gasoil a granel)
                fijh_i=0;%JGC [t.p.1] inflació dels costos fixos
%                 E_eh=0; %JGC: porció d'energia tèrmica equivalent al consum elèctric
            elseif app.OpcionsAlternativa_h.Value=="Electricitat"
                AltText1='SISTEMA ELÈCTRIC';
                OpAlth=5;%JGC, en aquest cas, es tracta de consum elèctric que ja ha estat comptabilitzat prèviament.
                Effh=Effh*0.01*param(114); %JGC: 
                em_h=em_e; %JGC: [kgCO2/kWhe E.Final]
                heat=0; %JGC: Es consumeix combustible no electric? (heat=1 Sí, heat=0: No)
                E_eh=E_eh+1/(0.01*param(114)); %JGC: porció d'energia tèrmica equivalent al consum elèctric
                if (Si_ACS==1)&&(app.OpcionsAlternativa_ACS.Value=="Col·lectors Solars Tèrmics")
                    Cons_h=Cons_h+0.4*Cons_ACS; %JGC: En cas de tenir sistema elèctric per calefacció, hem de comptar el consum 
                    % de suport a ACS si aquest es fa amb col·lectors solars
                end
            else %(Aerotèrmia)
                AltText1='AEROTÈRMIA';
                OpAlth=6;
                Effh=COP_AER1m;
                em_h=em_e; %JGC: [kgCO2/kWhe E.Final]
                heat=0; %JGC: Es consumeix combustible no electric? (heat=1 Sí, heat=0: No)
                for j=1:1:12
                   E_eh(j)=1/Effh(j); 
                end
            end
            if app.OpcionsAlternativa_c.Value=="Aire Condicionat"
                AltText2='AIRE CONDICIONAT';
                OpAltc=1;
                Effc=EER_AER2m;
                for j=1:1:12
                    E_ec(j)=1/Effc(j);
                end
            else
                AltText2='AEROTÈRMIA';
                OpAltc=2;
                Effc=EER_AER1m;
                for j=1:1:12
                    E_ec(j)=1/Effc(j);
                end 
            end
            if app.OpcionsAlternativa_ACS.Value=="Col·lectors Solars Tèrmics"
                AltText3='SOLAR TÈRMICA';
                OpAltACS=1;
                if Si_Calf==1 %JGC: Si tenim demanda de calefacció i tenim colectors solars, la instal·lació de suport serà GN, GC, Gasoil o 
                              % sistema eElèctric (ni Biomassa ni Aerotèrmia, pq llavors implicaría que no podem escollir col·lectors solars per l'ACS)
                   EffACS=Effh;
                   em_ACS=em_h;
                   varACS=varh;
                   varACS_i=varh_i;
                   fijACS=fijh;
                   fijACS_i=fijh_i;
                   heatACS=heat; %JGC: Si la opció de calefacció és electrictat (dissipació efecte Joule), la electricitat ja la tenim comptabilitzada prèviament
                else %JGC: En aquet cas, la instal·lació de suport és el gas natural
                   EffACS=EffACS*0.01*param(18);
                   em_ACS=param(19);
                   varACS=app.CostkWhGN.Value;
                   varACS_i=0.01*app.Infl_AnualVar_GN.Value;
                   fijACS=app.CostGN.Value;
                   fijACS_i=0.01*app.Infl_AnualFix_GN.Value;
                   heatACS=1;
                end
                E_eACS=E_eACS+param(15)*0.6; %JGC: El factor 0.6 és perquè només produïrem un 60% d'ACS amb aquesta tecnologia
            elseif app.OpcionsAlternativa_ACS.Value=="Biomassa"
                AltText3='BIOMASSA';
                OpAltACS=2;
                EffACS=EffACS*0.01*param(7);
                em_ACS=param(9); % JGC [kgCO2/kWht E.Final] emissions equivalents de CO2 associades (energia.gob.es)
                %JGC: Es consumeix combustible no electric (heat=1)? 
                heatACS=1;
                varACS=app.CostkgBiom.Value/kg2kWh_biom;%JGC: [€/kWh] cost variable combustible (Biomassa)
                varACS_i=0.01*app.Infl_AnualVar_Biom.Value;%JGC [t.p.1] inflació promig anual del combustible
                fijACS=0; %JGC: [€/kWt] cost fixe combustible (Biomassa)
                fijACS_i=0;%JGC [t.p.1] inflació dels costos fixos
                E_eACS=E_eACS+param(8);
            else % JGC: "Aerotèrmia"
                AltText3='AEROTÈRMIA';
                OpAltACS=3;
                EffACS=COP_AER1ACSm;
                em_ACS=em_e; %JGC: [kgCO2/kWhe E.Final]
                heatACS=0;
                for j=1:1:12
                    E_eACS(j)=1/COP_AER1ACSm(j);
                end
            end
            
            %JGC Anem a definir el títol del gràfic de la alternativa a la pestanya de resultats Eco&Amb
            app.ALTPV_PLOTlabel_c.FontSize=10;
            if Cons_h==0
                app.ALTPV_PLOTlabel_h.Text='';
                app.plus_1.Text=''; 
                if Cons_c==0
                    app.ALTPV_PLOTlabel_c.Text='';
                    app.plus_2.Text='';
                    app.ALTPV_PLOTlabel_ACS.Text=AltText3;
                    app.ALTPV_PLOTlabel_ACS.HorizontalAlignment='left';
                else
                    app.ALTPV_PLOTlabel_c.Text=AltText2;
                    app.ALTPV_PLOTlabel_c.FontColor=[0 0 1];
                    if Cons_ACS==0
                        app.plus_2.Text='';
                        app.ALTPV_PLOTlabel_ACS.Text='';
                        app.ALTPV_PLOTlabel_c.HorizontalAlignment='center';
                    else
                        app.plus_2.Text='+';
                        app.ALTPV_PLOTlabel_ACS.HorizontalAlignment='left';
                        app.ALTPV_PLOTlabel_c.HorizontalAlignment='right';
                    end
                end
            else
                app.ALTPV_PLOTlabel_h.Text=AltText1;
                if Cons_c==0
                    app.plus_1.Text='';
                    app.plus_2.Text='';
                    app.ALTPV_PLOTlabel_h.HorizontalAlignment='right';
                    if Cons_ACS==0
                        app.ALTPV_PLOTlabel_c.Text='';
                        app.ALTPV_PLOTlabel_ACS.Text='';
                    else
                        app.ALTPV_PLOTlabel_c.Text='+';
                        app.ALTPV_PLOTlabel_c.FontColor=[0 0 0];
                        app.ALTPV_PLOTlabel_ACS.Text=AltText3;
                        app.ALTPV_PLOTlabel_ACS.HorizontalAlignment='left';
                    end
                else
                   app.ALTPV_PLOTlabel_c.Text=AltText2;
                   app.ALTPV_PLOTlabel_c.FontColor=[0 0 1];
                   app.plus_1.Text='+';
                   if Cons_ACS==0
                       app.plus_2.Text='';
                       app.ALTPV_PLOTlabel_ACS.Text='';
                       app.ALTPV_PLOTlabel_h.HorizontalAlignment='right';
                       app.ALTPV_PLOTlabel_c.HorizontalAlignment='left';
                   else
                       app.plus_2.Text='+';
                       app.ALTPV_PLOTlabel_ACS.Text=AltText3; 
                       app.ALTPV_PLOTlabel_ACS.HorizontalAlignment='left';
                       app.ALTPV_PLOTlabel_h.HorizontalAlignment='right';
                       app.ALTPV_PLOTlabel_c.HorizontalAlignment='center';
                   end
                end
            end

            %JGC: Anem a definir el valor del cost dels equips (només equips) i el manteniment de l'Alternativa segons les diferents opcions
%             if (OpAlth==1)||(OpAlth==2)||(OpAlth==3)||(OpAlth==4)||(OpAlth==5)
            C_Alth=Si_Calf*P_insA*app.CostEspecificAlt_h.Value; %JGC: utilitzem el valor de potència de la Geotèrmia per asumir els mateixos kW.
            Man_Ah=Si_Calf*P_insA*app.MantAlt_h.Value; %JGC: utilitzem el valor de potència de la Geotèrmia per asumir els mateixos kW.
%             else
%                 C_Alth=Si_Calf*P_insA*app.CostEspecificAlt_h.Value;
%                 Man_Ah=Si_Calf*P_insA*app.MantAlt_h.Value;
%             end
            if (OpAltc==1) % (Aire condicionat)
                C_Altc=Si_Refr*P_inscA*app.CostEspecificAlt_c.Value;
                Man_Ac=Si_Refr*P_inscA*app.MantAlt_c.Value;
            else % (Aerotèrmia)
%                 C_Altc=Si_Refr*max(P_inscA, P_insACSA)*app.CostEspecificAlt_c.Value;
%                 Man_Ac=Si_Refr*max(P_inscA, P_insACSA)*app.MantAlt_c.Value;
                C_Altc=Si_Refr*P_insA*app.CostEspecificAlt_c.Value; %JGC: utilitzem el valor de potència de la Geotèrmia per asumir els mateixos kW.
                Man_Ac=Si_Refr*P_insA*app.MantAlt_c.Value; %JGC: utilitzem el valor de potència de la Geotèrmia per asumir els mateixos kW.
            end
            if (OpAltACS==1) %(Col·lectors Solars Tèrmics)
                if Si_Calf==1
                    C_AltACS=Si_ACS*app.AreaMinST.Value*app.CostEspecificAlt_ACS.Value;
                    Man_AACS=Si_ACS*app.AreaMinST.Value*app.MantAlt_ACS.Value;
                else
                    if Cons_ACS<5 %JGC: Tarifa 3.1 GN
                       Cald_esp=param(22);
                       Man_esp=param(23);
                    elseif (Cons_ACS>=5)&&(Cons_ACS<15) %JGC: Tarifa 3.2(a) GN
                       Cald_esp=param(28);
                       Man_esp=param(29);
                    elseif (Cons_ACS>=15)&&(Cons_ACS<50) %JGC: Tarifa 3.2(b) GN
                       Cald_esp=param(34);
                       Man_esp=param(35);
                    elseif (Cons_ACS>=50)&&(Cons_ACS<100) %JGC: Tarifa 3.3 GN
                       Cald_esp=param(40);
                       Man_esp=param(41);
                    elseif (Cons_ACS>=100) %JGC: Tarifa 3.4 GN
                       Cald_esp=param(46);
                       Man_esp=param(47);
                    end
                    CostACSEqpsup=P_insACSA*Cald_esp;
                    CostACSMansup=P_insACSA*Man_esp;
                    C_AltACS=Si_ACS*(app.AreaMinST.Value*app.CostEspecificAlt_ACS.Value+CostACSEqpsup);
                    Man_AACS=Si_ACS*(app.AreaMinST.Value*app.MantAlt_ACS.Value+CostACSMansup);
                end
            else %(Biomassa o Aerotèrmia)
                C_AltACS=Si_ACS*P_insACSA*app.CostEspecificAlt_ACS.Value;
                Man_AACS=Si_ACS*P_insACSA*app.MantAlt_ACS.Value;
            end
            C_Alt=C_Alth+C_Altc+C_AltACS;
            Man_A=Man_Ah+Man_Ac+Man_AACS;
            
            %JGC: Anem a calcular el percentatge de "matching" entre consum elèctric (climatització) i una eventual producció PV
            EneBatGeoMax=app.GeoBat.Enable*app.GeoBat.Value*app.PVGeoEneBat.Value*1000; %JGC: [Wh] valor màxim  d'emmagatzemmatge (Geotèrmia)
            EneBatAltMax=app.AltBat.Enable*app.AltBat.Value*app.PVAltEneBat.Value*1000; %JGC: [Wh] valor màxim  d'emmagatzemmatge (Alternativa)
            Eff_Ch=0.01*app.Ch_DisCh_EffBat.Value; %JGC: eficiència de càrrega/descàrrega de les bateries
            DoD=0.01*app.DoD_Bat.Value; %JGC: profunditat de descàrrega de les bateries
            EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: [Wh] energia emmagatzemada inicialment a les bateries a les 0:00h de l'1 de Gener (Geotèrmia)
            EneBatAlt0=EneBatAltMax*(1-DoD);%JGC: [Wh] energia emmagatzemada inicialment a les bateries a les 0:00h de l'1 de Gener (Alternativa)
           
            %JGC: construïm una taula d'equivalència entre irradiació solar global (sobre superficie 30º) i consum elèctric de les instal·lacions
            %(climatització només. L'ACS el tractem apart), segons valor T_ambient, hora del dia i tipus de tarifa.
            PV_hourlyGeo=zeros(8760,16);
            PV_hourlyAlt=zeros(8760,16);
            
            %JGC:matriu definida com: [Col1:T_amb Col2:Irr30 Col3:mes Col4:tarifa Col5:Cons_eVall Col6:Cons_epla Col7:Cons_epunta
            %Col8:ProdPV_cobrint_consum_vall Col9:ProdPV_cobrint_consum_pla Col10:ProdPV_cobrint_consum_punta Col11: ExcedentVall 
            %Col12: ExcedentPla Col13:ExcedentPunta Col14:EneBat_cobrint_consum_vall Col15:EneBat_cobrint_consum_pla Col16:EneBat_cobrint_consum_punta]    
            
            capacitat=getappdata(0,'capacitat');%JGC: Corba capacitat Q_P(T_0) en condicions desfavorables (sense sol a l'hivern i amb sol a l'estiu)
            capacitat_fav=getappdata(0,'capacitat_fav'); %JGC: Corba capacitat Q_P(T_0) en situacions favorables (amb sol a l'hivern i sense sol a l'estiu)
            capacitat_a=getappdata(0,'capacitat_a');%JGC: Corba capacitat Q_P(T_0) en condicions desfavorables (sense sol a l'hivern i amb sol a l'estiu) per a instal·lació alternativa
            capacitat_fava=getappdata(0,'capacitat_fava'); %JGC: Corba capacitat Q_P(T_0) en situacions favorables (amb sol a l'hivern i sense sol a l'estiu) per a instal·lació alternativa
            F_ocup=getappdata(0,'F_ocup');
            
            Prod=getappdata(0,'Produccio'); %JGC: matriu (12,3) amb les produccións mes a mes
            %JGC: Consum elèctric mes a mes tant per geotèrmia com per alternativa:
            Cons_eGeoACSm=zeros(12,1);
            Cons_eAltACSm=zeros(12,1);
            for j=1:1:12
                Cons_eGeoACSm(j)=Prod(j,3)/COPACSGeo(j); %JGC: [MWhe] consum elèctric mes a mes ACS Geotèrmia
                Cons_eAltACSm(j)=Prod(j,3)*E_eACS(j); %JGC: [MWhe] consum elèctric mes a mes ACS Alternativa
            end
            SignProd=zeros(12,1);
            %JGC: Ara genero un array que em dirà per a cada mes si tinc calefacció (signe (+) ="1"), refrigeració (signe (-)="-1") o res (="0")
            for i=1:1:12
                SignProd(i)=sign(Prod(i,1)+Prod(i,2));
            end
            factors_norm=getappdata(0,'factors_norm'); %JGC: factors normalització de les produccions. Son els factors pel quals s'han de 
            % multiplicar els valors de producció de calf. i refr. per fer coindicir integral de les corbes E(mes) i E(T_amb)
            factor_h=factors_norm(1); %JGC: correcció producció_h
            factor_c=factors_norm(2); %JGC: correcció producció_c
            
            bin_values=getappdata(0,'eix_temperatura');
            bin_values_size = size(bin_values);
            T_balanc=getappdata(0,'Temp_Balanc');
%             rough_total=readmatrix('004_Hourly t-bin.xlsx');
            hourly_T0=getappdata(0,'hourly_temp');
            Irr30_hourly=readmatrix('016_rough_IRR30.xlsx');
            timeANDtarif=readmatrix('017_time_and_tariff.xlsx');
            T_meanMonth=getappdata(0,'T_mitjana_mes');
            thermal_case=app.ClimaRef.Value;
            
            for i=1:1:8760
                PV_hourlyGeo(i,2)=Irr30_hourly(i,thermal_case);
                PV_hourlyAlt(i,2)=PV_hourlyGeo(i,2);
                mes=timeANDtarif(i,1);%JGC: número de mes
                PV_hourlyGeo(i,3)=mes; 
                PV_hourlyAlt(i,3)=mes;
                %JGC: Imposem unes condicions en cas de conèixer demanda i només tenir-ne un tipus (CALOR o FRED)
                if (app.UnknownDemand.Value==false)&&(app.e_hivern.Value==0) %JGC: si coneixem demanda però només tenim refrigeració
                    if T_meanMonth(mes)<(T_balanc-1) %JGC: si per al mes corresponent a la hora "i" la Temperatura mitjana mensual és inferior a T_bal
                       PV_hourlyGeo(i,1)=100; %JGC:valor que donem per fer que més endavant la producció de CALOR per aquesta hora es calculi com nul·la
                    else
                       PV_hourlyGeo(i,1)=hourly_T0(i); 
                    end
                    PV_hourlyAlt(i,1)=PV_hourlyGeo(i,1);
                elseif (app.UnknownDemand.Value==false)&&(app.e_estiu.Value==0) %JGC: si coneixem demanda però només tenim calefacció
                    if T_meanMonth(mes)>(T_balanc+1)  %JGC: si per al mes corresponent a la hora "i" la Temperatura mitjana mensual és superior a T_bal
                       PV_hourlyGeo(i,1)=100; %JGC:valor que donem per fer que més endavant la producció de FRED per aquesta hora es calculi com nul·la
                    else
                       PV_hourlyGeo(i,1)=hourly_T0(i); 
                    end
                else %JGC: la resta de casos en que tenim ambdos, CALOR i FRED
                    PV_hourlyGeo(i,1)=hourly_T0(i); 
                end
                PV_hourlyAlt(i,1)=PV_hourlyGeo(i,1);
                PV_hourlyGeo(i,4)=timeANDtarif(i,4); %JGC: tipus de tarifa (1: vall, 2:pla, 3: punta)
                PV_hourlyAlt(i,4)=PV_hourlyGeo(i,4);
                if  PV_hourlyGeo(i,4)==1%JGC: Periode vall    
                    for j=1:1:bin_values_size(2)
                        if (PV_hourlyGeo(i,1)>=(bin_values(j)-1))&&(PV_hourlyGeo(i,1)<(bin_values(j)+1))
                            if bin_values(j)<(T_balanc-1)
                                if PV_hourlyGeo(i,2)==0 %JGC: si no hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,5)=F_ocup*capacitat(j);%/COPGeo;%JGC [Wh] producció calefacció període vall (sense sol)
                                    PV_hourlyAlt(i,5)=F_ocup*capacitat_a(j);%*E_eh;
                                else %JGC: si hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,5)=F_ocup*capacitat_fav(j);%/COPGeo;%JGC [Wh] producció calefacció període vall (amb sol)
                                    PV_hourlyAlt(i,5)=F_ocup*capacitat_fava(j);%*E_eh;
                                end
                            elseif bin_values(j)>(T_balanc+1)
                                if PV_hourlyGeo(i,2)==0 %JGC: si no hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,5)=F_ocup*capacitat_fav(j);%/EERGeo;%JGC [Wh] producció refrigeració període vall (sense sol)
                                    PV_hourlyAlt(i,5)=F_ocup*capacitat_fava(j);%*E_ec;
                                else %JGC: si hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,5)=F_ocup*capacitat(j);%/EERGeo;%JGC [Wh] producció refrigeració període vall (amb sol)
                                    PV_hourlyAlt(i,5)=F_ocup*capacitat_a(j);%*E_ec;
                                end
                            else
                                PV_hourlyGeo(i,5)=0;
                                PV_hourlyAlt(i,5)=0;
                            end
                            break
                        else
                            PV_hourlyGeo(i,5)=0;
                            PV_hourlyAlt(i,5)=0;
                        end
                    end
                elseif PV_hourlyGeo(i,4)==2 %JGC: Periode pla
                    for j=1:1:bin_values_size(2)
                        if (PV_hourlyGeo(i,1)>=(bin_values(j)-1))&&(PV_hourlyGeo(i,1)<(bin_values(j)+1))
                            if bin_values(j)<(T_balanc-1)
                                if PV_hourlyGeo(i,2)==0 %JGC: si no hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,6)=F_ocup*capacitat(j);%/COPGeo;%JGC [Wh] producció calefacció període pla (sense sol)
                                    PV_hourlyAlt(i,6)=F_ocup*capacitat_a(j);%*E_eh;
                                else
                                    PV_hourlyGeo(i,6)=F_ocup*capacitat_fav(j);%/COPGeo;%JGC [Wh] producció calefacció període pla (amb sol)
                                    PV_hourlyAlt(i,6)=F_ocup*capacitat_fava(j);%*E_eh;
                                end
                            elseif bin_values(j)>(T_balanc+1)
                                if PV_hourlyGeo(i,2)==0 %JGC: si no hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,6)=F_ocup*capacitat_fav(j);%/EERGeo;%JGC [Wh] producció refrigeració període pla (sense sol)
                                    PV_hourlyAlt(i,6)=F_ocup*capacitat_fava(j);%*E_ec;
                                else
                                    PV_hourlyGeo(i,6)=F_ocup*capacitat(j);%/EERGeo;%JGC [Wh] producció refrigeració període pla (amb sol)
                                    PV_hourlyAlt(i,6)=F_ocup*capacitat_a(j);%*E_ec;
                                end
                            else
                                PV_hourlyGeo(i,6)=0;
                                PV_hourlyAlt(i,6)=0;
                            end
                            break
                        else
                            PV_hourlyGeo(i,6)=0;
                            PV_hourlyAlt(i,6)=0;
                        end
                    end
                 elseif PV_hourlyGeo(i,4)==3 %JGC: Periode punta
                     for j=1:1:bin_values_size(2)
                        if (PV_hourlyGeo(i,1)>=(bin_values(j)-1))&&(PV_hourlyGeo(i,1)<(bin_values(j)+1))
                            if bin_values(j)<(T_balanc-1)
                                if PV_hourlyGeo(i,2)==0 %JGC: si no hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,7)=F_ocup*capacitat(j);%/COPGeo;%JGC [Wh] producció calefacció període punta (sense sol)
                                    PV_hourlyAlt(i,7)=F_ocup*capacitat_a(j);%*E_eh;
                                else
                                    PV_hourlyGeo(i,7)=F_ocup*capacitat_fav(j);%/COPGeo;%JGC [Wh] producció calefacció període punta (amb sol)
                                    PV_hourlyAlt(i,7)=F_ocup*capacitat_fava(j);%*E_eh;
                                end
                            elseif bin_values(j)>(T_balanc+1)
                                if PV_hourlyGeo(i,2)==0 %JGC: si no hi ha irradiació solar en aquesta hora en particular
                                    PV_hourlyGeo(i,7)=F_ocup*capacitat_fav(j);%/EERGeo;%JGC [Wh] producció refrigeració període punta (sense sol)
                                    PV_hourlyAlt(i,7)=F_ocup*capacitat_fava(j);%*E_ec;
                                else
                                    PV_hourlyGeo(i,7)=F_ocup*capacitat(j);%/EERGeo;%JGC [Wh] producció refrigeració període punta (amb sol)
                                    PV_hourlyAlt(i,7)=F_ocup*capacitat_a(j);%*E_ec;
                                end
                            else
                                PV_hourlyGeo(i,7)=0;
                                PV_hourlyAlt(i,7)=0;
                            end
                            break
                        else
                            PV_hourlyGeo(i,7)=0;
                            PV_hourlyAlt(i,7)=0;
                        end
                    end
                end
            end
            
            %JGC: Expresat en MWhe, anem a calcular les corbes de consum_e mes a mes (climatització)
            Cons_eGeoClim=zeros(12,3); %JGC: Consum elèctric mensual de la Geotèrmia, però només climatització
            Cons_eAltClim=zeros(12,3); %JGC: Consum elèctric mensual de l'Alternativa, però només climatització
            for j=1:1:12
                SumGeoVall=0;
                SumGeoPla=0;
                SumGeoPunta=0;
                SumAltVall=0;
                SumAltPla=0;
                SumAltPunta=0;
                SumGeoVall_pos=0;
                SumGeoPla_pos=0;
                SumGeoPunta_pos=0;
                SumAltVall_pos=0;
                SumAltPla_pos=0;
                SumAltPunta_pos=0;
                SumGeoVall_neg=0;
                SumGeoPla_neg=0;
                SumGeoPunta_neg=0;
                SumAltVall_neg=0;
                SumAltPla_neg=0;
                SumAltPunta_neg=0;
                for i=1:1:8760
                    if PV_hourlyGeo(i,3)==j
                        SumGeoVall=SumGeoVall+PV_hourlyGeo(i,5);
                        if PV_hourlyGeo(i,5)>=0
                           SumGeoVall_pos=SumGeoVall_pos+PV_hourlyGeo(i,5);
                        else
                           SumGeoVall_neg=SumGeoVall_neg+PV_hourlyGeo(i,5);
                        end
                        SumAltVall=SumAltVall+PV_hourlyAlt(i,5);
                        if PV_hourlyAlt(i,5)>=0
                           SumAltVall_pos=SumAltVall_pos+PV_hourlyAlt(i,5);
                        else
                           SumAltVall_neg=SumAltVall_neg+PV_hourlyAlt(i,5);
                        end
                        SumGeoPla=SumGeoPla+PV_hourlyGeo(i,6);
                        if PV_hourlyGeo(i,6)>=0
                           SumGeoPla_pos=SumGeoPla_pos+PV_hourlyGeo(i,6);
                        else
                           SumGeoPla_neg=SumGeoPla_neg+PV_hourlyGeo(i,6);
                        end
                        SumAltPla=SumAltPla+PV_hourlyAlt(i,6);
                        if PV_hourlyAlt(i,6)>=0
                           SumAltPla_pos=SumAltPla_pos+PV_hourlyAlt(i,6);
                        else
                           SumAltPla_neg=SumAltPla_neg+PV_hourlyAlt(i,6);
                        end
                        SumGeoPunta=SumGeoPunta+PV_hourlyGeo(i,7);
                        if PV_hourlyGeo(i,7)>=0
                           SumGeoPunta_pos=SumGeoPunta_pos+PV_hourlyGeo(i,7);
                        else
                           SumGeoPunta_neg=SumGeoPunta_neg+PV_hourlyGeo(i,7);
                        end
                        SumAltPunta=SumAltPunta+PV_hourlyAlt(i,7);
                        if PV_hourlyAlt(i,7)>=0
                           SumAltPunta_pos=SumAltPunta_pos+PV_hourlyAlt(i,7);
                        else
                           SumAltPunta_neg=SumAltPunta_neg+PV_hourlyAlt(i,7);
                        end
                    end 
                end
                %JGC: De cara a calcular els consums elèctrics corresponents a la climatització, faig anàlogament a com
                %es va fer pel càlcul mensual. La producció de fred es resta a la de calor, i els consums elèctrics segons tarifa
                %es distribueixen de manera normalitzada a com ho fan els consums d'un o altre signe
                if (SumGeoVall+SumGeoPla+SumGeoPunta)<0
                     if (SumGeoVall+SumGeoPla+SumGeoPunta)==0
                        Cons_eGeoClim(j,1)=0;
                        Cons_eGeoClim(j,2)=0;
                        Cons_eGeoClim(j,3)=0;
                    else
                        Cons_eGeoClim(j,1)=factor_h*(SumGeoVall_neg/(SumGeoVall_neg+SumGeoPla_neg+SumGeoPunta_neg))*abs((SumGeoVall+SumGeoPla+SumGeoPunta)/1e6)/COPGeo(j);
                        Cons_eGeoClim(j,2)=factor_h*(SumGeoPla_neg/(SumGeoVall_neg+SumGeoPla_neg+SumGeoPunta_neg))*abs((SumGeoVall+SumGeoPla+SumGeoPunta)/1e6)/COPGeo(j);
                        Cons_eGeoClim(j,3)=factor_h*(SumGeoPunta_neg/(SumGeoVall_neg+SumGeoPla_neg+SumGeoPunta_neg))*abs((SumGeoVall+SumGeoPla+SumGeoPunta)/1e6)/COPGeo(j);
                    end
                    if (SumAltVall+SumAltPla+SumAltPunta)==0
                        Cons_eAltClim(j,1)=0;
                        Cons_eAltClim(j,2)=0;
                        Cons_eAltClim(j,3)=0;
                    else
                        Cons_eAltClim(j,1)=factor_h*(SumAltVall_neg/(SumAltVall_neg+SumAltPla_neg+SumAltPunta_neg))*abs((SumAltVall+SumAltPla+SumAltPunta)/1e6)*E_eh(j);
                        Cons_eAltClim(j,2)=factor_h*(SumAltPla_neg/(SumAltVall_neg+SumAltPla_neg+SumAltPunta_neg))*abs((SumAltVall+SumAltPla+SumAltPunta)/1e6)*E_eh(j);
                        Cons_eAltClim(j,3)=factor_h*(SumAltPunta_neg/(SumAltVall_neg+SumAltPla_neg+SumAltPunta_neg))*abs((SumAltVall+SumAltPla+SumAltPunta)/1e6)*E_eh(j);
                    end
                else
                    if (SumGeoVall+SumGeoPla+SumGeoPunta)==0
                        Cons_eGeoClim(j,1)=0;
                        Cons_eGeoClim(j,2)=0;
                        Cons_eGeoClim(j,3)=0;
                    else
                        Cons_eGeoClim(j,1)=factor_c*(SumGeoVall_pos/(SumGeoVall_pos+SumGeoPla_pos+SumGeoPunta_pos))*abs((SumGeoVall+SumGeoPla+SumGeoPunta)/1e6)/EERGeo(j);
                        Cons_eGeoClim(j,3)=factor_c*(SumGeoPunta_pos/(SumGeoVall_pos+SumGeoPla_pos+SumGeoPunta_pos))*abs((SumGeoVall+SumGeoPla+SumGeoPunta)/1e6)/EERGeo(j);
                        Cons_eGeoClim(j,2)=factor_c*(SumGeoPla_pos/(SumGeoVall_pos+SumGeoPla_pos+SumGeoPunta_pos))*abs((SumGeoVall+SumGeoPla+SumGeoPunta)/1e6)/EERGeo(j);
                    end
                    if (SumAltVall+SumAltPla+SumAltPunta)==0
                        Cons_eAltClim(j,1)=0;
                        Cons_eAltClim(j,2)=0;
                        Cons_eAltClim(j,3)=0;
                    else
                        Cons_eAltClim(j,1)=factor_c*(SumAltVall_pos/(SumAltVall_pos+SumAltPla_pos+SumAltPunta_pos))*abs((SumAltVall+SumAltPla+SumAltPunta)/1e6)*E_ec(j);
                        Cons_eAltClim(j,3)=factor_c*(SumAltPunta_pos/(SumAltVall_pos+SumAltPla_pos+SumAltPunta_pos))*abs((SumAltVall+SumAltPla+SumAltPunta)/1e6)*E_ec(j);
                        Cons_eAltClim(j,2)=factor_c*(SumAltPla_pos/(SumAltVall_pos+SumAltPla_pos+SumAltPunta_pos))*abs((SumAltVall+SumAltPla+SumAltPunta)/1e6)*E_ec(j);
                    end
                end
            end

            %JGC: Expresat en MWhe, anem a calcular els consums anuals d'electricitat per tarifa
            Cons_eVallGeoClim=sum(Cons_eGeoClim(:,1));%+1/3*Cons_eGeoACSm;
            Cons_ePlaGeoClim=sum(Cons_eGeoClim(:,2));%+1/3*Cons_eGeoACSm;
            Cons_ePuntaGeoClim=sum(Cons_eGeoClim(:,3));%+1/3*Cons_eGeoACSm;
            Cons_eVallAltClim=sum(Cons_eAltClim(:,1));%+1/3*Cons_eAltACSm;
            Cons_ePlaAltClim=sum(Cons_eAltClim(:,2));%+1/3*Cons_eAltACSm;
            Cons_ePuntaAltClim=sum(Cons_eAltClim(:,3));%+1/3*Cons_eAltACSm;
            
            %JGC: Expresat en MWhe, anem a calcular les integrals dels consums d'electricitat per mes (climatització)
            Cons_e_GeoClim_int=transpose(sum(transpose(Cons_eGeoClim)));
            Cons_e_AltClim_int=transpose(sum(transpose(Cons_eAltClim)));
            
            %JGC: Redefinim els valors de PV_hourlyGeo i PV_hourlyAlt com valors de consum elèctric, que després normalitzarem per a que les integrals de consum elèctric coincideixin
            for i=1:1:8760
                sgn=SignProd(PV_hourlyGeo(i,3)); %JGC: signe del mes on ens trobem (1 si és de calefacció, -1 si es de refrigeració, 0 si no hi ha res)
                if sgn==-sign(PV_hourlyGeo(i,5)+PV_hourlyGeo(i,6)+PV_hourlyGeo(i,7))
                    if sgn==1
                        PV_hourlyGeo(i,5)=factor_h*abs(PV_hourlyGeo(i,5))/COPGeo(j);
                        PV_hourlyAlt(i,5)=factor_h*abs(PV_hourlyAlt(i,5))*E_eh(j);
                        PV_hourlyGeo(i,6)=factor_h*abs(PV_hourlyGeo(i,6))/COPGeo(j);
                        PV_hourlyAlt(i,6)=factor_h*abs(PV_hourlyAlt(i,6))*E_eh(j);
                        PV_hourlyGeo(i,7)=factor_h*abs(PV_hourlyGeo(i,7))/COPGeo(j);
                        PV_hourlyAlt(i,7)=factor_h*abs(PV_hourlyAlt(i,7))*E_eh(j);
                    elseif sgn==-1
                        PV_hourlyGeo(i,5)=factor_c*abs(PV_hourlyGeo(i,5))/EERGeo(j);
                        PV_hourlyAlt(i,5)=factor_c*abs(PV_hourlyAlt(i,5))*E_ec(j);
                        PV_hourlyGeo(i,6)=factor_c*abs(PV_hourlyGeo(i,6))/EERGeo(j);
                        PV_hourlyAlt(i,6)=factor_c*abs(PV_hourlyAlt(i,6))*E_ec(j);
                        PV_hourlyGeo(i,7)=factor_c*abs(PV_hourlyGeo(i,7))/EERGeo(j);
                        PV_hourlyAlt(i,7)=factor_c*abs(PV_hourlyAlt(i,7))*E_ec(j);    
                    else
                        PV_hourlyGeo(i,5)=0;
                        PV_hourlyAlt(i,5)=0;
                        PV_hourlyGeo(i,6)=0;
                        PV_hourlyAlt(i,6)=0;
                        PV_hourlyGeo(i,7)=0;
                        PV_hourlyAlt(i,7)=0;
                    end
                else
                    PV_hourlyGeo(i,5)=0;
                    PV_hourlyAlt(i,5)=0;
                    PV_hourlyGeo(i,6)=0;
                    PV_hourlyAlt(i,6)=0;
                    PV_hourlyGeo(i,7)=0;
                    PV_hourlyAlt(i,7)=0;
                end
            end
            int_mes_eG=zeros(12,1);
            int_mes_eA=zeros(12,1);
            for j=1:1:12
                for i=1:1:8760
                    if PV_hourlyGeo(i,3)==j
                       int_mes_eG(j)=int_mes_eG(j)+(PV_hourlyGeo(i,5)+PV_hourlyGeo(i,6)+PV_hourlyGeo(i,7))/1e6; %JGC: [MWhe]
                       int_mes_eA(j)=int_mes_eA(j)+(PV_hourlyAlt(i,5)+PV_hourlyAlt(i,6)+PV_hourlyAlt(i,7))/1e6;
                    end
                end 
                if int_mes_eG(j)==0
                    int_mes_eG(j)=1;
                end
                if int_mes_eA(j)==0
                    int_mes_eA(j)=1;
                end
            end
            factor_e_mG=zeros(12,1);
            factor_e_mA=zeros(12,1);
            %JGC: definim els factors que relacionen les corbes E_e(mes) i E_e(hora)
            for j=1:1:12
                factor_e_mG(j)=Cons_e_GeoClim_int(j)/int_mes_eG(j); %JGC: factor de normalització consum elèctric Instal·lació geo
                factor_e_mA(j)=Cons_e_AltClim_int(j)/int_mes_eA(j); %JGC: factor de normalització consum elèctric Instal·lació alt
            end
            
            %JGC: Finalment redefinim els valors horaris (cal tenir en compte que fem coincidir el total d'electricitat mes a mes i
            %tarifa a tarifa. Les hores atribuïdes a la producció no coincidiran necessàriament, però el total mes a mes, sí.
            for j=1:1:12
                for i=1:1:8760
                    if PV_hourlyGeo(i,3)==j
                       PV_hourlyGeo(i,5)=PV_hourlyGeo(i,5)*factor_e_mG(j);
                       PV_hourlyGeo(i,6)=PV_hourlyGeo(i,6)*factor_e_mG(j);
                       PV_hourlyGeo(i,7)=PV_hourlyGeo(i,7)*factor_e_mG(j);
                       PV_hourlyAlt(i,5)=PV_hourlyAlt(i,5)*factor_e_mA(j);
                       PV_hourlyAlt(i,6)=PV_hourlyAlt(i,6)*factor_e_mA(j);
                       PV_hourlyAlt(i,7)=PV_hourlyAlt(i,7)*factor_e_mA(j);
                    end
                end 
            end
     
            %JGC: Ara farem el càlcul del matching, en cas de que tinguem PV
            Cons_eGeom_xx=zeros(12,3); %JGC: [MWhe] Consum mensual d'electricitat de xarxa
            MatchPV_Geom=zeros(12,3);
            ExcdPV_Geom=zeros(12,3); 
            MatchBat_Geom=zeros(12,3);
            if app.GeoHibPV.Value==1
                for i=1:1:8760
                    EneBatGeo=EneBatGeo0; %JGC (EneBatGeo i EneBatGeo0 com els estats de la bateria a l'inici i al final del loop for, respectivament)
                    PV_hourlyGeo(i,2)=PV_hourlyGeo(i,2)*(app.Geom2PV.Value)*(app.PV_eff.Value)*0.01; %JGC [Wh] Redefinim aquesta columna amb valors absoluts de producció PV per hora 
                    if  PV_hourlyGeo(i,4)==1%JGC: Periode vall
                        if PV_hourlyGeo(i,2)>PV_hourlyGeo(i,5) %JGC: Si la producció de PV supera el consum vall
                            PV_hourlyGeo(i,8)=PV_hourlyGeo(i,5); %JGC: la produció PV destinada a cobrir consum equival al consum d'aquella hora
                            excd_vall=PV_hourlyGeo(i,2)-PV_hourlyGeo(i,5); %JGC: Hi ha un excedent que anirà a bateries o a xarxa/ACS
                            if (EneBatGeoMax>0)&&(EneBatGeo<EneBatGeoMax) %JGC: Si tenim bateria i aquesta no està plena
                                if excd_vall>=(EneBatGeoMax-EneBatGeo)/Eff_Ch %JGC: Si l'excedent iguala o supera el màxim que necessitariem per carregar la bateria
                                    EneBatGeo0=EneBatGeoMax; %JGC: omplim bateria al màxim
                                    PV_hourlyGeo(i,11)=excd_vall-(EneBatGeoMax-EneBatGeo)/Eff_Ch;  %JGC: generem un excedent (cap a xarxa o ACS)
                                    PV_hourlyGeo(i,14)=0; %JGC No haurem utilitzat res de la bateria
                                else %JGC: Si l'excedent no supera el màxim que necessitariem per carregar la bateria
                                    EneBatGeo0=EneBatGeo+excd_vall*Eff_Ch; %JGC: omplim bateria parcialment
                                    PV_hourlyGeo(i,11)=0;  %JGC: No generarem cap excedent
                                    PV_hourlyGeo(i,14)=0; %JGC No haurem utilitzat res de la bateria
                                end
                            elseif (EneBatGeoMax>0)&&(EneBatGeo==EneBatGeoMax) %JGC: Si tenim bateria i la bateria està plena
                                EneBatGeo0=EneBatGeoMax; %JGC: La bateria romandrà plena
                                PV_hourlyGeo(i,11)=excd_vall; %JGC: Bateria plena, així que tot l'excedent PV aniria cap a xarxa o ACS
                                PV_hourlyGeo(i,14)=0; %JGC No haurem utilitzat res de la bateria
                            else %JGC: si No tenim bateria
                                PV_hourlyGeo(i,11)=excd_vall;
                                PV_hourlyGeo(i,14)=0;
                            end   
                        elseif PV_hourlyGeo(i,2)<=PV_hourlyGeo(i,5) %JGC: Si la producció de PV NO supera el consum vall   
                            PV_hourlyGeo(i,8)=PV_hourlyGeo(i,2); %JGC: la produció PV destinada a cobrir consum equival a la producció PV d'aquella hora
                            PV_hourlyGeo(i,11)=0; %JGC: No generarem cap excedent
                            defc_vall=PV_hourlyGeo(i,5)-PV_hourlyGeo(i,2); %JGC: Tindrem un déficit Producció PV respecte consum
                            if (EneBatGeoMax>0)&&(EneBatGeo==EneBatGeoMax*(1-DoD)) %JGC: Si tenim bateria i la bateria està buida
                                PV_hourlyGeo(i,14)=0; %JGC No haurem utilitzat res de la bateria  
                                EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: La bateria seguirà buida
                            elseif (EneBatGeoMax>0)&&(EneBatGeo>EneBatGeoMax*(1-DoD))%JGC: Si tenim bateria i la bateria no està buida
                                if defc_vall >= (EneBatGeo-EneBatGeoMax*(1-DoD))*Eff_Ch %JGC: Si el déficit supera el màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyGeo(i,14)=(EneBatGeo-EneBatGeoMax*(1-DoD))*Eff_Ch; %JGC: Buidarem la bateria
                                    EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: La bateria quedarà buida
                                else %JGC: Si el déficit és inferior al màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyGeo(i,14)= defc_vall; %JGC: Buidarem parcialment la bateria
                                    EneBatGeo0=EneBatGeo-defc_vall/Eff_Ch; %JGC: Buidarem la bateria parcialment
                                end 
                            else %JGC: si No tenim bateria
                                PV_hourlyGeo(i,11)=0; %JGC: No generem excedents
                                PV_hourlyGeo(i,14)=0;
                            end
                        end
                    elseif PV_hourlyGeo(i,4)==2 %JGC: Periode pla      
                        if PV_hourlyGeo(i,2)>PV_hourlyGeo(i,6) %JGC: Si la producció de PV supera el consum pla
                            PV_hourlyGeo(i,9)=PV_hourlyGeo(i,6); %JGC: la produció PV destinada a cobrir consum equival al consum d'aquella hora
                            excd_pla=PV_hourlyGeo(i,2)-PV_hourlyGeo(i,6); %JGC: Hi ha un excedent que anirà a bateries o a xarxa/ACS
                            if (EneBatGeoMax>0)&&(EneBatGeo<EneBatGeoMax) %JGC: Si tenim bateria i no està plena
                                if excd_pla>=(EneBatGeoMax-EneBatGeo)/Eff_Ch %JGC: Si l'excedent iguala o supera el màxim que necessitariem per carregar la bateria
                                    EneBatGeo0=EneBatGeoMax; %JGC: omplim bateria al màxim
                                    PV_hourlyGeo(i,12)=excd_pla-(EneBatGeoMax-EneBatGeo)/Eff_Ch;  %JGC: generem un excedent (cap a xarxa o ACS)
                                    PV_hourlyGeo(i,15)=0; %JGC No haurem utilitzat res de la bateria
                                else %JGC: Si l'excedent no supera el màxim que necessitariem per carregar la bateria
                                    EneBatGeo0=EneBatGeo+excd_pla*Eff_Ch; %JGC: omplim bateria parcialment
                                    PV_hourlyGeo(i,12)=0;  %JGC: No generarem cap excedent
                                    PV_hourlyGeo(i,15)=0; %JGC No haurem utilitzat res de la bateria
                                end
                            elseif (EneBatGeoMax>0)&&(EneBatGeo==EneBatGeoMax) %JGC: Si tenim bateria i la bateria està plena
                                EneBatGeo0=EneBatGeoMax; %JGC: La bateria romandrà plena
                                PV_hourlyGeo(i,12)=excd_pla; %JGC: Bateria plena, així que tot l'excedent PV aniria cap a xarxa o ACS
                                PV_hourlyGeo(i,15)=0; %JGC No haurem utilitzat res de la bateria
                            else %JGC: si No tenim bateria
                                PV_hourlyGeo(i,12)=excd_pla;
                                PV_hourlyGeo(i,15)=0;
                            end   
                        elseif PV_hourlyGeo(i,2)<=PV_hourlyGeo(i,6) %JGC: Si la producció de PV NO supera el consum pla   
                            PV_hourlyGeo(i,9)=PV_hourlyGeo(i,2); %JGC: la produció PV destinada a cobrir consum equival a la producció PV d'aquella hora
                            PV_hourlyGeo(i,12)=0; %JGC: No generarem cap excedent
                            defc_pla=PV_hourlyGeo(i,6)-PV_hourlyGeo(i,2); %JGC: Tindrem un déficit Producció PV respecte consum
                            if (EneBatGeo==EneBatGeoMax*(1-DoD))&&(EneBatGeoMax>0) %JGC: Si tenim bateria i la bateria està buida
                                PV_hourlyGeo(i,15)=0; %JGC No haurem utilitzat res de la bateria  
                                EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: La bateria seguirà buida
                            elseif (EneBatGeo>EneBatGeoMax*(1-DoD))&&(EneBatGeoMax>0)%JGC: Si tenim bateria i la bateria no està buida
                                if defc_pla >= (EneBatGeo-EneBatGeoMax*(1-DoD))*Eff_Ch %JGC: Si el déficit supera el màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyGeo(i,15)=(EneBatGeo-EneBatGeoMax*(1-DoD))*Eff_Ch; %JGC: Buidarem la bateria
                                    EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: La bateria quedarà buida
                                else %JGC: Si el déficit és inferior al màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyGeo(i,15)= defc_pla; %JGC: Buidarem parcialment la bateria
                                    EneBatGeo0=EneBatGeo-defc_pla/Eff_Ch; %JGC: Buidarem la bateria parcialment
                                end
                            else %JGC: si No tenim bateria
                                PV_hourlyGeo(i,12)=0;
                                PV_hourlyGeo(i,15)=0;
                            end
                        end
                    elseif PV_hourlyGeo(i,4)==3 %JGC: Periode punta 
                        if PV_hourlyGeo(i,2)>PV_hourlyGeo(i,7) %JGC: Si la producció de PV supera el consum punta
                            PV_hourlyGeo(i,10)=PV_hourlyGeo(i,7); %JGC: la produció PV destinada a cobrir consum equival al consum d'aquella hora
                            excd_punta=PV_hourlyGeo(i,2)-PV_hourlyGeo(i,7); %JGC: Hi ha un excedent que anirà a bateries o a xarxa/ACS
                            if (EneBatGeoMax>0)&&(EneBatGeo<EneBatGeoMax) %JGC: Si tenim bateria i no està plena
                                if excd_punta>=(EneBatGeoMax-EneBatGeo)/Eff_Ch %JGC: Si l'excedent iguala o supera el màxim que necessitariem per carregar la bateria
                                    EneBatGeo0=EneBatGeoMax; %JGC: omplim bateria al màxim
                                    PV_hourlyGeo(i,13)=excd_punta-(EneBatGeoMax-EneBatGeo)/Eff_Ch;  %JGC: generem un excedent (cap a xarxa o ACS)
                                    PV_hourlyGeo(i,16)=0; %JGC No haurem utilitzat res de la bateria
                                else %JGC: Si l'excedent no supera el màxim que necessitariem per carregar la bateria
                                    EneBatGeo0=EneBatGeo+excd_punta*Eff_Ch; %JGC: omplim bateria parcialment
                                    PV_hourlyGeo(i,13)=0;  %JGC: No generarem cap excedent
                                    PV_hourlyGeo(i,16)=0; %JGC No haurem utilitzat res de la bateria
                                end
                            elseif (EneBatGeoMax>0)&&(EneBatGeo==EneBatGeoMax) %JGC: Si tenim bateria i la bateria està plena
                                EneBatGeo0=EneBatGeoMax; %JGC: La bateria romandrà plena
                                PV_hourlyGeo(i,13)=excd_punta; %JGC: Bateria plena, així que tot l'excedent PV aniria cap a xarxa o ACS
                                PV_hourlyGeo(i,16)=0; %JGC No haurem utilitzat res de la bateria
                            else %JGC: si No tenim bateria
                                PV_hourlyGeo(i,13)=excd_punta;
                                PV_hourlyGeo(i,16)=0;
                            end   
                        elseif PV_hourlyGeo(i,2)<=PV_hourlyGeo(i,7) %JGC: Si la producció de PV NO supera el consum punta   
                            PV_hourlyGeo(i,10)=PV_hourlyGeo(i,2); %JGC: la produció PV destinada a cobrir consum equival a la producció PV d'aquella hora
                            PV_hourlyGeo(i,13)=0; %JGC: No generarem cap excedent
                            defc_punta=PV_hourlyGeo(i,7)-PV_hourlyGeo(i,2); %JGC: Tindrem un déficit Producció PV respecte consum
                            if (EneBatGeo==EneBatGeoMax*(1-DoD))&&(EneBatGeoMax>0) %JGC: Si tenim bateria i la bateria està buida
                                PV_hourlyGeo(i,16)=0; %JGC No haurem utilitzat res de la bateria  
                                EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: La bateria seguirà buida
                            elseif (EneBatGeo>EneBatGeoMax*(1-DoD))&&(EneBatGeoMax>0)%JGC: Si tenim bateria i la bateria no està buida
                                if defc_punta >= (EneBatGeo-EneBatGeoMax*(1-DoD))*Eff_Ch %JGC: Si el déficit supera el màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyGeo(i,16)=(EneBatGeo-EneBatGeoMax*(1-DoD))*Eff_Ch; %JGC: Buidarem la bateria
                                    EneBatGeo0=EneBatGeoMax*(1-DoD); %JGC: La bateria quedarà buida
                                else %JGC: Si el déficit és inferior al màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyGeo(i,16)= defc_punta; %JGC: Buidarem parcialment la bateria
                                    EneBatGeo0=EneBatGeo-defc_punta/Eff_Ch; %JGC: Buidarem la bateria parcialment
                                end
                            else %JGC: si No tenim bateria
                                PV_hourlyGeo(i,13)=0;
                                PV_hourlyGeo(i,16)=0;
                            end
                        end
                    end
                end
                for j=1:1:12
                    SumMatchPV_GeoVall=0;
                    SumMatchPV_GeoPla=0;
                    SumMatchPV_GeoPunta=0;
                    SumExcdPV_GeoVall=0;
                    SumExcdPV_GeoPla=0;
                    SumExcdPV_GeoPunta=0;
                    SumMatchBat_GeoVall=0;
                    SumMatchBat_GeoPla=0;
                    SumMatchBat_GeoPunta=0;
                    for i=1:1:8760
                        if PV_hourlyGeo(i,3)==j
                            SumMatchPV_GeoVall=SumMatchPV_GeoVall+PV_hourlyGeo(i,8); %JGC: [Wh] Electricitat autoproduïda i autoconsumida al instant (periode vall)
                            SumMatchPV_GeoPla=SumMatchPV_GeoPla+PV_hourlyGeo(i,9); %JGC: [Wh] Electricitat autoproduïda i autoconsumida al instant (periode pla)
                            SumMatchPV_GeoPunta=SumMatchPV_GeoPunta+PV_hourlyGeo(i,10); %JGC: [Wh] Electricitat autoproduïda i autoconsumida al instant (periode punta)
                            SumExcdPV_GeoVall=SumExcdPV_GeoVall+PV_hourlyGeo(i,11); %JGC: [Wh] Excedent electricitat autoproduïda (periode vall)
                            SumExcdPV_GeoPla=SumExcdPV_GeoPla+PV_hourlyGeo(i,12); %JGC: [Wh]Excedent electricitat autoproduïda (periode pla)
                            SumExcdPV_GeoPunta=SumExcdPV_GeoPunta+PV_hourlyGeo(i,13); %JGC: [Wh] Excedent electricitat autoproduïda (periode punta)
                            SumMatchBat_GeoVall=SumMatchBat_GeoVall+PV_hourlyGeo(i,14); %JGC:[Wh] Electricitat autoconsumida a partir de bateries (periode vall)
                            SumMatchBat_GeoPla=SumMatchBat_GeoPla+PV_hourlyGeo(i,15); %JGC: [Wh] Electricitat autoconsumida a partir de bateries (periode pla)
                            SumMatchBat_GeoPunta=SumMatchBat_GeoPunta+PV_hourlyGeo(i,16); %JGC: [Wh] Electricitat autoconsumida a partir de bateries (periode punta)
                        end 
                    end
                    MatchPV_Geom(j,1)=SumMatchPV_GeoVall/1e6; %JGC: ara en [MWh] 
                    MatchPV_Geom(j,2)=SumMatchPV_GeoPla/1e6; %JGC: ara en [MWh] 
                    MatchPV_Geom(j,3)=SumMatchPV_GeoPunta/1e6; %JGC: ara en [MWh] 
                    ExcdPV_Geom(j,1)=SumExcdPV_GeoVall/1e6; %JGC: ara en [MWh] 
                    ExcdPV_Geom(j,2)=SumExcdPV_GeoPla/1e6; %JGC: ara en [MWh] 
                    ExcdPV_Geom(j,3)=SumExcdPV_GeoPunta/1e6; %JGC: ara en [MWh]  
                    MatchBat_Geom(j,1)=SumMatchBat_GeoVall/1e6; %JGC: ara en [MWh] 
                    MatchBat_Geom(j,2)=SumMatchBat_GeoPla/1e6; %JGC: ara en [MWh] 
                    MatchBat_Geom(j,3)=SumMatchBat_GeoPunta/1e6;%JGC: ara en [MWh]  
                end
                %JGC: D'altra banda, considerem que tot l'excedent PV després es reparteix entre ACS primer i volcat a xarxa segon
                % (assumim que ACS i climatització són independents gràcies al tanc acumulador d'ACS)
                for i=1:1:12
                    GeoPVexcdVall0=ExcdPV_Geom(i,1); %JGC: [MWhe] Valor mensual excedent considerant només climatització (període vall)
                    GeoPVexcdPla0=ExcdPV_Geom(i,2); %JGC:  [MWhe] Valor mensual excedent considerant només climatització (període pla)
                    GeoPVexcdPunta0=ExcdPV_Geom(i,3); %JGC:  [MWhe] Valor mensual excedent considerant només climatització (període punta)
                    if GeoPVexcdVall0>Cons_eGeoACSm(i)/3 %JGC: Si l'excedent PV vall supera el consum per ACS vall, hi haurà un 100% de cobertura de l'ACS vall i un excedent PV
                        ExcdPV_Geom(i,1)=GeoPVexcdVall0-Cons_eGeoACSm(i)/3; %JGC: [MWhe]
                        MatchPV_Geom(i,1)=MatchPV_Geom(i,1)+Cons_eGeoACSm(i)/3; %JGC: [MWhe]
                        Cons_eGeom_xx(i,1)=Cons_eGeoClim(i,1)+Cons_eGeoACSm(i)/3-MatchPV_Geom(i,1)-MatchBat_Geom(i,1);
                    else
                        ExcdPV_Geom(i,1)=0; %JGC: Si l'excedent és inferior al consum ACS, no hi haurà excedent i la cobertura serà inferior al 100%
                        MatchPV_Geom(i,1)=MatchPV_Geom(i,1)+GeoPVexcdVall0; %JGC: [MWhe]
                        Cons_eGeom_xx(i,1)=Cons_eGeoClim(i,1)+Cons_eGeoACSm(i)/3-MatchPV_Geom(i,1)-MatchBat_Geom(i,1);
                    end
                    if GeoPVexcdPla0>Cons_eGeoACSm(i)/3 %JGC: Si l'excedent PV pla supera el consum per ACS Pla, hi haurà un 100% de cobertura de l'ACS pla i un excedent PV
                        ExcdPV_Geom(i,2)=GeoPVexcdPla0-Cons_eGeoACSm(i)/3; %JGC: [MWhe]
                        MatchPV_Geom(i,2)=MatchPV_Geom(i,2)+Cons_eGeoACSm(i)/3; %JGC: [MWhe]
                        Cons_eGeom_xx(i,2)=Cons_eGeoClim(i,2)+Cons_eGeoACSm(i)/3-MatchPV_Geom(i,2)-MatchBat_Geom(i,2);
                    else
                        ExcdPV_Geom(i,2)=0; %JGC: Si l'excedent és inferior al consum ACS, no hi haurà excedent i la cobertura serà inferior al 100%
                        MatchPV_Geom(i,2)=MatchPV_Geom(i,2)+GeoPVexcdPla0; %JGC: [MWhe]
                        Cons_eGeom_xx(i,2)=Cons_eGeoClim(i,2)+Cons_eGeoACSm(i)/3-MatchPV_Geom(i,2)-MatchBat_Geom(i,2);
                    end
                    if GeoPVexcdPunta0>Cons_eGeoACSm(i)/3 %JGC: Si l'excedent PV punta supera el consum per ACS Punta, hi haurà un 100% de cobertura de l'ACS punta i un excedent PV
                        ExcdPV_Geom(i,3)=GeoPVexcdPunta0-Cons_eGeoACSm(i)/3; %JGC: [MWhe]
                        MatchPV_Geom(i,3)=MatchPV_Geom(i,3)+Cons_eGeoACSm(i)/3; %JGC: [MWhe]
                        Cons_eGeom_xx(i,3)=Cons_eGeoClim(i,3)+Cons_eGeoACSm(i)/3-MatchPV_Geom(i,3)-MatchBat_Geom(i,3);
                    else
                        ExcdPV_Geom(i,3)=0; %JGC: Si l'excedent és inferior al consum ACS, no hi haurà excedent i la cobertura serà inferior al 100%
                        MatchPV_Geom(i,3)=MatchPV_Geom(i,3)+GeoPVexcdPunta0; %JGC: [MWhe]
                        Cons_eGeom_xx(i,3)=Cons_eGeoClim(i,3)+Cons_eGeoACSm(i)/3-MatchPV_Geom(i,3)-MatchBat_Geom(i,3);
                    end
                end
                GeoUnmatch_PV=sum(sum(transpose(Cons_eGeom_xx)))/Cons_eGeo; %JGC: [t.p.1] GeoUnmatch_PV és la porció total de consum elèctric provinent de xarxa
                GeoExcd_PV=sum(sum(ExcdPV_Geom)); %JGC: [MWhe] excedents totals de producció amb PV
            else
                GeoUnmatch_PV=1;
                GeoExcd_PV=0;
                for i=1:1:12
                   Cons_eGeom_xx(i,1)=Cons_eGeoClim(i,1)+Cons_eGeoACSm(i)/3;       
                   Cons_eGeom_xx(i,2)=Cons_eGeoClim(i,2)+Cons_eGeoACSm(i)/3;
                   Cons_eGeom_xx(i,3)=Cons_eGeoClim(i,3)+Cons_eGeoACSm(i)/3; 
                end
            end
                    
            %JGC: En el cas de l'alternativa: 
            Cons_eAltm_xx=zeros(12,3);
            MatchPV_Altm=zeros(12,3);
            ExcdPV_Altm=zeros(12,3);
            MatchBat_Altm=zeros(12,3);
            if app.AltHibPV.Value==1
                for i=1:1:8760
                    EneBatAlt=EneBatAlt0; %JGC (EneBatAlt i EneBatAlt0 com els estats de la bateria a l'inici i al final del loop for, respectivament)
                    PV_hourlyAlt(i,2)=PV_hourlyAlt(i,2)*(app.Altm2PV.Value)*(app.PV_eff.Value)*0.01; %JGC [Wh] Redefinim aquesta columna amb valors absoluts de producció PV per hora 
                    if  PV_hourlyAlt(i,4)==1%JGC: Periode vall
                        if PV_hourlyAlt(i,2)>PV_hourlyAlt(i,5) %JGC: Si la producció de PV supera el consum vall
                            PV_hourlyAlt(i,8)=PV_hourlyAlt(i,5); %JGC: la produció PV destinada a cobrir consum equival al consum d'aquella hora
                            excd_vall=PV_hourlyAlt(i,2)-PV_hourlyAlt(i,5); %JGC: Hi ha un excedent que anirà a bateries o a xarxa/ACS
                            if (EneBatAltMax>0)&&(EneBatAlt<EneBatAltMax) %JGC: Si tenim bateria i aquesta no està plena
                                if excd_vall>=(EneBatAltMax-EneBatAlt)/Eff_Ch %JGC: Si l'excedent iguala o supera el màxim que necessitariem per carregar la bateria
                                    EneBatAlt0=EneBatAltMax; %JGC: omplim bateria al màxim
                                    PV_hourlyAlt(i,11)=excd_vall-(EneBatAltMax-EneBatAlt)/Eff_Ch;  %JGC: generem un excedent (cap a xarxa o ACS)
                                    PV_hourlyAlt(i,14)=0; %JGC No haurem utilitzat res de la bateria
                                else %JGC: Si l'excedent no supera el màxim que necessitariem per carregar la bateria
                                    EneBatAlt0=EneBatAlt+excd_vall*Eff_Ch; %JGC: omplim bateria parcialment
                                    PV_hourlyAlt(i,11)=0;  %JGC: No generarem cap excedent
                                    PV_hourlyAlt(i,14)=0; %JGC No haurem utilitzat res de la bateria
                                end
                            elseif (EneBatAltMax>0)&&(EneBatAlt==EneBatAltMax) %JGC: Si tenim bateria i la bateria està plena
                                EneBatAlt0=EneBatAltMax; %JGC: La bateria romandrà plena
                                PV_hourlyAlt(i,11)=excd_vall; %JGC: Bateria plena, així que tot l'excedent PV aniria cap a xarxa o ACS
                                PV_hourlyAlt(i,14)=0; %JGC No haurem utilitzat res de la bateria
                            else %JGC: si No tenim bateria
                                PV_hourlyAlt(i,11)=excd_vall;
                                PV_hourlyAlt(i,14)=0;
                            end   
                        elseif PV_hourlyAlt(i,2)<=PV_hourlyAlt(i,5) %JGC: Si la producció de PV NO supera el consum vall   
                            PV_hourlyAlt(i,8)=PV_hourlyAlt(i,2); %JGC: la produció PV destinada a cobrir consum equival a la producció PV d'aquella hora
                            PV_hourlyAlt(i,11)=0; %JGC: No generarem cap excedent
                            defc_vall=PV_hourlyAlt(i,5)-PV_hourlyAlt(i,2); %JGC: Tindrem un déficit Producció PV respecte consum
                            if (EneBatAltMax>0)&&(EneBatAlt==EneBatAltMax*(1-DoD)) %JGC: Si tenim bateria i la bateria està buida
                                PV_hourlyAlt(i,14)=0; %JGC No haurem utilitzat res de la bateria  
                                EneBatAlt0=EneBatAltMax*(1-DoD); %JGC: La bateria seguirà buida
                            elseif (EneBatAltMax>0)&&(EneBatAlt>EneBatAltMax*(1-DoD))%JGC: Si tenim bateria i la bateria no està buida
                                if defc_vall >= (EneBatAlt-EneBatAltMax*(1-DoD))*Eff_Ch %JGC: Si el déficit supera el màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyAlt(i,14)=(EneBatAlt-EneBatAltMax*(1-DoD))*Eff_Ch; %JGC: Buidarem la bateria
                                    EneBatAlt0=EneBatAltMax*(1-DoD); %JGC: La bateria quedarà buida
                                else %JGC: Si el déficit és inferior al màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyAlt(i,14)= defc_vall; %JGC: Buidarem parcialment la bateria
                                    EneBatAlt0=EneBatAlt-defc_vall/Eff_Ch; %JGC: Buidarem la bateria parcialment
                                end 
                            else %JGC: si No tenim bateria
                                PV_hourlyAlt(i,11)=0; %JGC: No generem excedents
                                PV_hourlyAlt(i,14)=0;
                            end
                        end
                    elseif PV_hourlyAlt(i,4)==2 %JGC: Periode pla      
                        if PV_hourlyAlt(i,2)>PV_hourlyAlt(i,6) %JGC: Si la producció de PV supera el consum pla
                            PV_hourlyAlt(i,9)=PV_hourlyAlt(i,6); %JGC: la produció PV destinada a cobrir consum equival al consum d'aquella hora
                            excd_pla=PV_hourlyAlt(i,2)-PV_hourlyAlt(i,6); %JGC: Hi ha un excedent que anirà a bateries o a xarxa/ACS
                            if (EneBatAltMax>0)&&(EneBatAlt<EneBatAltMax) %JGC: Si tenim bateria i no està plena
                                if excd_pla>=(EneBatAltMax-EneBatAlt)/Eff_Ch %JGC: Si l'excedent iguala o supera el màxim que necessitariem per carregar la bateria
                                    EneBatAlt0=EneBatAltMax; %JGC: omplim bateria al màxim
                                    PV_hourlyAlt(i,12)=excd_pla-(EneBatAltMax-EneBatAlt)/Eff_Ch;  %JGC: generem un excedent (cap a xarxa o ACS)
                                    PV_hourlyAlt(i,15)=0; %JGC No haurem utilitzat res de la bateria
                                else %JGC: Si l'excedent no supera el màxim que necessitariem per carregar la bateria
                                    EneBatAlt0=EneBatAlt+excd_pla*Eff_Ch; %JGC: omplim bateria parcialment
                                    PV_hourlyAlt(i,12)=0;  %JGC: No generarem cap excedent
                                    PV_hourlyAlt(i,15)=0; %JGC No haurem utilitzat res de la bateria
                                end
                            elseif (EneBatAltMax>0)&&(EneBatAlt==EneBatAltMax) %JGC: Si tenim bateria i la bateria està plena
                                EneBatAlt0=EneBatAltMax; %JGC: La bateria romandrà plena
                                PV_hourlyAlt(i,12)=excd_pla; %JGC: Bateria plena, així que tot l'excedent PV aniria cap a xarxa o ACS
                                PV_hourlyAlt(i,15)=0; %JGC No haurem utilitzat res de la bateria
                            else %JGC: si No tenim bateria
                                PV_hourlyAlt(i,12)=excd_pla;
                                PV_hourlyAlt(i,15)=0;
                            end   
                        elseif PV_hourlyAlt(i,2)<=PV_hourlyAlt(i,6) %JGC: Si la producció de PV NO supera el consum pla   
                            PV_hourlyAlt(i,9)=PV_hourlyAlt(i,2); %JGC: la produció PV destinada a cobrir consum equival a la producció PV d'aquella hora
                            PV_hourlyAlt(i,12)=0; %JGC: No generarem cap excedent
                            defc_pla=PV_hourlyAlt(i,6)-PV_hourlyAlt(i,2); %JGC: Tindrem un déficit Producció PV respecte consum
                            if (EneBatAlt==EneBatAltMax*(1-DoD))&&(EneBatAltMax>0) %JGC: Si tenim bateria i la bateria està buida
                                PV_hourlyAlt(i,15)=0; %JGC No haurem utilitzat res de la bateria  
                                EneBatAlt0=EneBatAltMax*(1-DoD); %JGC: La bateria seguirà buida
                            elseif (EneBatAlt>EneBatAltMax*(1-DoD))&&(EneBatAltMax>0)%JGC: Si tenim bateria i la bateria no està buida
                                if defc_pla >= (EneBatAlt-EneBatAltMax*(1-DoD))*Eff_Ch %JGC: Si el déficit supera el màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyAlt(i,15)=(EneBatAlt-EneBatAltMax*(1-DoD))*Eff_Ch; %JGC: Buidarem la bateria
                                    EneBatAlt0=EneBatAltMax*(1-DoD); %JGC: La bateria quedarà buida
                                else %JGC: Si el déficit és inferior al màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyAlt(i,15)= defc_pla; %JGC: Buidarem parcialment la bateria
                                    EneBatAlt0=EneBatAlt-defc_pla/Eff_Ch; %JGC: Buidarem la bateria parcialment
                                end
                            else %JGC: si No tenim bateria
                                PV_hourlyAlt(i,12)=0;
                                PV_hourlyAlt(i,15)=0;
                            end
                        end
                    elseif PV_hourlyAlt(i,4)==3 %JGC: Periode punta 
                        if PV_hourlyAlt(i,2)>PV_hourlyAlt(i,7) %JGC: Si la producció de PV supera el consum punta
                            PV_hourlyAlt(i,10)=PV_hourlyAlt(i,7); %JGC: la produció PV destinada a cobrir consum equival al consum d'aquella hora
                            excd_punta=PV_hourlyAlt(i,2)-PV_hourlyAlt(i,7); %JGC: Hi ha un excedent que anirà a bateries o a xarxa/ACS
                            if (EneBatAltMax>0)&&(EneBatAlt<EneBatAltMax) %JGC: Si tenim bateria i no està plena
                                if excd_punta>=(EneBatAltMax-EneBatAlt)/Eff_Ch %JGC: Si l'excedent iguala o supera el màxim que necessitariem per carregar la bateria
                                    EneBatAlt0=EneBatAltMax; %JGC: omplim bateria al màxim
                                    PV_hourlyAlt(i,13)=excd_punta-(EneBatAltMax-EneBatAlt)/Eff_Ch;  %JGC: generem un excedent (cap a xarxa o ACS)
                                    PV_hourlyAlt(i,16)=0; %JGC No haurem utilitzat res de la bateria
                                else %JGC: Si l'excedent no supera el màxim que necessitariem per carregar la bateria
                                    EneBatAlt0=EneBatAlt+excd_punta*Eff_Ch; %JGC: omplim bateria parcialment
                                    PV_hourlyAlt(i,13)=0;  %JGC: No generarem cap excedent
                                    PV_hourlyAlt(i,16)=0; %JGC No haurem utilitzat res de la bateria
                                end
                            elseif (EneBatAltMax>0)&&(EneBatAlt==EneBatAltMax) %JGC: Si tenim bateria i la bateria està plena
                                EneBatAlt0=EneBatAltMax; %JGC: La bateria romandrà plena
                                PV_hourlyAlt(i,13)=excd_punta; %JGC: Bateria plena, així que tot l'excedent PV aniria cap a xarxa o ACS
                                PV_hourlyAlt(i,16)=0; %JGC No haurem utilitzat res de la bateria
                            else %JGC: si No tenim bateria
                                PV_hourlyAlt(i,13)=excd_punta;
                                PV_hourlyAlt(i,16)=0;
                            end   
                        elseif PV_hourlyAlt(i,2)<=PV_hourlyAlt(i,7) %JGC: Si la producció de PV NO supera el consum punta   
                            PV_hourlyAlt(i,10)=PV_hourlyAlt(i,2); %JGC: la produció PV destinada a cobrir consum equival a la producció PV d'aquella hora
                            PV_hourlyAlt(i,13)=0; %JGC: No generarem cap excedent
                            defc_punta=PV_hourlyAlt(i,7)-PV_hourlyAlt(i,2); %JGC: Tindrem un déficit Producció PV respecte consum
                            if (EneBatAlt==EneBatAltMax*(1-DoD))&&(EneBatAltMax>0) %JGC: Si tenim bateria i la bateria està buida
                                PV_hourlyAlt(i,16)=0; %JGC No haurem utilitzat res de la bateria  
                                EneBatAlt0=EneBatAltMax*(1-DoD); %JGC: La bateria seguirà buida
                            elseif (EneBatAlt>EneBatAltMax*(1-DoD))&&(EneBatAltMax>0)%JGC: Si tenim bateria i la bateria no està buida
                                if defc_punta >= (EneBatAlt-EneBatAltMax*(1-DoD))*Eff_Ch %JGC: Si el déficit supera el màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyAlt(i,16)=(EneBatAlt-EneBatAltMax*(1-DoD))*Eff_Ch; %JGC: Buidarem la bateria
                                    EneBatAlt0=EneBatAltMax*(1-DoD); %JGC: La bateria quedarà buida
                                else %JGC: Si el déficit és inferior al màxim que podriem extreure i utilitzar de la bateria
                                    PV_hourlyAlt(i,16)= defc_punta; %JGC: Buidarem parcialment la bateria
                                    EneBatAlt0=EneBatAlt-defc_punta/Eff_Ch; %JGC: Buidarem la bateria parcialment
                                end
                            else %JGC: si No tenim bateria
                                PV_hourlyAlt(i,13)=0;
                                PV_hourlyAlt(i,16)=0;
                            end
                        end
                    end
                end
                for j=1:1:12
                    SumMatchPV_AltVall=0;
                    SumMatchPV_AltPla=0;
                    SumMatchPV_AltPunta=0;
                    SumExcdPV_AltVall=0;
                    SumExcdPV_AltPla=0;
                    SumExcdPV_AltPunta=0;
                    SumMatchBat_AltVall=0;
                    SumMatchBat_AltPla=0;
                    SumMatchBat_AltPunta=0;
                    for i=1:1:8760
                        if PV_hourlyGeo(i,3)==j
                            SumMatchPV_AltVall=SumMatchPV_AltVall+PV_hourlyAlt(i,8);
                            SumMatchPV_AltPla=SumMatchPV_AltPla+PV_hourlyAlt(i,9);
                            SumMatchPV_AltPunta=SumMatchPV_AltPunta+PV_hourlyAlt(i,10);
                            SumExcdPV_AltVall=SumExcdPV_AltVall+PV_hourlyAlt(i,11);
                            SumExcdPV_AltPla=SumExcdPV_AltPla+PV_hourlyAlt(i,12);
                            SumExcdPV_AltPunta=SumExcdPV_AltPunta+PV_hourlyAlt(i,13);
                            SumMatchBat_AltVall=SumMatchBat_AltVall+PV_hourlyAlt(i,14);
                            SumMatchBat_AltPla=SumMatchBat_AltPla+PV_hourlyAlt(i,15);
                            SumMatchBat_AltPunta=SumMatchBat_AltPunta+PV_hourlyAlt(i,16);
                        end 
                    end
                    MatchPV_Altm(j,1)=SumMatchPV_AltVall/1e6;
                    MatchPV_Altm(j,2)=SumMatchPV_AltPla/1e6;
                    MatchPV_Altm(j,3)=SumMatchPV_AltPunta/1e6;
                    ExcdPV_Altm(j,1)=SumExcdPV_AltVall/1e6;
                    ExcdPV_Altm(j,2)=SumExcdPV_AltPla/1e6;
                    ExcdPV_Altm(j,3)=SumExcdPV_AltPunta/1e6;
                    MatchBat_Altm(j,1)=SumMatchBat_AltVall/1e6;
                    MatchBat_Altm(j,2)=SumMatchBat_AltPla/1e6;
                    MatchBat_Altm(j,3)=SumMatchBat_AltPunta/1e6;
                end
                %JGC: D'altra banda, considerem que tot l'excedent PV després es reparteix entre ACS primer i volcat a xarxa segon
                % (assumim que ACS i climatització són independents gràcies al tanc acumulador d'ACS)
                for i=1:1:12
                    AltPVexcdVall0=ExcdPV_Altm(i,1); %JGC: [MWhe] Valor mensual excedent considerant només climatització (període vall)
                    AltPVexcdPla0=ExcdPV_Altm(i,2); %JGC:  [MWhe] Valor mensual excedent considerant només climatització (període pla)
                    AltPVexcdPunta0=ExcdPV_Altm(i,3); %JGC:  [MWhe] Valor mensual excedent considerant només climatització (període punta)
                    if AltPVexcdVall0>Cons_eAltACSm(i)/3 %JGC: Si l'excedent PV vall supera el consum per ACS vall, hi haurà un 100% de cobertura i un excedent PV
                        ExcdPV_Altm(i,1)=AltPVexcdVall0-Cons_eAltACSm(i)/3; %JGC: [MWhe]
                        MatchPV_Altm(i,1)=MatchPV_Altm(i,1)+Cons_eAltACSm(i)/3; %JGC: [MWhe]
                        Cons_eAltm_xx(i,1)=Cons_eAltClim(i,1)+Cons_eAltACSm(i)/3-MatchPV_Altm(i,1)-MatchBat_Altm(i,1);
                    else
                        ExcdPV_Altm(i,1)=0; %JGC: Si l'excedent és inferior al consum ACS, no hi haurà excedent i la cobertura serà inferior al 100%
                        MatchPV_Altm(i,1)=MatchPV_Altm(i,1)+AltPVexcdVall0; %JGC: [MWhe]
                        Cons_eAltm_xx(i,1)=Cons_eAltClim(i,1)+Cons_eAltACSm(i)/3-MatchPV_Altm(i,1)-MatchBat_Altm(i,1);
                    end
                    if AltPVexcdPla0>Cons_eAltACSm(i)/3 %JGC: Si l'excedent PV pla supera el consum per ACS Pla, hi haurà un 100% de cobertura i un excedent PV
                        ExcdPV_Altm(i,2)=AltPVexcdPla0-Cons_eAltACSm(i)/3; %JGC: [MWhe]
                        MatchPV_Altm(i,2)=MatchPV_Altm(i,2)+Cons_eAltACSm(i)/3; %JGC: [MWhe]
                        Cons_eAltm_xx(i,2)=Cons_eAltClim(i,2)+Cons_eAltACSm(i)/3-MatchPV_Altm(i,2)-MatchBat_Altm(i,2);
                    else
                        ExcdPV_Altm(i,2)=0; %JGC: Si l'excedent és inferior al consum ACS, no hi haurà excedent i la cobertura serà inferior al 100%
                        MatchPV_Altm(i,2)=MatchPV_Altm(i,2)+AltPVexcdPla0; %JGC: [MWhe]
                        Cons_eAltm_xx(i,2)=Cons_eAltClim(i,2)+Cons_eAltACSm(i)/3-MatchPV_Altm(i,2)-MatchBat_Altm(i,2);
                    end
                    if AltPVexcdPunta0>Cons_eAltACSm(i)/3 %JGC: Si l'excedent PV punta supera el consum per ACS Punta, hi haurà un 100% de cobertura i un excedent PV
                        ExcdPV_Altm(i,3)=AltPVexcdPunta0-Cons_eAltACSm(i)/3; %JGC: [MWhe]
                        MatchPV_Altm(i,3)=MatchPV_Altm(i,3)+Cons_eAltACSm(i)/3; %JGC: [MWhe]
                        Cons_eAltm_xx(i,3)=Cons_eAltClim(i,3)+Cons_eAltACSm(i)/3-MatchPV_Altm(i,3)-MatchBat_Altm(i,3);
                    else
                        ExcdPV_Altm(i,3)=0; %JGC: Si l'excedent és inferior al consum ACS, no hi haurà excedent i la cobertura serà inferior al 100%
                        MatchPV_Altm(i,3)=MatchPV_Altm(i,3)+AltPVexcdPunta0; %JGC: [MWhe]
                        Cons_eAltm_xx(i,3)=Cons_eAltClim(i,3)+Cons_eAltACSm(i)/3-MatchPV_Altm(i,3)-MatchBat_Altm(i,3);
                    end
                end
                if Cons_eAlt==0
                   AltUnmatch_PV=0;
                else
                   AltUnmatch_PV=sum(sum(transpose(Cons_eAltm_xx)))/Cons_eAlt; %JGC: [t.p.1] AltUnmatch_PV és la porció total de consum elèctric provinent de xarxa  
                end   
                AltExcd_PV=sum(sum(ExcdPV_Altm)); %JGC: [MWhe] excedents totals de producció amb PV
            else
                AltUnmatch_PV=1;
                AltExcd_PV=0;
                for i=1:1:12
                    Cons_eAltm_xx(i,1)=Cons_eAltClim(i,1)+Cons_eAltACSm(i)/3;
                    Cons_eAltm_xx(i,2)=Cons_eAltClim(i,2)+Cons_eAltACSm(i)/3;
                    Cons_eAltm_xx(i,3)=Cons_eAltClim(i,3)+Cons_eAltACSm(i)/3;
                end
            end
            
            n_lifePV=20; %JGC: Considerem que una instal·lació PV pot durar més que els equips de producció de calor/fred
            CAPEXPVGeo=app.PVGeoCostInv.Value; %JGC [€] cost de la instal·lació PV per a Geotèrmia
            CAPEXPVAlt=app.PVAltCostInv.Value; %JGC [€] cost de la instal·lació PV per a Alternativa
            
            varPV=app.Retrib_excdPV.Value; %JGC [€/kWh] preu rebut per vendre excedents elèctrics de la instal·lació PV.
            varPV_i=0.01*app.Infl_general.Value; %JGC: [t.p.1] Inflació promig anual del preu de compensació d'excedents d'electricitat provinent de panells PV
            
            app.Panel_RendibilitatEcoAmb.Title = ['5. Rendibilitat econòmica i emissions a ' num2str(n_life) ' anys'];
            %JGC: definim els conjunts de dades que ens donaran lloc als resultats de l'anàlisi
            %JGC: Càlcul Emissions
            em_Geo=1000*Cons_eGeo*GeoUnmatch_PV*em_e; %JGC [kgCO2/any] aquest càlcul es pot fer amb caràcter anual pel cas de la geotèrmia
            em_Alt=1000*(heat*em_h*Cons_h/Effh(1)+Cons_eAlt*AltUnmatch_PV*em_e+heatACS*em_ACS*Cons_ACS/EffACS(1)); %JGC [kgCO2/any]
            Estalvi_CO2=em_Alt-em_Geo; %JGC: [kg/any] 
            app.EstalviCO2.Value=Estalvi_CO2; %JGC: [kg/any] 
            Estalvi_CO2rel=100*(1-em_Geo/em_Alt); %JGC: [%] percentatge d'emissions estalviades respecte l'Alternativa
            app.EstalviCO2rel.Value=Estalvi_CO2rel;
            if Estalvi_CO2rel<0
                app.EstalviCO2rel.BackgroundColor=[1 0.5 0.5];
                app.EstalviCO2.BackgroundColor=[1 0.5 0.5];
            else
                app.EstalviCO2rel.BackgroundColor=[1 1 1];
                app.EstalviCO2.BackgroundColor=[1 1 1];
            end
            
            %JGC: Càlcul Valors Econòmics
            PagGeo=zeros(n_life,2);
            PagGeo_m=zeros(12,2);
            QuPresGeo=zeros(n_life,2);
            InvGeo=zeros(n_life,2);
            InvPVGeo=zeros(n_life,2);
            InvGeo(1,2)=CAPEX_G-SubG-PreG;
            PagGeoAcum=zeros(n_life,2);
            PagAlt=zeros(n_life,2);
            PagAlt_m=zeros(12,2);
            QuPresAlt=zeros(n_life,2);
            InvAlt=zeros(n_life,2);
            InvAlt(1,2)=CAPEX_A-SubA-PreA;
            InvPVAlt=zeros(n_life,2);
            PagAltAcum=zeros(n_life,2);
            van=zeros(n_life,2);
            vanAcum=zeros(n_life,2);
            

            %JGC: Calculem els valors anuals i mensuals dels pagaments en concepte de costos variables
            PagVallGeo_m=zeros(12,1);
            for j=1:1:12
               PagVallGeo_m(j)= Cons_eGeom_xx(j,1)*1000*vare_vall;%JGC:[€]
            end
            PagPlaGeo_m=zeros(12,1);
            for j=1:1:12
               PagPlaGeo_m(j)= Cons_eGeom_xx(j,2)*1000*vare_pla;%JGC:[€]
            end
            PagPuntaGeo_m=zeros(12,1);
            for j=1:1:12
               PagPuntaGeo_m(j)= Cons_eGeom_xx(j,3)*1000*vare_punta;%JGC:[€]
            end
            PagGeoVar_m=zeros(12,1);
            for j=1:1:12
                PagGeoVar_m(j)=PagVallGeo_m(j)+PagPlaGeo_m(j)+PagPuntaGeo_m(j);
            end
            Excedents_GmPV=transpose(sum(transpose(ExcdPV_Geom)));%JGC: Excedents PV mes a mes per a l'geotèrmia
            PagVallGeoFij=P_e*fije_vall*((8*5+24*2)/(24*7)); %JGC: multipliquem pel percentatge d'hores setmanals en periode vall %JGC:[€]
            PagPlaPuntaGeoFij=P_e*fije_plapunta*(1-((8*5+24*2)/(24*7))); %JGC: multipliquem pel percentatge d'hores setmanals en periode pla+punta %JGC:[€]
            PagGeoFij=PagVallGeoFij+PagPlaPuntaGeoFij; %JGC:[€]
            PagVallAlt_m=zeros(12,1);
            for j=1:1:12
               PagVallAlt_m(j)= Cons_eAltm_xx(j,1)*1000*vare_vall;%JGC:[€]
            end
            PagPlaAlt_m=zeros(12,1);
            for j=1:1:12
               PagPlaAlt_m(j)= Cons_eAltm_xx(j,2)*1000*vare_pla;%JGC:[€]
            end
            PagPuntaAlt_m=zeros(12,1);
            for j=1:1:12
               PagPuntaAlt_m(j)= Cons_eAltm_xx(j,3)*1000*vare_punta;%JGC:[€]
            end
            PagAltVar_m=zeros(12,1);
            for j=1:1:12
                PagAltVar_m(j)=PagVallAlt_m(j)+PagPlaAlt_m(j)+PagPuntaAlt_m(j);
            end
            Excedents_AmPV=transpose(sum(transpose(ExcdPV_Altm)));%JGC: Excedents PV mes a mes per a l'alternativa
            PagVallAltFij=P_eAlt*fije_vall*((8*5+24*2)/(24*7)); %JGC: multipliquem pel percentatge d'hores setmanals en periode vall %JGC:[€]
            PagPlaPuntaAltFij=P_eAlt*fije_plapunta*(1-((8*5+24*2)/(24*7))); %JGC: multipliquem pel percentatge d'hores setmanals en periode pla+punta %JGC:[€]
            PagAltFij=PagVallAltFij+PagPlaPuntaAltFij; %JGC:[€]
            PagAltheatVar_m=zeros(12,1);
            for j=1:1:12
                PagAltheatVar_m(j)=heat*(Prod(j,1)/Effh(1))*1000*varh; %JGC:[€] %JGC:[€]NOTA: 03/11/2021 REVISAR PORCIÓ ACS AMB TECNOLOGIA ACS I TECNOLOGIA CALEFACCIÓ (60/40 O 100/0)
            end
            PagAltheatFij=heat*fijh; %JGC:[€]
            PagAltACSVar_m=zeros(12,1);
            for j=1:1:12
                 PagAltACSVar_m(j)=heatACS*(Prod(j,3)/EffACS(1))*1000*varACS; %JGC:[€]NOTA: 03/11/2021 REVISAR PORCIÓ ACS AMB TECNOLOGIA ACS I TECNOLOGIA CALEFACCIÓ (60/40 O 100/0)
            end
            PagAltACSFij=heatACS*fijACS; %JGC:[€]
            for i=1:1:n_life
                PagGeo(i,1)=i; 
                PagGeo(i,2)=Man_G*(1+Infl)^(i-1)+sum(PagGeoVar_m)*(1+vare_i)^(i-1)+PagGeoFij*(1+fije_i)^(i-1)-GeoExcd_PV*1000*varPV*(1+varPV_i)^(i-1);
                PagAlt(i,1)=i;
                PagAlt(i,2)=Man_A*(1+Infl)^(i-1)+sum(PagAltVar_m)*(1+vare_i)^(i-1)+PagAltFij*(1+fije_i)^(i-1)-AltExcd_PV*1000*varPV*(1+varPV_i)^(i-1)+sum(PagAltheatVar_m)*(1+varh_i)^(i-1)+PagAltheatFij*(1+fijh_i)^(i-1)+sum(PagAltACSVar_m)*(1+varACS_i)^(i-1)+PagAltACSFij*(1+fijACS_i)^(i-1);
            end
            t_mesos=[31; 28; 31; 30; 31; 30; 31; 31; 30; 31; 30; 31];
            %JGC:Anem a calcular les despeses mes a mes al llarg de tota la vida útil del projecte
            for i=1:1:12
                PagGeo_m(i,1)=i;
                sum_kG=0;
                for k=1:1:n_life
                    sum_kG=sum_kG+PagGeoVar_m(i)*(1+vare_i)^(k-1)+(t_mesos(i)/365)*(Man_G*(1+Infl)^(k-1)+PagGeoFij*(1+fije_i)^(k-1))-Excedents_GmPV(i)*1000*varPV*(1+varPV_i)^(k-1);
                end
                PagGeo_m(i,2)=sum_kG;
            end
            for i=1:1:12
                PagAlt_m(i,1)=i;
                sum_kA=0;
                for k=1:1:n_life
                    sum_kA=sum_kA+PagAltVar_m(i)*(1+vare_i)^(k-1)+(t_mesos(i)/365)*(Man_A*(1+Infl)^(k-1)+PagAltFij*(1+fije_i)^(k-1))-Excedents_AmPV(i)*1000*varPV*(1+varPV_i)^(k-1)+PagAltheatVar_m(i)*(1+varh_i)^(k-1)+(t_mesos(i)/365)*PagAltheatFij*(1+fijh_i)^(k-1)+PagAltACSVar_m(i)*(1+varACS_i)^(k-1)+(t_mesos(i)/365)*PagAltACSFij*(1+fijACS_i)^(k-1);
                end
                PagAlt_m(i,2)=sum_kA;
            end
            %JGC: Calculem els valors anuals dels pagaments en concepte de costos financers (devolució quotes prèstec, si n'hi ha)
            app.Estalviesperat.Value=100*(1-sum(PagGeo(:,2))/sum(PagAlt(:,2)));%JGC: Estalvi esperat en despesa variable (al llarg de tota la vida del projecte)
            if app.Estalviesperat.Value<0
                app.Estalviesperat.BackgroundColor= [1 0.5 0.5];
            else
                app.Estalviesperat.BackgroundColor= [1 1 1];
            end
            for i=1:1:t_pG
                QuPresGeo(i,1)=i;
                QuPresGeo(i,2)=QuGeo;
            end
            for i=1:1:t_pA
                QuPresAlt(i,1)=i;
                QuPresAlt(i,2)=QuAlt;
            end
            %JGC: Calculem i situem al temps tots els costos d'inversió a part de l'inicial. Es contempla el cas que la instal·lació alternativa sigui existent des de n_Alt anys
            for i=2:1:n_life
                InvGeo(i,1)=i;
                if ((i-1)/n_renew-fix((i-1)/n_renew))==0
                    InvGeo(i,2)=C_BCG*(1+Infl)^i;
                else
                    InvGeo(i,2)=0;
                end
            end
            for i=2:1:n_life
                InvAlt(i,1)=i;
                if ((i-1+n_renew-n_Altrenew)/n_renew-fix((i-1+n_renew-n_Altrenew)/n_renew))==0
                    InvAlt(i,2)=C_Alt*(1+Infl)^i;
                else
                    InvAlt(i,2)=0;
                end
            end
            %JGC: Calculem i situem al temps tots els costos dinversió en panells fotovoltàics + bateries a part de l'inicial
            for i=2:1:n_life
                InvPVGeo(i,1)=i;
                InvPVAlt(i,1)=i;
                if ((i-1)/n_lifePV-fix((i-1)/n_lifePV))==0
                    InvPVGeo(i,2)=CAPEXPVGeo*(1+Infl)^i;
                    InvPVAlt(i,2)=CAPEXPVAlt*(1+Infl)^i;
                else
                    InvPVGeo(i,2)=0;
                    InvPVAlt(i,2)=0;
                end
            end
            %JGC: Calculem el valors dels pagaments acumulats, que es poden representar
            for i=1:1:n_life
                PagGeoAcum(i,1)=i;
                PagGeoAcum(i,2)=(sum(PagGeo(1:i,2))+sum(QuPresGeo(1:i,2))+sum(InvGeo(1:i,2))+sum(InvPVGeo(1:i,2)))/1000; %JGC [k€]
                PagAltAcum(i,1)=i;
                PagAltAcum(i,2)=(sum(PagAlt(1:i,2))+sum(QuPresAlt(1:i,2))+sum(InvAlt(1:i,2))+sum(InvPVAlt(1:i,2)))/1000; %JGC [k€]
            end
           
            %JGC: Calculem el VAN, que es pot representar
%             for i=1:1:n_life
%                 van(i,1)=i;
%                 van(i,2)=InvAlt(i,2)+InvPVAlt(i,2)-InvGeo(i,2)-InvPVGeo(i,2)+(PagAlt(i,2)+QuPresAlt(i,2)-PagGeo(i,2)-QuPresGeo(i,2))/((1+d_rate)^i);
%                 vanAcum(i,1)=i;
%                 vanAcum(i,2)=sum(van(1:i,2))/1000; %JGC: [k€]
%             end
            van(1,2)=InvAlt(1,2)+InvPVAlt(1,2)-InvGeo(1,2)-InvPVGeo(1,2)+(PagAlt(1,2)+QuPresAlt(1,2)-PagGeo(1,2)-QuPresGeo(1,2))/((1+d_rate)^1);
            vanAcum(1,2)= van(1,2)/1000;
            for i=2:1:n_life
                van(i,1)=i;
                van(i,2)=(InvAlt(i,2)+InvPVAlt(i,2)-InvGeo(i,2)-InvPVGeo(i,2)+PagAlt(i,2)+QuPresAlt(i,2)-PagGeo(i,2)-QuPresGeo(i,2))/((1+d_rate)^i);
                vanAcum(i,1)=i;
                vanAcum(i,2)=sum(van(1:i,2))/1000; %JGC: [k€]
            end
            app.VAN.Value=vanAcum(n_life,2)*1000;
            if app.VAN.Value <=0
                app.VAN.BackgroundColor=[1.00 0.50 0.50];
            else
                app.VAN.BackgroundColor=[1.00 1.00 1.00];
            end
            
            CashFlow=zeros(n_life,1);
            %JGC: Calculem el TIR(%) i periodes retorns
            %JGC: El vector de Cashflow ha d'incloure amb valor negatiu les inversions que es vagin fent a mida que s'hagin de renovar equips (climatització cada 15, PV cada 20)
            CashFlow_tir=zeros(1+n_life,1);
            for i=1:1:n_life
                CashFlow(i)=InvAlt(i,2)+InvPVAlt(i,2)-InvGeo(i,2)-InvPVGeo(i,2)+(PagAlt(i,2)+QuPresAlt(i,2)-PagGeo(i,2)-QuPresGeo(i,2));
            end
            CashFlow_tir(1)=InvAlt(1,2)+InvPVAlt(1,2)-InvGeo(1,2)-InvPVGeo(1,2); %JGC: el primer valor del vector serà la suma de totes les inversions
            %al llarg de la vida útil del projecte
            
            for i=2:1:1+n_life
                if i==2
                    CashFlow_tir(i)=(PagAlt(i-1,2)+QuPresAlt(i-1,2)-PagGeo(i-1,2)-QuPresGeo(i-1,2));
                else
                    CashFlow_tir(i)=CashFlow(i-1);
                end
            end
            
            %JGC:Defineixo les pendents del pagaments acumulats, per si l'alternativa presenta una de menor (això voldria dir que no s'estalvia amb la geotèrmia)
            pendentGeo=(PagGeoAcum(n_life,2)-PagGeoAcum(1,2))/n_life;
            pendentAlt=(PagAltAcum(n_life,2)-PagAltAcum(1,2))/n_life;
            
            %JGC: Calculem benefici net
            benefici=PagAltAcum(n_life,2)-PagGeoAcum(n_life,2); %JGC: [k€]
            INV_GEO=app.CAPEXGeoTOT.Value*(1-0.01*app.TramGeoPres.Value*app.TramGeoPres.Enable)-app.ImportGeoSubv.Value*app.ImportGeoSubv.Enable;
            INV_ALT=app.CAPEXAltTOT.Value*(1-0.01*app.TramAltPres.Value*app.TramAltPres.Enable)-app.ImportAltSubv.Value*app.ImportAltSubv.Enable;
            INV_NETA=INV_GEO-INV_ALT;  
            app.EstalviNetTotal.Value=sum(PagAlt(:,2))-sum(PagGeo(:,2));
%             if app.EstalviNetTotal.Value<=0
%                 app.EstalviNetTotal.BackgroundColor=[1 0.5 0.5];
%             else
%                 app.EstalviNetTotal.BackgroundColor=[1 1 1];
%             end
            if (benefici>0)&&(INV_NETA>0)
                rendibilitat=round(100*benefici*1000/INV_NETA,1);
                app.RendibNetTotal.Value=num2str(rendibilitat);
                app.RendibNetTotal.BackgroundColor=[1 1 1];
            elseif (benefici>0)&&(INV_NETA<=0)
                app.RendibNetTotal.Value="Inf";
                app.RendibNetTotal.BackgroundColor=[1 1 1];
            elseif (benefici<0)
                app.RendibNetTotal.Value="N.A.";
                app.RendibNetTotal.BackgroundColor=[1 0.5 0.5];          
            end
            
            if pendentGeo<pendentAlt
                if CashFlow(1)>=0
                    app.TIRt.Value="N.A.";
                    app.TIRt.BackgroundColor=[1 1 1];
                    app.Paybacktext.Value= "0.0";
                    app.Paybacktext.BackgroundColor=[1 1 1];
                    app.PaybackDesctext.Value= "0.0";
                    app.PaybackDesctext.BackgroundColor=[1 1 1];
                else
%                     tir=irr(CashFlow)*100;
                    tir=irr(CashFlow_tir)*100;
                    tir=round(tir,1);
                    tirtext=num2str(tir);
                    app.TIRt.Value=tirtext;
                    if  tir<=0
                        app.TIRt.BackgroundColor=[1.00 0.50 0.50];
                    elseif (tir>0)&&(tir<=app.Taxadescompte.Value)
                        app.TIRt.BackgroundColor=[0.98 0.84 0.63];
                    else
                        app.TIRt.BackgroundColor=[1.00 1.00 1.00];
                    end
                    
                    %JGC: Calculem el període de retorn simple:
                    rs=1;
                    Retorn_sim=round(0,1);
                    while rs<=n_life
                        dif=PagAltAcum(rs,2)-PagGeoAcum(rs,2);
                        if dif>0
                            x1=rs;
                            x0=rs-1;
                            if x0==0
                                Retorn_sim=round(0,1);
                                break
                            end
                            y1=PagAltAcum(rs,2);
                            y1_=PagGeoAcum(rs,2);
                            y0=PagAltAcum(rs-1,2);
                            y0_=PagGeoAcum(rs-1,2);
%                             JGC:sistema d'equacions x y
%                             eqn1: (y1-y0)/(x1-x0)*x-y=(y1-y0)/(x1-x0)*x0-y0;
%                             eqn2: (y1_-y0_)/(x1-x0)*x-y=(y1_-y0_)/(x1-x0)*x0-y0_;
                            Retorn_solve=x0+(y0_-y0)*(x1-x0)/((y1-y0)-(y1_-y0_));
                            Retorn_sim=round(double(Retorn_solve(1,1)),1);
                            break
                        end
                        rs=rs+1; 
                    end
                    if rs>n_life
                        app.Paybacktext.Value= "too long";
                        app.Paybacktext.BackgroundColor=[1.00 0.50 0.50];
                    else
                        app.Paybacktext.Value=num2str(Retorn_sim); 
                        app.Paybacktext.BackgroundColor=[1.00 1.00 1.00];
                    end
                    
                    %JGC: Calculem el període de retorn descomptat:
                    rd=1;
                    Retorn_des=round(0,1);
                    while rd<=n_life
                        if vanAcum(rd,2)>0
                            x1=rd;
                            x0=rd-1;
                            if x0==0
                                Retorn_des=round(0,1);
                                break
                            end
                            y_pos=vanAcum(rd,2);
                            y_neg=vanAcum(rd-1,2);
                            x_retorn=(x0*y_pos-x1*y_neg)/(y_pos-y_neg);
                            Retorn_des=round(x_retorn,1);
                            break
                        end
                        rd=rd+1; 
                    end
                    if rd>n_life
                        app.PaybackDesctext.Value= "too long";
                        app.PaybackDesctext.BackgroundColor=[1.00 0.50 0.50];
                    else
                        app.PaybackDesctext.Value=num2str(Retorn_des); 
                        app.PaybackDesctext.BackgroundColor=[1.00 1.00 1.00];
                    end
                end  
            else 
                app.TIRt.Value="N.A.";
                app.TIRt.BackgroundColor=[1.00 0.5 0.5];
                app.Paybacktext.Value= "N.A.";
                app.Paybacktext.BackgroundColor=[1 0.5 0.5];
                app.PaybackDesctext.Value= "N.A.";
                app.PaybackDesctext.BackgroundColor=[1.00 0.50 0.50];
            end
            
            %JGC A Plotear!
            X_axis=PagGeoAcum(:,1);
            YGeo=PagGeoAcum(:,2);
            YAlt=PagAltAcum(:,2);
            YGeoYAlt=zeros(n_life,2);
            for i=1:1:n_life
                YGeoYAlt(i,1)=YGeo(i);
                YGeoYAlt(i,2)=YAlt(i);
            end
            YVAN=vanAcum(:,2);
            app.UIplotFluxeVAN.XLim = [0 n_life+1];
            
            setappdata(0,'X_axis',X_axis);
            setappdata(0,'Y_GeoAlt',YGeoYAlt);
%             setappdata(0,'Y_PagAlt',YAlt);
            setappdata(0,'Y_VAN',YVAN);
            Ready_plot=1;
            setappdata(0,'Ready_toplot',Ready_plot);
            
            if (app.PlotDespesa.Value == 1)
                app.UIplotFluxeVAN.cla;
                ylabel(app.UIplotFluxeVAN,'Despesa instal·lacions (k€)');
                plot(app.UIplotFluxeVAN,X_axis,YGeoYAlt);
                GeoAlt= plot(app.UIplotFluxeVAN,X_axis,YGeoYAlt);
                set(GeoAlt(1),'Color',[0.96 0.62 0.13],'LineWidth',1.0)
                set(GeoAlt(2),'Color',[0.5 0.5 0.5],'LineWidth',1.0)
                legend(app.UIplotFluxeVAN,{'Geotèrmia','Alternativa'});
                legend(app.UIplotFluxeVAN,'boxoff');
                legend(app.UIplotFluxeVAN,'Location','northwest');
            elseif (app.PlotVAN.Value == 1)
                app.UIplotFluxeVAN.cla;
                ylabel(app.UIplotFluxeVAN,'VAN (k€)');
                plot(app.UIplotFluxeVAN,X_axis,YVAN,'Color',[0 0 0],'LineWidth',1.0);
                legend(app.UIplotFluxeVAN,'VAN');
                legend(app.UIplotFluxeVAN,'boxoff');
                legend(app.UIplotFluxeVAN,'Location','northwest');
            else
                app.UIplotFluxeVAN.cla;
                ylabel(app.UIplotFluxeVAN,'Despesa instal·lacions (k€)');
            end
            
            %JGC: Anem a construïr la corba mes a mes de la produccio fotovoltàica per a Geotèrmia, mes a mes         
%             canexecute = getappdata(0,'executable');
            %Irr30=getappdata(0,'Irradiacio30graus');
            Cons_e_Geom=transpose(sum(transpose(Cons_eGeom_xx)));
            Cons_e_Altm=transpose(sum(transpose(Cons_eAltm_xx)));
            Prod_PV_Geom=transpose(sum(transpose(MatchPV_Geom)));
            Prod_PV_Altm=transpose(sum(transpose(MatchPV_Altm)));
            Excd_PV_Geom=transpose(sum(transpose(ExcdPV_Geom)));
            Excd_PV_Altm=transpose(sum(transpose(ExcdPV_Altm)));
            Prod_Bat_Geom=transpose(sum(transpose(MatchBat_Geom)));
            Prod_Bat_Altm=transpose(sum(transpose(MatchBat_Altm)));

% %             prod_plot = getappdata(0,'valors_prod');
%             elec_plot = getappdata(0,'valors_elec');
            ResultGeo_e=zeros(12,4);
            ResultAlt_e=zeros(12,4);
            for i=1:1:12
                ResultGeo_e(i,1)=Cons_e_Geom(i);
                ResultGeo_e(i,2)=Prod_PV_Geom(i);
                ResultGeo_e(i,3)=Prod_Bat_Geom(i);
                ResultGeo_e(i,4)=-Excd_PV_Geom(i);
                ResultAlt_e(i,1)=Cons_e_Altm(i);
                ResultAlt_e(i,2)=Prod_PV_Altm(i);
                ResultAlt_e(i,3)=Prod_Bat_Altm(i);
                ResultAlt_e(i,4)=-Excd_PV_Altm(i);
            end
            eix_mes = getappdata(0,'eix_mes');
            %JGC: Definim els mateixos limits per ambdos gràfics, per poder
            %comparar en la mateixa escala
            max_e_Geo=max(Cons_e_Geom+Prod_PV_Geom+Prod_Bat_Geom);
            min_e_Geo=min(ResultGeo_e(:,4));
            max_e_Alt=max(Cons_e_Altm+Prod_PV_Altm+Prod_Bat_Altm);
            min_e_Alt=min(ResultAlt_e(:,4));
%             Y_high = round(1.4*max(max_e_Geo,max_e_Alt),1); %JGC:round instead of ceil
%             Y_low = round(1.2*min(min_e_Geo,min_e_Alt),1); %JGC:round instead of floor
            Y_high = ceil(14*max(max_e_Geo,max_e_Alt))/10; 
            Y_low = floor(12*min(min_e_Geo,min_e_Alt))/10; 
            
            %JGC:Grafiquem la part de Geotèrmia
            app.MonthPlotGeo.cla;
            ylabel(app.MonthPlotGeo,'Energia elèctrica (MWh)');
            app.MonthPlotGeo.YLim=[Y_low Y_high];
            bar(app.MonthPlotGeo,eix_mes,ResultGeo_e);
            b=bar(app.MonthPlotGeo,eix_mes,ResultGeo_e,'stacked','FaceColor','Flat');
            set(b(1),'FaceColor',[0.5 0.5 0.5],'EdgeColor',[0 0 0]); %JGC: Electricitat xarxa
            set(b(2),'FaceColor',[0.98 0.84 0.63],'EdgeColor',[0 0 0]); %JGC: Electricitat PV inst
            set(b(3),'FaceColor',[1 0.95 0.62],'EdgeColor',[0 0 0]); %JGC: Electricitat Bateries
            set(b(4),'FaceColor',[0.6 0.8 1],'EdgeColor',[0 0 0]); %JGC: Excedents Electricitat
            legend(app.MonthPlotGeo,{'Consum de xarxa','Consum instantani PV','Consum de bateries','Excedents PV'});
            legend(app.MonthPlotGeo,'Location', 'north','NumColumns',2);  
            legend(app.MonthPlotGeo,'boxoff'); 
            
            %JGC:Grafiquem la part Alternativa
            app.MonthPlotAlt.cla;
            ylabel(app.MonthPlotAlt,'Energia elèctrica (MWh)');
            app.MonthPlotAlt.YLim=[Y_low Y_high];
            bar(app.MonthPlotAlt,eix_mes,ResultAlt_e);
            b=bar(app.MonthPlotAlt,eix_mes,ResultAlt_e,'stacked','FaceColor','Flat');
            set(b(1),'FaceColor',[0.5 0.5 0.5],'EdgeColor',[0 0 0]); %JGC: Electricitat xarxa
            set(b(2),'FaceColor',[0.98 0.84 0.63],'EdgeColor',[0 0 0]); %JGC: Electricitat PV inst
            set(b(3),'FaceColor',[1 0.95 0.62],'EdgeColor',[0 0 0]); %JGC: Electricitat Bateries
            set(b(4),'FaceColor',[0.6 0.8 1],'EdgeColor',[0 0 0]); %JGC: Excedents Electricitat
            legend(app.MonthPlotAlt,{'Consum de xarxa','Consum instantani PV','Consum de bateries','Excedents PV'});
            legend(app.MonthPlotAlt,'Location', 'north','NumColumns',2);  
            legend(app.MonthPlotAlt,'boxoff'); 
            
            %JGC: Anem a omplir resulats finals:
            app.GeoCons_e.Value=app.ConElecMWhe.Value;
            app.AltCons_e.Value=app.ConElecAlthMWhe.Value+app.ConElecAltcMWhe.Value+app.ConElecAltACSMWhe.Value;
            app.GeoExcdPV_e.Value=sum(Excd_PV_Geom);
            app.AltExcdPV_e.Value=sum(Excd_PV_Altm);
            app.GeoAutocons_e.Value=100*(sum(Prod_PV_Geom)+sum(Prod_Bat_Geom))/Cons_eGeo;
            if Cons_eAlt==0
                app.AltAutocons_e.Value=0;
            else
                app.AltAutocons_e.Value=100*(sum(Prod_PV_Altm)+sum(Prod_Bat_Altm))/Cons_eAlt;
            end
            app.GeoAutoprod_e.Value=app.GeoCons_e.Value*0.01*app.HibPVGeo_cob.Value*app.GeoHibPV.Enable*app.GeoHibPV.Value;
            app.AltAutoprod_e.Value=app.AltCons_e.Value*0.01*app.HibPVAlt_cob.Value*app.AltHibPV.Enable*app.AltHibPV.Value;
            COSTeGEOvall=(Cons_eVallGeoClim+sum(Cons_eGeoACSm)/3)*1000*vare_vall;%JGC:[€]
            COSTeGEOpla=(Cons_ePlaGeoClim+sum(Cons_eGeoACSm)/3)*1000*vare_pla;%JGC:[€]
            COSTeGEOpunta=(Cons_ePuntaGeoClim+sum(Cons_eGeoACSm)/3)*1000*vare_punta;%JGC:[€]
            COSTeALTvall=(Cons_eVallAltClim+sum(Cons_eAltACSm)/3)*1000*vare_vall;%JGC:[€]
            COSTeALTpla=(Cons_ePlaAltClim+sum(Cons_eAltACSm)/3)*1000*vare_pla;%JGC:[€]
            COSTeALTpunta=(Cons_ePuntaAltClim+sum(Cons_eAltACSm)/3)*1000*vare_punta;%JGC:[€]
            COSTeGEO=0;
            COSTeALT=0;
            %JGC: Anem a calcular el cost de l'electricitat consumida, independentment de si tenim PV o no
            for i=1:1:n_life
                COSTeGEO=COSTeGEO+(COSTeGEOvall+COSTeGEOpla+COSTeGEOpunta)*(1+vare_i)^(i-1)+PagGeoFij*(1+fije_i)^(i-1); %JGC: [€]
                COSTeALT=COSTeALT+(COSTeALTvall+COSTeALTpla+COSTeALTpunta)*(1+vare_i)^(i-1)+PagAltFij*(1+fije_i)^(i-1); %JGC: [€]
            end
            DESPeGEO=0;
            DESPeALT=0;
            %JGC: Anem a calcular la despesa neta en electricitat. Descomptem l'electricitat consumida instantàniament o de bateries i la retribució dels excedents
            for i=1:1:n_life
                DESPeGEO=DESPeGEO+sum(PagGeoVar_m)*(1+vare_i)^(i-1)+PagGeoFij*(1+fije_i)^(i-1)-GeoExcd_PV*1000*varPV*(1+varPV_i)^(i-1); %JGC: [€]
                DESPeALT=DESPeALT+sum(PagAltVar_m)*(1+vare_i)^(i-1)+PagAltFij*(1+fije_i)^(i-1)-AltExcd_PV*1000*varPV*(1+varPV_i)^(i-1); %JGC: [€]
            end
            app.GeoDesp_e.Value=DESPeGEO;%JGC: [k€]
            app.AltDesp_e.Value=DESPeALT;%JGC: [k€]
            app.GeoEstalviAutoc_e.Value=100*abs((COSTeGEO-DESPeGEO)/COSTeGEO);
            if COSTeALT==0
                app.AltEstalviAutoc_e.Value=0;
            else
                app.AltEstalviAutoc_e.Value=100*abs((COSTeALT-DESPeALT)/COSTeALT); 
            end
            app.TabGroup.SelectedTab=app.ResultatsEcoAmbTab;
            
            %JGC: Anem a omplir la informació a la pestanya de resum
            %JGC: Panell Rendabilitat
            app.EscenariProjecte1.Text='Es comparen instal·lació geotèrmica i equivalent alternativa:';
            
            if app.ProdCalfMWht.Value>0
                if OpAlth==1 %JGC: Biomassa
                    if app.Prod_ACSMWht.Value>0
                        if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                               txtrend4='Caldera de biomassa (CALOR + ACS) i aire condicionat (FRED).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de biomassa (CALOR + ACS) i bomba de calor aerotèrmica'; 
                               txtrend5='(FRED).';
                            end
                        else
                           txtrend4='Caldera de biomassa (CALOR + ACS).'; 
                           txtrend5='';
                        end
                    else
                       if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                               txtrend4='Caldera de biomassa (CALOR) i aire condicionat (FRED).';
                               txtrend5='';
                               
                            else
                               txtrend4='Caldera de biomassa (CALOR) i bomba de calor aerotèrmica'; 
                               txtrend5='(FRED).';
                            end
                        else
                           txtrend4='Caldera de biomassa (CALOR).'; 
                           txtrend5='';
                        end 
                    end
                elseif OpAlth==2 %JGC: Gas Natural
                    if app.Prod_ACSMWht.Value>0
                        if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                                if OpAltACS==2
                                   txtrend4='Caldera de gas natural (CALOR + suport ACS), aire condicionat';
                                   txtrend5='(FRED) i caldera de biomassa (ACS).';
                                elseif OpAltACS==3
                                   txtrend4='Caldera de gas natural (CALOR), aire condicionat (FRED) i bomba';
                                   txtrend5='de calor aerotèrmica (ACS).';
                                else
                                   txtrend4='Caldera de gas natural (CALOR + suport ACS), aire condicionat'; 
                                   txtrend5='(FRED) i col·lectors solars tèrmics (ACS).';
                                end
                            else
                               txtrend4='Caldera de gas natural (CALOR ) i bomba de calor aerotèrmica';
                               txtrend5='(FRED + ACS).';
                            end
                        else
                            if OpAltACS==2
                               txtrend4='Caldera de gas natural (CALOR + suport ACS) i caldera de biomassa.';
                               txtrend5='(ACS)';
                            elseif OpAltACS==3
                               txtrend4='Caldera de gas natural (CALOR) i bomba de calor aerotèrmica (ACS).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de gas natural (CALOR + suport ACS) i col·lectors solars';
                               txtrend5='tèrmics (ACS).';
                            end
                        end
                    else
                       if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                               txtrend4='Caldera de gas natural (CALOR) i aire condicionat (FRED).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de gas natural (CALOR) i bomba de calor aerotèrmica';
                               txtrend5='(FRED).';
                            end
                        else
                           txtrend4='Caldera de gas natural (CALOR).';
                           txtrend5='';
                        end 
                    end
                elseif OpAlth==3
                    if app.Prod_ACSMWht.Value>0
                        if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                                if OpAltACS==2
                                   txtrend4='Caldera de gas propà (CALOR + suport ACS), aire condicionat';
                                   txtrend5='(FRED) i caldera de biomassa (ACS).';
                                elseif OpAltACS==3
                                   txtrend4='Caldera de gas propà (CALOR), aire condicionat (FRED) i bomba de';
                                   txtrend5='calor aerotèrmica (ACS).';
                                else
                                   txtrend4='Caldera de gas propà (CALOR + suport ACS), aire condicionat';
                                   txtrend5='(FRED) i col·lectors solars tèrmics (ACS).';  
                                end
                            else
                               txtrend4='Caldera de gas propà (CALOR ) bomba de calor aerotèrmica'; 
                               txtrend5='(FRED + ACS).';
                            end
                        else
                            if OpAltACS==2
                               txtrend4='Caldera de gas propà (CALOR + suport ACS) i caldera de biomassa';
                               txtrend5='(ACS).';
                            elseif OpAltACS==3
                               txtrend4='Caldera de gas propà (CALOR) i bomba de calor aerotèrmica (ACS).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de gas propà (CALOR + suport ACS) i col·lectors solars'; 
                               txtrend5='tèrmics (ACS).';
                            end
                        end
                    else
                       if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                               txtrend4='Caldera de gas propà (CALOR) i aire condicionat (FRED).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de gas propà (CALOR) i bomba de calor aerotèrmica (FRED).';
                               txtrend5='';
                            end
                        else
                           txtrend4='Caldera de gas propà (CALOR).';
                           txtrend5='';
                        end 
                    end
                elseif OpAlth==4
                    if app.Prod_ACSMWht.Value>0
                        if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                                if OpAltACS==2
                                   txtrend4='Caldera de gasoil (CALOR + suport ACS), aire condicionat (FRED)';
                                   txtrend5='i caldera de biomassa (ACS).';
                                elseif OpAltACS==3
                                   txtrend4='Caldera de gasoil (CALOR), aire condicionat (FRED) i bomba de';
                                   txtrend5='calor aerotèrmica (ACS).';
                                else
                                   txtrend4='Caldera de gasoil (CALOR + suport ACS), aire condicionat (FRED)';
                                   txtrend5='i col·lectors solars tèrmics (ACS).';  
                                end
                            else
                               txtrend4='Caldera de gasoil (CALOR ) bomba de calor aerotèrmica'; 
                               txtrend5='(FRED + ACS).';
                            end
                        else
                            if OpAltACS==2
                               txtrend4='Caldera de gasoil (CALOR + ACS) i caldera de biomassa (ACS).';
                               txtrend5='';
                            elseif OpAltACS==3
                               txtrend4='Caldera de gasoil (CALOR) i bomba de calor aerotèrmica (ACS).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de gasoil (CALOR + ACS) i col·lectors solars tèrmics (ACS).';
                               txtrend5='';
                            end
                        end
                    else
                       if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                               txtrend4='Caldera de gasoil (CALOR) i aire condicionat (FRED).';
                               txtrend5='';
                            else
                               txtrend4='Caldera de gasoil (CALOR) i bomba de calor aerotèrmica (FRED).'; 
                               txtrend5='';
                            end
                        else
                           txtrend4='Caldera de gasoil (CALOR).'; 
                           txtrend5='';
                        end 
                    end
                elseif OpAlth==5
                    if app.Prod_ACSMWht.Value>0
                        if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                                if OpAltACS==2
                                   txtrend4='Sistema elèctric (CALOR + suport ACS), aire condicionat (FRED)';
                                   txtrend5='i caldera de biomassa (ACS).';
                                elseif OpAltACS==3
                                   txtrend4='Sistema elèctric (CALOR), aire condicionat (FRED) i bomba de calor'; 
                                   txtrend5='aerotèrmica (ACS).';
                                else
                                   txtrend4='Sistema elèctric (CALOR + suport ACS), aire condicionat (FRED) i';
                                   txtrend5='col·lectors solars tèrmics (ACS).';  
                                end
                            else
                               txtrend4='Sistema elèctric (CALOR ) bomba de calor aerotèrmica (FRED + ACS).'; 
                               txtrend5='';
                            end
                        else
                            if OpAltACS==2
                               txtrend4='Sistema elèctric (CALOR + suport ACS) i caldera de biomassa (ACS).';
                               txtrend5='';
                            elseif OpAltACS==3
                               txtrend4='Sistema elèctric (CALOR) i bomba de calor aerotèrmica (ACS).';
                               txtrend5='';
                            else
                               txtrend4='Sistema elèctric (CALOR + suport ACS) i col·lectors solars tèrmics'; 
                               txtrend5='(ACS).';
                            end
                        end
                    else
                       if app.ProdRefrMWht.Value>0
                            if OpAltc==1
                               txtrend4='Sistema elèctric (CALOR) i aire Condicionat (FRED).';
                               txtrend5='';
                            else
                               txtrend4='Sistema elèctric (CALOR) i bomba de calor aerotèrmica (FRED).';
                               txtrend5='';
                            end
                        else
                           txtrend4='Sistema elèctric (CALOR).';
                           txtrend5='';
                        end 
                    end
                elseif OpAlth==6
                    if app.Prod_ACSMWht.Value>0
                        if app.ProdRefrMWht.Value>0
                            txtrend4='Bomba de calor aerotèrmica (CALOR + FRED + ACS).';
                            txtrend5='';
                        else
                            txtrend4='Bomba de calor aerotèrmica (CALOR + ACS).';
                            txtrend5='';
                        end
                    else
                        if app.ProdRefrMWht.Value>0
                            txtrend4='Bomba de calor aerotèrmica (CALOR + FRED).';
                            txtrend5='';
                        else
                            txtrend4='Bomba de calor aerotèrmica (CALOR).';
                            txtrend5='';
                        end   
                    end
                end
            else
               if app.ProdRefrMWht.Value>0
                   if app.Prod_ACSMWht.Value>0
                       if OpAltc==1
                           if OpAltACS==2
                              txtrend4='Aire condicionat (FRED) i caldera de biomassa + caldera de suport';
                              txtrend5='de gas natural (ACS).';
                           elseif OpAltACS==3
                              txtrend4='Aire condicionat (FRED) i bomba de calor aerotèrmica (ACS).';
                              txtrend5='';
                           else
                              txtrend4='Aire condicionat (FRED) i col·lectors solars tèrmics + caldera de';
                              txtrend5='suport de gas natural (ACS).';  
                           end
                       else
                          txtrend4='Bomba de calor aerotèrmica (FRED + ACS).'; 
                          txtrend5='';
                       end
                   else
                       if OpAltc==1
                           txtrend4='Aire condicionat (FRED).';
                           txtrend5='';
                       else
                           txtrend4='Bomba de calor aerotèrmica (FRED).';
                           txtrend5='';
                       end
                   end
               else
                   if OpAltACS==2
                      txtrend4='Caldera de biomassa + caldera de suport de gas natural (ACS).';
                      txtrend5='';
                   elseif OpAltACS==3
                      txtrend4='Bomba de calor aerotèrmica (ACS).';
                      txtrend5='';
                   else
                      txtrend4='Col·lectors solars tèrmics + caldera de suport de gas natural (ACS).';
                      txtrend5='';
                   end
               end 
            end
            app.EscenariProjecte2.Text=txtrend4;
            app.EscenariProjecte3.Text=txtrend5;
            txtrend6=num2str(app.vidaprojecte.Value);
            if app.vidaprojecte.Value==1
                txtrend7=' any';
            else
                txtrend7=' anys';
            end
            if Nova==1
                txtrend8='';
                txtrend9='';
                txtrend10='';
            else 
                txtrend9=num2str(app.vidaprojexistent.Value);
                if app.vidaprojexistent.Value==1
                    txtrend8=' (es tracta de substituir una instal·lació existent d''';
                    txtrend10=' any d''antiguitat).';
                else
                    txtrend8=' (es tracta de substituir una instal·lació existent de ';
                    txtrend10=' anys d''antiguitat).';
                end
            end
            app.VidaProjecte.Text=[txtrend6 txtrend7 txtrend8 txtrend9 txtrend10];
            app.Label_BeneficiNet.Text=['BENEFICI NET TOTAL (a ' txtrend6 txtrend7 '):'];
            app.Panel_Rendibilitat.Title=['Rendibilitat neta (a ' txtrend6 txtrend7 ')'];
            app.Panel_EstalviMensual.Title=['Estalvi acumulat (a ' txtrend6 txtrend7 ')'];
            
            if (benefici>0)&&(INV_NETA>0)
                rendibilitat=round(100*benefici*1000/INV_NETA,1);
                txtrend11='D''acord amb l''escenari comparatiu definit per l''usuari, invertir en';
                txtrend12='GEOTÈRMIA suposaria una rendibilitat del ';
                txtrend13=num2str(rendibilitat);
                txtrend14=' %, calculada com la';
                txtrend15='relació entre el benefici net i el cost extra d''invertir en geotèrmia.';   
                app.Rendibilitat1.Text=txtrend11;
                app.Rendibilitat1.FontColor=[0 0.5 0];
                app.Rendibilitat2.Text=[txtrend12 txtrend13 txtrend14];
                app.Rendibilitat2.FontColor=[0 0.5 0];
                app.Rendibilitat3.Text=txtrend15;
                app.Rendibilitat3.FontColor=[0 0.5 0];
            elseif (benefici>0)&&(INV_NETA<=0)
                txtrend11='D''acord amb l''escenari comparatiu definit per l''usuari, la instal·lació';
                txtrend12='geotèrmica no suposaria cap inversió extra en comparació amb';
                txtrend13='l''alternativa. Es tractaria d''un PROJECTE RENDIBLE DES DE L''INICI.';
                app.Rendibilitat1.Text=txtrend11;
                app.Rendibilitat1.FontColor=[0 0.5 0];
                app.Rendibilitat2.Text=txtrend12;
                app.Rendibilitat2.FontColor=[0 0.5 0];
                app.Rendibilitat3.Text=txtrend13;
                app.Rendibilitat3.FontColor=[0 0.5 0];
            elseif (benefici<=0)
                txtrend11='Sense beneficis. Projecte NO rendible.';
                app.Rendibilitat1.Text=txtrend11;
                app.Rendibilitat1.FontColor=[1 0 0];
                app.Rendibilitat2.Text='';
                app.Rendibilitat3.Text='';          
            end
            if pendentGeo<pendentAlt
                if CashFlow(1)<0
                    if rs>n_life
                        app.RecuperacioInversio.Text='La inversió no es recuperaria al llarg de la vida del projecte.';
                        app.RecuperacioInversio.FontColor=[1 0 0];
                    else
                        txtrend16='La inversió es recupera en ';
                        txtrend17=app.Paybacktext.Value;
                        txtrend18=' anys (període de retorn simple).';
                        app.RecuperacioInversio.Text=[txtrend16 txtrend17 txtrend18];
                        app.RecuperacioInversio.FontColor=[0 0.5 0];
                    end
                else
                    if INV_NETA>0
                        app.RecuperacioInversio.Text='La inversió es recupera en el primer any.';
                        app.RecuperacioInversio.FontColor=[0 0.5 0];
                    else
                        app.RecuperacioInversio.Text='Sense inversió inicial neta.';
                        app.RecuperacioInversio.FontColor=[0 0.5 0];
                    end  
                end
            else %JGC: en l'improbable cas que l'alternativa provoca més estalvi que la geotèrmia
                app.RecuperacioInversio.Text='La inversió no seria recuperable en cap cas.';
                app.RecuperacioInversio.FontColor=[1 0 0];
            end

            if app.EstalviCO2.Value<=0
                app.BeneficisAmbientals.Text='Sense estalvi d''emissions en comparació amb instal·lació alternativa.';
                app.BeneficisAmbientals.FontColor=[1 0 0];
            else
                app.BeneficisAmbientals.FontColor=[0 0.5 0];
                txtrend19= num2str(round(app.EstalviCO2.Value/1000,2));
                txtrend20=' tones (';
                txtrend21=num2str(round(app.EstalviCO2rel.Value,1));
                txtrend23=' % menys d''emissions respecte a l''alternativa).';
                app.BeneficisAmbientals.Text=[txtrend19 txtrend20 txtrend21 txtrend23];
            end
            
            %JGC: Anem a exposar l'estalvi mes a mes, considerant el total i el percentatge respecte l'alternativa
            
            Estalvi_mes_tot=zeros(12,1); %JGC: [k€] Valor absolut mes a mes
            Estalvi_mes_per=zeros(12,1); %JGC: [%] Valor relatiu mes a mes
            Estalvi_per=100*(1-sum(PagGeo(:,2))/sum(PagAlt(:,2))); %JGC: [%] Valor relatiu total
            for j=1:1:12
                Estalvi_mes_tot(j)=PagAlt_m(j,2)-PagGeo_m(j,2);
                Estalvi_mes_per(j)=100*(1-PagGeo_m(j,2)/PagAlt_m(j,2));
            end
            app.Label_Est01TOT.Text=[num2str(Estalvi_mes_tot(1),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(1),'%.1f') ' %)'];
            app.Label_Est02TOT.Text=[num2str(Estalvi_mes_tot(2),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(2),'%.1f') ' %)'];
            app.Label_Est03TOT.Text=[num2str(Estalvi_mes_tot(3),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(3),'%.1f') ' %)'];
            app.Label_Est04TOT.Text=[num2str(Estalvi_mes_tot(4),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(4),'%.1f') ' %)'];
            app.Label_Est05TOT.Text=[num2str(Estalvi_mes_tot(5),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(5),'%.1f') ' %)'];
            app.Label_Est06TOT.Text=[num2str(Estalvi_mes_tot(6),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(6),'%.1f') ' %)'];
            app.Label_Est07TOT.Text=[num2str(Estalvi_mes_tot(7),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(7),'%.1f') ' %)'];
            app.Label_Est08TOT.Text=[num2str(Estalvi_mes_tot(8),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(8),'%.1f') ' %)'];
            app.Label_Est09TOT.Text=[num2str(Estalvi_mes_tot(9),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(9),'%.1f') ' %)'];
            app.Label_Est10TOT.Text=[num2str(Estalvi_mes_tot(10),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(10),'%.1f') ' %)'];
            app.Label_Est11TOT.Text=[num2str(Estalvi_mes_tot(11),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(11),'%.1f') ' %)'];
            app.Label_Est12TOT.Text=[num2str(Estalvi_mes_tot(12),'%.0f') ' €' ' (' num2str(Estalvi_mes_per(12),'%.1f') ' %)'];
            app.LabelTOTALestalvi.Text=['TOTAL: ' num2str(sum(Estalvi_mes_tot),'%.0f') ' €' ' (' num2str(Estalvi_per,'%.1f') ' %)'];

            
            app.CostCaptacioGeo.Text=[num2str(round(app.n_well.Value*app.l_well.Value*app.CostEspecificPous.Value,0)) ' €'];
            CostCaptacio=round(app.n_well.Value*app.l_well.Value*app.CostEspecificPous.Value,0);
            setappdata(0,'CostCaptacio',CostCaptacio);
            app.CostEquipsGeo.Text=[num2str(round((app.CostEspecInstGeo.Value+app.CostEspecificGeo.Value)*P_insG,0)) ' €'];
            CostEqGeo=round((app.CostEspecInstGeo.Value+app.CostEspecificGeo.Value)*P_insG,0);
            setappdata(0,'CostEqGeo',CostEqGeo);
            app.CostEquipsAlt.Text=[num2str(round((app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value),0)) ' €'];
            CostEqAlt=round((app.CAPEXAlth.Value+app.CAPEXAltc.Value+app.CAPEXAltACS.Value),0);
            setappdata(0,'CostEqAlt',CostEqAlt);
            if app.GeoHibPV.Value==1
                app.HibSiNoGeo.Text='Si';
                app.CostPanellsGeo.Text=[num2str(round(app.GeokWpPV.Value*app.PV_cost.Value,0)) ' €'];
                CostPanGeo_ex=round(app.GeokWpPV.Value*app.PV_cost.Value,0);
                if (app.GeoBat.Enable==1)&&(app.GeoBat.Value==1)
                    app.CostBateriesGeo.Text=[num2str(round(app.PVGeoEneBat.Value*app.CostEspecBat.Value,0)) ' €'];
                    CostBatGeo_ex=round(app.PVGeoEneBat.Value*app.CostEspecBat.Value,0);
                else
                    app.CostBateriesGeo.Text='';
                    CostBatGeo_ex=0;
                end
            else
                app.HibSiNoGeo.Text='No';
                app.CostPanellsGeo.Text='';
                app.CostBateriesGeo.Text='';
                CostPanGeo_ex=0;
                CostBatGeo_ex=0;
            end
            if app.AltHibPV.Value==1
                app.HibSiNoAlt.Text='Si';
                app.CostPanellsAlt.Text=[num2str(round(app.AltkWpPV.Value*app.PV_cost.Value,0)) ' €'];
                CostPanAlt_ex=round(app.AltkWpPV.Value*app.PV_cost.Value,0);
                if (app.AltBat.Enable==1)&&(app.AltBat.Value==1)
                    app.CostBateriesAlt.Text=[num2str(round(app.PVAltEneBat.Value*app.CostEspecBat.Value,0)) ' €'];
                    CostBatAlt_ex=round(app.PVAltEneBat.Value*app.CostEspecBat.Value,0);
                else
                    app.CostBateriesAlt.Text='';
                    CostBatAlt_ex=0;
                end
            else
                app.HibSiNoAlt.Text='No';
                app.CostPanellsAlt.Text='';
                app.CostBateriesAlt.Text='';
                CostPanAlt_ex=0;
                CostBatAlt_ex=0;
            end
            app.COSTTOTALGeo.Text=[num2str(round(app.CAPEXGeoTOT.Value,0)) ' €'];
            CostInvGeo_ex=round(app.CAPEXGeoTOT.Value,0);
            app.COSTTOTALAlt.Text=[num2str(round(app.CAPEXAltTOT.Value,0)) ' €'];
            CostInvAlt_ex=round(app.CAPEXAltTOT.Value,0);
            if app.GeoSubv.Enable*app.GeoSubv.Value==1
                app.SubvencioGeo.Text=[num2str(round(app.ImportGeoSubv.Value,0)) ' €'];
                ImpSubvGeo_ex=round(app.ImportGeoSubv.Value,0);
            else
                app.SubvencioGeo.Text=''; 
                ImpSubvGeo_ex=0;
            end
            if app.AltSubv.Enable*app.AltSubv.Value==1
                app.SubvencioAlt.Text=[num2str(round(app.ImportAltSubv.Value,0)) ' €'];
                ImpSubvAlt_ex=round(app.ImportAltSubv.Value,0);
            else
                app.SubvencioAlt.Text='';
                ImpSubvAlt_ex=0;
            end
            txtPrev1=num2str(app.RetornGeoPres.Value);
            txtPrev2=num2str(app.InteresGeoPres.Value);
            if app.GeoPres.Enable*app.GeoPres.Value==1
                app.PrestecGeo.Text=[num2str(round(0.01*app.TramGeoPres.Value*app.CAPEXGeoTOT.Value,0)) ' €'];
                app.DadesPresGeo.Text=[txtPrev1 ' anys al ' txtPrev2 ' %'];
                ImpPresGeo_ex=round(0.01*app.TramGeoPres.Value*app.CAPEXGeoTOT.Value,0);
            else
                app.PrestecGeo.Text='';
                app.DadesPresGeo.Text='';
                ImpPresGeo_ex=0;
            end
            txtPrev3=num2str(app.RetornAltPres.Value);
            txtPrev4=num2str(app.InteresAltPres.Value);
            if app.AltPres.Enable*app.AltPres.Value==1
                app.PrestecAlt.Text=[num2str(round(0.01*app.TramAltPres.Value*app.CAPEXAltTOT.Value,0)) ' €'];
                app.DadesPresAlt.Text=[txtPrev3 ' anys al ' txtPrev4 ' %'];
                ImpPresAlt_ex=round(0.01*app.TramAltPres.Value*app.CAPEXAltTOT.Value,0);
            else
                app.PrestecAlt.Text='';
                app.DadesPresAlt.Text='';
                ImpPresAlt_ex=0;
            end
            
            if INV_GEO>INV_ALT
                app.COSTNETGeo.Text=[num2str(round(INV_NETA,0)) ' €'];
                CostInvExtGeo_ex=round(INV_NETA,0);
            else
                app.COSTNETGeo.Text='0 €';
                CostInvExtGeo_ex=0;
            end
                     
            txtPrev7=num2str(round(1000*benefici,0));
            app.BeneficiNet.Text=[txtPrev7 ' €'];
            if 1000*benefici>=(INV_NETA)
                app.BeneficiNet.FontColor=[0 0.5 0];
                app.BeneficiNet.Text=[txtPrev7 ' €'];
            elseif (benefici>0)&&(benefici<(INV_NETA))
                app.BeneficiNet.Text=[txtPrev7 ' €'];
                app.BeneficiNet.FontColor=[0 0 0];
            else
                app.BeneficiNet.Text='Sense benefici';
                app.BeneficiNet.FontColor=[1 0 0];
            end
            %JGC:Activem el botó de generar informe total
            app.InformeTOT.Enable=1;
            setappdata(0,'opcio_alth',OpAlth);
            setappdata(0,'opcio_altc',OpAltc);
            setappdata(0,'opcio_altACS',OpAltACS);
            setappdata(0,'CostInvExtGeo',CostInvExtGeo_ex);
            setappdata(0,'CostPanGeo',CostPanGeo_ex);
            setappdata(0,'CostPanAlt',CostPanAlt_ex);
            setappdata(0,'CostBatGeo',CostBatGeo_ex);
            setappdata(0,'CostBatAlt',CostBatAlt_ex);
            setappdata(0,'CostInvGeo',CostInvGeo_ex);
            setappdata(0,'CostInvAlt',CostInvAlt_ex);
            setappdata(0,'ImpSubvGeo',ImpSubvGeo_ex);
            setappdata(0,'ImpSubvAlt',ImpSubvAlt_ex);
            setappdata(0,'ImpPresGeo',ImpPresGeo_ex);
            setappdata(0,'ImpPresAlt',ImpPresAlt_ex);
            setappdata(0,'benefici',benefici);
        end

        % Button pushed function: ValorsperdefecteButton
        function Write_ValorsDefecteEcoAmb(app, event)
            SPFRefrAER2=getappdata(0,'SPFr_AER2');
            EER_AER2m=getappdata(0,'EER_AER2_m');
            param=readmatrix("018_parametersEcoAmb.xlsx");
            app.SwitchTipusProjecte.Value='Nova instal·lació';
            app.vidaprojecte.Value=15;
            app.Taxadescompte.Value=5;
            app.vidaprojexistent.Value=0;
            app.vidaprojexistent.Enable=0;
            app.Infl_general.Value=param(130);
            app.CostEspecificPous.Enable=1;
            app.CostEspecificPous.Value=param(126);
            app.CostEspecInstGeo.Enable=1;
            app.CostEspecInstGeo.Value=param(127);
            app.CostEspecificGeo.Enable=1;
            app.CostEspecificGeo.Value=param(128);
            app.MantGeo.Enable=1;
            app.MantGeo.Value=param(129);
            app.GeoSubv.Value=false; 
            app.ImportGeoSubv.Value=0;
            app.ImportGeoSubv.Enable=0;
            app.GeoPres.Value=false;
            app.TramGeoPres.Value=0;
            app.TramGeoPres.Enable=0;
            app.InteresGeoPres.Value=4;
            app.InteresGeoPres.Enable=0;
            app.RetornGeoPres.Value=app.vidaprojecte.Value;
            app.RetornGeoPres.Enable=0;          
            app.AltSubv.Value=false;
            app.ImportAltSubv.Enable=0;
            app.AltPres.Value=false;
            app.TramAltPres.Value=0;
            app.TramAltPres.Enable=0;
            app.InteresAltPres.Value=4;
            app.InteresAltPres.Enable=0;
            app.RetornAltPres.Value=app.vidaprojecte.Value;
            app.RetornAltPres.Enable=0;
            app.GeoHibPV.Value=false;
            app.GeoHibPV.Enable=0;
            if app.ProdCalfMWht.Value>0
                app.OpcionsAlternativa_h.Enable=1;
                app.OpcionsAlternativa_h.Value='Biomassa';
                app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de Biomassa amb un rendiment del ' num2str(param(7)) ' %'];
                app.CostEscomesa.Visible=0;
                app.CostEscomesa.Value=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEspecInstAlt_h.Enable=1;
                app.CostEspecInstAlt_h.Value=param(1);
                app.CostEspecificAlt_h.Enable=1;
                app.CostEspecificAlt_h.Value=param(2);
                app.MantAlt_h.Enable=1;
                app.MantAlt_h.Value=param(3);
                app.OpcionsAlternativa_ACS.Enable=0;
                app.OpcionsAlternativa_ACS.Value='Biomassa';
                app.CostEspecInstAlt_ACS.Enable=0;
                app.CostEspecInstAlt_ACS.Value=param(1);
                app.LabelCostEspecInstAlt_ACS.Text=' €/kW';
                app.CostEspecificAlt_ACS.Enable=0;
                app.CostEspecificAlt_ACS.Value=param(2);
                app.LabelCostEspecificAlt_ACS.Text=' €/kW';
                app.MantAlt_ACS.Enable=0;
                app.MantAlt_ACS.Value=param(3);
                app.LabelMantAlt_ACS.Text=' €/kW';
%                 app.Label_EcoAmbObs3.Text='Si s''opta per una caldera de biomassa per calefacció, s''entén la opció òptima és utilitzar-la també per ACS';
            else
                app.OpcionsAlternativa_h.Enable=0;
                app.OpcionsAlternativa_h.Value='Biomassa';
                app.CostEscomesa.Visible=0;
                app.Label_CostEscomesa.Visible=0;
                app.CostEscomesa.Value=0;
                app.CostEspecInstAlt_h.Enable=0;
                app.CostEspecInstAlt_h.Value=param(1);
                app.CostEspecificAlt_h.Enable=0;
                app.CostEspecificAlt_h.Value=param(2);
                app.MantAlt_h.Enable=0;
                app.MantAlt_h.Value=param(3);
                if app.Prod_ACSMWht.Value>0
                    app.OpcionsAlternativa_ACS.Enable=1;
                    app.OpcionsAlternativa_ACS.Value='Biomassa';
                    app.Label_EcoAmbObs3.Text=[ 'Es considera una caldera de Biomassa amb un rendiment del ' num2str(param(7)) ' %'];
                    app.CostEspecInstAlt_ACS.Enable=1;
                    app.CostEspecInstAlt_ACS.Value=param(1);
                    app.CostEspecificAlt_ACS.Enable=1;
                    app.CostEspecificAlt_ACS.Value=param(2);
                    app.MantAlt_ACS.Enable=1;
                    app.MantAlt_ACS.Value=param(3);
                else
                    app.OpcionsAlternativa_h.Enable=0;
                    app.OpcionsAlternativa_h.Value='Biomassa';
                    app.Label_EcoAmbObs3.Text='No hi ha producció de CALOR (calefacció) ni d''ACS.';
                    app.CostEspecInstAlt_h.Enable=0;
                    app.CostEspecInstAlt_h.Value=param(1);
                    app.CostEspecificAlt_h.Enable=0;
                    app.CostEspecificAlt_h.Value=param(2);
                    app.MantAlt_h.Enable=0;
                    app.MantAlt_h.Value=param(3);
                end
            end
            if app.ProdRefrMWht.Value>0
                app.OpcionsAlternativa_c.Enable=1;
                app.OpcionsAlternativa_c.Value='Aire Condicionat';
                app.Label_EcoAmbObs4.Text=['Es considera un sistema d''aire condicionat amb un rendiment estacional SPFr = ' num2str(SPFRefrAER2,'%.2f') '. '];
                app.CostEspecInstAlt_c.Enable=1;
                app.CostEspecInstAlt_c.Value=param(115);
                app.CostEspecificAlt_c.Enable=1;
                app.CostEspecificAlt_c.Value=param(116);
                app.MantAlt_c.Enable=1;
                app.MantAlt_c.Value=param(117);
            else
                app.OpcionsAlternativa_c.Enable=0;
                app.OpcionsAlternativa_c.Value='Aire Condicionat';
                app.Label_EcoAmbObs4.Text='No hi ha producció de FRED (refigeració).';
                app.CostEspecInstAlt_c.Enable=0;
                app.CostEspecInstAlt_c.Value=param(115);
                app.CostEspecificAlt_c.Enable=0;
                app.CostEspecificAlt_c.Value=param(116);
                app.MantAlt_c.Enable=0;
                app.MantAlt_c.Value=param(117); 
            end
            app.CostkWVall.Enable=1;
            app.CostkWVall.Value=param(104);
            app.CostkWPlaPunta.Enable=1;
            app.CostkWPlaPunta.Value=param(107);
            app.CostkWhVall.Enable=1;
            app.CostkWhVall.Value=param(103);
            app.CostkWhPla.Enable=1;
            app.CostkWhPla.Value=param(105);
            app.CostkWhPunta.Enable=1;
            app.CostkWhPunta.Value=param(106);
            app.Infl_AnualFix_e.Enable=1;
            app.Infl_AnualFix_e.Value=param(108);
            app.Infl_AnualVar_e.Enable=1;
            app.Infl_AnualVar_e.Value=param(109);
            app.CostkgBiom.Enable=1;
            app.CostkgBiom.Value=param(4);
            app.Infl_AnualVar_Biom.Enable=1;
            app.Infl_AnualVar_Biom.Value=param(6);
            app.CostkWhGN.Enable=0;
            app.CostkWhGN.Value=param(24);
            app.CostGN.Enable=0;
            app.CostGN.Value=param(25);
            app.Infl_AnualVar_GN.Enable=0;
            app.Infl_AnualVar_GN.Value=param(17);
            app.Infl_AnualFix_GN.Enable=0;
            app.Infl_AnualFix_GN.Value=param(16);
            app.CostkgGC.Enable=0;
            app.CostkgGC.Value=param(63);
            app.CostGC.Enable=0;
            app.CostGC.Value=0;
            app.Infl_AnualVar_GC.Enable=0;
            app.Infl_AnualVar_GC.Value=param(51);
            app.Infl_AnualFix_GC.Enable=0;
            app.Infl_AnualFix_GC.Value=param(50);
            app.CostlGasoil.Enable=0;
            app.CostlGasoil.Value=param(98);
            app.Infl_AnualVar_Gasoil.Enable=0;
            app.Infl_AnualVar_Gasoil.Value=param(99);           
            app.GeoHibPV.Value=0;
            app.GeoHibPV.Enable=1;
            app.AltHibPV.Value=false;
            app.AltHibPV.Enable=1;          
            app.HibPVGeo_cob.Value=0;
            app.HibPVGeo_cob.Enable=0;
            app.HibPVAlt_cob.Value=0;
            app.HibPVAlt_cob.Enable=0;
            app.PV_area.Value=ceil(2.5*app.ConElecMWhe.Value*1000*0.01/(365*5*0.10)); %JGC Lower Limit definit com el mínim per cobrir 1% de la demanda elèctrica de Geotèrmia
            app.PV_area.Enable=0;
            app.PV_cost.Value=1000;
            app.PV_cost.Enable=0;
            app.PV_eff.Value=15;%JGC
            app.PV_eff.Enable=0;
            app.Retrib_excdPV.Enable=0;
            app.Retrib_excdPV.Value=0.07;
            app.GeokWpPV.Value=0;
            app.AltkWpPV.Value=0;
            app.Geom2PV.Value=0;
            app.Altm2PV.Value=0;
            app.GeoBat.Enable=0;
            app.GeoBat.Value=0;
            app.AltBat.Enable=0;
            app.AltBat.Value=0;
            app.CostEspecBat.Enable=0;
            app.CostEspecBat.Value=0;
            app.Ch_DisCh_EffBat.Enable=0;
            app.Ch_DisCh_EffBat.Value=param(131);
            app.PVGeoEneBat.Enable=0;
            app.PVGeoEneBat.Value=0;
            app.PVAltEneBat.Enable=0;
            app.PVAltEneBat.Value=0;
            app.PVGeoCostInv.Enable=0;
            app.PVGeoCostInv.Value=0;
            app.PVAltCostInv.Enable=0;
            app.PVAltCostInv.Value=0;
         

            %JGC:El valor del CAPEX Geo torna a correspondre només al camp de captació i BCG
            c_insGeo=app.CostEspecInstGeo.Value;
            c_pous = app.CostEspecificPous.Value;
            c_BCG= app.CostEspecificGeo.Value;
            n_pous=app.n_well.Value;
            L_BHE=app.l_well.Value;
            P_insG=getappdata(0,'Pot_G');
            app.CAPEXGeo.Value=c_pous*n_pous*L_BHE+(c_insGeo+c_BCG)*P_insG;
            
            P_insA=P_insG;
            P_inshA=app.PotInsCalfkW.Value;
            P_inscA=app.PotInsRefrkW.Value;
            P_insACSA=1.1*app.PotDis_ACSkW.Value;          
            
            %JGC: Definim valors per defecte dels consums elèctric de l'Alternativa assumint Configuració per defecte: Biomassa+AC
            cons_e_biom=param(8); %JGC: [kWe/kWt] potència elèctrica específica d'una caldera de biomassa
%             EER_AER2=0.01*param(118); %JGC: EER per defecte d'una aerotèrmia aire-aire en mode refrigeració.
            app.ConElecAlthMWhe.Value=app.ProdCalfMWht.Value*cons_e_biom; %JGC: [MWhe] Consum elèctric anual de la Caldera de Biomassa (elecció per defecte)
            app.PotElecAlthkW.Value=max(P_inshA,P_insACSA)*cons_e_biom; %JGC: [kWe]
            app.ConElecAltcMWhe.Value=app.ProdRefrMWht.Value/SPFRefrAER2; %JGC: [MWhe]
            app.PotElecAltckW.Value=P_inscA/min(EER_AER2m(3:10)); %JGC: [kWe]
            app.ConElecAltACSMWhe.Value=app.Prod_ACSMWht.Value*cons_e_biom; %JGC: [MWhe]
            app.PotElecAltACSkW.Value=max(P_inshA,P_insACSA)*cons_e_biom; %JGC: [kWe] 
            %JGC:El valor del CAPEX Alt torna a correspondre a una instal·lació de Biomassa + Aire Condicionat
            app.CAPEXAlth.Value=(app.CostEspecificAlt_h.Value+app.CostEspecInstAlt_h.Value)*P_insA;
            app.CAPEXAltc.Value=(app.CostEspecificAlt_c.Value+app.CostEspecInstAlt_c.Value)*P_inscA;
            app.CAPEXAltACS.Value=0;
            %JGC: Reset del gràfic
            app.PlotDespesa.Value=true;
            ylabel(app.UIplotFluxeVAN, 'Despesa instal·lacions (k€)');
            app.UIplotFluxeVAN.XLim=[0 double(app.vidaprojecte.Value)+1];
            
             %JGC: També anem a la pestanya següent de resultats
            app.Estalviesperat.Enable=0;
            app.Estalviesperat.Value=0;
            app.Estalviesperat.BackgroundColor=[1.00 1.00 1.00];
            app.Paybacktext.Enable=0;
            app.Paybacktext.Value="0.0";
%             app.EstalviNetTotal.Value=0;
%             app.EstalviNetTotal.Enable=0;
%             app.EstalviNetTotal.BackgroundColor=[1 1 1];
            app.RendibNetTotal.Value="0.0";
            app.RendibNetTotal.Enable=0;
            app.RendibNetTotal.BackgroundColor=[1 1 1];
            app.Paybacktext.BackgroundColor=[1.00 1.00 1.00];
            app.PaybackDesctext.Enable=0;
            app.PaybackDesctext.Value="0.0";
            app.PaybackDesctext.BackgroundColor=[1.00 1.00 1.00];
            app.VAN.Enable=0;
            app.VAN.Value=0;
            app.VAN.BackgroundColor=[1.00 1.00 1.00];
            app.TIRt.Enable=0;
            app.TIRt.Value="0.0";
            app.TIRt.BackgroundColor=[1.00 1.00 1.00];
            app.EstalviCO2rel.Enable=0;
            app.EstalviCO2rel.Value=0;
            app.EstalviCO2rel.BackgroundColor=[1.00 1.00 1.00];
            app.EstalviCO2.Enable=0;
            app.EstalviCO2.Value=0;
            app.EstalviCO2.BackgroundColor=[1.00 1.00 1.00];
            app.PlotDespesa.Enable=0;
            app.PlotVAN.Enable=0;
            app.RunEcoAmb.Enable=1;
            app.GeoCons_e.Enable=0;
            app.GeoCons_e.Value=0;  
            app.AltCons_e.Enable=0;
            app.AltCons_e.Value=0;
            app.GeoAutoprod_e.Enable=0;
            app.GeoAutoprod_e.Value=0;
            app.AltAutoprod_e.Enable=0;
            app.AltAutoprod_e.Value=0;
            app.GeoExcdPV_e.Enable=0;
            app.GeoExcdPV_e.Value=0;
            app.AltExcdPV_e.Enable=0;
            app.AltExcdPV_e.Value=0;
            app.GeoAutocons_e.Enable=0;
            app.GeoAutocons_e.Value=0;
            app.AltAutocons_e.Enable=0;
            app.AltAutocons_e.Value=0;
            app.GeoDesp_e.Enable=0;
            app.GeoDesp_e.Value=0;
            app.AltDesp_e.Enable=0;
            app.AltDesp_e.Value=0;
            app.GeoEstalviAutoc_e.Enable=0;
            app.GeoEstalviAutoc_e.Value=0;
            app.AltEstalviAutoc_e.Enable=0;
            app.AltEstalviAutoc_e.Value=0;
            app.PlotDespesa.Enable=0;
            app.PlotVAN.Enable=0;
            app.PlotDespesa.Value=true;
            app.UIplotFluxeVAN.cla;
            ylabel(app.UIplotFluxeVAN, 'Despesa instal·lacions (k€)');
            app.UIplotFluxeVAN.XLim=[0 double(app.vidaprojecte.Value)+1];
            app.MonthPlotGeo.cla;
            app.MonthPlotAlt.cla;
        end

        % Value changed function: VAN
        function Write_VANBackgroundColor(app, event)
            value = app.VAN.Value;
            if value <=0
                app.VAN.BackgroundColor=[1.00 0.00 0.00];
            else
                app.VAN.BackgroundColor=[1.00 1.00 1.00];
            end
        end

        % Selection changed function: EcoPlotOption
        function Choose_Eco_o_VANPlot(app, event)
%  JGC: Línea Borrable?           selectedButton = app.EcoPlotOption.SelectedObject;            
            n_life=app.vidaprojecte.Value;            
            X_axis=getappdata(0,'X_axis');
            YGeoYAlt=getappdata(0,'Y_GeoAlt');
%             YAlt=getappdata(0,'Y_PagAlt');
            YVAN=getappdata(0,'Y_VAN');
            Ready_toplot=getappdata(0,'Ready_toplot');
            app.UIplotFluxeVAN.XLim = [0 n_life+1];
            
            if (Ready_toplot==1)&&(app.PlotDespesa.Value == 1)
                app.UIplotFluxeVAN.cla;
                ylabel(app.UIplotFluxeVAN,'Despesa instal·lacions (k€)');
%                     hold(app.UIplotFluxeVAN,'on');
                plot(app.UIplotFluxeVAN,X_axis,YGeoYAlt);
                GeoAlt= plot(app.UIplotFluxeVAN,X_axis,YGeoYAlt);
                set(GeoAlt(1),'Color',[0.96 0.62 0.13],'LineWidth',1.0)
                set(GeoAlt(2),'Color',[0.5 0.5 0.5],'LineWidth',1.0)
                legend(app.UIplotFluxeVAN,{'Geotèrmia','Alternativa'});
                legend(app.UIplotFluxeVAN,'boxoff');
                legend(app.UIplotFluxeVAN,'Location','northwest');
%                     hold(app.UIplotFluxeVAN,'off');
            elseif(Ready_toplot==1)&&(app.PlotVAN.Value == 1)
                app.UIplotFluxeVAN.cla;
                ylabel(app.UIplotFluxeVAN,'VAN (k€)');
                plot(app.UIplotFluxeVAN,X_axis,YVAN,'Color',[0 0 0],'LineWidth',1.0);
                legend(app.UIplotFluxeVAN,'VAN');
                legend(app.UIplotFluxeVAN,'boxoff');
                legend(app.UIplotFluxeVAN,'Location','northwest');
            else
                app.UIplotFluxeVAN.cla;
                ylabel(app.UIplotFluxeVAN,'Despesa instal·lacions (k€)');
            end
        end

        % Button pushed function: EnrereButton_2
        function GoTo_Intro(app, event)
            app.TabGroup.SelectedTab = app.IntroTab;
        end

        % Button pushed function: EnrereButton_3
        function GoTo_ResultatsEcoAmb(app, event)
            app.TabGroup.SelectedTab = app.ResultatsEcoAmbTab;
        end

        % Button pushed function: ResumprojecteButton
        function GoTo_Resum(app, event)
            app.TabGroup.SelectedTab = app.ResumTab;
        end

        % Callback function: EnrereButtonResultatsEcoAmb, 
        % RendibilitatprojecteButton
        function GoTo_EcoAmbTab(app, event)
            app.TabGroup.SelectedTab=app.AnalisiEcoAmbTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeoSIVdesigntoolv10UIFigure = uifigure('Visible', 'off');
            app.GeoSIVdesigntoolv10UIFigure.Name = 'Geo-SIV';
            app.lay_1 = uigridlayout(app.GeoSIVdesigntoolv10UIFigure);
            app.TabGroup = uitabgroup(app.lay_1);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Introducció';
            app.GridLayout210 = uigridlayout(app.IntroTab);
            app.GridLayout211 = uigridlayout(app.GridLayout210);
            app.MetodologiaButton = uibutton(app.GridLayout211, 'push');
            app.MetodologiaButton.ButtonPushedFcn = createCallbackFcn(app, @GoTo_Metodologia, true);
            app.MetodologiaButton.Text = 'Metodologia';
            app.IniciareldissenyButton_2 = uibutton(app.GridLayout211, 'push');
            app.IniciareldissenyButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoTo_DadesEntrada, true);
            app.IniciareldissenyButton_2.Text = 'Iniciar el disseny';
            app.GridLayout213 = uigridlayout(app.GridLayout210);
            app.Image51 = uiimage(app.GridLayout213);
            app.Image51.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.GridLayout214 = uigridlayout(app.GridLayout210);
            app.Image49 = uiimage(app.GridLayout214);
            app.Image49.ImageSource = 'Logos&ICGC@UPC.JPG';
            app.GridLayout291 = uigridlayout(app.GridLayout210);
            app.Image59 = uiimage(app.GridLayout291);
            app.Image59.ImageSource = 'DESCRIPCIO_CAT.jpg';
            app.MetodologiaTab = uitab(app.TabGroup);
            app.MetodologiaTab.Title = 'Metodologia';
            app.lay_2 = uigridlayout(app.MetodologiaTab);
            app.GridLayout69 = uigridlayout(app.lay_2);
            app.DiagramadelfluxdetreballdeGeoSIVv12Label = uilabel(app.GridLayout69);
            app.DiagramadelfluxdetreballdeGeoSIVv12Label.Text = '   Diagrama del flux de treball de Geo-SIV v1.2';
            app.Image52 = uiimage(app.GridLayout69);
            app.Image52.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.GridLayout70 = uigridlayout(app.lay_2);
            app.ManualGeoSIVv12Button_6 = uibutton(app.GridLayout70, 'push');
            app.ManualGeoSIVv12Button_6.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_Manual, true);
            app.ManualGeoSIVv12Button_6.Text = 'Manual Geo-SIV v1.2';
            app.EnrereButton_2 = uibutton(app.GridLayout70, 'push');
            app.EnrereButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoTo_Intro, true);
            app.EnrereButton_2.Text = 'Enrere';
            app.IniciareldissenyButton = uibutton(app.GridLayout70, 'push');
            app.IniciareldissenyButton.ButtonPushedFcn = createCallbackFcn(app, @GoTo_DadesEntrada, true);
            app.IniciareldissenyButton.Text = 'Iniciar el disseny';
            app.Image58 = uiimage(app.lay_2);
            app.Image58.ImageSource = 'Metodologia_GeoSIV.png';
            app.DadesdentradaTab = uitab(app.TabGroup);
            app.DadesdentradaTab.Title = 'Dades d''entrada';
            app.lay_17 = uigridlayout(app.DadesdentradaTab);
            app.lay_23 = uigridlayout(app.lay_17);
            app.GridLayout7 = uigridlayout(app.lay_23);
            app.building_typoligy_panel = uipanel(app.GridLayout7);
            app.building_typoligy_panel.Title = '3. Càrrega i demanda tèrmica (edifici genèric)';
            app.lay_26 = uigridlayout(app.building_typoligy_panel);
            app.lay_29 = uigridlayout(app.lay_26);
            app.GridLayout80 = uigridlayout(app.lay_29);
            app.Superficieconsiderantsoterranim2Label = uilabel(app.GridLayout80);
            app.Superficieconsiderantsoterranim2Label.Text = 'Superficie (considerant soterrani)  [m2]';
            app.superficie = uieditfield(app.GridLayout80, 'numeric');
            app.superficie.Limits = [20 10000];
            app.superficie.ValueDisplayFormat = '%.2f';
            app.superficie.ValueChangedFcn = createCallbackFcn(app, @Input_SuperficieTotalEdifici, true);
            app.superficie.Enable = 'off';
            app.superficie.Tooltip = {'Superfície total construïda.'; ''};
            app.superficie.Value = 100;
            app.RelacioberturessuperficieLabel = uilabel(app.GridLayout80);
            app.RelacioberturessuperficieLabel.Text = 'Relació obertures/superficie';
            app.finestra = uieditfield(app.GridLayout80, 'numeric');
            app.finestra.Limits = [0 3];
            app.finestra.ValueDisplayFormat = '%.2f';
            app.finestra.ValueChangedFcn = createCallbackFcn(app, @Input_SuperficieTotalEdifici, true);
            app.finestra.Enable = 'off';
            app.finestra.Tooltip = {'Quocient de la superfície afinestrada dividida entre la superfície construida (sense comptar soterrani). El valor limit queda definit per la superficie, el nombre de plantes i la geometria de planta.'};
            app.finestra.Value = 0.2;
            app.NombredeplantessoterraninoinclsLabel = uilabel(app.GridLayout80);
            app.NombredeplantessoterraninoinclsLabel.Text = 'Nombre de plantes (soterrani no inclós)';
            app.plantes = uieditfield(app.GridLayout80, 'numeric');
            app.plantes.Limits = [0 20];
            app.plantes.ValueDisplayFormat = '%.0f';
            app.plantes.ValueChangedFcn = createCallbackFcn(app, @Input_SuperficieTotalEdifici, true);
            app.plantes.Enable = 'off';
            app.plantes.Tooltip = {'Nombre de plantes per sobre del nivell de carrer.'};
            app.plantes.Value = 1;
            app.MximnombreefectiudocupantsLabel = uilabel(app.GridLayout80);
            app.MximnombreefectiudocupantsLabel.Text = 'Màxim nombre efectiu d''ocupants';
            app.persones = uieditfield(app.GridLayout80, 'numeric');
            app.persones.Limits = [0 500];
            app.persones.ValueDisplayFormat = '%.1f';
            app.persones.Enable = 'off';
            app.persones.Tooltip = {'A efectes d''emisió tèrmica, a l''hora de comptabilitzar ocupants efectius, aquests s''han de comptabilitzar de la següent manera: 1 home = 1; 1 dona = 0.85; 1 nen (10-14anys) = 0.75; 1 nen (<10 anys) = 0.5. El valor resultant és el nombre màxim d''ocupants de manera simultània a l''edifici.'};
            app.persones.Value = 4;
            app.TemperaturadeconfortalhivernCLabel = uilabel(app.GridLayout80);
            app.TemperaturadeconfortalhivernCLabel.Text = 'Temperatura de confort a l''hivern [ºC]';
            app.t_confort_h = uieditfield(app.GridLayout80, 'numeric');
            app.t_confort_h.Limits = [15 25];
            app.t_confort_h.ValueDisplayFormat = '%.1f';
            app.t_confort_h.ValueChangedFcn = createCallbackFcn(app, @Input_Tconforth, true);
            app.t_confort_h.Enable = 'off';
            app.t_confort_h.Tooltip = {'Temperatura de confort a l''interior de l''edifici en mode calefacció'};
            app.t_confort_h.Value = 21;
            app.TemperaturadeconfortalestiuCEditFieldLabel = uilabel(app.GridLayout80);
            app.TemperaturadeconfortalestiuCEditFieldLabel.Text = 'Temperatura de confort a l''estiu [ºC]';
            app.t_confort_c = uieditfield(app.GridLayout80, 'numeric');
            app.t_confort_c.Limits = [20 35];
            app.t_confort_c.ValueDisplayFormat = '%.1f';
            app.t_confort_c.Enable = 'off';
            app.t_confort_c.Tooltip = {'Temperatura de confort a l''interior de l''edifici en mode refrigeració'};
            app.t_confort_c.Value = 26;
            app.DemandaACSlocupantdiaLabel = uilabel(app.GridLayout80);
            app.DemandaACSlocupantdiaLabel.Text = 'Demanda ACS [l/ocupant/dia]';
            app.ACS_especific = uieditfield(app.GridLayout80, 'numeric');
            app.ACS_especific.Limits = [0 200];
            app.ACS_especific.ValueDisplayFormat = '%.0f';
            app.ACS_especific.Enable = 'off';
            app.ACS_especific.Tooltip = {'demanda per persona i dia d''aigua calenta sanitària a 60ºC'};
            app.ACS_especific.Value = 41;
            app.FactorguanysolarhivernEditFieldLabel = uilabel(app.GridLayout80);
            app.FactorguanysolarhivernEditFieldLabel.Text = 'Factor guany solar (hivern)';
            app.SC_h = uieditfield(app.GridLayout80, 'numeric');
            app.SC_h.Limits = [0 1];
            app.SC_h.ValueDisplayFormat = '%.2f';
            app.SC_h.Enable = 'off';
            app.SC_h.Value = 0.7;
            app.FactorguanysolarestiuEditFieldLabel = uilabel(app.GridLayout80);
            app.FactorguanysolarestiuEditFieldLabel.Text = 'Factor guany solar (estiu)';
            app.SC_c = uieditfield(app.GridLayout80, 'numeric');
            app.SC_c.Limits = [0 1];
            app.SC_c.ValueDisplayFormat = '%.2f';
            app.SC_c.Enable = 'off';
            app.SC_c.Value = 0.45;
            app.UnknownDemand = uicheckbox(app.lay_29);
            app.UnknownDemand.ValueChangedFcn = createCallbackFcn(app, @Select_DemandaConeguda, true);
            app.UnknownDemand.Text = {'Calcular càrrega i demanda tèrmiques genèriques'; ''};
            app.GridLayout265 = uigridlayout(app.lay_26);
            app.TipologiaEdifici = uibuttongroup(app.GridLayout265);
            app.TipologiaEdifici.SelectionChangedFcn = createCallbackFcn(app, @Choose_TipologiaEdifici, true);
            app.TipologiaEdifici.Title = 'Tipologia d''edifici';
            app.ResiNoSoterrani = uiradiobutton(app.TipologiaEdifici);
            app.ResiNoSoterrani.Enable = 'off';
            app.ResiNoSoterrani.Tooltip = {'Exemple: casa unifamiliar sense soterrani.'};
            app.ResiNoSoterrani.Text = 'Residencial (sense soterrani)';
            app.ResiNoSoterrani.Value = true;
            app.ResiSoterrani = uiradiobutton(app.TipologiaEdifici);
            app.ResiSoterrani.Enable = 'off';
            app.ResiSoterrani.Tooltip = {'Exemple: Casa unifamiliar amb soterrani.'};
            app.ResiSoterrani.Text = 'Residencial (amb soterrani)';
            app.CentreEducatiu = uiradiobutton(app.TipologiaEdifici);
            app.CentreEducatiu.Enable = 'off';
            app.CentreEducatiu.Tooltip = {'Exemple: Escola educació infantil i primària d''una sola linia per curs.'};
            app.CentreEducatiu.Text = 'Centre educatiu';
            app.Oficines = uiradiobutton(app.TipologiaEdifici);
            app.Oficines.Enable = 'off';
            app.Oficines.Tooltip = {'Exemple: Petit edifici d''oficines amb un alt grau d''acristallament a la façana'};
            app.Oficines.Text = 'Oficines';
            app.Hosteleria = uiradiobutton(app.TipologiaEdifici);
            app.Hosteleria.Enable = 'off';
            app.Hosteleria.Tooltip = {'Exemple: Petit hotel de 3 estrelles, amb restaurant a planta 0.'};
            app.Hosteleria.Text = 'Hosteleria';
            app.Hospital = uiradiobutton(app.TipologiaEdifici);
            app.Hospital.Enable = 'off';
            app.Hospital.Tooltip = {'Exemple: Ambulatori aïllat en població petita (<10000hab.)'};
            app.Hospital.Text = 'Centre sanitari';
            app.CentreEsportiu = uiradiobutton(app.TipologiaEdifici);
            app.CentreEsportiu.Enable = 'off';
            app.CentreEsportiu.Tooltip = {'Exemple: Gimnàs aïllat en zona comercial, sense piscina.'};
            app.CentreEsportiu.Text = 'Centre esportiu (sense piscina)';
            app.GridLayout266 = uigridlayout(app.GridLayout265);
            app.GeometriaPlanta = uibuttongroup(app.GridLayout266);
            app.GeometriaPlanta.SelectionChangedFcn = createCallbackFcn(app, @Input_SuperficieTotalEdifici, true);
            app.GeometriaPlanta.Title = 'Geometria planta';
            app.PlantaQuadrada = uiradiobutton(app.GeometriaPlanta);
            app.PlantaQuadrada.Enable = 'off';
            app.PlantaQuadrada.Tooltip = {'Si no es coneix la geometria de planta, seleccionar aquesta opció.'};
            app.PlantaQuadrada.Text = 'Quadrada';
            app.PlantaQuadrada.Value = true;
            app.PlantaRectangular1 = uiradiobutton(app.GeometriaPlanta);
            app.PlantaRectangular1.Enable = 'off';
            app.PlantaRectangular1.Text = 'Rectangular 1';
            app.PlantaRectangular2 = uiradiobutton(app.GeometriaPlanta);
            app.PlantaRectangular2.Enable = 'off';
            app.PlantaRectangular2.Tooltip = {'La planta es considera un rectangle amb una relació de costats 2:1. El costat curt'; ' està orientat al sud.'};
            app.PlantaRectangular2.Text = 'Rectangular 2';
            app.GridLayout267 = uigridlayout(app.GridLayout266);
            app.Image19 = uiimage(app.GridLayout267);
            app.Image19.ImageSource = 'CAS A.png';
            app.Image20 = uiimage(app.GridLayout267);
            app.Image20.ImageSource = 'CAS B1.PNG';
            app.Image21 = uiimage(app.GridLayout267);
            app.Image21.ImageSource = 'CAS B2.PNG';
            app.GridLayout268 = uigridlayout(app.GridLayout266);
            app.Image39 = uiimage(app.GridLayout268);
            app.Image39.ImageSource = 'DireccioN.PNG';
            app.GridLayout4 = uigridlayout(app.lay_23);
            app.borehole_panel = uipanel(app.GridLayout4);
            app.borehole_panel.Title = '6. Disseny dels bescanviadors de calor (pous verticals de circuit tancat)';
            app.lay_41 = uigridlayout(app.borehole_panel);
            app.GridLayout12 = uigridlayout(app.lay_41);
            app.GridLayout83 = uigridlayout(app.GridLayout12);
            app.user_PropTerm = uicheckbox(app.GridLayout83);
            app.user_PropTerm.ValueChangedFcn = createCallbackFcn(app, @Choose_PropTermManual_o_Automatic, true);
            app.user_PropTerm.Text = 'Introducció manual de propietats del subsol ';
            app.info_tooltip = uiimage(app.GridLayout83);
            app.info_tooltip.Tooltip = {'Els valors per defecte provenen de la base de dades de l''ICGC. Si aquests apareixen com igual a zero, reviseu les coordenades UTM introduïdes '};
            app.info_tooltip.ImageSource = 'alerta_icono_2.png';
            app.Diffusivitattrmicadelterrenymm2sLabel = uilabel(app.GridLayout83);
            app.Diffusivitattrmicadelterrenymm2sLabel.Text = '     Diffusivitat tèrmica del terreny [mm2/s]';
            app.user_difu = uieditfield(app.GridLayout83, 'numeric');
            app.user_difu.Limits = [0.1 3];
            app.user_difu.ValueDisplayFormat = '%.3f';
            app.user_difu.Enable = 'off';
            app.user_difu.Tooltip = {''};
            app.user_difu.Value = 1.22;
            app.ConductivitattrmicadelterrenyWmKLabel = uilabel(app.GridLayout83);
            app.ConductivitattrmicadelterrenyWmKLabel.Text = '     Conductivitat tèrmica del terreny [W/mK]';
            app.user_cdty = uieditfield(app.GridLayout83, 'numeric');
            app.user_cdty.Limits = [0.2 8];
            app.user_cdty.ValueDisplayFormat = '%.3f';
            app.user_cdty.Enable = 'off';
            app.user_cdty.Tooltip = {''};
            app.user_cdty.Value = 2.68;
            app.T_subsolTnopertorbadadelterrenyCLabel = uilabel(app.GridLayout83);
            app.T_subsolTnopertorbadadelterrenyCLabel.Text = '     T_subsol (T no pertorbada del terreny) [ºC]';
            app.ChangeT_ground0 = uieditfield(app.GridLayout83, 'numeric');
            app.ChangeT_ground0.Limits = [-5 25];
            app.ChangeT_ground0.ValueDisplayFormat = '%.1f';
            app.ChangeT_ground0.Enable = 'off';
            app.ChangeT_ground0.Tooltip = {'Temperatura del subsol a una profunditat a on desapareix la influència de la T_ambient'};
            app.ChangeT_ground0.Value = 18.1;
            app.GridLayout86 = uigridlayout(app.GridLayout12);
            app.ConfiguracisondesPanel = uipanel(app.GridLayout86);
            app.ConfiguracisondesPanel.Title = 'Configuració sondes';
            app.GridLayout196 = uigridlayout(app.ConfiguracisondesPanel);
            app.GridLayout197 = uigridlayout(app.GridLayout196);
            app.Image22 = uiimage(app.GridLayout197);
            app.Image22.ImageSource = 'U-simple.PNG';
            app.Image23 = uiimage(app.GridLayout197);
            app.Image23.ImageSource = 'U-doble.PNG';
            app.Panel_12 = uipanel(app.GridLayout196);
            app.ConfigSondes = uibuttongroup(app.Panel_12);
            app.Udoble = uiradiobutton(app.ConfigSondes);
            app.Udoble.Text = 'U-doble';
            app.Usimple = uiradiobutton(app.ConfigSondes);
            app.Usimple.Text = 'U-simple';
            app.Usimple.Value = true;
            app.GridLayout195 = uigridlayout(app.GridLayout86);
            app.EspaiatpousmLabel = uilabel(app.GridLayout195);
            app.EspaiatpousmLabel.Text = 'Espaiat pous [m]';
            app.d_well = uieditfield(app.GridLayout195, 'numeric');
            app.d_well.Limits = [6 25];
            app.d_well.ValueDisplayFormat = '%.1f';
            app.d_well.Tooltip = {'Distància mitjana entre pous'};
            app.d_well.Value = 6;
            app.GridLayout89 = uigridlayout(app.GridLayout12);
            app.LprospPous = uibuttongroup(app.GridLayout89);
            app.LprospPous.Title = 'Profunditat preliminar dels pous';
            app.m80 = uiradiobutton(app.LprospPous);
            app.m80.Text = '80 m';
            app.m90 = uiradiobutton(app.LprospPous);
            app.m90.Text = '90 m ';
            app.m100 = uiradiobutton(app.LprospPous);
            app.m100.Text = '100 m';
            app.m100.Value = true;
            app.m110 = uiradiobutton(app.LprospPous);
            app.m110.Text = '110 m';
            app.m120 = uiradiobutton(app.LprospPous);
            app.m120.Text = '120 m';
            app.m130 = uiradiobutton(app.LprospPous);
            app.m130.Text = '130 m ';
            app.m140 = uiradiobutton(app.LprospPous);
            app.m140.Text = '140 m';
            app.m150 = uiradiobutton(app.LprospPous);
            app.m150.Text = '150 m';
            app.GridLayout276 = uigridlayout(app.GridLayout89);
            app.Corr_T_ground = uicheckbox(app.GridLayout276);
            app.Corr_T_ground.Tooltip = {'La temperatura mitjana del subsol pot veure''s afectada per una eventual descompensació entre l''extracció i la injecció de calor al llarg dels anys. Aquest fet pot afectar a la grandària requerida del camp de captació.'};
            app.Corr_T_ground.Text = 'Considerar correccions a la temperatura del subsol';
            app.Corr_T_ground.Value = true;
            app.GridLayout90 = uigridlayout(app.GridLayout12);
            app.DiamSondes = uibuttongroup(app.GridLayout90);
            app.DiamSondes.Title = 'Ø_ext sondes (SDR-11)';
            app.mm32 = uiradiobutton(app.DiamSondes);
            app.mm32.Text = '32 mm';
            app.mm40 = uiradiobutton(app.DiamSondes);
            app.mm40.Text = '40 mm';
            app.mm40.Value = true;
            app.SistemadedistribucialinteriordeledificiPanel = uipanel(app.GridLayout4);
            app.SistemadedistribucialinteriordeledificiPanel.Title = '5. Sistema de distribució a l''interior de l''edifici';
            app.GridLayout277 = uigridlayout(app.SistemadedistribucialinteriordeledificiPanel);
            app.CALORLabel_5 = uilabel(app.GridLayout277);
            app.CALORLabel_5.Text = 'CALOR';
            app.FREDLabel_5 = uilabel(app.GridLayout277);
            app.FREDLabel_5.Text = 'FRED';
            app.GridLayout278 = uigridlayout(app.GridLayout277);
            app.GridLayout279 = uigridlayout(app.GridLayout278);
            app.SistemaDist_c = uibuttongroup(app.GridLayout279);
            app.SistemaDist_c.SelectionChangedFcn = createCallbackFcn(app, @Select_SistemaDist_c, true);
            app.Dist_TR_c = uiradiobutton(app.SistemaDist_c);
            app.Dist_TR_c.Enable = 'off';
            app.Dist_TR_c.Text = '';
            app.Dist_FC_c = uiradiobutton(app.SistemaDist_c);
            app.Dist_FC_c.Text = '';
            app.Dist_FC_c.Value = true;
            app.GridLayout280 = uigridlayout(app.GridLayout278);
            app.SistemaDist_h = uibuttongroup(app.GridLayout280);
            app.SistemaDist_h.SelectionChangedFcn = createCallbackFcn(app, @Select_SistemaDist_h, true);
            app.Dist_TR_h = uiradiobutton(app.SistemaDist_h);
            app.Dist_TR_h.Text = '';
            app.Dist_FC_h = uiradiobutton(app.SistemaDist_h);
            app.Dist_FC_h.Text = '';
            app.Dist_FC_h.Value = true;
            app.Dist_RR_h = uiradiobutton(app.SistemaDist_h);
            app.Dist_RR_h.Text = '';
            app.GridLayout281 = uigridlayout(app.GridLayout278);
            app.TerraradiantLabel = uilabel(app.GridLayout281);
            app.TerraradiantLabel.Text = 'Terra radiant';
            app.FancoilsLabel = uilabel(app.GridLayout281);
            app.FancoilsLabel.Text = 'Fancoils';
            app.RadiadorsLabel = uilabel(app.GridLayout281);
            app.RadiadorsLabel.Text = 'Radiadors';
            app.GridLayout282 = uigridlayout(app.GridLayout277);
            app.Image44 = uiimage(app.GridLayout282);
            app.Image44.Tooltip = {'L''elecció d''un o altre sistema de distribució a l''interior de l''edifici és primordial per al correcte dimensionament de la instal·lació geotèrmica. No obstant, el seu cost NO es tindrà en compte en l''anàlisi econòmica i ambiental.'};
            app.Image44.ImageSource = 'alerta_icono_2.png';
            app.GridLayout287 = uigridlayout(app.lay_23);
            app.PanelLocalitzacio = uipanel(app.GridLayout287);
            app.PanelLocalitzacio.Title = '1. Localització del projecte. Coordenades ETRS89 / UTM zona 31N (EPSG: 25831)';
            app.GridLayout3 = uigridlayout(app.PanelLocalitzacio);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.YUTMLabel = uilabel(app.GridLayout5);
            app.YUTMLabel.Text = 'Y UTM';
            app.y_loc = uieditfield(app.GridLayout5, 'numeric');
            app.y_loc.Limits = [4488675.1 4748054.9];
            app.y_loc.ValueDisplayFormat = '%.1f';
            app.y_loc.Value = 4580260;
            app.XUTMLabel = uilabel(app.GridLayout5);
            app.XUTMLabel.Text = 'X UTM';
            app.x_loc = uieditfield(app.GridLayout5, 'numeric');
            app.x_loc.Limits = [260130.1 527429.9];
            app.x_loc.ValueDisplayFormat = '%.1f';
            app.x_loc.Value = 428960;
            app.GridLayout73 = uigridlayout(app.GridLayout3);
            app.Image2 = uiimage(app.GridLayout73);
            app.Image2.Tooltip = {'Si desconeixeu les coordenades UTM de la instal·lació geotèrmica, podreu trobar-les al visor web de l''ICGC seguint l''enllaç de sota'};
            app.Image2.ImageSource = 'alerta_icono_2.png';
            app.LinkVISSIR = uibutton(app.GridLayout73, 'push');
            app.LinkVISSIR.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_VISSIRPushed, true);
            app.LinkVISSIR.Text = 'VISSIR v3.26 (ICGC)';
            app.SwitchCAT_Gir = uiswitch(app.GridLayout3, 'slider');
            app.SwitchCAT_Gir.Items = {'Catalunya', ' '};
            app.SwitchCAT_Gir.ValueChangedFcn = createCallbackFcn(app, @Select_GeographicalDomain, true);
            app.SwitchCAT_Gir.Value = 'Catalunya';
            app.GironaciutatreesurbanaiperiurbanaLabel = uilabel(app.GridLayout3);
            app.GironaciutatreesurbanaiperiurbanaLabel.Text = {'Girona ciutat (àrees'; 'urbana i periurbana)'};
            app.thermal_demand_panel = uipanel(app.GridLayout287);
            app.thermal_demand_panel.Title = '2. Càrrega i demanda tèrmica conegudes';
            app.lay_25 = uigridlayout(app.thermal_demand_panel);
            app.lab1 = uilabel(app.lay_25);
            app.lab1.Text = 'Càrrega tèrmica  de disseny a l''estiu (refrigeració) [kW]';
            app.pot_estiu = uieditfield(app.lay_25, 'numeric');
            app.pot_estiu.Limits = [0 70];
            app.pot_estiu.ValueDisplayFormat = '%.2f';
            app.pot_estiu.Value = 19;
            app.lab2 = uilabel(app.lay_25);
            app.lab2.Text = 'Càrrega tèrmica de disseny a l''hivern (calefaccio) [kW]';
            app.pot_hivern = uieditfield(app.lay_25, 'numeric');
            app.pot_hivern.Limits = [0 70];
            app.pot_hivern.ValueDisplayFormat = '%.2f';
            app.pot_hivern.Value = 22;
            app.lab3 = uilabel(app.lay_25);
            app.lab3.Text = 'Demanda anual d''energia en mode calefacció [kWh]';
            app.e_hivern = uieditfield(app.lay_25, 'numeric');
            app.e_hivern.Limits = [0 200000];
            app.e_hivern.ValueDisplayFormat = '%.0f';
            app.e_hivern.Value = 45000;
            app.lab4 = uilabel(app.lay_25);
            app.lab4.Text = 'Demanda anual d''energia en mode refrigeració [kWh]';
            app.e_estiu = uieditfield(app.lay_25, 'numeric');
            app.e_estiu.Limits = [0 200000];
            app.e_estiu.ValueDisplayFormat = '%.0f';
            app.e_estiu.Value = 20000;
            app.CrregatrmicadedissenyenmodeproduccidACSkWLabel = uilabel(app.lay_25);
            app.CrregatrmicadedissenyenmodeproduccidACSkWLabel.Text = 'Càrrega tèrmica de disseny en mode producció d''ACS [kW]';
            app.pot_ACS = uieditfield(app.lay_25, 'numeric');
            app.pot_ACS.Limits = [0 70];
            app.pot_ACS.ValueDisplayFormat = '%.2f';
            app.pot_ACS.Value = 2;
            app.DemandaanualdenergiaperaproduccidACSkWhLabel = uilabel(app.lay_25);
            app.DemandaanualdenergiaperaproduccidACSkWhLabel.Text = 'Demanda anual d''energia per a producció d''ACS [kWh]';
            app.e_ACS = uieditfield(app.lay_25, 'numeric');
            app.e_ACS.Limits = [0 200000];
            app.e_ACS.ValueDisplayFormat = '%.0f';
            app.e_ACS.Value = 5000;
            app.GridLayout288 = uigridlayout(app.lay_23);
            app.thermal_load_panel = uipanel(app.GridLayout288);
            app.thermal_load_panel.Title = '4. Característiques de la bomba de calor geotèrmica (BCG) (Valors nominals)';
            app.GridLayout9 = uigridlayout(app.thermal_load_panel);
            app.lay_40 = uigridlayout(app.GridLayout9);
            app.hp_criteria = uibuttongroup(app.lay_40);
            app.hp_criteria.SelectionChangedFcn = createCallbackFcn(app, @Choose_BCGManual_o_Automatic, true);
            app.database_hp = uiradiobutton(app.hp_criteria);
            app.database_hp.Text = 'Elecció automàtica (base de dades)';
            app.database_hp.Value = true;
            app.owner_hp = uiradiobutton(app.hp_criteria);
            app.owner_hp.Text = 'Escollir la meva pròpia BCG (Ref. #0)';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.GridLayout203 = uigridlayout(app.GridLayout10);
            app.PotnciacalefaccikWLabel = uilabel(app.GridLayout203);
            app.PotnciacalefaccikWLabel.Text = 'Potència calefacció [kW]';
            app.user_hp_heat = uieditfield(app.GridLayout203, 'numeric');
            app.user_hp_heat.Limits = [0 100];
            app.user_hp_heat.ValueDisplayFormat = '%.2f';
            app.user_hp_heat.Enable = 'off';
            app.user_hp_heat.Value = 26;
            app.COPLabel = uilabel(app.GridLayout203);
            app.COPLabel.Text = 'COP ';
            app.user_COP = uieditfield(app.GridLayout203, 'numeric');
            app.user_COP.Limits = [2 15];
            app.user_COP.ValueDisplayFormat = '%.2f';
            app.user_COP.Enable = 'off';
            app.user_COP.Value = 4;
            app.PotnciarefrigeracikWLabel = uilabel(app.GridLayout203);
            app.PotnciarefrigeracikWLabel.Text = 'Potència refrigeració  [kW]';
            app.user_hp_cool = uieditfield(app.GridLayout203, 'numeric');
            app.user_hp_cool.Limits = [0 100];
            app.user_hp_cool.ValueDisplayFormat = '%.2f';
            app.user_hp_cool.Enable = 'off';
            app.user_hp_cool.Value = 22;
            app.EERLabel = uilabel(app.GridLayout203);
            app.EERLabel.Text = 'EER';
            app.user_EER = uieditfield(app.GridLayout203, 'numeric');
            app.user_EER.Limits = [2 15];
            app.user_EER.ValueDisplayFormat = '%.2f';
            app.user_EER.Enable = 'off';
            app.user_EER.Value = 3.9;
            app.PotnciaACSkWLabel = uilabel(app.GridLayout203);
            app.PotnciaACSkWLabel.Text = 'Potència ACS [kW]';
            app.user_hp_ACS = uieditfield(app.GridLayout203, 'numeric');
            app.user_hp_ACS.Limits = [0 100];
            app.user_hp_ACS.ValueDisplayFormat = '%.2f';
            app.user_hp_ACS.Enable = 'off';
            app.user_hp_ACS.Value = 25;
            app.COPacsLabel_2 = uilabel(app.GridLayout203);
            app.COPacsLabel_2.Text = 'COPacs';
            app.user_COPACS = uieditfield(app.GridLayout203, 'numeric');
            app.user_COPACS.Limits = [2 15];
            app.user_COPACS.ValueDisplayFormat = '%.2f';
            app.user_COPACS.Enable = 'off';
            app.user_COPACS.Value = 3.25;
            app.GridLayout283 = uigridlayout(app.GridLayout203);
            app.Image45 = uiimage(app.GridLayout283);
            app.Image45.Tooltip = {'Els valors aportats per l''usuari han de ser congruents amb el sistema de distribuió de l''edifici (veure panell #5).'};
            app.Image45.ImageSource = 'alerta_icono_2.png';
            app.GridLayout204 = uigridlayout(app.GridLayout10);
            app.RefBCGLabel = uilabel(app.GridLayout204);
            app.RefBCGLabel.Text = {'Ref.'; 'BCG'};
            app.PcalefkWLabel = uilabel(app.GridLayout204);
            app.PcalefkWLabel.Text = {'Pcalef. '; '[kW]'};
            app.COPLabel_3 = uilabel(app.GridLayout204);
            app.COPLabel_3.Text = 'COP';
            app.PrefrikWLabel = uilabel(app.GridLayout204);
            app.PrefrikWLabel.Text = {'Prefri.'; '[kW]'};
            app.EERLabel_3 = uilabel(app.GridLayout204);
            app.EERLabel_3.Text = 'EER';
            app.PacskWLabel = uilabel(app.GridLayout204);
            app.PacskWLabel.Text = {'Pacs'; '[kW]'};
            app.COPacsLabel = uilabel(app.GridLayout204);
            app.COPacsLabel.Text = {'COP'; 'acs'};
            app.Label_23 = uilabel(app.GridLayout204);
            app.Label_23.Text = '1';
            app.Label_34 = uilabel(app.GridLayout204);
            app.Label_34.Text = '6.77';
            app.Label_45 = uilabel(app.GridLayout204);
            app.Label_45.Text = '4.72';
            app.Label_56 = uilabel(app.GridLayout204);
            app.Label_56.Text = '6.13';
            app.Label_67 = uilabel(app.GridLayout204);
            app.Label_67.Text = '4.38';
            app.Label_78 = uilabel(app.GridLayout204);
            app.Label_78.Text = '6.20';
            app.Label_89 = uilabel(app.GridLayout204);
            app.Label_89.Text = '3.05';
            app.Label_24 = uilabel(app.GridLayout204);
            app.Label_24.Text = '2';
            app.Label_35 = uilabel(app.GridLayout204);
            app.Label_35.Text = '7.30';
            app.Label_46 = uilabel(app.GridLayout204);
            app.Label_46.Text = '4.65';
            app.Label_57 = uilabel(app.GridLayout204);
            app.Label_57.Text = '6.47';
            app.Label_68 = uilabel(app.GridLayout204);
            app.Label_68.Text = '4.23';
            app.Label_79 = uilabel(app.GridLayout204);
            app.Label_79.Text = '6.69';
            app.Label_90 = uilabel(app.GridLayout204);
            app.Label_90.Text = '3.01';
            app.Label_25 = uilabel(app.GridLayout204);
            app.Label_25.Text = '3';
            app.Label_36 = uilabel(app.GridLayout204);
            app.Label_36.Text = '9.04';
            app.Label_47 = uilabel(app.GridLayout204);
            app.Label_47.Text = '4.55';
            app.Label_58 = uilabel(app.GridLayout204);
            app.Label_58.Text = '7.94';
            app.Label_69 = uilabel(app.GridLayout204);
            app.Label_69.Text = '4.18';
            app.Label_80 = uilabel(app.GridLayout204);
            app.Label_80.Text = '8.29';
            app.Label_91 = uilabel(app.GridLayout204);
            app.Label_91.Text = '2.94';
            app.Label_26 = uilabel(app.GridLayout204);
            app.Label_26.Text = '4';
            app.Label_37 = uilabel(app.GridLayout204);
            app.Label_37.Text = '11.62';
            app.Label_48 = uilabel(app.GridLayout204);
            app.Label_48.Text = '4.55';
            app.Label_59 = uilabel(app.GridLayout204);
            app.Label_59.Text = '10.70';
            app.Label_70 = uilabel(app.GridLayout204);
            app.Label_70.Text = '4.61';
            app.Label_81 = uilabel(app.GridLayout204);
            app.Label_81.Text = '10.65';
            app.Label_92 = uilabel(app.GridLayout204);
            app.Label_92.Text = '2.94';
            app.Label_27 = uilabel(app.GridLayout204);
            app.Label_27.Text = '5';
            app.Label_38 = uilabel(app.GridLayout204);
            app.Label_38.Text = '15.46';
            app.Label_49 = uilabel(app.GridLayout204);
            app.Label_49.Text = '4.73';
            app.Label_60 = uilabel(app.GridLayout204);
            app.Label_60.Text = '13.10';
            app.Label_71 = uilabel(app.GridLayout204);
            app.Label_71.Text = '4.13';
            app.Label_82 = uilabel(app.GridLayout204);
            app.Label_82.Text = '14.17';
            app.Label_93 = uilabel(app.GridLayout204);
            app.Label_93.Text = '3.06';
            app.Label_28 = uilabel(app.GridLayout204);
            app.Label_28.Text = '6';
            app.Label_39 = uilabel(app.GridLayout204);
            app.Label_39.Text = '18.91';
            app.Label_50 = uilabel(app.GridLayout204);
            app.Label_50.Text = '4.73';
            app.Label_61 = uilabel(app.GridLayout204);
            app.Label_61.Text = '16.10';
            app.Label_72 = uilabel(app.GridLayout204);
            app.Label_72.Text = '4.17';
            app.Label_83 = uilabel(app.GridLayout204);
            app.Label_83.Text = '17.34';
            app.Label_94 = uilabel(app.GridLayout204);
            app.Label_94.Text = '3.06';
            app.Label_29 = uilabel(app.GridLayout204);
            app.Label_29.Text = '7';
            app.Label_40 = uilabel(app.GridLayout204);
            app.Label_40.Text = '23.90';
            app.Label_51 = uilabel(app.GridLayout204);
            app.Label_51.Text = '4.78';
            app.Label_62 = uilabel(app.GridLayout204);
            app.Label_62.Text = '20.50';
            app.Label_73 = uilabel(app.GridLayout204);
            app.Label_73.Text = '4.40';
            app.Label_84 = uilabel(app.GridLayout204);
            app.Label_84.Text = '21.91';
            app.Label_95 = uilabel(app.GridLayout204);
            app.Label_95.Text = '3.09';
            app.Label_30 = uilabel(app.GridLayout204);
            app.Label_30.Text = '8';
            app.Label_41 = uilabel(app.GridLayout204);
            app.Label_41.Text = '25.44';
            app.Label_52 = uilabel(app.GridLayout204);
            app.Label_52.Text = '4.60';
            app.Label_63 = uilabel(app.GridLayout204);
            app.Label_63.Text = '22.10';
            app.Label_74 = uilabel(app.GridLayout204);
            app.Label_74.Text = '4.60';
            app.Label_85 = uilabel(app.GridLayout204);
            app.Label_85.Text = '23.32';
            app.Label_96 = uilabel(app.GridLayout204);
            app.Label_96.Text = '2.98';
            app.Label_31 = uilabel(app.GridLayout204);
            app.Label_31.Text = '9';
            app.Label_42 = uilabel(app.GridLayout204);
            app.Label_42.Text = '29.76';
            app.Label_53 = uilabel(app.GridLayout204);
            app.Label_53.Text = '4.68';
            app.Label_64 = uilabel(app.GridLayout204);
            app.Label_64.Text = '25.60';
            app.Label_75 = uilabel(app.GridLayout204);
            app.Label_75.Text = '4.41';
            app.Label_86 = uilabel(app.GridLayout204);
            app.Label_86.Text = '27.28';
            app.Label_97 = uilabel(app.GridLayout204);
            app.Label_97.Text = '3.03';
            app.Label_32 = uilabel(app.GridLayout204);
            app.Label_32.Text = '10';
            app.Label_43 = uilabel(app.GridLayout204);
            app.Label_43.Text = '35.23';
            app.Label_54 = uilabel(app.GridLayout204);
            app.Label_54.Text = '4.86';
            app.Label_65 = uilabel(app.GridLayout204);
            app.Label_65.Text = '29.00';
            app.Label_76 = uilabel(app.GridLayout204);
            app.Label_76.Text = '4.28';
            app.Label_87 = uilabel(app.GridLayout204);
            app.Label_87.Text = '32.30';
            app.Label_98 = uilabel(app.GridLayout204);
            app.Label_98.Text = '3.15';
            app.Label_33 = uilabel(app.GridLayout204);
            app.Label_33.Text = '11';
            app.Label_44 = uilabel(app.GridLayout204);
            app.Label_44.Text = '3.50';
            app.Label_55 = uilabel(app.GridLayout204);
            app.Label_55.Text = '4.63';
            app.Label_66 = uilabel(app.GridLayout204);
            app.Label_66.Text = '3.14';
            app.Label_77 = uilabel(app.GridLayout204);
            app.Label_77.Text = '4.35';
            app.Label_88 = uilabel(app.GridLayout204);
            app.Label_88.Text = '3.21';
            app.Label_99 = uilabel(app.GridLayout204);
            app.Label_99.Text = '3.00';
            app.GridLayout36 = uigridlayout(app.lay_17);
            app.Etiqueta_PestanyaDemanda = uilabel(app.GridLayout36);
            app.Etiqueta_PestanyaDemanda.Text = '   Dades d''entrada de la instal·lació geotèrmica';
            app.Image53 = uiimage(app.GridLayout36);
            app.Image53.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.GridLayout198 = uigridlayout(app.lay_17);
            app.ManualGeoSIVv12Button = uibutton(app.GridLayout198, 'push');
            app.ManualGeoSIVv12Button.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_Manual, true);
            app.ManualGeoSIVv12Button.Text = 'Manual Geo-SIV v1.2';
            app.back_2_workf = uibutton(app.GridLayout198, 'push');
            app.back_2_workf.ButtonPushedFcn = createCallbackFcn(app, @GoTo_Metodologia, true);
            app.back_2_workf.Text = 'Enrere';
            app.reset = uibutton(app.GridLayout198, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @Write_ValorsDefecteDadesEntrada, true);
            app.reset.Text = 'Valors per defecte';
            app.RunSizing = uibutton(app.GridLayout198, 'push');
            app.RunSizing.ButtonPushedFcn = createCallbackFcn(app, @RUN_DimensionamentGeotermia, true);
            app.RunSizing.Text = 'Executar';
            app.ResultatsDissenyTab = uitab(app.TabGroup);
            app.ResultatsDissenyTab.Title = 'Resultats disseny';
            app.lay_36 = uigridlayout(app.ResultatsDissenyTab);
            app.lay_37 = uigridlayout(app.lay_36);
            app.Etiqueta_PestanyaDisseny = uilabel(app.lay_37);
            app.Etiqueta_PestanyaDisseny.Text = '   Resultats dimensionament de la instal·lació geotèrmica';
            app.Image54 = uiimage(app.lay_37);
            app.Image54.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.lay_38 = uigridlayout(app.lay_36);
            app.GridLayout13 = uigridlayout(app.lay_38);
            app.GridLayout93 = uigridlayout(app.GridLayout13);
            app.GridLayout94 = uigridlayout(app.GridLayout93);
            app.label_show_result = uilabel(app.GridLayout94);
            app.label_show_result.Text = '';
            app.label_show_result2 = uilabel(app.GridLayout94);
            app.label_show_result2.Text = '';
            app.recomendation_label = uilabel(app.GridLayout93);
            app.recomendation_label.Text = 'Observacions:';
            app.GridLayout95 = uigridlayout(app.GridLayout13);
            app.Panel_8 = uipanel(app.GridLayout95);
            app.plot_results = uiaxes(app.Panel_8);
            title(app.plot_results, '')
            xlabel(app.plot_results, 'Temperatura exterior [ºC]')
            ylabel(app.plot_results, 'Potència tèrmica [kW]')
            app.plot_results.XLim = [-5 37];
            app.plot_results.XTick = [-5 -1 3 7 11 15 19 23 27 31 35];
            app.plot_results.XTickLabel = {'-5'; '-1'; '3'; '7'; '11'; '15'; '19'; '23'; '27'; '31'; '35'};
            app.Panel_7 = uipanel(app.GridLayout95);
            app.MonthDemandPlot = uiaxes(app.Panel_7);
            title(app.MonthDemandPlot, '')
            xlabel(app.MonthDemandPlot, '      ')
            ylabel(app.MonthDemandPlot, 'Energia tèrmica [MWh]')
            app.MonthDemandPlot.XLim = [0 13];
            app.MonthDemandPlot.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.MonthDemandPlot.XTickLabel = {'Gen'; 'Feb'; 'Mar'; 'Abr'; 'Mai'; 'Jun'; 'Jul'; 'Ago'; 'Set'; 'Oct'; 'Nov'; 'Des'};
            app.GridLayout263 = uigridlayout(app.GridLayout95);
            app.ChooseCarCap_DemPro_Plot = uibuttongroup(app.GridLayout263);
            app.ChooseCarCap_DemPro_Plot.SelectionChangedFcn = createCallbackFcn(app, @Choose_CarCap_o_DemPro_Plot, true);
            app.show_thermal_r = uiradiobutton(app.ChooseCarCap_DemPro_Plot);
            app.show_thermal_r.Text = 'Càrrega / Potència';
            app.show_thermal_r.Value = true;
            app.show_e_r = uiradiobutton(app.ChooseCarCap_DemPro_Plot);
            app.show_e_r.Text = 'Demanda / Producció climatització';
            app.GridLayout264 = uigridlayout(app.GridLayout95);
            app.ChooseEner_t_e_Plot = uibuttongroup(app.GridLayout264);
            app.ChooseEner_t_e_Plot.SelectionChangedFcn = createCallbackFcn(app, @Choose_Ener_t_o_e_Plot, true);
            app.ConsumEnergia = uiradiobutton(app.ChooseEner_t_e_Plot);
            app.ConsumEnergia.Text = 'Producció energia tèrmica';
            app.ConsumEnergia.Value = true;
            app.ConsumElectricitat = uiradiobutton(app.ChooseEner_t_e_Plot);
            app.ConsumElectricitat.Text = 'Consum electricitat';
            app.GridLayout104 = uigridlayout(app.GridLayout13);
            app.Panel_Instalacio = uipanel(app.GridLayout104);
            app.Panel_Instalacio.Title = '10. Característiques i comportament de la instal·lació';
            app.GridLayout107 = uigridlayout(app.Panel_Instalacio);
            app.PotnciaEnergiatrmicaPanel = uipanel(app.GridLayout107);
            app.PotnciaEnergiatrmicaPanel.Title = '10.1. Potència / Energia tèrmica          ';
            app.GridLayout108 = uigridlayout(app.PotnciaEnergiatrmicaPanel);
            app.CrregadedissenykWtLabel = uilabel(app.GridLayout108);
            app.CrregadedissenykWtLabel.Text = 'Càrrega de disseny [kWt]';
            app.PotDisCalfkW = uieditfield(app.GridLayout108, 'numeric');
            app.PotDisCalfkW.Limits = [0 Inf];
            app.PotDisCalfkW.ValueDisplayFormat = '%.2f';
            app.PotDisCalfkW.Editable = 'off';
            app.PotDisCalfkW.Tooltip = {'Potència tèrmica requerida en mode calefacció per cobrir la càrrega de l''edifici  quan la temperatua ambient és igual a la temperatura de disseny d''hivern.'};
            app.CALORLabel = uilabel(app.GridLayout108);
            app.CALORLabel.Text = 'CALOR';
            app.ACSLabel = uilabel(app.GridLayout108);
            app.ACSLabel.Text = 'ACS';
            app.PotDis_ACSkW = uieditfield(app.GridLayout108, 'numeric');
            app.PotDis_ACSkW.Limits = [0 Inf];
            app.PotDis_ACSkW.ValueDisplayFormat = '%.2f';
            app.PotDis_ACSkW.Editable = 'off';
            app.PotDis_ACSkW.Tooltip = {'Potència tèrmica requerida per a la producció d''ACS a un ritme tal que la demanda diària es pugui cobrir en un màxim de 8 h (S''assumeix un tanc d''acumulació).'};
            app.PotDisRefrkW = uieditfield(app.GridLayout108, 'numeric');
            app.PotDisRefrkW.Limits = [0 Inf];
            app.PotDisRefrkW.ValueDisplayFormat = '%.2f';
            app.PotDisRefrkW.Editable = 'off';
            app.PotDisRefrkW.Tooltip = {'Potència tèrmica requerida en mode refrigeració per cobrir la càrrega de l''edifici  quan la temperatua ambient és igual a la temperatura de disseny d''estiu.'};
            app.PotInsRefrkW = uieditfield(app.GridLayout108, 'numeric');
            app.PotInsRefrkW.Limits = [0 Inf];
            app.PotInsRefrkW.ValueDisplayFormat = '%.2f';
            app.PotInsRefrkW.Editable = 'off';
            app.ProdRefrMWht = uieditfield(app.GridLayout108, 'numeric');
            app.ProdRefrMWht.Limits = [0 Inf];
            app.ProdRefrMWht.ValueDisplayFormat = '%.2f';
            app.ProdRefrMWht.Editable = 'off';
            app.ProdRefrMWht.Tooltip = {'Extracció anual d''energia tèrmica (mode refrigeració).'};
            app.PotInsCalfkW = uieditfield(app.GridLayout108, 'numeric');
            app.PotInsCalfkW.Limits = [0 Inf];
            app.PotInsCalfkW.ValueDisplayFormat = '%.2f';
            app.PotInsCalfkW.Editable = 'off';
            app.PotnciainstalladakWtLabel = uilabel(app.GridLayout108);
            app.PotnciainstalladakWtLabel.Text = 'Potència instal·lada [kWt]';
            app.PotInsACSkW = uieditfield(app.GridLayout108, 'numeric');
            app.PotInsACSkW.ValueDisplayFormat = '%.2f';
            app.PotInsACSkW.Editable = 'off';
            app.Prod_ACSMWht = uieditfield(app.GridLayout108, 'numeric');
            app.Prod_ACSMWht.Limits = [0 Inf];
            app.Prod_ACSMWht.ValueDisplayFormat = '%.2f';
            app.Prod_ACSMWht.Editable = 'off';
            app.Prod_ACSMWht.Tooltip = {'Aportació anual d''energia tèrmica per a la producció d''ACS.'};
            app.ProduccienergiaMWhtLabel = uilabel(app.GridLayout108);
            app.ProduccienergiaMWhtLabel.Text = 'Producció energia [MWht]';
            app.ProdCalfMWht = uieditfield(app.GridLayout108, 'numeric');
            app.ProdCalfMWht.Limits = [0 Inf];
            app.ProdCalfMWht.ValueDisplayFormat = '%.2f';
            app.ProdCalfMWht.Editable = 'off';
            app.ProdCalfMWht.Tooltip = {'Aportació anual d''energia tèrmica (mode calefacció).'};
            app.SPFcSPFrSPFacsWhtWheLabel = uilabel(app.GridLayout108);
            app.SPFcSPFrSPFacsWhtWheLabel.Text = 'SPFc/ SPFr / SPFacs [Wht/Whe]';
            app.SPF_Calf = uieditfield(app.GridLayout108, 'numeric');
            app.SPF_Calf.ValueDisplayFormat = '%.2f';
            app.SPF_Calf.Editable = 'off';
            app.SPF_Calf.Tooltip = {'Factor de rendiment estacional (SPF en anglès: seasonal performance factor), en mode calefacció'};
            app.SPF_Refr = uieditfield(app.GridLayout108, 'numeric');
            app.SPF_Refr.ValueDisplayFormat = '%.2f';
            app.SPF_Refr.Editable = 'off';
            app.SPF_Refr.Tooltip = {'Factor de rendiment estacional (SPF en anglès: seasonal performance factor), en mode refrigeració'};
            app.SPF_ACS = uieditfield(app.GridLayout108, 'numeric');
            app.SPF_ACS.ValueDisplayFormat = '%.2f';
            app.SPF_ACS.Editable = 'off';
            app.SPF_ACS.Tooltip = {'Factor de rendiment estacional (SPF en anglès: seasonal performance factor), en mode producció d''ACS'};
            app.FREDLabel = uilabel(app.GridLayout108);
            app.FREDLabel.Text = 'FRED';
            app.PotnciaEnergiaelctricaPanel = uipanel(app.GridLayout107);
            app.PotnciaEnergiaelctricaPanel.Title = '10.2. Potència / Energia elèctrica';
            app.GridLayout109 = uigridlayout(app.PotnciaEnergiaelctricaPanel);
            app.GridLayout252 = uigridlayout(app.GridLayout109);
            app.ConsumelctricMWheEditFieldLabel = uilabel(app.GridLayout252);
            app.ConsumelctricMWheEditFieldLabel.Text = 'Consum elèctric [MWhe]';
            app.Cons_eCalfMWhe = uieditfield(app.GridLayout252, 'numeric');
            app.Cons_eCalfMWhe.Limits = [0 Inf];
            app.Cons_eCalfMWhe.ValueDisplayFormat = '%.2f';
            app.Cons_eCalfMWhe.Editable = 'off';
            app.Cons_eRefrMWhe = uieditfield(app.GridLayout252, 'numeric');
            app.Cons_eRefrMWhe.Limits = [0 Inf];
            app.Cons_eRefrMWhe.ValueDisplayFormat = '%.2f';
            app.Cons_eRefrMWhe.Editable = 'off';
            app.Cons_e_ACSMWhe = uieditfield(app.GridLayout252, 'numeric');
            app.Cons_e_ACSMWhe.Limits = [0 Inf];
            app.Cons_e_ACSMWhe.ValueDisplayFormat = '%.2f';
            app.Cons_e_ACSMWhe.Editable = 'off';
            app.GridLayout253 = uigridlayout(app.GridLayout109);
            app.PotnciaacontractarkWeLabel = uilabel(app.GridLayout253);
            app.PotnciaacontractarkWeLabel.Text = 'Potència a contractar [kWe]';
            app.PotElecContrat = uieditfield(app.GridLayout253, 'numeric');
            app.PotElecContrat.Limits = [0 Inf];
            app.PotElecContrat.ValueDisplayFormat = '%.2f';
            app.PotElecContrat.Editable = 'off';
            app.GridLayout254 = uigridlayout(app.GridLayout109);
            app.ConsumelctrictotalMWheLabel = uilabel(app.GridLayout254);
            app.ConsumelctrictotalMWheLabel.Text = 'Consum elèctric total [MWhe]';
            app.ConElecMWhe = uieditfield(app.GridLayout254, 'numeric');
            app.ConElecMWhe.Limits = [0 Inf];
            app.ConElecMWhe.ValueDisplayFormat = '%.2f';
            app.ConElecMWhe.Editable = 'off';
            app.GridLayout110 = uigridlayout(app.GridLayout104);
            app.CampdecaptaciPanel = uipanel(app.GridLayout110);
            app.CampdecaptaciPanel.Title = '7. Camp de captació';
            app.GridLayout273 = uigridlayout(app.CampdecaptaciPanel);
            app.CaracterstiquesprincipalsPanel = uipanel(app.GridLayout273);
            app.CaracterstiquesprincipalsPanel.Title = '7.1. Característiques principals';
            app.GridLayout274 = uigridlayout(app.CaracterstiquesprincipalsPanel);
            app.NombredepousgeotrmicsEditFieldLabel = uilabel(app.GridLayout274);
            app.NombredepousgeotrmicsEditFieldLabel.Text = 'Nombre de pous geotèrmics';
            app.n_well = uieditfield(app.GridLayout274, 'numeric');
            app.n_well.Limits = [0 Inf];
            app.n_well.ValueDisplayFormat = '%.0f';
            app.n_well.Editable = 'off';
            app.LongitudmitjanadelspousmLabel = uilabel(app.GridLayout274);
            app.LongitudmitjanadelspousmLabel.Text = 'Longitud mitjana dels pous [m]';
            app.l_well = uieditfield(app.GridLayout274, 'numeric');
            app.l_well.Limits = [0 Inf];
            app.l_well.ValueDisplayFormat = '%.0f';
            app.l_well.Editable = 'off';
            app.Superfcieocupadapelcampdecaptacim2EditFieldLabel = uilabel(app.GridLayout274);
            app.Superfcieocupadapelcampdecaptacim2EditFieldLabel.Text = 'Superfície ocupada pel camp de captació [m2]';
            app.m2_well = uieditfield(app.GridLayout274, 'numeric');
            app.m2_well.Limits = [0 Inf];
            app.m2_well.ValueDisplayFormat = '%.0f';
            app.m2_well.Editable = 'off';
            app.m2_well.Tooltip = {'Superfície aproximada considerant el nombre de pous i la distància entre ells especificada per l''usuari'};
            app.T_subsolinicialCLabel = uilabel(app.GridLayout274);
            app.T_subsolinicialCLabel.Text = 'T_subsol (inicial) [ºC]';
            app.T_ground0 = uieditfield(app.GridLayout274, 'numeric');
            app.T_ground0.ValueDisplayFormat = '%.1f';
            app.T_ground0.Editable = 'off';
            app.T_ground0.Tooltip = {'Temperatura del subsol a una profunditat a on les oscil·lacions ocasionadas per les condicions climàtiques esdevenen negligibles'};
            app.ComportamenttrmicPanel = uipanel(app.GridLayout273);
            app.ComportamenttrmicPanel.Title = '7.2. Comportament tèrmic';
            app.GridLayout275 = uigridlayout(app.ComportamenttrmicPanel);
            app.BalanenergticanualenelsubsolMWhtLabel = uilabel(app.GridLayout275);
            app.BalanenergticanualenelsubsolMWhtLabel.Text = 'Balanç energètic anual en el subsol [MWht]';
            app.BalancSubsol = uieditfield(app.GridLayout275, 'numeric');
            app.BalancSubsol.ValueDisplayFormat = '%.2f';
            app.BalancSubsol.Editable = 'off';
            app.BalancSubsol.Tooltip = {'Signe (-): extracció neta de calor des del subsol.'; 'Signe (+): aportació neta de calor cap al subsol.'};
            app.EstimacivariacilocalT_subsolallargterminiCLabel = uilabel(app.GridLayout275);
            app.EstimacivariacilocalT_subsolallargterminiCLabel.Text = 'Estimació variació local T_subsol a llarg termini [ºC]';
            app.Estimacio_Var_T = uieditfield(app.GridLayout275, 'text');
            app.Estimacio_Var_T.Editable = 'off';
            app.Estimacio_Var_T.Tooltip = {'Estimació de la variació màxima del valor de temperatura no pertorbada del terreny degut al desequilibri anual d''energia tèrmica bescanviada entre  l''edifici i el terreny'};
            app.Estimacio_Var_T.Value = '0.0';
            app.PerodedestabilitzacitrmicadelterrenyanysLabel = uilabel(app.GridLayout275);
            app.PerodedestabilitzacitrmicadelterrenyanysLabel.Text = 'Període d''estabilització tèrmica del terreny [anys]';
            app.t_estabilitzacio = uieditfield(app.GridLayout275, 'text');
            app.t_estabilitzacio.Editable = 'off';
            app.t_estabilitzacio.Tooltip = {'Estimació del nombre de cicles complerts hivern-estiu (anys) fins a assolir un valor estable (variacions <1%) de la temperatura no pertorbada del terreny al camp de captació.'};
            app.t_estabilitzacio.Value = '0';
            app.GridLayout272 = uigridlayout(app.GridLayout104);
            app.Panels_BCGValorsNom = uipanel(app.GridLayout272);
            app.Panels_BCGValorsNom.Title = '8. Bomba de calor. Ref. # (valors nominals)';
            app.GridLayout106 = uigridlayout(app.Panels_BCGValorsNom);
            app.PotnciatrmicakWtLabel = uilabel(app.GridLayout106);
            app.PotnciatrmicakWtLabel.Text = 'Potència tèrmica [kWt]';
            app.hp_heat = uieditfield(app.GridLayout106, 'numeric');
            app.hp_heat.ValueDisplayFormat = '%.2f';
            app.hp_heat.Editable = 'off';
            app.hp_heat.Tooltip = {'Valor nominal de la potència tèrmica en mode calefacció.'};
            app.COPEERCOPacsWtWeLabel = uilabel(app.GridLayout106);
            app.COPEERCOPacsWtWeLabel.Text = 'COP / EER / COPacs [Wt/We]';
            app.COP_hp_heat = uieditfield(app.GridLayout106, 'numeric');
            app.COP_hp_heat.ValueDisplayFormat = '%.2f';
            app.COP_hp_heat.Editable = 'off';
            app.COP_hp_heat.Tooltip = {'Factor de rendiment (COP en anglès: coefficient of performance) en mode calefacció.'};
            app.hp_cool = uieditfield(app.GridLayout106, 'numeric');
            app.hp_cool.ValueDisplayFormat = '%.2f';
            app.hp_cool.Editable = 'off';
            app.hp_cool.Tooltip = {'Valor nominal de la potència tèrmica en mode refrigeració.'};
            app.EER_hp_cool = uieditfield(app.GridLayout106, 'numeric');
            app.EER_hp_cool.ValueDisplayFormat = '%.2f';
            app.EER_hp_cool.Editable = 'off';
            app.EER_hp_cool.Tooltip = {'Factor de rendiment (EER en anglès: energy efficiency ratio) en mode refrigeració.'};
            app.hp_ACS = uieditfield(app.GridLayout106, 'numeric');
            app.hp_ACS.ValueDisplayFormat = '%.2f';
            app.hp_ACS.Editable = 'off';
            app.hp_ACS.Tooltip = {'Valor nominal de la potència tèrmica en mode producció d''ACS.'};
            app.COP_hp_ACS = uieditfield(app.GridLayout106, 'numeric');
            app.COP_hp_ACS.ValueDisplayFormat = '%.2f';
            app.COP_hp_ACS.Editable = 'off';
            app.COP_hp_ACS.Tooltip = {'COP en mode producció d''ACS.'};
            app.CALORLabel_4 = uilabel(app.GridLayout106);
            app.CALORLabel_4.Text = 'CALOR';
            app.FREDLabel_4 = uilabel(app.GridLayout106);
            app.FREDLabel_4.Text = 'FRED';
            app.ACSLabel_4 = uilabel(app.GridLayout106);
            app.ACSLabel_4.Text = 'ACS';
            app.EWT_ACS = uieditfield(app.GridLayout106, 'numeric');
            app.EWT_ACS.ValueDisplayFormat = '%.1f';
            app.EWT_ACS.Editable = 'off';
            app.EWT_ACS.Tooltip = {'Temperatura d''entrada del líquid caloportador a la BCG associada al valor nominal de potència de producció d''ACS (evaporador a costat pous).'};
            app.unitats_BCG = uilabel(app.GridLayout106);
            app.unitats_BCG.Text = 'Nombre d''unitats BCG necessàries:';
            app.TemperaturasortidacondCLabel = uilabel(app.GridLayout106);
            app.TemperaturasortidacondCLabel.Text = 'Temperatura sortida (cond.) [ºC]';
            app.LWT_Calf = uieditfield(app.GridLayout106, 'numeric');
            app.LWT_Calf.ValueDisplayFormat = '%.1f';
            app.LWT_Calf.Editable = 'off';
            app.LWT_Calf.Tooltip = {'Temperatura del líquid caloportador a la sortida de la BCG associada al valor nominal de potència de calefacció (condensador a costat edifici).'};
            app.EWT_Calf = uieditfield(app.GridLayout106, 'numeric');
            app.EWT_Calf.ValueDisplayFormat = '%.1f';
            app.EWT_Calf.Editable = 'off';
            app.EWT_Calf.Tooltip = {'Temperatura d''entrada del líquid caloportador a la BCG associada al valor nominal de Potència de calefacció (evaporador a costat pous).'};
            app.EWT_Refr = uieditfield(app.GridLayout106, 'numeric');
            app.EWT_Refr.ValueDisplayFormat = '%.1f';
            app.EWT_Refr.Editable = 'off';
            app.EWT_Refr.Tooltip = {'Temperatura d''entrada del líquid caloportador a la BCG associada al valor nominal de potència de refrigeració  (evaporador a costat edifici).'};
            app.T_inBCG_label = uilabel(app.GridLayout106);
            app.T_inBCG_label.Text = 'Temperatura entrada (evap.) [ºC]';
            app.LWT_Refr = uieditfield(app.GridLayout106, 'numeric');
            app.LWT_Refr.ValueDisplayFormat = '%.1f';
            app.LWT_Refr.Editable = 'off';
            app.LWT_Refr.Tooltip = {'Temperatura del líquid caloportador a la sortida de la BCG associada al valor nominal de potència de refrigeració  (condensador a costat pous).'};
            app.LWT_ACS = uieditfield(app.GridLayout106, 'numeric');
            app.LWT_ACS.ValueDisplayFormat = '%.1f';
            app.LWT_ACS.Editable = 'off';
            app.LWT_ACS.Tooltip = {'Temperatura del líquid caloportador a la sortida de la BCG associada al valor nominal de potència de producció ACS (condensador a costat edifici).'};
            app.CondicionstrmiquesdepartidaPanel = uipanel(app.GridLayout272);
            app.CondicionstrmiquesdepartidaPanel.Title = '9. Condicions tèrmiques de partida';
            app.GridLayout111 = uigridlayout(app.CondicionstrmiquesdepartidaPanel);
            app.HIVERNLabel = uilabel(app.GridLayout111);
            app.HIVERNLabel.Text = 'HIVERN';
            app.ESTIULabel = uilabel(app.GridLayout111);
            app.ESTIULabel.Text = 'ESTIU';
            app.TemperaturaambientdedissenyCLabel = uilabel(app.GridLayout111);
            app.TemperaturaambientdedissenyCLabel.Text = 'Temperatura ambient de disseny [ºC]';
            app.T_dis_w = uieditfield(app.GridLayout111, 'numeric');
            app.T_dis_w.ValueDisplayFormat = '%.1f';
            app.T_dis_w.Editable = 'off';
            app.T_dis_w.Tooltip = {'Valor de temperatura ambient considerada per dimensionar la BCG en mode calefacció'};
            app.T_dis_s = uieditfield(app.GridLayout111, 'numeric');
            app.T_dis_s.ValueDisplayFormat = '%.1f';
            app.T_dis_s.Editable = 'off';
            app.T_dis_s.Tooltip = {'Valor de temperatura ambient considerada per dimensionar la BCG en mode refrigeració'};
            app.dheat_show = uieditfield(app.GridLayout111, 'numeric');
            app.dheat_show.Limits = [0 Inf];
            app.dheat_show.Editable = 'off';
            app.dheat_show.Tooltip = {'Total dies en mode calefacció'};
            app.DuraciperodeestacionaldiesLabel = uilabel(app.GridLayout111);
            app.DuraciperodeestacionaldiesLabel.Text = 'Duració període estacional [dies]';
            app.dcool_show = uieditfield(app.GridLayout111, 'numeric');
            app.dcool_show.Limits = [0 Inf];
            app.dcool_show.Editable = 'off';
            app.dcool_show.Tooltip = {'Total dies en mode refrigeració'};
            app.LabelClimaRef = uilabel(app.GridLayout111);
            app.LabelClimaRef.Tooltip = {'Denominació d''acord amb els climes de referència especificats al CTE (IDAE)'};
            app.LabelClimaRef.Text = 'Clima de referència aplicat: ';
            app.TemperaturaliquidapousmnmxCLabel = uilabel(app.GridLayout111);
            app.TemperaturaliquidapousmnmxCLabel.Text = 'Temperatura liquid a pous (mín. / màx.) [ºC]';
            app.t_ent_min_w = uieditfield(app.GridLayout111, 'numeric');
            app.t_ent_min_w.ValueDisplayFormat = '%.1f';
            app.t_ent_min_w.Editable = 'off';
            app.t_ent_min_w.Tooltip = {'Temperatura mitjana de disseny d''hivern del liquid caloportador a pous (T_in+T_out)/2. '};
            app.t_ent_max_w = uieditfield(app.GridLayout111, 'numeric');
            app.t_ent_max_w.ValueDisplayFormat = '%.1f';
            app.t_ent_max_w.Editable = 'off';
            app.t_ent_max_w.Tooltip = {'Temperatura mitjana de disseny d''estiu del liquid caloportador a pous (T_in+T_out)/2 '};
            app.GridLayout200 = uigridlayout(app.lay_36);
            app.back_2_demand = uibutton(app.GridLayout200, 'push');
            app.back_2_demand.ButtonPushedFcn = createCallbackFcn(app, @GoTo_DadesEntrada, true);
            app.back_2_demand.Text = 'Enrere';
            app.ManualGeoSIVv12Button_2 = uibutton(app.GridLayout200, 'push');
            app.ManualGeoSIVv12Button_2.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_Manual, true);
            app.ManualGeoSIVv12Button_2.Text = 'Manual Geo-SIV v1.2';
            app.Informe_disseny = uibutton(app.GridLayout200, 'push');
            app.Informe_disseny.ButtonPushedFcn = createCallbackFcn(app, @Generate_Report, true);
            app.Informe_disseny.Enable = 'off';
            app.Informe_disseny.Text = 'Generar informe';
            app.Panel_11 = uipanel(app.GridLayout200);
            app.PotElecAlthkWeLabel = uilabel(app.Panel_11);
            app.PotElecAlthkWeLabel.Enable = 'off';
            app.PotElecAlthkWeLabel.Visible = 'off';
            app.PotElecAlthkWeLabel.Text = 'PotElecAlth(kWe)';
            app.PotElecAlthkW = uieditfield(app.Panel_11, 'numeric');
            app.PotElecAlthkW.Limits = [0 Inf];
            app.PotElecAlthkW.ValueDisplayFormat = '%.1f';
            app.PotElecAlthkW.Editable = 'off';
            app.PotElecAlthkW.Enable = 'off';
            app.PotElecAlthkW.Visible = 'off';
            app.ConElecAltcMWheLabel = uilabel(app.Panel_11);
            app.ConElecAltcMWheLabel.Enable = 'off';
            app.ConElecAltcMWheLabel.Visible = 'off';
            app.ConElecAltcMWheLabel.Text = 'ConElecAltc (MWhe)';
            app.ConElecAltcMWhe = uieditfield(app.Panel_11, 'numeric');
            app.ConElecAltcMWhe.Limits = [0 Inf];
            app.ConElecAltcMWhe.ValueDisplayFormat = '%.1f';
            app.ConElecAltcMWhe.Editable = 'off';
            app.ConElecAltcMWhe.Enable = 'off';
            app.ConElecAltcMWhe.Visible = 'off';
            app.PotElecAltckWeLabel = uilabel(app.Panel_11);
            app.PotElecAltckWeLabel.Enable = 'off';
            app.PotElecAltckWeLabel.Visible = 'off';
            app.PotElecAltckWeLabel.Text = 'PotElecAltc (kWe)';
            app.PotElecAltckW = uieditfield(app.Panel_11, 'numeric');
            app.PotElecAltckW.Limits = [0 Inf];
            app.PotElecAltckW.ValueDisplayFormat = '%.1f';
            app.PotElecAltckW.Editable = 'off';
            app.PotElecAltckW.Enable = 'off';
            app.PotElecAltckW.Visible = 'off';
            app.ConElecAlthMWheEditFieldLabel = uilabel(app.Panel_11);
            app.ConElecAlthMWheEditFieldLabel.Enable = 'off';
            app.ConElecAlthMWheEditFieldLabel.Visible = 'off';
            app.ConElecAlthMWheEditFieldLabel.Text = 'ConElecAlth (MWhe)';
            app.ConElecAlthMWhe = uieditfield(app.Panel_11, 'numeric');
            app.ConElecAlthMWhe.Limits = [0 Inf];
            app.ConElecAlthMWhe.ValueDisplayFormat = '%.1f';
            app.ConElecAlthMWhe.Editable = 'off';
            app.ConElecAlthMWhe.Enable = 'off';
            app.ConElecAlthMWhe.Visible = 'off';
            app.ClimaRefLabel = uilabel(app.Panel_11);
            app.ClimaRefLabel.Enable = 'off';
            app.ClimaRefLabel.Visible = 'off';
            app.ClimaRefLabel.Text = 'ClimaRef';
            app.ClimaRef = uieditfield(app.Panel_11, 'numeric');
            app.ClimaRef.Editable = 'off';
            app.ClimaRef.Enable = 'off';
            app.ClimaRef.Visible = 'off';
            app.PotElecAltACSkWeLabel = uilabel(app.Panel_11);
            app.PotElecAltACSkWeLabel.Enable = 'off';
            app.PotElecAltACSkWeLabel.Visible = 'off';
            app.PotElecAltACSkWeLabel.Text = 'PotElecAltACS (kWe)';
            app.PotElecAltACSkW = uieditfield(app.Panel_11, 'numeric');
            app.PotElecAltACSkW.Limits = [0 Inf];
            app.PotElecAltACSkW.ValueDisplayFormat = '%.1f';
            app.PotElecAltACSkW.Editable = 'off';
            app.PotElecAltACSkW.Enable = 'off';
            app.PotElecAltACSkW.Visible = 'off';
            app.ConElecAltACSMWheLabel = uilabel(app.Panel_11);
            app.ConElecAltACSMWheLabel.Enable = 'off';
            app.ConElecAltACSMWheLabel.Visible = 'off';
            app.ConElecAltACSMWheLabel.Text = 'ConElecAltACS (MWhe)';
            app.ConElecAltACSMWhe = uieditfield(app.Panel_11, 'numeric');
            app.ConElecAltACSMWhe.Limits = [0 Inf];
            app.ConElecAltACSMWhe.ValueDisplayFormat = '%.1f';
            app.ConElecAltACSMWhe.Editable = 'off';
            app.ConElecAltACSMWhe.Enable = 'off';
            app.ConElecAltACSMWhe.Visible = 'off';
            app.reaColectorsSTLabel = uilabel(app.Panel_11);
            app.reaColectorsSTLabel.Enable = 'off';
            app.reaColectorsSTLabel.Visible = 'off';
            app.reaColectorsSTLabel.Text = 'Área Colectors ST';
            app.AreaMinST = uieditfield(app.Panel_11, 'numeric');
            app.AreaMinST.Editable = 'off';
            app.AreaMinST.Enable = 'off';
            app.AreaMinST.Visible = 'off';
            app.RendibilitatprojecteButton = uibutton(app.GridLayout200, 'push');
            app.RendibilitatprojecteButton.ButtonPushedFcn = createCallbackFcn(app, @GoTo_EcoAmbTab, true);
            app.RendibilitatprojecteButton.Text = 'Rendibilitat projecte';
            app.AnalisiEcoAmbTab = uitab(app.TabGroup);
            app.AnalisiEcoAmbTab.Title = 'Anàlisi econòmica i ambiental';
            app.GridLayout22 = uigridlayout(app.AnalisiEcoAmbTab);
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.DadesEntradaComparacio = uipanel(app.GridLayout25);
            app.DadesEntradaComparacio.Title = '2. Dades d''entrada per a l''estudi comparatiu (valors monetaris amb I.V.A. inclòs)';
            app.GridLayout37 = uigridlayout(app.DadesEntradaComparacio);
            app.GEOTRMIALabel = uilabel(app.GridLayout37);
            app.GEOTRMIALabel.Text = ' GEOTÈRMIA';
            app.ALTERNATIVALabel = uilabel(app.GridLayout37);
            app.ALTERNATIVALabel.Text = 'ALTERNATIVA';
            app.OpcionsAlternativa_h = uidropdown(app.GridLayout37);
            app.OpcionsAlternativa_h.Items = {'Biomassa', 'Gas Natural', 'Gas Propà', 'Gas Propà Canalitzat', 'Gasoil', 'Electricitat', 'Aerotèrmia'};
            app.OpcionsAlternativa_h.ValueChangedFcn = createCallbackFcn(app, @Choose_Alternativa_h, true);
            app.OpcionsAlternativa_h.Enable = 'off';
            app.OpcionsAlternativa_h.Value = 'Biomassa';
            app.OpcionsAlternativa_c = uidropdown(app.GridLayout37);
            app.OpcionsAlternativa_c.Items = {'Aire Condicionat', 'Aerotèrmia'};
            app.OpcionsAlternativa_c.ValueChangedFcn = createCallbackFcn(app, @Choose_Alternativa_c, true);
            app.OpcionsAlternativa_c.Enable = 'off';
            app.OpcionsAlternativa_c.Value = 'Aire Condicionat';
            app.OpcionsAlternativa_ACS = uidropdown(app.GridLayout37);
            app.OpcionsAlternativa_ACS.Items = {'Biomassa', 'Aerotèrmia', 'Col·lectors Solars Tèrmics'};
            app.OpcionsAlternativa_ACS.ValueChangedFcn = createCallbackFcn(app, @Choose_Alternativa_ACS, true);
            app.OpcionsAlternativa_ACS.Enable = 'off';
            app.OpcionsAlternativa_ACS.Value = 'Biomassa';
            app.CALORLabel_2 = uilabel(app.GridLayout37);
            app.CALORLabel_2.Text = 'CALOR';
            app.FREDLabel_2 = uilabel(app.GridLayout37);
            app.FREDLabel_2.Text = 'FRED';
            app.ACSLabel_2 = uilabel(app.GridLayout37);
            app.ACSLabel_2.Text = 'ACS';
            app.InstallacisubvencionadaLabel = uilabel(app.GridLayout37);
            app.InstallacisubvencionadaLabel.Text = 'Instal·lació subvencionada';
            app.GridLayout121 = uigridlayout(app.GridLayout37);
            app.AltSubv = uicheckbox(app.GridLayout121);
            app.AltSubv.ValueChangedFcn = createCallbackFcn(app, @Select_AltSubv, true);
            app.AltSubv.Enable = 'off';
            app.AltSubv.Text = '';
            app.GridLayout122 = uigridlayout(app.GridLayout37);
            app.GeoSubv = uicheckbox(app.GridLayout122);
            app.GeoSubv.ValueChangedFcn = createCallbackFcn(app, @Select_GeoSubv, true);
            app.GeoSubv.Enable = 'off';
            app.GeoSubv.Text = '';
            app.GridLayout123 = uigridlayout(app.GridLayout37);
            app.GeoPres = uicheckbox(app.GridLayout123);
            app.GeoPres.ValueChangedFcn = createCallbackFcn(app, @Select_GeoPres, true);
            app.GeoPres.Enable = 'off';
            app.GeoPres.Text = '';
            app.GridLayout124 = uigridlayout(app.GridLayout37);
            app.AltPres = uicheckbox(app.GridLayout124);
            app.AltPres.ValueChangedFcn = createCallbackFcn(app, @Select_AltPres, true);
            app.AltPres.Enable = 'off';
            app.AltPres.Text = '';
            app.GridLayout126 = uigridlayout(app.GridLayout37);
            app.CostEscomesa = uieditfield(app.GridLayout126, 'numeric');
            app.CostEscomesa.Limits = [0 Inf];
            app.CostEscomesa.ValueDisplayFormat = '%.0f';
            app.CostEscomesa.ValueChangedFcn = createCallbackFcn(app, @Input_CostosAlt_h, true);
            app.CostEscomesa.Enable = 'off';
            app.CostEscomesa.Visible = 'off';
            app.Label_CostEscomesa = uilabel(app.GridLayout126);
            app.Label_CostEscomesa.Visible = 'off';
            app.Label_CostEscomesa.Text = ' €';
            app.GridLayout127 = uigridlayout(app.GridLayout37);
            app.kWLabel_4 = uilabel(app.GridLayout127);
            app.kWLabel_4.Text = ' €/kW';
            app.CostEspecificAlt_h = uieditfield(app.GridLayout127, 'numeric');
            app.CostEspecificAlt_h.Limits = [0 5000];
            app.CostEspecificAlt_h.ValueDisplayFormat = '%.0f';
            app.CostEspecificAlt_h.ValueChangedFcn = createCallbackFcn(app, @Input_CostosAlt_h, true);
            app.CostEspecificAlt_h.Enable = 'off';
            app.CostEspecificAlt_h.Tooltip = {'Cost de tots els elements que conformen el sistema de producció de calefacció, i la seva instal·lació'};
            app.CostEspecificAlt_h.Value = 540;
            app.GridLayout128 = uigridlayout(app.GridLayout37);
            app.kWLabel_9 = uilabel(app.GridLayout128);
            app.kWLabel_9.Text = ' €/kW';
            app.MantAlt_h = uieditfield(app.GridLayout128, 'numeric');
            app.MantAlt_h.Limits = [0 300];
            app.MantAlt_h.ValueDisplayFormat = '%.0f';
            app.MantAlt_h.ValueChangedFcn = createCallbackFcn(app, @Input_MantAlt_h, true);
            app.MantAlt_h.Enable = 'off';
            app.MantAlt_h.Tooltip = {'Tasques de manteniment preventiu anual. No inclou possibles reparacions.'};
            app.MantAlt_h.Value = 15;
            app.GridLayout129 = uigridlayout(app.GridLayout37);
            app.GridLayout130 = uigridlayout(app.GridLayout37);
            app.CostEspecificAlt_c = uieditfield(app.GridLayout130, 'numeric');
            app.CostEspecificAlt_c.Limits = [0 5000];
            app.CostEspecificAlt_c.ValueDisplayFormat = '%.0f';
            app.CostEspecificAlt_c.ValueChangedFcn = createCallbackFcn(app, @Input_CostosAlt_c, true);
            app.CostEspecificAlt_c.Enable = 'off';
            app.CostEspecificAlt_c.Tooltip = {'Cost de tots els elements que conformen el sistema de refrigeració, i la seva instal·lació'};
            app.CostEspecificAlt_c.Value = 580;
            app.kWLabel_5 = uilabel(app.GridLayout130);
            app.kWLabel_5.Text = ' €/kW';
            app.GridLayout131 = uigridlayout(app.GridLayout37);
            app.MantAlt_c = uieditfield(app.GridLayout131, 'numeric');
            app.MantAlt_c.Limits = [0 300];
            app.MantAlt_c.ValueDisplayFormat = '%.0f';
            app.MantAlt_c.ValueChangedFcn = createCallbackFcn(app, @Input_MantAlt_c, true);
            app.MantAlt_c.Enable = 'off';
            app.MantAlt_c.Tooltip = {'Tasques de manteniment preventiu anual. No inclou possibles reparacions.'};
            app.MantAlt_c.Value = 6;
            app.kWLabel_10 = uilabel(app.GridLayout131);
            app.kWLabel_10.Text = ' €/kW';
            app.GridLayout132 = uigridlayout(app.GridLayout37);
            app.GridLayout133 = uigridlayout(app.GridLayout37);
            app.CostEspecificAlt_ACS = uieditfield(app.GridLayout133, 'numeric');
            app.CostEspecificAlt_ACS.Limits = [0 5000];
            app.CostEspecificAlt_ACS.ValueDisplayFormat = '%.0f';
            app.CostEspecificAlt_ACS.ValueChangedFcn = createCallbackFcn(app, @Input_CostostAlt_ACS, true);
            app.CostEspecificAlt_ACS.Enable = 'off';
            app.CostEspecificAlt_ACS.Tooltip = {'Cost de tots els elements que conformen el sistema de producció d''ACS, i la seva instal·lació'};
            app.CostEspecificAlt_ACS.Value = 540;
            app.LabelCostEspecificAlt_ACS = uilabel(app.GridLayout133);
            app.LabelCostEspecificAlt_ACS.Text = ' €/kW';
            app.GridLayout134 = uigridlayout(app.GridLayout37);
            app.MantAlt_ACS = uieditfield(app.GridLayout134, 'numeric');
            app.MantAlt_ACS.Limits = [0 300];
            app.MantAlt_ACS.ValueDisplayFormat = '%.0f';
            app.MantAlt_ACS.Enable = 'off';
            app.MantAlt_ACS.Tooltip = {'Tasques de manteniment preventiu anual. No inclou possibles reparacions.'};
            app.MantAlt_ACS.Value = 15;
            app.LabelMantAlt_ACS = uilabel(app.GridLayout134);
            app.LabelMantAlt_ACS.Text = ' €/kW';
            app.GridLayout137 = uigridlayout(app.GridLayout37);
            app.ImportAltSubv = uieditfield(app.GridLayout137, 'numeric');
            app.ImportAltSubv.Limits = [0 10000000];
            app.ImportAltSubv.ValueDisplayFormat = '%.0f';
            app.ImportAltSubv.ValueChangedFcn = createCallbackFcn(app, @Input_ImportAltSubv, true);
            app.ImportAltSubv.Enable = 'off';
            app.ImportAltSubv.Tooltip = {'Valor total subvencionat (inclou climatització i una eventual hibridació amb energia solar fotovoltàica)'};
            app.Label_10 = uilabel(app.GridLayout137);
            app.Label_10.Text = ' €';
            app.GridLayout138 = uigridlayout(app.GridLayout37);
            app.TramAltPres = uieditfield(app.GridLayout138, 'numeric');
            app.TramAltPres.Limits = [0 100];
            app.TramAltPres.ValueDisplayFormat = '%.0f';
            app.TramAltPres.Enable = 'off';
            app.TramAltPres.Tooltip = {'Percentatge que es desitja finançar mitjançant prèstec bancari.'};
            app.Label_9 = uilabel(app.GridLayout138);
            app.Label_9.Text = ' %';
            app.GridLayout139 = uigridlayout(app.GridLayout37);
            app.InteresAltPres = uieditfield(app.GridLayout139, 'numeric');
            app.InteresAltPres.Limits = [0.5 20];
            app.InteresAltPres.ValueDisplayFormat = '%.2f';
            app.InteresAltPres.Enable = 'off';
            app.InteresAltPres.Value = 4;
            app.Label_8 = uilabel(app.GridLayout139);
            app.Label_8.Text = ' %';
            app.GridLayout140 = uigridlayout(app.GridLayout37);
            app.RetornAltPres = uieditfield(app.GridLayout140, 'numeric');
            app.RetornAltPres.Limits = [1 40];
            app.RetornAltPres.ValueDisplayFormat = '%.0f';
            app.RetornAltPres.ValueChangedFcn = createCallbackFcn(app, @Input_RetornAltPres, true);
            app.RetornAltPres.Enable = 'off';
            app.RetornAltPres.Value = 15;
            app.anysLabel_2 = uilabel(app.GridLayout140);
            app.anysLabel_2.Text = ' anys';
            app.CostespecficpousescomesaLabel = uilabel(app.GridLayout37);
            app.CostespecficpousescomesaLabel.Text = 'Cost específic pous/escomesa';
            app.CostmantenimentanualLabel = uilabel(app.GridLayout37);
            app.CostmantenimentanualLabel.Text = 'Cost manteniment (anual)';
            app.GridLayout141 = uigridlayout(app.GridLayout37);
            app.CostEspecInstGeo = uieditfield(app.GridLayout141, 'numeric');
            app.CostEspecInstGeo.Limits = [0 500];
            app.CostEspecInstGeo.ValueDisplayFormat = '%.0f';
            app.CostEspecInstGeo.ValueChangedFcn = createCallbackFcn(app, @Input_CostosGeotermia, true);
            app.CostEspecInstGeo.Editable = 'off';
            app.CostEspecInstGeo.Enable = 'off';
            app.CostEspecInstGeo.Visible = 'off';
            app.CostEspecInstGeo.Tooltip = {''};
            app.kWLabel = uilabel(app.GridLayout141);
            app.kWLabel.Enable = 'off';
            app.kWLabel.Visible = 'off';
            app.kWLabel.Text = ' €/kW';
            app.GridLayout142 = uigridlayout(app.GridLayout37);
            app.CostEspecificPous = uieditfield(app.GridLayout142, 'numeric');
            app.CostEspecificPous.Limits = [0 200];
            app.CostEspecificPous.ValueDisplayFormat = '%.0f';
            app.CostEspecificPous.ValueChangedFcn = createCallbackFcn(app, @Input_CostosGeotermia, true);
            app.CostEspecificPous.Enable = 'off';
            app.CostEspecificPous.Value = 40;
            app.mlLabel = uilabel(app.GridLayout142);
            app.mlLabel.Text = ' €/m.l.';
            app.GridLayout143 = uigridlayout(app.GridLayout37);
            app.CostEspecificGeo = uieditfield(app.GridLayout143, 'numeric');
            app.CostEspecificGeo.Limits = [0 5000];
            app.CostEspecificGeo.ValueDisplayFormat = '%.0f';
            app.CostEspecificGeo.ValueChangedFcn = createCallbackFcn(app, @Input_CostosGeotermia, true);
            app.CostEspecificGeo.Enable = 'off';
            app.CostEspecificGeo.Tooltip = {'Cost de tots els elements que conformen el sistema de producció de calefacció, refrigeració i ACS (menys els pous), a més de la seva instal·lació'};
            app.CostEspecificGeo.Value = 1500;
            app.kWLabel_2 = uilabel(app.GridLayout143);
            app.kWLabel_2.Text = ' €/kW';
            app.GridLayout144 = uigridlayout(app.GridLayout37);
            app.MantGeo = uieditfield(app.GridLayout144, 'numeric');
            app.MantGeo.Limits = [0 300];
            app.MantGeo.Enable = 'off';
            app.MantGeo.Tooltip = {'Tasques de manteniment preventiu anual. No inclou possibles reparacions.'};
            app.MantGeo.Value = 10;
            app.kWLabel_8 = uilabel(app.GridLayout144);
            app.kWLabel_8.Text = ' €/kW';
            app.ImportsubvenciLabel = uilabel(app.GridLayout37);
            app.ImportsubvenciLabel.Text = '   Import subvenció';
            app.GridLayout146 = uigridlayout(app.GridLayout37);
            app.ImportGeoSubv = uieditfield(app.GridLayout146, 'numeric');
            app.ImportGeoSubv.Limits = [0 10000000];
            app.ImportGeoSubv.ValueDisplayFormat = '%.0f';
            app.ImportGeoSubv.ValueChangedFcn = createCallbackFcn(app, @Input_ImportGeoSubv, true);
            app.ImportGeoSubv.Enable = 'off';
            app.ImportGeoSubv.Tooltip = {'Valor total subvencionat (inclou climatització i una eventual hibridació amb energia solar fotovoltàica)'};
            app.Label_7 = uilabel(app.GridLayout146);
            app.Label_7.Text = ' €';
            app.TraminversiambprstecLabel = uilabel(app.GridLayout37);
            app.TraminversiambprstecLabel.Text = '   Tram inversió amb prèstec';
            app.GridLayout147 = uigridlayout(app.GridLayout37);
            app.TramGeoPres = uieditfield(app.GridLayout147, 'numeric');
            app.TramGeoPres.Limits = [0 100];
            app.TramGeoPres.ValueDisplayFormat = '%.0f';
            app.TramGeoPres.Enable = 'off';
            app.TramGeoPres.Tooltip = {'Percentatge que es desitja finançar mitjançant prèstec bancari.'};
            app.Label_3 = uilabel(app.GridLayout147);
            app.Label_3.Text = ' %';
            app.TaxaintersprstecLabel = uilabel(app.GridLayout37);
            app.TaxaintersprstecLabel.Text = '   Taxa interès prèstec';
            app.GridLayout148 = uigridlayout(app.GridLayout37);
            app.InteresGeoPres = uieditfield(app.GridLayout148, 'numeric');
            app.InteresGeoPres.Limits = [0.5 20];
            app.InteresGeoPres.ValueDisplayFormat = '%.2f';
            app.InteresGeoPres.Enable = 'off';
            app.InteresGeoPres.Value = 4;
            app.Label_4 = uilabel(app.GridLayout148);
            app.Label_4.Text = ' %';
            app.PeroderetornprstecLabel = uilabel(app.GridLayout37);
            app.PeroderetornprstecLabel.Text = '   Període retorn prèstec';
            app.GridLayout149 = uigridlayout(app.GridLayout37);
            app.RetornGeoPres = uieditfield(app.GridLayout149, 'numeric');
            app.RetornGeoPres.Limits = [1 40];
            app.RetornGeoPres.ValueDisplayFormat = '%.0f';
            app.RetornGeoPres.ValueChangedFcn = createCallbackFcn(app, @Input_RetornGeoPres, true);
            app.RetornGeoPres.Enable = 'off';
            app.RetornGeoPres.Value = 15;
            app.anysLabel = uilabel(app.GridLayout149);
            app.anysLabel.Text = ' anys';
            app.CostinversiclimatitzaciLabel = uilabel(app.GridLayout37);
            app.CostinversiclimatitzaciLabel.Text = 'Cost inversió climatització [€]';
            app.GridLayout160 = uigridlayout(app.GridLayout37);
            app.CAPEXGeo = uieditfield(app.GridLayout160, 'numeric');
            app.CAPEXGeo.Limits = [0 10000000];
            app.CAPEXGeo.ValueDisplayFormat = '%.0f';
            app.CAPEXGeo.Editable = 'off';
            app.CAPEXGeo.Tooltip = {'Cost total d''inversió en la instal·lació de Geotèrmia.'};
            app.Label = uilabel(app.GridLayout160);
            app.Label.Text = ' €';
            app.GridLayout161 = uigridlayout(app.GridLayout37);
            app.CAPEXAltc = uieditfield(app.GridLayout161, 'numeric');
            app.CAPEXAltc.Limits = [0 10000000];
            app.CAPEXAltc.ValueDisplayFormat = '%.0f';
            app.CAPEXAltc.Editable = 'off';
            app.CAPEXAltc.Tooltip = {'Cost total d''inversió en la component de refrigeració de la instal·lació alternativa'};
            app.Label_2 = uilabel(app.GridLayout161);
            app.Label_2.Text = ' €';
            app.FinanamentambprstecLabel = uilabel(app.GridLayout37);
            app.FinanamentambprstecLabel.Text = 'Finançament amb prèstec';
            app.GridLayout187 = uigridlayout(app.GridLayout37);
            app.CostEspecInstAlt_h = uieditfield(app.GridLayout187, 'numeric');
            app.CostEspecInstAlt_h.Limits = [0 500];
            app.CostEspecInstAlt_h.ValueDisplayFormat = '%.0f';
            app.CostEspecInstAlt_h.ValueChangedFcn = createCallbackFcn(app, @Input_CostosAlt_h, true);
            app.CostEspecInstAlt_h.Editable = 'off';
            app.CostEspecInstAlt_h.Enable = 'off';
            app.CostEspecInstAlt_h.Visible = 'off';
            app.CostEspecInstAlt_h.Tooltip = {''};
            app.kWLabel_3 = uilabel(app.GridLayout187);
            app.kWLabel_3.Enable = 'off';
            app.kWLabel_3.Visible = 'off';
            app.kWLabel_3.Text = ' €/kW';
            app.GridLayout205 = uigridlayout(app.GridLayout37);
            app.Label_100 = uilabel(app.GridLayout205);
            app.Label_100.Text = ' €';
            app.CAPEXAlth = uieditfield(app.GridLayout205, 'numeric');
            app.CAPEXAlth.Limits = [0 10000000];
            app.CAPEXAlth.ValueDisplayFormat = '%.0f';
            app.CAPEXAlth.Editable = 'off';
            app.CAPEXAlth.Tooltip = {'Cost total d''inversió en la component de calefacció de la instal·lació alternativa'};
            app.GridLayout206 = uigridlayout(app.GridLayout37);
            app.Label_101 = uilabel(app.GridLayout206);
            app.Label_101.Text = ' €';
            app.CAPEXAltACS = uieditfield(app.GridLayout206, 'numeric');
            app.CAPEXAltACS.Limits = [0 10000000];
            app.CAPEXAltACS.ValueDisplayFormat = '%.0f';
            app.CAPEXAltACS.Editable = 'off';
            app.CAPEXAltACS.Tooltip = {'Cost total d''inversió en la component de d''ACS de la instal·lació alternativa'};
            app.GridLayout208 = uigridlayout(app.GridLayout37);
            app.Label_102 = uilabel(app.GridLayout208);
            app.Label_102.Text = ' €';
            app.CAPEXGeoTOT = uieditfield(app.GridLayout208, 'numeric');
            app.CAPEXGeoTOT.Limits = [0 10000000];
            app.CAPEXGeoTOT.ValueDisplayFormat = '%.0f';
            app.CAPEXGeoTOT.Editable = 'off';
            app.CAPEXGeoTOT.Tooltip = {'Cost total d''inversió en la instal·lació de Geotèrmia més una eventual instal·lació d''energia fotovoltàica'};
            app.GridLayout209 = uigridlayout(app.GridLayout37);
            app.Label_103 = uilabel(app.GridLayout209);
            app.Label_103.Text = ' €';
            app.CAPEXAltTOT = uieditfield(app.GridLayout209, 'numeric');
            app.CAPEXAltTOT.Limits = [0 10000000];
            app.CAPEXAltTOT.ValueDisplayFormat = '%.0f';
            app.CAPEXAltTOT.Editable = 'off';
            app.CAPEXAltTOT.Tooltip = {'Cost total d''inversió en la instal·lació del conjunt de tecnologies alternatives més una eventual instal·lació d''energia fotovoltàica'};
            app.CostinversiTOTALLabel = uilabel(app.GridLayout37);
            app.CostinversiTOTALLabel.Text = 'Cost inversió TOTAL [€]';
            app.CostespecficinstallaciLabel = uilabel(app.GridLayout37);
            app.CostespecficinstallaciLabel.Visible = 'off';
            app.CostespecficinstallaciLabel.Text = 'Cost específic instal·lació';
            app.CostespecficsistemaLabel = uilabel(app.GridLayout37);
            app.CostespecficsistemaLabel.Text = 'Cost específic sistema ';
            app.GridLayout289 = uigridlayout(app.GridLayout37);
            app.CostEspecInstAlt_c = uieditfield(app.GridLayout289, 'numeric');
            app.CostEspecInstAlt_c.Limits = [0 500];
            app.CostEspecInstAlt_c.ValueDisplayFormat = '%.0f';
            app.CostEspecInstAlt_c.ValueChangedFcn = createCallbackFcn(app, @Input_CostosAlt_c, true);
            app.CostEspecInstAlt_c.Editable = 'off';
            app.CostEspecInstAlt_c.Enable = 'off';
            app.CostEspecInstAlt_c.Visible = 'off';
            app.CostEspecInstAlt_c.Tooltip = {''};
            app.kWLabel_6 = uilabel(app.GridLayout289);
            app.kWLabel_6.Enable = 'off';
            app.kWLabel_6.Visible = 'off';
            app.kWLabel_6.Text = ' €/kW';
            app.GridLayout290 = uigridlayout(app.GridLayout37);
            app.CostEspecInstAlt_ACS = uieditfield(app.GridLayout290, 'numeric');
            app.CostEspecInstAlt_ACS.Limits = [0 500];
            app.CostEspecInstAlt_ACS.ValueDisplayFormat = '%.0f';
            app.CostEspecInstAlt_ACS.ValueChangedFcn = createCallbackFcn(app, @Input_CostostAlt_ACS, true);
            app.CostEspecInstAlt_ACS.Editable = 'off';
            app.CostEspecInstAlt_ACS.Enable = 'off';
            app.CostEspecInstAlt_ACS.Visible = 'off';
            app.CostEspecInstAlt_ACS.Tooltip = {''};
            app.LabelCostEspecInstAlt_ACS = uilabel(app.GridLayout290);
            app.LabelCostEspecInstAlt_ACS.Enable = 'off';
            app.LabelCostEspecInstAlt_ACS.Visible = 'off';
            app.LabelCostEspecInstAlt_ACS.Text = ' €/kW';
            app.GridLayout27 = uigridlayout(app.GridLayout24);
            app.HibridaciambenergiasolarfotovolticaPanel_2 = uipanel(app.GridLayout27);
            app.HibridaciambenergiasolarfotovolticaPanel_2.Title = '4. Hibridació amb energia solar fotovoltàica';
            app.GridLayout52 = uigridlayout(app.HibridaciambenergiasolarfotovolticaPanel_2);
            app.GridLayout56 = uigridlayout(app.GridLayout52);
            app.AcumulaciambbateriesLabel = uilabel(app.GridLayout56);
            app.AcumulaciambbateriesLabel.Text = 'Acumulació amb bateries';
            app.GeoBat = uicheckbox(app.GridLayout56);
            app.GeoBat.ValueChangedFcn = createCallbackFcn(app, @Select_GeoBat, true);
            app.GeoBat.Enable = 'off';
            app.GeoBat.Text = '';
            app.AltBat = uicheckbox(app.GridLayout56);
            app.AltBat.ValueChangedFcn = createCallbackFcn(app, @Select_AltBat, true);
            app.AltBat.Enable = 'off';
            app.AltBat.Text = '';
            app.GridLayout57 = uigridlayout(app.GridLayout52);
            app.PotnciaPVkWpLabel_2 = uilabel(app.GridLayout57);
            app.PotnciaPVkWpLabel_2.Text = 'Potència PV [kWp]';
            app.GeokWpPV = uieditfield(app.GridLayout57, 'numeric');
            app.GeokWpPV.Limits = [0 Inf];
            app.GeokWpPV.ValueDisplayFormat = '%.1f';
            app.GeokWpPV.Editable = 'off';
            app.GeokWpPV.Enable = 'off';
            app.GeokWpPV.Tooltip = {'Potència pic que es necessita instal·lar en el cas de la instal·lació geotèrmica'};
            app.AltkWpPV = uieditfield(app.GridLayout57, 'numeric');
            app.AltkWpPV.Limits = [0 Inf];
            app.AltkWpPV.ValueDisplayFormat = '%.1f';
            app.AltkWpPV.Editable = 'off';
            app.AltkWpPV.Enable = 'off';
            app.AltkWpPV.Tooltip = {'Potència pic que es necessita instal·lar en el cas de la instal·lació alternativa'};
            app.GridLayout58 = uigridlayout(app.GridLayout52);
            app.reapanellsm2Label_2 = uilabel(app.GridLayout58);
            app.reapanellsm2Label_2.Text = 'Àrea panells [m2] ';
            app.Geom2PV = uieditfield(app.GridLayout58, 'numeric');
            app.Geom2PV.Limits = [0 Inf];
            app.Geom2PV.ValueDisplayFormat = '%.1f';
            app.Geom2PV.Editable = 'off';
            app.Geom2PV.Enable = 'off';
            app.Geom2PV.Tooltip = {'Superfície efectiva de panells a instal·lar (inclinació 30º. Factor d''ocupació 40%)'};
            app.Altm2PV = uieditfield(app.GridLayout58, 'numeric');
            app.Altm2PV.Limits = [0 Inf];
            app.Altm2PV.ValueDisplayFormat = '%.1f';
            app.Altm2PV.Editable = 'off';
            app.Altm2PV.Enable = 'off';
            app.Altm2PV.Tooltip = {'Superfície efectiva de panells a instal·lar (inclinació 30º. Factor d''ocupació 40%)'};
            app.GridLayout59 = uigridlayout(app.GridLayout52);
            app.CostespecficbaterieskWhLabel = uilabel(app.GridLayout59);
            app.CostespecficbaterieskWhLabel.Text = 'Cost específic bateries [€/kWh]';
            app.CostEspecBat = uieditfield(app.GridLayout59, 'numeric');
            app.CostEspecBat.Limits = [0 10000];
            app.CostEspecBat.ValueDisplayFormat = '%.2f';
            app.CostEspecBat.ValueChangedFcn = createCallbackFcn(app, @Input_CostEspecBat, true);
            app.CostEspecBat.Enable = 'off';
            app.CostEspecBat.Value = 1000;
            app.GridLayout60 = uigridlayout(app.GridLayout52);
            app.EficinciacrregadescrregaLabel = uilabel(app.GridLayout60);
            app.EficinciacrregadescrregaLabel.Text = 'Eficiència càrrega/descàrrega [%]';
            app.Ch_DisCh_EffBat = uieditfield(app.GridLayout60, 'numeric');
            app.Ch_DisCh_EffBat.Limits = [0 100];
            app.Ch_DisCh_EffBat.ValueDisplayFormat = '%.1f';
            app.Ch_DisCh_EffBat.Enable = 'off';
            app.Ch_DisCh_EffBat.Tooltip = {'També coneguda coma "round-trip efficiency", és el ratio entre energia extreta i energia aportada a la bateria'; ''};
            app.Ch_DisCh_EffBat.Value = 92;
            app.GridLayout61 = uigridlayout(app.GridLayout52);
            app.CapacitatnominalkWhLabel = uilabel(app.GridLayout61);
            app.CapacitatnominalkWhLabel.Text = 'Capacitat nominal [kWh]';
            app.PVGeoEneBat = uieditfield(app.GridLayout61, 'numeric');
            app.PVGeoEneBat.Limits = [0 100];
            app.PVGeoEneBat.ValueDisplayFormat = '%.1f';
            app.PVGeoEneBat.ValueChangedFcn = createCallbackFcn(app, @Input_EneGeoBat, true);
            app.PVGeoEneBat.Enable = 'off';
            app.PVGeoEneBat.Tooltip = {'Com a valor per defecte, es proposa la capacitat necessària per emmagatzemar l''equivalent a la producció mitjana diària amb panells fotovoltàics (Inst. geotèrmica)'};
            app.PVAltEneBat = uieditfield(app.GridLayout61, 'numeric');
            app.PVAltEneBat.Limits = [0 100];
            app.PVAltEneBat.ValueDisplayFormat = '%.1f';
            app.PVAltEneBat.ValueChangedFcn = createCallbackFcn(app, @Input_EneAltBat, true);
            app.PVAltEneBat.Enable = 'off';
            app.PVAltEneBat.Tooltip = {'Com a valor per defecte, es proposa la capacitat necessària per emmagatzemar l''equivalent a la producció mitjana diària amb panells fotovoltàics (Inst. alternativa)'};
            app.GridLayout179 = uigridlayout(app.GridLayout52);
            app.GEOTRMIALabel_2 = uilabel(app.GridLayout179);
            app.GEOTRMIALabel_2.Text = 'GEOTÈRMIA';
            app.ALTERNATIVALabel_2 = uilabel(app.GridLayout179);
            app.ALTERNATIVALabel_2.Text = 'ALTERNATIVA';
            app.GridLayout180 = uigridlayout(app.GridLayout52);
            app.HibridaciambPVLabel = uilabel(app.GridLayout180);
            app.HibridaciambPVLabel.Text = 'Hibridació amb PV';
            app.GeoHibPV = uicheckbox(app.GridLayout180);
            app.GeoHibPV.ValueChangedFcn = createCallbackFcn(app, @Select_GeoHibPV, true);
            app.GeoHibPV.Enable = 'off';
            app.GeoHibPV.Text = '';
            app.AltHibPV = uicheckbox(app.GridLayout180);
            app.AltHibPV.ValueChangedFcn = createCallbackFcn(app, @Select_AltHibPV, true);
            app.AltHibPV.Enable = 'off';
            app.AltHibPV.Text = '';
            app.GridLayout181 = uigridlayout(app.GridLayout52);
            app.Superfciedisponiblem2Label = uilabel(app.GridLayout181);
            app.Superfciedisponiblem2Label.Text = 'Superfície disponible [m2]';
            app.PV_area = uieditfield(app.GridLayout181, 'numeric');
            app.PV_area.Limits = [0 5000];
            app.PV_area.ValueDisplayFormat = '%.1f';
            app.PV_area.ValueChangedFcn = createCallbackFcn(app, @Input_PV_area, true);
            app.PV_area.Enable = 'off';
            app.PV_area.Tooltip = {'Introduïr la superfície de teulada o altri que se''n disposi per a instal·lar panells solars'; ''};
            app.GridLayout182 = uigridlayout(app.GridLayout52);
            app.CostespecficPVkWpLabel = uilabel(app.GridLayout182);
            app.CostespecficPVkWpLabel.Text = 'Cost específic PV [€/kWp]';
            app.PV_cost = uieditfield(app.GridLayout182, 'numeric');
            app.PV_cost.Limits = [0 10000];
            app.PV_cost.ValueDisplayFormat = '%.2f';
            app.PV_cost.ValueChangedFcn = createCallbackFcn(app, @Input_PV_area, true);
            app.PV_cost.Enable = 'off';
            app.PV_cost.Tooltip = {'Inclou els panells, inversors i i altres components essencials d''una instal·lació fotovoltàica sense acumulació.'};
            app.PV_cost.Value = 1000;
            app.GridLayout183 = uigridlayout(app.GridLayout52);
            app.EficinciaconversiLabel = uilabel(app.GridLayout183);
            app.EficinciaconversiLabel.Text = 'Eficiència conversió [%]';
            app.PV_eff = uieditfield(app.GridLayout183, 'numeric');
            app.PV_eff.Limits = [10 25];
            app.PV_eff.ValueDisplayFormat = '%.1f';
            app.PV_eff.ValueChangedFcn = createCallbackFcn(app, @Input_PV_area, true);
            app.PV_eff.Enable = 'off';
            app.PV_eff.Tooltip = {'Eficiència total fotoeléctrica de la instal·lació'; '. Inclou panells, inversors i altres components'};
            app.PV_eff.Value = 15;
            app.GridLayout184 = uigridlayout(app.GridLayout52);
            app.RetribuciexcedentskWhLabel = uilabel(app.GridLayout184);
            app.RetribuciexcedentskWhLabel.Text = 'Retribució excedents [€/kWh]';
            app.Retrib_excdPV = uieditfield(app.GridLayout184, 'numeric');
            app.Retrib_excdPV.Limits = [0 5];
            app.Retrib_excdPV.ValueDisplayFormat = '%.2f';
            app.Retrib_excdPV.Enable = 'off';
            app.Retrib_excdPV.Value = 0.07;
            app.GridLayout185 = uigridlayout(app.GridLayout52);
            app.AutoproducciPVLabel = uilabel(app.GridLayout185);
            app.AutoproducciPVLabel.Text = 'Autoproducció PV [%]';
            app.HibPVGeo_cob = uieditfield(app.GridLayout185, 'numeric');
            app.HibPVGeo_cob.Limits = [0 100];
            app.HibPVGeo_cob.ValueDisplayFormat = '%.1f';
            app.HibPVGeo_cob.ValueChangedFcn = createCallbackFcn(app, @Input_HibPVGeo_cob, true);
            app.HibPVGeo_cob.Enable = 'off';
            app.HibPVGeo_cob.Tooltip = {'Percentatge del consum elèctric de la Instal·lació geotèrmica que s''espera produïr amb els panells fotovoltàics.'};
            app.HibPVAlt_cob = uieditfield(app.GridLayout185, 'numeric');
            app.HibPVAlt_cob.Limits = [0 100];
            app.HibPVAlt_cob.ValueDisplayFormat = '%.1f';
            app.HibPVAlt_cob.ValueChangedFcn = createCallbackFcn(app, @Input_HibPVAlt_cob, true);
            app.HibPVAlt_cob.Enable = 'off';
            app.HibPVAlt_cob.Tooltip = {'Percentatge del consum elèctric de la Instal·lació alternativa que s''espera produïr amb els panells fotovoltàics.'};
            app.GridLayout186 = uigridlayout(app.GridLayout52);
            app.CostinversiPVBateriesLabel = uilabel(app.GridLayout186);
            app.CostinversiPVBateriesLabel.Text = 'Cost inversió PV+Bateries [€]';
            app.PVGeoCostInv = uieditfield(app.GridLayout186, 'numeric');
            app.PVGeoCostInv.Limits = [0 Inf];
            app.PVGeoCostInv.ValueDisplayFormat = '%.0f';
            app.PVGeoCostInv.Editable = 'off';
            app.PVGeoCostInv.Enable = 'off';
            app.PVAltCostInv = uieditfield(app.GridLayout186, 'numeric');
            app.PVAltCostInv.Limits = [0 Inf];
            app.PVAltCostInv.ValueDisplayFormat = '%.0f';
            app.PVAltCostInv.Editable = 'off';
            app.PVAltCostInv.Enable = 'off';

            app.GridLayout207 = uigridlayout(app.GridLayout52);
            app.ProfunditatdedescrregaEditFieldLabel = uilabel(app.GridLayout207);
            app.ProfunditatdedescrregaEditFieldLabel.Text = 'Profunditat de descàrrega [%]';
            app.DoD_Bat = uieditfield(app.GridLayout207, 'numeric');
            app.DoD_Bat.Limits = [10 90];
            app.DoD_Bat.ValueDisplayFormat = '%.1f';
            app.DoD_Bat.Enable = 'off';
            app.DoD_Bat.Tooltip = {'També conegut com "Depth-of-discharge" (DoD) és el màxim percentatge d''energia emmagatzemada a la bateria utilitzable en cada cicle de càrrega-descàrrega  per garantir una vida útil òptima de les bateries'};
            app.DoD_Bat.Value = 80;
            app.GridLayout150 = uigridlayout(app.GridLayout23);
            app.DadesbsiquesdelprojectePanel_2 = uipanel(app.GridLayout150);
            app.DadesbsiquesdelprojectePanel_2.Title = '1. Dades bàsiques del projecte';
            app.GridLayout32 = uigridlayout(app.DadesbsiquesdelprojectePanel_2);
            app.SwitchTipusProjecte = uiswitch(app.GridLayout32, 'slider');
            app.SwitchTipusProjecte.Items = {'Nova instal·lació', 'Substitució instal·lació existent'};
            app.SwitchTipusProjecte.ValueChangedFcn = createCallbackFcn(app, @Switch_Nova_o_Existent, true);
            app.SwitchTipusProjecte.Value = 'Nova instal·lació';
            app.GridLayout151 = uigridlayout(app.GridLayout32);
            app.AnysdevidaencasdinstallaciexistentLabel = uilabel(app.GridLayout151);
            app.AnysdevidaencasdinstallaciexistentLabel.Text = 'Anys de vida, en cas d''instal·lació existent ';
            app.vidaprojexistent = uieditfield(app.GridLayout151, 'numeric');
            app.vidaprojexistent.Limits = [0 100];
            app.vidaprojexistent.ValueDisplayFormat = '%.0f';
            app.vidaprojexistent.Enable = 'off';
            app.GridLayout152 = uigridlayout(app.GridLayout32);
            app.VidaestimadadelprojecteanysLabel = uilabel(app.GridLayout152);
            app.VidaestimadadelprojecteanysLabel.Text = 'Vida estimada del projecte [anys] ';
            app.vidaprojecte = uieditfield(app.GridLayout152, 'numeric');
            app.vidaprojecte.Limits = [5 50];
            app.vidaprojecte.ValueDisplayFormat = '%.0f';
            app.vidaprojecte.ValueChangedFcn = createCallbackFcn(app, @Input_VidaProjecte, true);
            app.vidaprojecte.Enable = 'off';
            app.vidaprojecte.Value = 15;
            app.GridLayout153 = uigridlayout(app.GridLayout32);
            app.TaxadedescompteLabel = uilabel(app.GridLayout153);
            app.TaxadedescompteLabel.Text = 'Taxa de descompte [%] ';
            app.Taxadescompte = uieditfield(app.GridLayout153, 'numeric');
            app.Taxadescompte.Limits = [0.5 20];
            app.Taxadescompte.ValueDisplayFormat = '%.2f';
            app.Taxadescompte.Enable = 'off';
            app.Taxadescompte.Value = 5;
            app.GridLayout178 = uigridlayout(app.GridLayout32);
            app.TaxainflacigeneralanualLabel = uilabel(app.GridLayout178);
            app.TaxainflacigeneralanualLabel.Text = 'Taxa inflació general anual [%]';
            app.Infl_general = uieditfield(app.GridLayout178, 'numeric');
            app.Infl_general.Limits = [0 100];
            app.Infl_general.ValueDisplayFormat = '%.2f';
            app.Infl_general.Enable = 'off';
            app.Infl_general.Value = 2;
            app.CostosdeconsumenergticvalorsmonetarisambIVAinclsPanel = uipanel(app.GridLayout150);
            app.CostosdeconsumenergticvalorsmonetarisambIVAinclsPanel.Title = '3. Costos de consum energètic (valors monetaris amb I.V.A. inclòs)';
            app.GridLayout154 = uigridlayout(app.CostosdeconsumenergticvalorsmonetarisambIVAinclsPanel);
            app.CostfixanualLabel = uilabel(app.GridLayout154);
            app.CostfixanualLabel.Text = 'Cost fix (anual)';
            app.CostvariableLabel = uilabel(app.GridLayout154);
            app.CostvariableLabel.Text = 'Cost variable';
            app.InflacianualcostfixLabel = uilabel(app.GridLayout154);
            app.InflacianualcostfixLabel.Text = 'Inflació anual cost fix';
            app.InflacianualcostvariableLabel = uilabel(app.GridLayout154);
            app.InflacianualcostvariableLabel.Text = 'Inflació anual cost variable';
            app.ELECTRICITATLabel = uilabel(app.GridLayout154);
            app.ELECTRICITATLabel.Text = 'ELECTRICITAT';
            app.BIOMASSALabel = uilabel(app.GridLayout154);
            app.BIOMASSALabel.Text = 'BIOMASSA';
            app.GASNATURALLabel = uilabel(app.GridLayout154);
            app.GASNATURALLabel.Text = 'GAS NATURAL';
            app.GASPROPLabel = uilabel(app.GridLayout154);
            app.GASPROPLabel.Text = 'GAS PROPÀ';
            app.GASOILLabel = uilabel(app.GridLayout154);
            app.GASOILLabel.Text = 'GASOIL';
            app.GridLayout155 = uigridlayout(app.GridLayout154);
            app.kWLabel_7 = uilabel(app.GridLayout155);
            app.kWLabel_7.Text = ' €/kW';
            app.CostkWVall = uieditfield(app.GridLayout155, 'numeric');
            app.CostkWVall.Limits = [0 50];
            app.CostkWVall.ValueDisplayFormat = '%.2f';
            app.CostkWVall.Enable = 'off';
            app.CostkWVall.Tooltip = {'Preu kW/any periode VALL,  impostos inclosos'};
            app.CostkWVall.Value = 3.5;
            app.CostkWPlaPunta = uieditfield(app.GridLayout155, 'numeric');
            app.CostkWPlaPunta.Limits = [0 500];
            app.CostkWPlaPunta.ValueDisplayFormat = '%.2f';
            app.CostkWPlaPunta.Enable = 'off';
            app.CostkWPlaPunta.Tooltip = {'Preu kW/any periode PLA-PUNTA, impostos inclosos'};
            app.CostkWPlaPunta.Value = 40;
            app.GridLayout156 = uigridlayout(app.GridLayout154);
            app.kWhLabel = uilabel(app.GridLayout156);
            app.kWhLabel.Text = ' €/kWh';
            app.CostkWhPunta = uieditfield(app.GridLayout156, 'numeric');
            app.CostkWhPunta.Limits = [0 5];
            app.CostkWhPunta.ValueDisplayFormat = '%.2f';
            app.CostkWhPunta.Enable = 'off';
            app.CostkWhPunta.Tooltip = {'Preu kWh periode PUNTA, impostos inclosos'};
            app.CostkWhPunta.Value = 0.29;
            app.CostkWhVall = uieditfield(app.GridLayout156, 'numeric');
            app.CostkWhVall.Limits = [0 5];
            app.CostkWhVall.ValueDisplayFormat = '%.2f';
            app.CostkWhVall.Enable = 'off';
            app.CostkWhVall.Tooltip = {'Preu kWh periode VALL, impostos inclosos'};
            app.CostkWhVall.Value = 0.11;
            app.CostkWhPla = uieditfield(app.GridLayout156, 'numeric');
            app.CostkWhPla.Limits = [0 5];
            app.CostkWhPla.ValueDisplayFormat = '%.2f';
            app.CostkWhPla.Enable = 'off';
            app.CostkWhPla.Tooltip = {'Preu kWh periode PLA, impostos inclosos'};
            app.CostkWhPla.Value = 0.16;
            app.GridLayout157 = uigridlayout(app.GridLayout154);
            app.Label_12 = uilabel(app.GridLayout157);
            app.Label_12.Text = ' %';
            app.Infl_AnualVar_e = uieditfield(app.GridLayout157, 'numeric');
            app.Infl_AnualVar_e.Limits = [0 100];
            app.Infl_AnualVar_e.ValueDisplayFormat = '%.2f';
            app.Infl_AnualVar_e.Enable = 'off';
            app.Infl_AnualVar_e.Value = 4;
            app.GridLayout158 = uigridlayout(app.GridLayout154);
            app.Infl_AnualFix_e = uieditfield(app.GridLayout158, 'numeric');
            app.Infl_AnualFix_e.Limits = [0 100];
            app.Infl_AnualFix_e.ValueDisplayFormat = '%.2f';
            app.Infl_AnualFix_e.Enable = 'off';
            app.Infl_AnualFix_e.Value = 2;
            app.Label_13 = uilabel(app.GridLayout158);
            app.Label_13.Text = ' %';
            app.GridLayout159 = uigridlayout(app.GridLayout154);
            app.NALabel = uilabel(app.GridLayout159);
            app.NALabel.Text = 'N.A.';
            app.GridLayout162 = uigridlayout(app.GridLayout154);
            app.NALabel_2 = uilabel(app.GridLayout162);
            app.NALabel_2.Text = 'N.A.';
            app.GridLayout163 = uigridlayout(app.GridLayout154);
            app.CostkgBiom = uieditfield(app.GridLayout163, 'numeric');
            app.CostkgBiom.Limits = [0 5];
            app.CostkgBiom.ValueDisplayFormat = '%.2f';
            app.CostkgBiom.Enable = 'off';
            app.CostkgBiom.Value = 0.25;
            app.kgLabel = uilabel(app.GridLayout163);
            app.kgLabel.Text = ' €/kg';
            app.GridLayout164 = uigridlayout(app.GridLayout154);
            app.Infl_AnualVar_Biom = uieditfield(app.GridLayout164, 'numeric');
            app.Infl_AnualVar_Biom.Limits = [0 100];
            app.Infl_AnualVar_Biom.ValueDisplayFormat = '%.2f';
            app.Infl_AnualVar_Biom.Enable = 'off';
            app.Infl_AnualVar_Biom.Value = 2;
            app.Label_14 = uilabel(app.GridLayout164);
            app.Label_14.Text = ' %';
            app.GridLayout165 = uigridlayout(app.GridLayout154);
            app.CostGN = uieditfield(app.GridLayout165, 'numeric');
            app.CostGN.Limits = [0 600];
            app.CostGN.ValueDisplayFormat = '%.2f';
            app.CostGN.Enable = 'off';
            app.CostGN.Value = 70;
            app.Label_18 = uilabel(app.GridLayout165);
            app.Label_18.Text = ' €';
            app.GridLayout166 = uigridlayout(app.GridLayout154);
            app.Infl_AnualFix_GN = uieditfield(app.GridLayout166, 'numeric');
            app.Infl_AnualFix_GN.Limits = [0 100];
            app.Infl_AnualFix_GN.ValueDisplayFormat = '%.2f';
            app.Infl_AnualFix_GN.Enable = 'off';
            app.Infl_AnualFix_GN.Value = 2;
            app.Label_19 = uilabel(app.GridLayout166);
            app.Label_19.Text = ' %';
            app.GridLayout167 = uigridlayout(app.GridLayout154);
            app.CostkWhGN = uieditfield(app.GridLayout167, 'numeric');
            app.CostkWhGN.Limits = [0 55];
            app.CostkWhGN.ValueDisplayFormat = '%.2f';
            app.CostkWhGN.Enable = 'off';
            app.CostkWhGN.Value = 0.07;
            app.kWhLabel_3 = uilabel(app.GridLayout167);
            app.kWhLabel_3.Text = ' €/kWh';
            app.GridLayout168 = uigridlayout(app.GridLayout154);
            app.Label_17 = uilabel(app.GridLayout168);
            app.Label_17.Text = ' %';
            app.Infl_AnualVar_GN = uieditfield(app.GridLayout168, 'numeric');
            app.Infl_AnualVar_GN.Limits = [0 100];
            app.Infl_AnualVar_GN.ValueDisplayFormat = '%.2f';
            app.Infl_AnualVar_GN.Enable = 'off';
            app.Infl_AnualVar_GN.Value = 1;
            app.GridLayout169 = uigridlayout(app.GridLayout154);
            app.CostGC = uieditfield(app.GridLayout169, 'numeric');
            app.CostGC.Limits = [0 250];
            app.CostGC.ValueDisplayFormat = '%.2f';
            app.CostGC.Enable = 'off';
            app.CostGC.Tooltip = {'Cost anual diferent de zero  en cas de gas ciutat canalitzat'; ''; ''; ''};
            app.CostGC.Value = 25;
            app.Label_21 = uilabel(app.GridLayout169);
            app.Label_21.Text = ' €';
            app.GridLayout171 = uigridlayout(app.GridLayout154);
            app.CostkgGC = uieditfield(app.GridLayout171, 'numeric');
            app.CostkgGC.Limits = [0 20];
            app.CostkgGC.ValueDisplayFormat = '%.2f';
            app.CostkgGC.Enable = 'off';
            app.CostkgGC.Value = 1.8;
            app.kgLabel_2 = uilabel(app.GridLayout171);
            app.kgLabel_2.Text = ' €/kg';
            app.GridLayout172 = uigridlayout(app.GridLayout154);
            app.Infl_AnualFix_GC = uieditfield(app.GridLayout172, 'numeric');
            app.Infl_AnualFix_GC.Limits = [0 100];
            app.Infl_AnualFix_GC.ValueDisplayFormat = '%.2f';
            app.Infl_AnualFix_GC.Enable = 'off';
            app.Infl_AnualFix_GC.Value = 2;
            app.Label_20 = uilabel(app.GridLayout172);
            app.Label_20.Text = ' %';
            app.GridLayout173 = uigridlayout(app.GridLayout154);
            app.Label_16 = uilabel(app.GridLayout173);
            app.Label_16.Text = ' %';
            app.Infl_AnualVar_GC = uieditfield(app.GridLayout173, 'numeric');
            app.Infl_AnualVar_GC.Limits = [0 100];
            app.Infl_AnualVar_GC.ValueDisplayFormat = '%.2f';
            app.Infl_AnualVar_GC.Enable = 'off';
            app.Infl_AnualVar_GC.Value = 1;
            app.GridLayout174 = uigridlayout(app.GridLayout154);
            app.NALabel_3 = uilabel(app.GridLayout174);
            app.NALabel_3.Text = 'N.A.';
            app.GridLayout175 = uigridlayout(app.GridLayout154);
            app.NALabel_4 = uilabel(app.GridLayout175);
            app.NALabel_4.Text = 'N.A.';
            app.GridLayout176 = uigridlayout(app.GridLayout154);
            app.CostlGasoil = uieditfield(app.GridLayout176, 'numeric');
            app.CostlGasoil.Limits = [0 8];
            app.CostlGasoil.ValueDisplayFormat = '%.2f';
            app.CostlGasoil.Enable = 'off';
            app.CostlGasoil.Value = 0.8;
            app.lLabel = uilabel(app.GridLayout176);
            app.lLabel.Text = ' €/l';
            app.GridLayout177 = uigridlayout(app.GridLayout154);
            app.Infl_AnualVar_Gasoil = uieditfield(app.GridLayout177, 'numeric');
            app.Infl_AnualVar_Gasoil.Limits = [0 100];
            app.Infl_AnualVar_Gasoil.ValueDisplayFormat = '%.2f';
            app.Infl_AnualVar_Gasoil.Enable = 'off';
            app.Infl_AnualVar_Gasoil.Value = 1;
            app.Label_15 = uilabel(app.GridLayout177);
            app.Label_15.Text = ' %';
            app.GridLayout188 = uigridlayout(app.GridLayout23);
            app.ObservacionsLabel = uilabel(app.GridLayout188);
            app.ObservacionsLabel.Text = 'Observacions:';
            app.Label_EcoAmbObs1 = uilabel(app.GridLayout188);
            app.Label_EcoAmbObs1.Text = 'Per a escenaris comparatius de llarga durada, es contempla la renovació dels equips de climatització cada 15 anys i dels panells solars i bateries cada 20 anys (en cas d''hibridació amb PV).';
            app.Label_EcoAmbObs2 = uilabel(app.GridLayout188);
            app.Label_EcoAmbObs2.Text = 'El cost de l''electricitat està diferenciat per període de tarificació vall/pla/punta (cost variable) i vall/pla-punta (cost fix). Es recomana incloure l'' I.V.A. als valors monetaris editats per l''usuari.';
            app.Label_EcoAmbObs3 = uilabel(app.GridLayout188);
            app.Label_EcoAmbObs3.Text = '';
            app.Label_EcoAmbObs4 = uilabel(app.GridLayout188);
            app.Label_EcoAmbObs4.Text = '';
            app.GridLayout35 = uigridlayout(app.GridLayout22);
            app.LabelEcoAmb_Main = uilabel(app.GridLayout35);
            app.LabelEcoAmb_Main.Text = '   Dades d''entrada per a l''anàlisi econòmica i ambiental';
            app.Image55 = uiimage(app.GridLayout35);
            app.Image55.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.GridLayout199 = uigridlayout(app.GridLayout22);
            app.RunEcoAmb = uibutton(app.GridLayout199, 'push');
            app.RunEcoAmb.ButtonPushedFcn = createCallbackFcn(app, @RUN_AnalisiEcoAmb, true);
            app.RunEcoAmb.Enable = 'off';
            app.RunEcoAmb.Text = 'Executar';
            app.ValorsperdefecteButton = uibutton(app.GridLayout199, 'push');
            app.ValorsperdefecteButton.ButtonPushedFcn = createCallbackFcn(app, @Write_ValorsDefecteEcoAmb, true);
            app.ValorsperdefecteButton.Text = 'Valors per defecte';
            app.ManualGeoSIVv12Button_3 = uibutton(app.GridLayout199, 'push');
            app.ManualGeoSIVv12Button_3.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_Manual, true);
            app.ManualGeoSIVv12Button_3.Text = 'Manual Geo-SIV v1.2';
            app.EnrereButton = uibutton(app.GridLayout199, 'state');
            app.EnrereButton.ValueChangedFcn = createCallbackFcn(app, @GoTo_ResultatsDisseny, true);
            app.EnrereButton.Text = 'Enrere';
            app.ResultatsEcoAmbTab = uitab(app.TabGroup);
            app.ResultatsEcoAmbTab.Title = 'Resultats rendibilitat i emissions';
            app.GridLayout96 = uigridlayout(app.ResultatsEcoAmbTab);
            app.GridLayout97 = uigridlayout(app.GridLayout96);
            app.ResultatsdelanlisieconmicaiambientalLabel = uilabel(app.GridLayout97);
            app.ResultatsdelanlisieconmicaiambientalLabel.Text = '   Resultats de l''anàlisi econòmica i ambiental';
            app.Image56 = uiimage(app.GridLayout97);
            app.Image56.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.GridLayout98 = uigridlayout(app.GridLayout96);
            app.GridLayout99 = uigridlayout(app.GridLayout98);
            app.GridLayout113 = uigridlayout(app.GridLayout99);
            app.Panel_9 = uipanel(app.GridLayout113);
            app.UIplotFluxeVAN = uiaxes(app.Panel_9);
            title(app.UIplotFluxeVAN, '')
            xlabel(app.UIplotFluxeVAN, 't (anys)')
            ylabel(app.UIplotFluxeVAN, 'Despesa instal·lacions (k€)')
            app.UIplotFluxeVAN.XLim = [0 16];
            app.UIplotFluxeVAN.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.UIplotFluxeVAN.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'};
            app.Panel_10 = uipanel(app.GridLayout113);
            app.GridLayout190 = uigridlayout(app.Panel_10);
            app.EcoPlotOption = uibuttongroup(app.GridLayout190);
            app.EcoPlotOption.SelectionChangedFcn = createCallbackFcn(app, @Choose_Eco_o_VANPlot, true);
            app.EcoPlotOption.Tooltip = {'Selecciona quines dades es representaran'};
            app.PlotDespesa = uiradiobutton(app.EcoPlotOption);
            app.PlotDespesa.Enable = 'off';
            app.PlotDespesa.Text = 'Evolució despesa acumulada';
            app.PlotDespesa.Value = true;
            app.PlotVAN = uiradiobutton(app.EcoPlotOption);
            app.PlotVAN.Enable = 'off';
            app.PlotVAN.Text = 'Evolució VAN';
            app.Panel_RendibilitatEcoAmb = uipanel(app.GridLayout99);
            app.Panel_RendibilitatEcoAmb.Title = '5. Rendibilitat econòmica i emissions';
            app.GridLayout31 = uigridlayout(app.Panel_RendibilitatEcoAmb);
            app.PerodederetornsimpleanysLabel = uilabel(app.GridLayout31);
            app.PerodederetornsimpleanysLabel.Text = 'Període de retorn simple [anys]';
            app.Paybacktext = uieditfield(app.GridLayout31, 'text');
            app.Paybacktext.Editable = 'off';
            app.Paybacktext.Enable = 'off';
            app.Paybacktext.Tooltip = {'Estimació temps fins que la despesa acumulada de l''alternativa supera a la despesa acumulada de la geotèrmia'};
            app.Paybacktext.Value = '0.0';
            app.ValoractualitzatnetVANLabel = uilabel(app.GridLayout31);
            app.ValoractualitzatnetVANLabel.Text = 'Valor actualitzat net (VAN) [€]';
            app.VAN = uieditfield(app.GridLayout31, 'numeric');
            app.VAN.ValueDisplayFormat = '%.1f';
            app.VAN.ValueChangedFcn = createCallbackFcn(app, @Write_VANBackgroundColor, true);
            app.VAN.Editable = 'off';
            app.VAN.Enable = 'off';
            app.VAN.Tooltip = {'Benefici tenint en compte el valor de taxa de descompte'};
            app.EstalviemissionsCO2eqLabel = uilabel(app.GridLayout31);
            app.EstalviemissionsCO2eqLabel.Text = 'Estalvi emissions CO2 eq. [%]';
            app.EstalviCO2rel = uieditfield(app.GridLayout31, 'numeric');
            app.EstalviCO2rel.ValueDisplayFormat = '%.1f';
            app.EstalviCO2rel.Editable = 'off';
            app.EstalviCO2rel.Enable = 'off';
            app.EstalviCO2rel.Tooltip = {'Percentatge estalviat d''emissions de CO2 equivalents respecte instal·lació alternativa.'};
            app.EstalviemissionsCO2eqkganyLabel = uilabel(app.GridLayout31);
            app.EstalviemissionsCO2eqkganyLabel.Text = 'Estalvi emissions CO2 eq. [kg/any]';
            app.EstalviCO2 = uieditfield(app.GridLayout31, 'numeric');
            app.EstalviCO2.ValueDisplayFormat = '%.1f';
            app.EstalviCO2.Editable = 'off';
            app.EstalviCO2.Enable = 'off';
            app.EstalviCO2.Tooltip = {'Estalvi anual d''emissions de CO2 equivalents respecte instal·lació alternativa.'};
            app.PerodederetorndescomptatanysLabel = uilabel(app.GridLayout31);
            app.PerodederetorndescomptatanysLabel.Text = 'Període de retorn descomptat [anys]';
            app.PaybackDesctext = uieditfield(app.GridLayout31, 'text');
            app.PaybackDesctext.Editable = 'off';
            app.PaybackDesctext.Enable = 'off';
            app.PaybackDesctext.Tooltip = {'Temps estimat en assolir VAN>0'};
            app.PaybackDesctext.Value = '0.0';
            app.Estalviesperat = uieditfield(app.GridLayout31, 'numeric');
            app.Estalviesperat.ValueDisplayFormat = '%.1f';
            app.Estalviesperat.Editable = 'off';
            app.Estalviesperat.Enable = 'off';
            app.Estalviesperat.Tooltip = {'Tant per cent d''estalvi al llarg de tota la vida del projecte, considerant totes les despeses operacionals (no inclou costos d''inversió ni costos financers).'};
            app.TaxainternaderetornTIREditFieldLabel = uilabel(app.GridLayout31);
            app.TaxainternaderetornTIREditFieldLabel.Text = 'Taxa interna de retorn (TIR) [%]';
            app.TIRt = uieditfield(app.GridLayout31, 'text');
            app.TIRt.Editable = 'off';
            app.TIRt.Enable = 'off';
            app.TIRt.Tooltip = {'Valor de la taxa de descompte que varia que VAN=0'};
            app.TIRt.Value = '0.0';
            app.RendibilitatnetaEditFieldLabel = uilabel(app.GridLayout31);
            app.RendibilitatnetaEditFieldLabel.Text = 'Rendibilitat neta [%]';
            app.RendibNetTotal = uieditfield(app.GridLayout31, 'text');
            app.RendibNetTotal.Editable = 'off';
            app.RendibNetTotal.Enable = 'off';
            app.RendibNetTotal.Tooltip = {'Relació entre el benefici net i inversió neta en geotèrmia'};
            app.RendibNetTotal.Value = '0.0';
            app.GridLayout285 = uigridlayout(app.GridLayout31);
            app.EstalviestimatLabel = uilabel(app.GridLayout285);
            app.EstalviestimatLabel.Text = 'Estalvi estimat [%]';
            app.EstalviNetTotal = uieditfield(app.GridLayout285, 'numeric');
            app.EstalviNetTotal.ValueDisplayFormat = '%.1f';
            app.EstalviNetTotal.Editable = 'off';
            app.EstalviNetTotal.Enable = 'off';
            app.EstalviNetTotal.Visible = 'off';
            app.EstalviNetTotal.Tooltip = {'Estalvi al llarg de tota la vida del projecte, considerant totes les despeses menys el cost d''inversió'};
            app.GridLayout189 = uigridlayout(app.GridLayout98);
            app.PanelAltCon_e = uipanel(app.GridLayout189);
            app.MonthPlotAlt = uiaxes(app.PanelAltCon_e);
            title(app.MonthPlotAlt, '')
            xlabel(app.MonthPlotAlt, '  ')
            ylabel(app.MonthPlotAlt, 'Energia Elèctrica [MWhe]')
            app.MonthPlotAlt.XLim = [0 13];
            app.MonthPlotAlt.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.MonthPlotAlt.XTickLabel = {'Gen'; 'Feb'; 'Mar'; 'Abr'; 'Mai'; 'Jun'; 'Jul'; 'Ago'; 'Set'; 'Oct'; 'Nov'; 'Des'};
            app.PanelGeoCon_e = uipanel(app.GridLayout189);
            app.MonthPlotGeo = uiaxes(app.PanelGeoCon_e);
            title(app.MonthPlotGeo, '')
            xlabel(app.MonthPlotGeo, '  ')
            ylabel(app.MonthPlotGeo, 'Energia elèctrica [MWhe]')
            app.MonthPlotGeo.XLim = [0 13];
            app.MonthPlotGeo.XTick = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.MonthPlotGeo.XTickLabel = {'Gen'; 'Feb'; 'Mar'; 'Abr'; 'Mai'; 'Jun'; 'Jul'; 'Ago'; 'Set'; 'Oct'; 'Nov'; 'Des'};
            app.GridLayout194 = uigridlayout(app.GridLayout189);
            app.ConLabel_ConsumElectricHib = uipanel(app.GridLayout194);
            app.ConLabel_ConsumElectricHib.Title = '6. Consum elèctric i autoproducció (cas hibridació amb PV)';
            app.GridLayout191 = uigridlayout(app.ConLabel_ConsumElectricHib);
            app.GridLayout192 = uigridlayout(app.GridLayout191);
            app.GEOTRMIALabel_3 = uilabel(app.GridLayout192);
            app.GEOTRMIALabel_3.Text = '◄ GEOTÈRMIA';
            app.ALTERNATIVALabel_3 = uilabel(app.GridLayout192);
            app.ALTERNATIVALabel_3.Text = 'ALTERNATIVA ►';
            app.GridLayout193 = uigridlayout(app.GridLayout191);
            app.AltCons_e = uieditfield(app.GridLayout193, 'numeric');
            app.AltCons_e.ValueDisplayFormat = '%.2f';
            app.AltCons_e.Editable = 'off';
            app.AltCons_e.Enable = 'off';
            app.AltCons_e.Tooltip = {'Consum total d''electricitat per part de la instal·lació alternativa'};
            app.GeoCons_e = uieditfield(app.GridLayout193, 'numeric');
            app.GeoCons_e.ValueDisplayFormat = '%.2f';
            app.GeoCons_e.Editable = 'off';
            app.GeoCons_e.Enable = 'off';
            app.GeoCons_e.Tooltip = {'Consum total d''electricitat per part de la instal·lació geotèrmica'};
            app.LabelCons_e = uilabel(app.GridLayout193);
            app.LabelCons_e.Text = 'Consum anual [MWhe]';
            app.LabelDesp_e = uilabel(app.GridLayout193);
            app.LabelDesp_e.Text = 'Despesa neta total electricitat [€]';
            app.LabelAuto_e = uilabel(app.GridLayout193);
            app.LabelAuto_e.Text = 'Autoproducció anual [MWhe]';
            app.GraudautoconsumLabel = uilabel(app.GridLayout193);
            app.GraudautoconsumLabel.Text = 'Grau d''autoconsum [%]';
            app.ExcedentanualaxarxaMWheLabel = uilabel(app.GridLayout193);
            app.ExcedentanualaxarxaMWheLabel.Text = 'Excedent anual a xarxa [MWhe]';
            app.GeoDesp_e = uieditfield(app.GridLayout193, 'numeric');
            app.GeoDesp_e.ValueDisplayFormat = '%.1f';
            app.GeoDesp_e.Editable = 'off';
            app.GeoDesp_e.Enable = 'off';
            app.GeoDesp_e.Tooltip = {'Despesa neta corresponent al consum elèctric només de xarxa i restant-li la retribució dels excedents (geotèrmia), al llarg de la vida del projecte. '};
            app.AltDesp_e = uieditfield(app.GridLayout193, 'numeric');
            app.AltDesp_e.ValueDisplayFormat = '%.1f';
            app.AltDesp_e.Editable = 'off';
            app.AltDesp_e.Enable = 'off';
            app.AltDesp_e.Tooltip = {'Despesa neta corresponent al consum elèctric només de xarxa i restant-li la retribució dels excedents (geotèrmia), al llarg de la vida del projecte. '};
            app.GeoAutoprod_e = uieditfield(app.GridLayout193, 'numeric');
            app.GeoAutoprod_e.ValueDisplayFormat = '%.2f';
            app.GeoAutoprod_e.Editable = 'off';
            app.GeoAutoprod_e.Enable = 'off';
            app.GeoAutoprod_e.Tooltip = {'Electricitat produïda anualment pels panells fotovoltaics de support a la instal·lació geotèrmica'};
            app.AltAutoprod_e = uieditfield(app.GridLayout193, 'numeric');
            app.AltAutoprod_e.ValueDisplayFormat = '%.2f';
            app.AltAutoprod_e.Editable = 'off';
            app.AltAutoprod_e.Enable = 'off';
            app.AltAutoprod_e.Tooltip = {'Electricitat produïda anualment pels panells fotovoltaics de support a la instal·lació alternativa'};
            app.GeoAutocons_e = uieditfield(app.GridLayout193, 'numeric');
            app.GeoAutocons_e.ValueDisplayFormat = '%.2f';
            app.GeoAutocons_e.Editable = 'off';
            app.GeoAutocons_e.Enable = 'off';
            app.GeoAutocons_e.Tooltip = {'Porció del consum d''electriciat corresponent als panells fotovoltaics de support a la geotèrmia (consum instantani o bé a través de bateries)'};
            app.AltAutocons_e = uieditfield(app.GridLayout193, 'numeric');
            app.AltAutocons_e.ValueDisplayFormat = '%.2f';
            app.AltAutocons_e.Editable = 'off';
            app.AltAutocons_e.Enable = 'off';
            app.AltAutocons_e.Tooltip = {'Porció del consum d''electriciat corresponent als panells fotovoltaics de support a l''alternativa (consum instantani o bé a través de bateries)'};
            app.EstalvigrciesaautoconsumLabel = uilabel(app.GridLayout193);
            app.EstalvigrciesaautoconsumLabel.Text = 'Estalvi gràcies a autoconsum [%]';
            app.GeoEstalviAutoc_e = uieditfield(app.GridLayout193, 'numeric');
            app.GeoEstalviAutoc_e.ValueDisplayFormat = '%.2f';
            app.GeoEstalviAutoc_e.Editable = 'off';
            app.GeoEstalviAutoc_e.Enable = 'off';
            app.GeoEstalviAutoc_e.Tooltip = {'Percentatge estalviat de la factura de la llum respecte a un escenari sense autoproducció/autoconsum (geotèrmia) al llarg de la vida útil del projecte.'};
            app.AltEstalviAutoc_e = uieditfield(app.GridLayout193, 'numeric');
            app.AltEstalviAutoc_e.ValueDisplayFormat = '%.2f';
            app.AltEstalviAutoc_e.Editable = 'off';
            app.AltEstalviAutoc_e.Enable = 'off';
            app.AltEstalviAutoc_e.Tooltip = {'Percentatge estalviat de la factura de la llum respecte a un escenari sense autoproducció/autoconsum (alternativa) al llarg de la vida útil del projecte.'};
            app.GeoExcdPV_e = uieditfield(app.GridLayout193, 'numeric');
            app.GeoExcdPV_e.ValueDisplayFormat = '%.2f';
            app.GeoExcdPV_e.Editable = 'off';
            app.GeoExcdPV_e.Enable = 'off';
            app.GeoExcdPV_e.Tooltip = {'Electricitat produïda anualment pels panells fotovoltaics de la instal·lació geotèrmica no consumida ni emmagatzemada'};
            app.AltExcdPV_e = uieditfield(app.GridLayout193, 'numeric');
            app.AltExcdPV_e.ValueDisplayFormat = '%.2f';
            app.AltExcdPV_e.Editable = 'off';
            app.AltExcdPV_e.Enable = 'off';
            app.AltExcdPV_e.Tooltip = {'Electricitat produïda anualment pels panells fotovoltaics de la instal·lació alternativa no consumida ni emmagatzemada'};
            app.GridLayout215 = uigridlayout(app.GridLayout189);
            app.GEOPV_PLOTlabel = uilabel(app.GridLayout215);
            app.GEOPV_PLOTlabel.Text = 'GEOTÈRMIA';
            app.GridLayout216 = uigridlayout(app.GridLayout189);
            app.ALTPV_PLOTlabel_c = uilabel(app.GridLayout216);
            app.ALTPV_PLOTlabel_c.Text = 'ALTERNATIVA';
            app.ALTPV_PLOTlabel_h = uilabel(app.GridLayout216);
            app.ALTPV_PLOTlabel_h.Text = '';
            app.ALTPV_PLOTlabel_ACS = uilabel(app.GridLayout216);
            app.ALTPV_PLOTlabel_ACS.Text = '';
            app.plus_1 = uilabel(app.GridLayout216);
            app.plus_1.Text = '';
            app.plus_2 = uilabel(app.GridLayout216);
            app.plus_2.Text = '';
            app.GridLayout201 = uigridlayout(app.GridLayout96);
            app.InformeTOT = uibutton(app.GridLayout201, 'push');
            app.InformeTOT.ButtonPushedFcn = createCallbackFcn(app, @Generate_Report, true);
            app.InformeTOT.Enable = 'off';
            app.InformeTOT.Text = 'Generar Informe';
            app.EnrereButtonResultatsEcoAmb = uibutton(app.GridLayout201, 'state');
            app.EnrereButtonResultatsEcoAmb.ValueChangedFcn = createCallbackFcn(app, @GoTo_EcoAmbTab, true);
            app.EnrereButtonResultatsEcoAmb.Text = 'Enrere';
            app.ManualGeoSIVv12Button_4 = uibutton(app.GridLayout201, 'push');
            app.ManualGeoSIVv12Button_4.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_Manual, true);
            app.ManualGeoSIVv12Button_4.Text = 'Manual Geo-SIV v1.2';
            app.ResumprojecteButton = uibutton(app.GridLayout201, 'push');
            app.ResumprojecteButton.ButtonPushedFcn = createCallbackFcn(app, @GoTo_Resum, true);
            app.ResumprojecteButton.Text = 'Resum projecte';
            app.ResumTab = uitab(app.TabGroup);
            app.ResumTab.Title = 'Resum';
            app.GridLayout217 = uigridlayout(app.ResumTab);
            app.GridLayout218 = uigridlayout(app.GridLayout217);
            app.Label_Resumdelprojecte = uilabel(app.GridLayout218);
            app.Label_Resumdelprojecte.Text = '  Resum del projecte';
            app.Image57 = uiimage(app.GridLayout218);
            app.Image57.ImageSource = '01_Logo_corto_Geo-SIV.png';
            app.GridLayout219 = uigridlayout(app.GridLayout217);
            app.EnrereButton_3 = uibutton(app.GridLayout219, 'push');
            app.EnrereButton_3.ButtonPushedFcn = createCallbackFcn(app, @GoTo_ResultatsEcoAmb, true);
            app.EnrereButton_3.Text = 'Enrere';
            app.ManualGeoSIVv12Button_5 = uibutton(app.GridLayout219, 'push');
            app.ManualGeoSIVv12Button_5.ButtonPushedFcn = createCallbackFcn(app, @LinkTo_Manual, true);
            app.ManualGeoSIVv12Button_5.Text = 'Manual Geo-SIV v1.2';
            app.GridLayout220 = uigridlayout(app.GridLayout217);
            app.GridLayout222 = uigridlayout(app.GridLayout220);
            app.Panel_PrevisioCostos = uipanel(app.GridLayout222);
            app.Panel_PrevisioCostos.Title = 'PREVISIÓ DE COSTOS D''INVERSIÓ I RENDIBILITAT DEL PROJECTE';
            app.GridLayout271 = uigridlayout(app.Panel_PrevisioCostos);
            app.Panel_SubPrevisioCostos = uipanel(app.GridLayout271);
            app.GridLayout224 = uigridlayout(app.Panel_SubPrevisioCostos);
            app.GridLayout242 = uigridlayout(app.GridLayout224);
            app.GridLayout245 = uigridlayout(app.GridLayout242);
            app.Panel_EscenariProjecte = uipanel(app.GridLayout245);
            app.Panel_EscenariProjecte.Title = 'Escenari de l''estudi comparatiu';
            app.GridLayout246 = uigridlayout(app.Panel_EscenariProjecte);
            app.EscenariProjecte1 = uilabel(app.GridLayout246);
            app.EscenariProjecte1.Text = '';
            app.EscenariProjecte2 = uilabel(app.GridLayout246);
            app.EscenariProjecte2.Text = '';
            app.EscenariProjecte3 = uilabel(app.GridLayout246);
            app.EscenariProjecte3.Text = '';
            app.Panel_VidaProjecte = uipanel(app.GridLayout245);
            app.Panel_VidaProjecte.Title = 'Duració del projecte a efectes d''anàlisi econòmica i ambiental';
            app.GridLayout247 = uigridlayout(app.Panel_VidaProjecte);
            app.VidaProjecte = uilabel(app.GridLayout247);
            app.VidaProjecte.Text = '';
            app.Panel_Rendibilitat = uipanel(app.GridLayout245);
            app.Panel_Rendibilitat.Title = 'Rendibilitat neta';
            app.GridLayout248 = uigridlayout(app.Panel_Rendibilitat);
            app.Rendibilitat1 = uilabel(app.GridLayout248);
            app.Rendibilitat1.Text = '';
            app.Rendibilitat2 = uilabel(app.GridLayout248);
            app.Rendibilitat2.Text = '';
            app.Rendibilitat3 = uilabel(app.GridLayout248);
            app.Rendibilitat3.Text = '';
            app.Panel_RecuperacioInversio = uipanel(app.GridLayout245);
            app.Panel_RecuperacioInversio.Title = 'Recuperació de la inversió';
            app.GridLayout248_2 = uigridlayout(app.Panel_RecuperacioInversio);
            app.RecuperacioInversio = uilabel(app.GridLayout248_2);
            app.RecuperacioInversio.Text = '';
            app.Panel_BeneficisAmbientals = uipanel(app.GridLayout245);
            app.Panel_BeneficisAmbientals.Title = 'Emissions anuals CO2 eq. potencialment estalviades';
            app.GridLayout248_3 = uigridlayout(app.Panel_BeneficisAmbientals);
            app.BeneficisAmbientals = uilabel(app.GridLayout248_3);
            app.BeneficisAmbientals.Text = '';
            app.Panel_CostosRentabilitat = uipanel(app.GridLayout242);
            app.Panel_CostosRentabilitat.Title = 'Previsió de costos d''inversió (I.V.A. inclòs) i finançament';
            app.GridLayout228 = uigridlayout(app.Panel_CostosRentabilitat);
            app.GridLayout229 = uigridlayout(app.GridLayout228);
            app.Label_Campdecaptacio = uilabel(app.GridLayout229);
            app.Label_Campdecaptacio.Text = 'Camp de captació:';
            app.CostCaptacioGeo = uilabel(app.GridLayout229);
            app.CostCaptacioGeo.Text = '';
            app.NALabel_5 = uilabel(app.GridLayout229);
            app.NALabel_5.Text = 'N.A.';
            app.GridLayout230 = uigridlayout(app.GridLayout228);
            app.EquipsInstalacioGeo = uilabel(app.GridLayout230);
            app.EquipsInstalacioGeo.Text = 'Equips + instal·lació:';
            app.CostEquipsAlt = uilabel(app.GridLayout230);
            app.CostEquipsAlt.Text = '';
            app.CostEquipsGeo = uilabel(app.GridLayout230);
            app.CostEquipsGeo.Text = '';
            app.GridLayout231 = uigridlayout(app.GridLayout228);
            app.Label_Hibridacio = uilabel(app.GridLayout231);
            app.Label_Hibridacio.Text = 'Hibridació amb energia solar PV:';
            app.HibSiNoGeo = uilabel(app.GridLayout231);
            app.HibSiNoGeo.Text = '';
            app.HibSiNoAlt = uilabel(app.GridLayout231);
            app.HibSiNoAlt.Text = '';
            app.GridLayout232 = uigridlayout(app.GridLayout228);
            app.Label_Panells = uilabel(app.GridLayout232);
            app.Label_Panells.Text = 'Panells:';
            app.CostPanellsGeo = uilabel(app.GridLayout232);
            app.CostPanellsGeo.Text = '';
            app.CostPanellsAlt = uilabel(app.GridLayout232);
            app.CostPanellsAlt.Text = '';
            app.GridLayout233 = uigridlayout(app.GridLayout228);
            app.CostBateriesAlt = uilabel(app.GridLayout233);
            app.CostBateriesAlt.Text = '';
            app.CostBateriesGeo = uilabel(app.GridLayout233);
            app.CostBateriesGeo.Text = '';
            app.Label_Bateries = uilabel(app.GridLayout233);
            app.Label_Bateries.Text = 'Bateries:';
            app.GridLayout234 = uigridlayout(app.GridLayout228);
            app.Label_COSTTOTAL = uilabel(app.GridLayout234);
            app.Label_COSTTOTAL.Text = 'COST D''INVERSIÓ TOTAL:';
            app.COSTTOTALAlt = uilabel(app.GridLayout234);
            app.COSTTOTALAlt.Text = '';
            app.COSTTOTALGeo = uilabel(app.GridLayout234);
            app.COSTTOTALGeo.Text = '';
            app.GridLayout235 = uigridlayout(app.GridLayout228);
            app.Label_FinSubvencio = uilabel(app.GridLayout235);
            app.Label_FinSubvencio.Text = 'Finançament amb subvenció:';
            app.SubvencioGeo = uilabel(app.GridLayout235);
            app.SubvencioGeo.Text = '';
            app.SubvencioAlt = uilabel(app.GridLayout235);
            app.SubvencioAlt.Text = '';
            app.GridLayout236 = uigridlayout(app.GridLayout228);
            app.Label_FinPrestec = uilabel(app.GridLayout236);
            app.Label_FinPrestec.Text = 'Finançament amb prèstec:';
            app.PrestecAlt = uilabel(app.GridLayout236);
            app.PrestecAlt.Text = '';
            app.PrestecGeo = uilabel(app.GridLayout236);
            app.PrestecGeo.Text = '';
            app.GridLayout249 = uigridlayout(app.GridLayout228);
            app.Label_ALTERNATIVA = uilabel(app.GridLayout249);
            app.Label_ALTERNATIVA.Text = 'ALTERNATIVA';
            app.Label_GEOTRMIA = uilabel(app.GridLayout249);
            app.Label_GEOTRMIA.Text = 'GEOTÈRMIA';
            app.GridLayout250 = uigridlayout(app.GridLayout228);
            app.DadesPresGeo = uilabel(app.GridLayout250);
            app.DadesPresGeo.Text = '';
            app.DadesPresAlt = uilabel(app.GridLayout250);
            app.DadesPresAlt.Text = '';
            app.GridLayout251 = uigridlayout(app.GridLayout228);
            app.Label_CostNetInversio = uilabel(app.GridLayout251);
            app.Label_CostNetInversio.Text = 'COST EXTRA INVERSIÓ GEOTÈRMIA:';
            app.COSTNETGeo = uilabel(app.GridLayout251);
            app.COSTNETGeo.Text = '';
            app.GridLayout262 = uigridlayout(app.GridLayout228);
            app.Label_BeneficiNet = uilabel(app.GridLayout262);
            app.Label_BeneficiNet.Text = 'BENEFICI NET TOTAL:';
            app.BeneficiNet = uilabel(app.GridLayout262);
            app.BeneficiNet.Text = '';
            app.GridLayout286 = uigridlayout(app.GridLayout242);
            app.Panel_EstalviMensual = uipanel(app.GridLayout286);
            app.Panel_EstalviMensual.Title = 'Estalvi acumulat';
            app.GridLayout284 = uigridlayout(app.Panel_EstalviMensual);
            app.GENLabel = uilabel(app.GridLayout284);
            app.GENLabel.Text = 'GEN';
            app.FEBLabel = uilabel(app.GridLayout284);
            app.FEBLabel.Text = 'FEB';
            app.MARLabel = uilabel(app.GridLayout284);
            app.MARLabel.Text = 'MAR';
            app.DESLabel = uilabel(app.GridLayout284);
            app.DESLabel.Text = 'DES';
            app.NOVLabel = uilabel(app.GridLayout284);
            app.NOVLabel.Text = 'NOV';
            app.OCTLabel = uilabel(app.GridLayout284);
            app.OCTLabel.Text = 'OCT';
            app.SETLabel = uilabel(app.GridLayout284);
            app.SETLabel.Text = 'SET';
            app.AGOLabel = uilabel(app.GridLayout284);
            app.AGOLabel.Text = 'AGO';
            app.JULLabel = uilabel(app.GridLayout284);
            app.JULLabel.Text = 'JUL';
            app.MAILabel = uilabel(app.GridLayout284);
            app.MAILabel.Text = 'MAI';
            app.JUNLabel = uilabel(app.GridLayout284);
            app.JUNLabel.Text = 'JUN';
            app.ABRLabel = uilabel(app.GridLayout284);
            app.ABRLabel.Text = 'ABR';
            app.Label_Est01TOT = uilabel(app.GridLayout284);
            app.Label_Est01TOT.Text = '';
            app.Label_Est02TOT = uilabel(app.GridLayout284);
            app.Label_Est02TOT.Text = '';
            app.Label_Est03TOT = uilabel(app.GridLayout284);
            app.Label_Est03TOT.Text = '';
            app.Label_Est04TOT = uilabel(app.GridLayout284);
            app.Label_Est04TOT.Text = '';
            app.Label_Est05TOT = uilabel(app.GridLayout284);
            app.Label_Est05TOT.Text = '';
            app.Label_Est06TOT = uilabel(app.GridLayout284);
            app.Label_Est06TOT.Text = '';
            app.Label_Est07TOT = uilabel(app.GridLayout284);
            app.Label_Est07TOT.Text = '';
            app.Label_Est08TOT = uilabel(app.GridLayout284);
            app.Label_Est08TOT.Text = '';
            app.Label_Est09TOT = uilabel(app.GridLayout284);
            app.Label_Est09TOT.Text = '';
            app.Label_Est10TOT = uilabel(app.GridLayout284);
            app.Label_Est10TOT.Text = '';
            app.Label_Est11TOT = uilabel(app.GridLayout284);
            app.Label_Est11TOT.Text = '';
            app.Label_Est12TOT = uilabel(app.GridLayout284);
            app.Label_Est12TOT.Text = '';
            app.LabelTOTALestalvi = uilabel(app.GridLayout286);
            app.LabelTOTALestalvi.Text = 'TOTAL:';
            app.GridLayout240 = uigridlayout(app.GridLayout222);
            app.Panel_DemandaConsums = uipanel(app.GridLayout240);
            app.Panel_DemandaConsums.Title = 'ESTIMACIÓ DE DEMANDA I CONSUM ANUALS D''ENERGIA';
            app.GridLayout269 = uigridlayout(app.Panel_DemandaConsums);
            app.Panel_SubDemandaConsums = uipanel(app.GridLayout269);
            app.GridLayout107_2 = uigridlayout(app.Panel_SubDemandaConsums);
            app.Panel_PotEneTermica = uipanel(app.GridLayout107_2);
            app.Panel_PotEneTermica.Title = 'Potència / Energia tèrmica          ';
            app.GridLayout108_2 = uigridlayout(app.Panel_PotEneTermica);
            app.CALORLabel_3 = uilabel(app.GridLayout108_2);
            app.CALORLabel_3.Text = 'CALOR';
            app.FREDLabel_3 = uilabel(app.GridLayout108_2);
            app.FREDLabel_3.Text = 'FRED';
            app.ACSLabel_3 = uilabel(app.GridLayout108_2);
            app.ACSLabel_3.Text = 'ACS';
            app.Label_Capacitat = uilabel(app.GridLayout108_2);
            app.Label_Capacitat.Text = '  Potència nominal (BCG)';
            app.Label_Demanda = uilabel(app.GridLayout108_2);
            app.Label_Demanda.Text = '  Demanda';
            app.DemandaCalf = uilabel(app.GridLayout108_2);
            app.DemandaCalf.Text = '';
            app.DemandaRefr = uilabel(app.GridLayout108_2);
            app.DemandaRefr.Text = '';
            app.Demanda_ACS = uilabel(app.GridLayout108_2);
            app.Demanda_ACS.Text = '';
            app.CapacitatCalf = uilabel(app.GridLayout108_2);
            app.CapacitatCalf.Text = '';
            app.CapacitatRefr = uilabel(app.GridLayout108_2);
            app.CapacitatRefr.Text = '';
            app.Capacitat_ACS = uilabel(app.GridLayout108_2);
            app.Capacitat_ACS.Text = '';
            app.RendimentsestacionalsLabel = uilabel(app.GridLayout108_2);
            app.RendimentsestacionalsLabel.Text = '  Rendiments estacionals ';
            app.SPFcalefaccio = uilabel(app.GridLayout108_2);
            app.SPFcalefaccio.Text = '';
            app.SPFrefrigeracio = uilabel(app.GridLayout108_2);
            app.SPFrefrigeracio.Text = '';
            app.SPFacs = uilabel(app.GridLayout108_2);
            app.SPFacs.Text = '';
            app.Panel_PotEneElectrica = uipanel(app.GridLayout107_2);
            app.Panel_PotEneElectrica.Title = 'Potència / Energia elèctrica';
            app.GridLayout109_2 = uigridlayout(app.Panel_PotEneElectrica);
            app.GridLayout256 = uigridlayout(app.GridLayout109_2);
            app.Label_PotenciaeContratada = uilabel(app.GridLayout256);
            app.Label_PotenciaeContratada.Text = '  Potència elèctrica a contractar';
            app.PotenciaeContratada = uilabel(app.GridLayout256);
            app.PotenciaeContratada.Text = '';
            app.GridLayout257 = uigridlayout(app.GridLayout109_2);
            app.Label_ConsumeTotal = uilabel(app.GridLayout257);
            app.Label_ConsumeTotal.Text = '  Consum elèctric total';
            app.ConsumeTotal = uilabel(app.GridLayout257);
            app.ConsumeTotal.Text = '';
            app.Panel_SimulacioCampCaptacio = uipanel(app.GridLayout240);
            app.Panel_SimulacioCampCaptacio.Title = 'SIMULACIÓ DEL CAMP DE CAPTACIÓ GEOTÈRMIC';
            app.GridLayout270 = uigridlayout(app.Panel_SimulacioCampCaptacio);
            app.Panel_SubSimulacioCampCaptacio = uipanel(app.GridLayout270);
            app.GridLayout258 = uigridlayout(app.Panel_SubSimulacioCampCaptacio);
            app.Panel_Pous = uipanel(app.GridLayout258);
            app.Panel_Pous.Title = 'Pous';
            app.GridLayout259 = uigridlayout(app.Panel_Pous);
            app.PousInfo1_wrt = uilabel(app.GridLayout259);
            app.PousInfo1_wrt.Text = '';
            app.PousInfo2_wrt = uilabel(app.GridLayout259);
            app.PousInfo2_wrt.Text = '';
            app.Panel_Sondes = uipanel(app.GridLayout258);
            app.Panel_Sondes.Title = 'Tipus de sonda';
            app.GridLayout260 = uigridlayout(app.Panel_Sondes);
            app.SondesInfo_wrt = uilabel(app.GridLayout260);
            app.SondesInfo_wrt.Text = '';
            app.Panel_Materials = uipanel(app.GridLayout258);
            app.Panel_Materials.Title = 'Material i dimensions sonda';
            app.GridLayout261 = uigridlayout(app.Panel_Materials);
            app.MaterialsInfo_wrt = uilabel(app.GridLayout261);
            app.MaterialsInfo_wrt.Text = '';
            % Show the figure after all components are created
            app.GeoSIVdesigntoolv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeoSIV_codi1_15

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeoSIVdesigntoolv10UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeoSIVdesigntoolv10UIFigure)
        end
    end
end